classdef FFproc_CombinePeriods < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Toolbar               matlab.ui.container.Toolbar
        PushReset             matlab.ui.container.toolbar.PushTool
        PushLoadFile          matlab.ui.container.toolbar.PushTool
        PushCombine           matlab.ui.container.toolbar.PushTool
        PushUpdate            matlab.ui.container.toolbar.PushTool
        PushAutoCombine       matlab.ui.container.toolbar.PushTool
        PushSave              matlab.ui.container.toolbar.PushTool
        GridLayout4           matlab.ui.container.GridLayout
        GridLayout5           matlab.ui.container.GridLayout
        table                 matlab.ui.control.Table
        GridLayout7           matlab.ui.container.GridLayout
        tipmax                matlab.ui.control.NumericEditField
        skewmaxLabel          matlab.ui.control.Label
        tipmin                matlab.ui.control.NumericEditField
        skewminLabel          matlab.ui.control.Label
        ptmax                 matlab.ui.control.NumericEditField
        ptmaxEditFieldLabel   matlab.ui.control.Label
        ptmin                 matlab.ui.control.NumericEditField
        ptminEditFieldLabel   matlab.ui.control.Label
        phimax                matlab.ui.control.NumericEditField
        phimaxLabel           matlab.ui.control.Label
        phimin                matlab.ui.control.NumericEditField
        phiminLabel           matlab.ui.control.Label
        rhomax                matlab.ui.control.NumericEditField
        rhomaxEditFieldLabel  matlab.ui.control.Label
        rhomin                matlab.ui.control.NumericEditField
        rhominEditFieldLabel  matlab.ui.control.Label
        permax                matlab.ui.control.NumericEditField
        permaxEditFieldLabel  matlab.ui.control.Label
        permin                matlab.ui.control.NumericEditField
        perminEditFieldLabel  matlab.ui.control.Label
        jobtable              matlab.ui.control.Table
        GridLayout6           matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
    end

    properties (Access = public)
        TF, EVal
        F, P, S
        mt
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TF = [];
            app.EVal = [];
            app.F = [];
            app.P = [];
            app.S = [];
            app.table.Data = [];
            app.jobtable.Data = [];
            app.Panel.AutoResizeChildren = 'off';  
            delete(findall(app.Panel,'type','axes'))
        end

        % Callback function: PushReset
        function RestartMenuCallback(app, event)
            startupFcn(app)
        end

        % Callback function: PushLoadFile
        function LoadFileCallback(app, event)
            if isempty(app.TF)                
                % Identifying Operative System
                [user,~] = getuser;
                % Path for each FFproc user (Documents)
                cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            end
            
            [file,path] = uigetfile({'*FIT***%.mat','FIT Files (*.mat)';...
                                     '*SLD***%.mat','SLD Files (*.mat)';...
                                     '*ALL***%.mat','ALL Files (*.mat)';...
                                     '*.mat','FFsave (*.mat)';
                                     '*.*','All Files (*.*)'},...
                                     'Select results to combine'); 
            if file == 0
                return
            else
                cd(path)     
                filein = load(fullfile(path,file));
            end
            figure(app.UIFigure)

            if isempty(app.TF)
                app.TF(1).data = filein.FFsave;
                app.EVal(1).data = filein.job;
            else
                app.TF(end+1).data = filein.FFsave;
                app.EVal(end+1).data = filein.job;
            end
            
            app.F = [app.F;cellstr(file)];   
            app.P = [app.P;cellstr(path)]; 
            app.S = [app.S;filein.FFsave(1).site];
       
            for i = 1:numel(app.TF)
                opt{i} = cat(1,app.TF(i).data.site)';
                app.jobtable.ColumnFormat{1,2} = opt{i};
            end
            
            fill = [app.F,app.S];            
            app.jobtable.Data = fill;           
        end

        % Callback function: PushCombine
        function CombineMenuCallback(app, event)
            app.mt = [];
            for i = 1:size(app.jobtable.Data,1)
                indx = find(strcmp(app.jobtable.DisplayData{i,2},cat(1,app.TF(i).data.site)));
                app.mt = [app.mt,app.TF(i).data(indx)];
            end

            sr = NaN(numel(app.EVal),1);
            for i = 1:numel(app.EVal)
                sr(i) = app.EVal(i).data(1).sr;
            end            
            [~,id] = sort(sr,'descend');

            app.TF = app.TF(id);
            app.EVal = app.EVal(id);
            app.mt = app.mt(id);

            for i = 1:numel(app.TF)
                for j = 1:numel(app.TF(i).data)
                    opt{i}(j) = app.TF(i).data(j).site;
                end
                app.jobtable.ColumnFormat{1,2} = opt{i};
            end
            app.jobtable.Data = app.jobtable.Data(id,:);
            
            info = getinfo(app);
            procsave_filltable(app,app.mt)                         
            procsave_plotTF(app,app.mt,info)
        end

        % Callback function: PushAutoCombine
        function AutoCombineClicked(app, event)
            FFproc_mergeOPTandRAW
        end

        % Callback function: PushSave
        function SaveMenuCallback(app, event)
            [file,path] = procsave_savecomb(app,app.mt,app.EVal);
            if file ~= 0
                uialert(app.UIFigure,{['File: ',file];'saved in:';path},...
                                     'FFproc - Saving','Icon','Success');
                figure(app.UIFigure);
            else
            end
        end

        % Value changed function: phimax, phimin, ptmax, ptmin, rhomax, 
        % ...and 3 other components
        function LimitChangedCallback(app, event)
            ax = findall(app.Panel.Children,'type','axes');

            set(ax([1,2,5,6]),'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(app.permin.Value:app.permax.Value))

            set(ax(6),'YLim',10.^[app.rhomin.Value,app.rhomax.Value],'YTick',10.^(app.rhomin.Value:app.rhomax.Value))
            set(ax(2),'YLim',[app.phimin.Value,app.phimax.Value],'YTick',round(linspace(app.phimin.Value,app.phimax.Value,9)))
            set(ax([1,5]),'YLim',[app.tipmin.Value,app.tipmax.Value])

            caxis(ax(3),[app.ptmin.Value,app.ptmax.Value])
        end

        % Callback function: PushUpdate, permax, permin
        function TableCellEditCallback(app, event)
            info = getinfo(app);
            procsave_plotTF(app,app.mt,info)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Combine Periods';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushReset = uipushtool(app.Toolbar);
            app.PushReset.Tooltip = {'Reset'};
            app.PushReset.ClickedCallback = createCallbackFcn(app, @RestartMenuCallback, true);
            app.PushReset.Icon = 'FFproc_restart.png';
            app.PushReset.Separator = 'on';
            app.PushLoadFile = uipushtool(app.Toolbar);
            app.PushLoadFile.Tooltip = {'Load File'};
            app.PushLoadFile.ClickedCallback = createCallbackFcn(app, @LoadFileCallback, true);
            app.PushLoadFile.Icon = 'FFproc_plus.png';
            app.PushCombine = uipushtool(app.Toolbar);
            app.PushCombine.Tooltip = {'Combine'};
            app.PushCombine.ClickedCallback = createCallbackFcn(app, @CombineMenuCallback, true);
            app.PushCombine.Icon = 'FFproc_cper.png';
            app.PushCombine.Separator = 'on';
            app.PushUpdate = uipushtool(app.Toolbar);
            app.PushUpdate.Tooltip = {'Update View'};
            app.PushUpdate.ClickedCallback = createCallbackFcn(app, @TableCellEditCallback, true);
            app.PushUpdate.Icon = 'FFproc_update.png';
            app.PushAutoCombine = uipushtool(app.Toolbar);
            app.PushAutoCombine.Tooltip = {'Auto-Combine'};
            app.PushAutoCombine.ClickedCallback = createCallbackFcn(app, @AutoCombineClicked, true);
            app.PushAutoCombine.Icon = 'FFproc_autocombine.png';
            app.PushAutoCombine.Separator = 'on';
            app.PushSave = uipushtool(app.Toolbar);
            app.PushSave.Tooltip = {'Save'};
            app.PushSave.ClickedCallback = createCallbackFcn(app, @SaveMenuCallback, true);
            app.PushSave.Icon = 'FFproc_save2.png';
            app.PushSave.Separator = 'on';
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.Panel = uipanel(app.GridLayout6);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.jobtable = uitable(app.GridLayout5);
            app.jobtable.ColumnName = {'File'; 'Site'};
            app.jobtable.ColumnEditable = [false true];
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.perminEditFieldLabel = uilabel(app.GridLayout7);
            app.perminEditFieldLabel.Text = 'per min';
            app.permin = uieditfield(app.GridLayout7, 'numeric');
            app.permin.ValueChangedFcn = createCallbackFcn(app, @TableCellEditCallback, true);
            app.permin.Value = -4;
            app.permaxEditFieldLabel = uilabel(app.GridLayout7);
            app.permaxEditFieldLabel.Text = 'per max';
            app.permax = uieditfield(app.GridLayout7, 'numeric');
            app.permax.ValueChangedFcn = createCallbackFcn(app, @TableCellEditCallback, true);
            app.permax.Value = 3;
            app.rhominEditFieldLabel = uilabel(app.GridLayout7);
            app.rhominEditFieldLabel.Text = 'rho min';
            app.rhomin = uieditfield(app.GridLayout7, 'numeric');
            app.rhomin.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.rhomaxEditFieldLabel = uilabel(app.GridLayout7);
            app.rhomaxEditFieldLabel.Text = 'rho max';
            app.rhomax = uieditfield(app.GridLayout7, 'numeric');
            app.rhomax.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.rhomax.Value = 4;
            app.phiminLabel = uilabel(app.GridLayout7);
            app.phiminLabel.Text = 'phi min';
            app.phimin = uieditfield(app.GridLayout7, 'numeric');
            app.phimin.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.phimaxLabel = uilabel(app.GridLayout7);
            app.phimaxLabel.Text = 'phi max';
            app.phimax = uieditfield(app.GridLayout7, 'numeric');
            app.phimax.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.phimax.Value = 90;
            app.ptminEditFieldLabel = uilabel(app.GridLayout7);
            app.ptminEditFieldLabel.Text = 'pt min';
            app.ptmin = uieditfield(app.GridLayout7, 'numeric');
            app.ptmin.Limits = [-45 135];
            app.ptmin.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.ptmaxEditFieldLabel = uilabel(app.GridLayout7);
            app.ptmaxEditFieldLabel.Text = 'pt max';
            app.ptmax = uieditfield(app.GridLayout7, 'numeric');
            app.ptmax.Limits = [-45 135];
            app.ptmax.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.ptmax.Value = 90;
            app.skewminLabel = uilabel(app.GridLayout7);
            app.skewminLabel.Text = 'tip min';
            app.tipmin = uieditfield(app.GridLayout7, 'numeric');
            app.tipmin.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.tipmin.Value = -1;
            app.skewmaxLabel = uilabel(app.GridLayout7);
            app.skewmaxLabel.Text = 'tip max';
            app.tipmax = uieditfield(app.GridLayout7, 'numeric');
            app.tipmax.ValueChangedFcn = createCallbackFcn(app, @LimitChangedCallback, true);
            app.tipmax.Value = 1;
            app.table = uitable(app.GridLayout5);
            app.table.ColumnName = {'ID'; 'Frequency/Period'; 'Include'};
            app.table.ColumnEditable = [false false true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_CombinePeriods

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end