classdef FFproc_CombineStations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Toolbar         matlab.ui.container.Toolbar
        PushTool        matlab.ui.container.toolbar.PushTool
        PushTool2       matlab.ui.container.toolbar.PushTool
        PushTool3       matlab.ui.container.toolbar.PushTool
        PushTool4       matlab.ui.container.toolbar.PushTool
        GridLayout      matlab.ui.container.GridLayout
        DirectoryLabel  matlab.ui.control.Label
        dirlabel        matlab.ui.control.Label
        intable         matlab.ui.control.Table
        outtable        matlab.ui.control.Table
    end

    properties (Access = public)
        inputinfo
        outputinfo
        ind
        indout
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Clicked callback: PushTool
        function BrowseButtonPushed(app, event)
            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            
            path = uigetdir;
            figure(app.UIFigure)
            cd(path)
            app.dirlabel.Text = path;
            
            info = dir('*.mat');
            app.inputinfo = info;
            file = {info(:).name}';
            file(ismember(file,{'.','..'})) = [];
            
            app.intable.Data = file;               
        end

        % Cell selection callback: intable
        function intableCellSelection(app, event)
            app.ind = event.Indices(:,1);
        end

        % Cell selection callback: outtable
        function outtableCellSelection(app, event)
            app.indout = unique(event.Indices(:,1));
        end

        % Clicked callback: PushTool3
        function addbuttonPushed(app, event)
            outinfo = app.inputinfo(app.ind);
            for i = 1:numel(outinfo)
                load(fullfile(outinfo(i).folder,outinfo(i).name))                
                if exist('FFsave','var')
                    nstation = numel(FFsave);
                elseif exist('mt','var')
                    nstation = numel(mt);
                else
                    uialert(app.UIFigure,{['File: ',outinfo(i).name];'is not compatible'},...
                                     'Saving','Icon','Error');
                    return
                end                
                app.outtable.Data = [app.outtable.Data;[outinfo(i).folder,app.intable.Data(app.ind(i)),num2cell(nstation)]];
            end

            app.outputinfo = mergestruct(outinfo,app.outputinfo);
        end

        % Clicked callback: PushTool4
        function rembuttonPushed(app, event)
            app.outtable.Data(app.indout,:) = [];
            app.outputinfo(app.indout) = [];
        end

        % Clicked callback: PushTool2
        function SaveButtonPushed(app, event)
            FFproc_SaveStations(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Combine Stations';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Selet Directory'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.PushTool.Icon = 'FFproc_browse.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Save'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @SaveButtonPushed, true);
            app.PushTool2.Icon = 'FFproc_save2.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Include'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @addbuttonPushed, true);
            app.PushTool3.Icon = 'FFproc_forward.png';
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Remove'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @rembuttonPushed, true);
            app.PushTool4.Icon = 'FFproc_rewind.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.outtable = uitable(app.GridLayout);
            app.outtable.ColumnName = {'Folder'; 'Selected files'; 'Stations'};
            app.outtable.CellSelectionCallback = createCallbackFcn(app, @outtableCellSelection, true);
            app.intable = uitable(app.GridLayout);
            app.intable.ColumnName = {'Contents'};
            app.intable.RowName = {};
            app.intable.CellSelectionCallback = createCallbackFcn(app, @intableCellSelection, true);
            app.dirlabel = uilabel(app.GridLayout);
            app.dirlabel.Text = '';
            app.DirectoryLabel = uilabel(app.GridLayout);
            app.DirectoryLabel.Text = 'Directory:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_CombineStations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end