classdef CreateCTS_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Toolbar        matlab.ui.container.Toolbar
        PushTool       matlab.ui.container.toolbar.PushTool
        PushTool2      matlab.ui.container.toolbar.PushTool
        PushTool3      matlab.ui.container.toolbar.PushTool
        PushTool4      matlab.ui.container.toolbar.PushTool
        GridLayout     matlab.ui.container.GridLayout
        table          matlab.ui.control.Table
        clcpath        matlab.ui.control.Label
        pfcpath        matlab.ui.control.Label
        clbpath        matlab.ui.control.Label
        syspath        matlab.ui.control.Label
        PFCPathButton  matlab.ui.control.Button
        CLBPathButton  matlab.ui.control.Button
        CLCPathButton  matlab.ui.control.Button
        SysCalButton   matlab.ui.control.Button
    end

    
    properties (Access = public)
        ind
        path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [user,~] = getuser;
            cd(fullfile(user,'Documents','FFMT','FFproc','Calibration Files','Phoenix Geophysics'))
            app.path = pwd;            
        end

        % Button pushed function: SysCalButton
        function SysCalButtonPushed(app, event)
            cd(app.path)
            [file,syscaldir] = uigetfile('.exe');
            if file == 0
                return
            else
                app.syspath.Text = fullfile(syscaldir,file);
            end
            figure(app.UIFigure)
        end

        % Button pushed function: CLBPathButton
        function CLBPathButtonPushed(app, event)
            cd(fullfile(app.path,'CLB'))
            clbdir = uigetdir(cd,'Select CLB folder location');
            if clbdir == 0
                return
            else
                app.clbpath.Text = clbdir;
            end
            figure(app.UIFigure)
        end

        % Button pushed function: CLCPathButton
        function CLCPathButtonPushed(app, event)
            cd(fullfile(app.path,'CLC'))
            clcdir = uigetdir(cd,'Select CLC folder location');
            if clcdir == 0
                return
            else
                app.clcpath.Text = clcdir;
            end
            figure(app.UIFigure)
        end

        % Callback function: PFCPathButton, PushTool3
        function PFCPathButtonPushed(app, event)
            cd(fullfile(app.path,'PFC'))
            pfcdir = uigetdir(cd,'Select PFC folder location');
            if pfcdir == 0
                return
            else
                app.pfcpath.Text = pfcdir;
            end
            figure(app.UIFigure)
        end

        % Clicked callback: PushTool
        function LoadTBLButtonPushed(app, event)
            tbl = readtbl;
            fill = [app.table.Data;[tbl.file,tbl.path,tbl.files(1),tbl.files(2),tbl.files(3),tbl.type,'Waiting...']];
            app.table.Data = fill;
            figure(app.UIFigure)
        end

        % Clicked callback: PushTool2
        function DeleteTBLButtonPushed(app, event)
            app.table.Data(app.ind,:) = [];
        end

        % Callback function
        function CreatePFCButtonPushed(app, event)
            create_pfc
            figure(app.UIFigure)
        end

        % Clicked callback: PushTool4
        function CreateButtonPushed(app, event)
            if isempty(app.syspath.Text)
                uialert(app.UIFigure,'No SysCal file has been selected','CreateCTS - Warning','Icon','Warning');
                return
            elseif isempty(app.clbpath.Text)
                uialert(app.UIFigure,'No CLB folder has been selected','CreateCTS - Warning','Icon','Warning');
                return
            elseif isempty(app.clcpath.Text)
                uialert(app.UIFigure,'No CLC folder has been selected','CreateCTS - Warning','Icon','Warning');
                return
            elseif isempty(app.pfcpath.Text)
                uialert(app.UIFigure,'No PFC folder has been selected','CreateCTS - Warning','Icon','Warning');
                return
            else
                create_cts(app)
                figure(app.UIFigure)
            end
        end

        % Cell selection callback: table
        function tableCellSelection(app, event)
            app.ind = event.Indices(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Create CTS Files';
            app.UIFigure.Icon = 'Phoenix_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Add TBL (as *.txt)'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @LoadTBLButtonPushed, true);
            app.PushTool.Icon = 'FFproc_plus.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Delete TBL'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @DeleteTBLButtonPushed, true);
            app.PushTool2.Icon = 'FFproc_minus.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Create PFC files'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PFCPathButtonPushed, true);
            app.PushTool3.Icon = 'FFproc_cal.png';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Get CTS files'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @CreateButtonPushed, true);
            app.PushTool4.Icon = 'FFproc_exe.png';
            app.PushTool4.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SysCalButton = uibutton(app.GridLayout, 'push');
            app.SysCalButton.ButtonPushedFcn = createCallbackFcn(app, @SysCalButtonPushed, true);
            app.SysCalButton.Tooltip = {'Set SysCal path'};
            app.SysCalButton.Text = 'SysCal';
            app.CLCPathButton = uibutton(app.GridLayout, 'push');
            app.CLCPathButton.ButtonPushedFcn = createCallbackFcn(app, @CLCPathButtonPushed, true);
            app.CLCPathButton.Text = 'CLC Path';
            app.CLBPathButton = uibutton(app.GridLayout, 'push');
            app.CLBPathButton.ButtonPushedFcn = createCallbackFcn(app, @CLBPathButtonPushed, true);
            app.CLBPathButton.Text = 'CLB Path';
            app.PFCPathButton = uibutton(app.GridLayout, 'push');
            app.PFCPathButton.ButtonPushedFcn = createCallbackFcn(app, @PFCPathButtonPushed, true);
            app.PFCPathButton.Text = 'PFC Path';
            app.syspath = uilabel(app.GridLayout);
            app.syspath.Text = '';
            app.clbpath = uilabel(app.GridLayout);
            app.clbpath.Text = '';
            app.pfcpath = uilabel(app.GridLayout);
            app.pfcpath.Text = '';
            app.clcpath = uilabel(app.GridLayout);
            app.clcpath.Text = '';
            app.table = uitable(app.GridLayout);
            app.table.ColumnName = {'File'; 'Path'; '.ts'; '.ts'; '.ts'; 'Band'; 'Status'};
            app.table.RowName = {};
            app.table.CellSelectionCallback = createCallbackFcn(app, @tableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateCTS_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end