classdef FFproc_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FFproc            matlab.ui.Figure
        Toolbar           matlab.ui.container.Toolbar
        PushTool6         matlab.ui.container.toolbar.PushTool
        PushTool          matlab.ui.container.toolbar.PushTool
        PushTool2         matlab.ui.container.toolbar.PushTool
        PushTool5         matlab.ui.container.toolbar.PushTool
        PushTool3         matlab.ui.container.toolbar.PushTool
        PushTool4         matlab.ui.container.toolbar.PushTool
        GridLayout        matlab.ui.container.GridLayout
        GridLayout3       matlab.ui.container.GridLayout
        RNKLabel          matlab.ui.control.Label
        RAWLabel          matlab.ui.control.Label
        SpectraLabel      matlab.ui.control.Label
        StatisticsLabel   matlab.ui.control.Label
        SpectraButton     matlab.ui.control.Button
        RAWButton         matlab.ui.control.Button
        SpectraButton_2   matlab.ui.control.Button
        StatisticsButton  matlab.ui.control.Button
        ConfigButton      matlab.ui.control.Button
        ConfigLabel       matlab.ui.control.Label
        JobEditorButton   matlab.ui.control.Button
        JobEditorLabel    matlab.ui.control.Label
        ResultsButton     matlab.ui.control.Button
        NewJobButton      matlab.ui.control.Button
        crlabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global font            
            set(0,'units','pixels')
            ss = get(0,'ScreenSize');
            app.FFproc.Position = [(ss(3)/2) - (app.FFproc.Position(3)/2),...
                                     (ss(4)/2) - (app.FFproc.Position(4)/2),...
                                     app.FFproc.Position(3),...
                                     app.FFproc.Position(4)];
            
            try
                trackmodules('FFproc_')
            catch
            end

            font = 'Verdana';            
            ffmt2path
        end

        % Button pushed function: NewJobButton
        function NewJobCallback(app, event)
            FFproc_NewJob
        end

        % Button pushed function: ResultsButton
        function ResultsCallback(app, event)
            FFproc_Results
        end

        % Callback function: PushTool
        function CombinePeriodsCallback(app, event)
            FFproc_CombinePeriods
        end

        % Callback function: PushTool2
        function CombineStationsCallback(app, event)
            FFproc_CombineStations
        end

        % Callback function: PushTool5
        function AutoCombineCallback(app, event)
            answer = uiconfirm(app.FFproc,{'Proceed with Auto-Combine?'; ...
                                           'Select this option ONLY if you want combine';...
                                           'AUTOMATICALLY ALL THE PROCESSED JOBS';...
                                           'within a source folder.';'';...
                                           'Output files:';...
                                           'AUTOCOMB: combination of all jobs for each site';...
                                           'AUTORAW: combination of all RAW files for each site'},'FFproc - Auto-Combine','Options',{'Yes','Return'},...
                                           'CancelOption',2,'Icon','warning');
            switch answer
                case 'Yes'
                    FFproc_mergeOPTandRAW(app)
                case 'No'
            end
        end

        % Callback function: PushTool3
        function EDICallback(app, event)
            [file,path] = uigetfile('.mat','Select MT-site variable');
            if path == 0
                figure(app.FFproc)
                return
            else
                figure(app.FFproc)
                load(fullfile(path,file));
                try
                    ffmt2EDI(app,'FFproc',FFsave,path,[])  
                catch
                    ffmt2EDI(app,'FFproc',mt,path,[]) 
                end
            end
            try
                winopen(path)
            catch
            end
        end

        % Callback function: PushTool4
        function MTDIMCallback(app, event)
            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            
            [file,path] = uigetfile('*.mat','Select MT-site variable');
            if path == 0
                figure(app.FFproc)
                return
            else
                figure(app.FFproc)
                load(fullfile(path,file))
                
                try
                    file = erase(file,'COMB_');
                catch
                end
                
                try
                    mt = FFsave2mt(FFsave);
                catch
                    mt = FFsave2mt(mt);
                end
                
                if numel(mt) > 1
                    dlg = uiprogressdlg(app.FFproc,'Title','FFproc - Exporting to MTDIM',...
                                                   'Message',{'Calculating Phase Tensor and Errors...';'(Caldwell et al, 2004; Bibby et al., 2005; Booker, 2012)';...
                                                          ' ';'Calculating CART, RT, RPT and Errors...';'(Hering et al., 2019)'});
                else
                    dlg = uiprogressdlg(app.FFproc,'Title','FFproc - Exporting to MTDIM',...
                                                   'Message',{'Calculating Phase Tensor and Errors...';'(Caldwell et al, 2004; Bibby et al., 2005; Booker, 2012)';...
                                                          ' ';'Calculating CART, RT, RPT and Errors...';'(Hering et al., 2019)'},'Indeterminate','on');
                end
                    % Removing previous CART values
                    mt = reset_CART(mt);
                    for i = 1:numel(mt)
                        mt(i).source = {'data'};
                        mt(i) = CART(mt(i));                        
                        if numel(mt)>1
                            dlg.Value = i/numel(mt);
                        end
                    end
                close(dlg)
                
                cd(path)
                outname = file;
                [outname,path] = uiputfile([upper(strtok(outname,'_')),'.mat'],'FFproc2MTDIM - Save MT-site variable in *.mat file');
                if numel(mt) == 1
                    mt.site = {strtok(outname,'.')};
                else
                    for i = 1:numel(mt)
                        mt(i).site = mt(i).site;
                    end
                end              
                    
                cd(path)                
                save(outname,'mt');                
                figure(app.FFproc)
                uialert(app.FFproc,{['File: ',outname,' has been saved in:'];...
                                             path},...
                                            'FFproc - Exporting to MTDIM',...
                                            'Icon','success');
            end
        end

        % Button pushed function: JobEditorButton
        function JobEditorCallback(app, event)
            FFproc_JobEditor
        end

        % Button pushed function: ConfigButton
        function ConfigCallback(app, event)
            FFproc_Settings
        end

        % Button pushed function: RAWButton
        function RAWCallback(app, event)
            FFproc_RAW
        end

        % Button pushed function: SpectraButton_2
        function RNKCallback(app, event)
            FFproc_RNK            
        end

        % Button pushed function: StatisticsButton
        function StatisticsCallback(app, event)
            FFproc_ChooseStatistics
        end

        % Button pushed function: SpectraButton
        function SpectraCallback(app, event)
            FFproc_Spectra
        end

        % Callback function: PushTool6
        function PushTool6Clicked(app, event)
            FFproc_ChangeSaveDirectory(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FFproc = uifigure('Visible', 'off');
            app.FFproc.Name = 'FFproc';
            app.FFproc.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.FFproc);
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.Tooltip = {'Change ''Save Results'' directory'};
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'Extra', 'Icons', 'FFproc_browse.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Combine periods'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @CombinePeriodsCallback, true);
            app.PushTool.Icon = 'FFproc_cper.png';
            app.PushTool.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Combine stations'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @CombineStationsCallback, true);
            app.PushTool2.Icon = 'FFproc_csite.png';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.Tooltip = {'Auto-Combine'; '(Available in next releases)'};
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @AutoCombineCallback, true);
            app.PushTool5.Enable = 'off';
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'Extra', 'Icons', 'FFproc_autocombine.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Export to EDI'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @EDICallback, true);
            app.PushTool3.Icon = 'FFproc_edi.png';
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.Tooltip = {'Export to MTDIM'};
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @MTDIMCallback, true);
            app.PushTool4.Icon = 'FFproc_mtdim.png';
            app.GridLayout = uigridlayout(app.FFproc);
            app.crlabel = uilabel(app.GridLayout);
            app.crlabel.Text = '© FFproc - Hering, Castro, Hogg and Junge (2023)';
            app.NewJobButton = uibutton(app.GridLayout, 'push');
            app.NewJobButton.ButtonPushedFcn = createCallbackFcn(app, @NewJobCallback, true);
            app.NewJobButton.Icon = 'FFpro_new.png';
            app.NewJobButton.Text = 'New Job';
            app.ResultsButton = uibutton(app.GridLayout, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsCallback, true);
            app.ResultsButton.Icon = 'FFproc_results.png';
            app.ResultsButton.Text = 'Results';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.JobEditorLabel = uilabel(app.GridLayout3);
            app.JobEditorLabel.Text = 'Job Editor';
            app.JobEditorButton = uibutton(app.GridLayout3, 'push');
            app.JobEditorButton.ButtonPushedFcn = createCallbackFcn(app, @JobEditorCallback, true);
            app.JobEditorButton.Icon = 'FFproc_editjob.png';
            app.JobEditorButton.Tooltip = {''};
            app.JobEditorButton.Text = '';
            app.ConfigLabel = uilabel(app.GridLayout3);
            app.ConfigLabel.Text = 'Site Config';
            app.ConfigButton = uibutton(app.GridLayout3, 'push');
            app.ConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigCallback, true);
            app.ConfigButton.Icon = 'FFproc_param.png';
            app.ConfigButton.Tooltip = {''};
            app.ConfigButton.Text = '';
            app.StatisticsButton = uibutton(app.GridLayout3, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsCallback, true);
            app.StatisticsButton.Icon = fullfile(pathToMLAPP, 'Extra', 'Icons', 'FFproc_Statistics.png');
            app.StatisticsButton.Text = '';
            app.SpectraButton_2 = uibutton(app.GridLayout3, 'push');
            app.SpectraButton_2.ButtonPushedFcn = createCallbackFcn(app, @RNKCallback, true);
            app.SpectraButton_2.Icon = fullfile(pathToMLAPP, 'Extra', 'Icons', 'FFproc_Ranking.png');
            app.SpectraButton_2.Text = '';
            app.RAWButton = uibutton(app.GridLayout3, 'push');
            app.RAWButton.ButtonPushedFcn = createCallbackFcn(app, @RAWCallback, true);
            app.RAWButton.Icon = fullfile(pathToMLAPP, 'Extra', 'Icons', 'FFproc_RAW.png');
            app.RAWButton.Text = '';
            app.SpectraButton = uibutton(app.GridLayout3, 'push');
            app.SpectraButton.ButtonPushedFcn = createCallbackFcn(app, @SpectraCallback, true);
            app.SpectraButton.Icon = 'FFproc_correlation.png';
            app.SpectraButton.Text = '';
            app.StatisticsLabel = uilabel(app.GridLayout3);
            app.StatisticsLabel.Text = 'Statistics';
            app.SpectraLabel = uilabel(app.GridLayout3);
            app.SpectraLabel.Text = 'Spectra';
            app.RAWLabel = uilabel(app.GridLayout3);
            app.RAWLabel.Text = 'RAW';
            app.RNKLabel = uilabel(app.GridLayout3);
            app.RNKLabel.Text = 'RNK';
            % Show the figure after all components are created
            app.FFproc.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FFproc)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FFproc)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FFproc)
        end
    end
end