classdef FFproc_JobEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ToolsMenu                    matlab.ui.container.Menu
        UpdateJobstructureMenu       matlab.ui.container.Menu
        ConcatenateJobsMenu          matlab.ui.container.Menu
        SplitJobsMenu                matlab.ui.container.Menu
        CreateMultisitefromSinglesiteMenu  matlab.ui.container.Menu
        OptionsMenu                  matlab.ui.container.Menu
        CreateautomaticallyjobsMenu  matlab.ui.container.Menu
        MetronixADU07eMenu           matlab.ui.container.Menu
        PhoenixMTU5AMenu             matlab.ui.container.Menu
        PhoenixMTU5CMenu             matlab.ui.container.Menu
        ExtrasMenu                   matlab.ui.container.Menu
        GetRecordingTimesMenu        matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        folder                       matlab.ui.container.toolbar.ToggleTool
        upTS                         matlab.ui.container.toolbar.PushTool
        downTS                       matlab.ui.container.toolbar.PushTool
        PushTool                     matlab.ui.container.toolbar.PushTool
        deleteTS                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        loadJob                      matlab.ui.container.toolbar.PushTool
        duplicateJob                 matlab.ui.container.toolbar.PushTool
        deleteJob                    matlab.ui.container.toolbar.PushTool
        upwardsJob                   matlab.ui.container.toolbar.PushTool
        downwardsJob                 matlab.ui.container.toolbar.PushTool
        infoJob                      matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        saveJob                      matlab.ui.container.toolbar.PushTool
        reset                        matlab.ui.container.toolbar.PushTool
        GridLayout                   matlab.ui.container.GridLayout
        jobtable                     matlab.ui.control.Table
        stationtable                 matlab.ui.control.Table
        paramtable                   matlab.ui.control.Table
        Panel                        matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        NewDirectoryLabel            matlab.ui.control.Label
        browse                       matlab.ui.control.Button
        pathtext                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        filename,dirname
        path
        job
        ffts
        jobind
        tsind
        sorted
    end
    
    methods (Access = public)        
        function checkfolder(app)
            if app.folder.State
                app.browse.Enable = true;
            else
                app.browse.Enable = false;
            end
        end
        
        function updatescreen(app)
            % Populate station table
            FFproc_stationtable(app,app.ffts);

            % Populate parameters table 
            FFproc_paramtable(app,app.ffts);
            
            % Update ffts structure
            FFproc_datafromtable(app,app.ffts)

            % Populate job table
            FFproc_filljobtable(app,app.job);

            % Update job structure
            FFproc_datafromjob(app,app.job)
        end

        function [tstart,tend] = trimtimes(~,tmin,tmax)
            % Find new tmin
            tmin = datevec(tmin);
            tmin(6) = 0;
            if tmin(5) > 0 && tmin(5) <= 15
                tmin(5)  = 30;
            elseif tmin(5) > 15 && tmin(5) <= 30
                tmin(5)  = 45;
            elseif tmin(5) > 30 && tmin(5) <= 45
                tmin(5)  = 0;
                tmin = datenum(tmin) + datenum([0,0,0,1,0,0]);
            elseif tmin(5) > 45 && tmin(5) <= 60
                tmin(5)  = 15;
                tmin = datenum(tmin) + datenum([0,0,0,1,0,0]);
            end
            tstart = datenum(tmin);

            % Find new tmax
            tmax = datevec(tmax);
            tmax(6) = 0;
            if tmax(5) > 0 && tmax(5) <= 15
                tmax  = datenum(tmax) - datenum([0,0,0,0,45,0]);
            elseif tmax(5) > 15 && tmax(5) <= 30
                tmax(5)  = 0;
            elseif tmax(5) > 30 && tmax(5) <= 45
                tmax(5)  = 15;
            elseif tmax(5) > 45 && tmax(5) <= 60
                tmax(5)  = 30;
            end
            tend = datenum(tmax);
        end

        function trimtimeseries(app) 
            for i = 1:numel(app.job)
                tmin = zeros(numel(app.job(i).ffts),1);
                tmax = zeros(numel(app.job(i).ffts),1);
                for j = 1:numel(app.job(i).ffts) 
                    tmin(j) = double(app.job(i).ffts(j).t(1));
                    tmax(j) = double(app.job(i).ffts(j).t(end));  
                end
                % Evaluating the common time between all time series
                tmin = double(max(tmin));
                tmax = double(min(tmax));
                % Calculating trimmed timed
                [tstart,tend] = trimtimes(app,tmin,tmax);
                % Setting new start and end time
                for j = 1:numel(app.job(i).ffts) 
                    app.job(i).ffts(j).tstart = tstart;
                    app.job(i).ffts(j).tend = tend;
                end
            end
            updatescreen(app)
        end

        function answer = checkjobs(app)

            [file,pathcheck] = uigetfile('.mat','Select Job file you want to update');
            cd(pathcheck)
            load(fullfile(pathcheck,file),'job');
            app.job = job;

            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Execute','Message','Check Error in job list...','Indeterminate','on');
            txt = cell(0);
                n = 0;
                for i = 1:numel(app.job)
                    for j = 1:numel(app.job(i).ffts)
                        if isfield(app.job(i).ffts(j),'mode')
                            switch app.job(i).ffts(j).mode
                                case 'single'
                                    try
                                        switch app.job(i).ffts(j).device
                                            case 'Phoenix MTU5-C'
                                                cd(app.job(i).ffts(j).path)
                                            otherwise
                                                app.job(i).ffts(j).path;
                                                app.job(i).ffts(j).file;
                                                cd(app.job(i).ffts(j).path)
                                                if ~isfile(app.job(i).ffts(j).file)
                                                    txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | File was not found'];
                                                n = n + 1;
                                                end
                                        end
                                    catch
                                        txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | Directory was not found'];
                                        n = n + 1;
                                    end
                                case 'merge'
                                    filemerge = strsplit(app.job(i).ffts(j).file,',');
                                    pathmerge = strsplit(app.job(i).ffts(j).path,',');
                                    for k = 1:numel(filemerge)
                                        try
                                            cd(pathmerge{k})
                                            if ~isfile(filemerge{k})
                                                txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | File was not found'];
                                                n = n + 1;
                                            end
                                        catch
                                            txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | Directory was not found'];
                                            n = n + 1;
                                        end
                                    end
                            end
                            if n > 0
                                m = [{[num2str(n),' Job entries have errors']};...
                                    {'FFproc will not be able to process this jobs:'};...
                                    {''}];
                                for i = 1:numel(txt)
                                    m{end+1} = txt{i};
                                end
                                answer = uiconfirm(app.UIFigure,m,...
                                                                 'FFproc - Job Editor', ...
                                                                 'Options',{'Execute','Return'},'DefaultOption',2,'CancelOption',2,'Icon','Error');
                            else
                                answer = 'Execute';
                            end
                        else
                            answer = uiconfirm(app.UIFigure,{'You are loading an old job (created before Jul 2022)';...
                                                             'Please select the folder which contains all your time series';...
                                                             'Do you want to update?'},...
                                                             'FFproc - Job Editor', ...
                                                             'Options',{'Update','Return'},'DefaultOption',1,'CancelOption',2,'Icon','Error');
                            switch answer
                                case 'Update'
                                    close(dlg)
                                    newpath = uigetdir('Select the folder which contains all your time series');
                                    cd(newpath)
                                    for m = 1:numel(app.job)
                                        for n = 1:numel(app.job(m).ffts)
                                            [~,sitefolder,~] = fileparts(app.job(m).ffts(n).path);
                                            if isempty(sitefolder)
                                                ssplit = strsplit(fileparts(app.job(m).ffts(n).path),'\');
                                                sitefolder = ssplit{end};
                                            end
                                            app.job(m).ffts(n).path = fullfile(newpath,sitefolder);
                                        end
                                    end
                                    updatejob(app,app.job);
                                    return
                                otherwise
                                    close(dlg)
                                    return
                            end
                        end
                    end
                end
            close(dlg)            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'units','pixels')
            ss = get(0,'ScreenSize');
            app.UIFigure.Position = [(ss(3)/2) - (app.UIFigure.Position(3)/2),...
                                     (ss(4)/2) - (app.UIFigure.Position(4)/2),...
                                                  app.UIFigure.Position(3),...
                                                  app.UIFigure.Position(4)];
            
            app.job = [];
            app.ffts = [];
            app.filename = [];
            app.dirname = [];
            try
                app.pathtext.Text = [];
            catch
            end

            app.jobtable.Data = [];
            app.stationtable.Data = [];
            app.paramtable.Data = [];

            app.sorted = 'descend';

            checkfolder(app)
        end

        % Callback function: folder
        function BrowseStateCallback(app, event)
            checkfolder(app)
        end

        % Button pushed function: browse
        function BrowseCallback(app, event)
            app.path = uigetdir(pwd,'Select folder which contains all the Time Series');
            if app.path == 0
                figure(app.UIFigure)
                return
            else
                app.pathtext.Text = app.path;
                figure(app.UIFigure)
            end
        end

        % Callback function: upTS
        function UpStationCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job is selected!','FFproc - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one ts is loaded or the index on the table
            % is on the first position
            if numel(app.job) == 1 || app.jobind == 1
                uialert(app.UIFigure,'Job can not be moved upwards','FFproc - Job Editor','Icon','Warning');
                return
            end
            
            % ID of selected time series in the table
            id = app.jobind;
            % Moving selected time series upwards
            aux = app.job(id);
            app.job(id) = app.job(id-1);
            app.job(id-1) = aux;
            for i = 1:length(app.job)
                app.job(i).ID = i;
            end

            app.jobind = app.jobind - 1;

            % Update screen
            updatescreen(app)
        end

        % Callback function: downTS
        function DownCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job is selected!','FFproc - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one ts is loaded or the index on the table
            % is on the first position
            if numel(app.job) == 1 || app.jobind == numel(app.job)
                uialert(app.UIFigure,'Job can not be moved downwards','FFproc - Job Editor','Icon','Warning');
                return
            end
            
            % ID of selected time series in the table
            id = app.jobind;
            % Moving selected time series upwards
            aux = app.job(id);
            app.job(id) = app.job(id+1);
            app.job(id+1) = aux;
            for i = 1:length(app.job)
                app.job(i).ID = i;
            end   

            app.jobind = app.jobind + 1;
            
            % Update screen
            updatescreen(app)
        end

        % Callback function: PushTool
        function SortCallback(app, event)
            idold = cell2mat(app.jobtable.Data(:,1));
            switch app.sorted
                case 'ascend'
                    [~,idnew] = sort(idold,app.sorted);
                    app.sorted = 'descend';
                case 'descend'
                    [~,idnew] = sort(idold,app.sorted);
                    app.sorted = 'ascend';
            end
            
            app.job = app.job(idnew);

            % Populate job table
            FFproc_filljobtable(app,app.job);           
        end

        % Callback function: deleteTS
        function DeleteStationCallback(app, event)
            % Deleting selected station
            app.ffts(app.tsind) = [];
            
            % Update ffts structure
            FFproc_datafromtable(app,app.ffts)

            % Populate station table
            FFproc_stationtable(app,app.ffts);

            % Populate parameters table 
            FFproc_paramtable(app,app.ffts);

            % Updating job structure with modified ffts
            app.job(app.jobind).ffts = app.ffts;

            % Populate job table
            FFproc_filljobtable(app,app.job);
        end

        % Callback function: PushTool2
        function ScheduleCallback(app, event)
            FFproc_SetTimes(app.job(app.jobind).ffts,'jobeditor',app)
        end

        % Callback function: loadJob
        function loadJobCallback(app, event)
            try
                % Updating job structure
                FFproc_datafromjob(app,app.job)
    
                if ~isempty(app.job)
                    answer = uiconfirm(app.UIFigure,'Job List is beign used', ...
                                           'FFproc - Load Job', ...
                                           'Options',{'Overwrite','Append','Back'},'DefaultOption',2,'CancelOption',3);
                    switch answer
                        case 'Overwrite'
                            clear job
                            [app.filename,app.dirname] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                                     'Load Job List');
                            load(fullfile(app.dirname,app.filename))
                            app.job = job;
                        case 'Append'
                            job_old = app.job;
                            [app.filename,app.dirname] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                                     'Load Job List');
                            load(fullfile(app.dirname,app.filename))
                            job_new = job;
                            try
                                app.job = cat(1,[job_old,job_new]);
                            catch ME
                                switch ME.identifier
                                    case 'MATLAB:catenate:structFieldBad'
                                        app.job = mergestruct(job_old,job_new);
                                end
                            end
                    end
                else
                    [app.filename,app.dirname] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                             'Load Job List');
                    load(fullfile(app.dirname,app.filename))
                    app.job = job;
                end
                FFproc_filljobtable(app,app.job);
                figure(app.UIFigure);
            catch
                startupFcn(app)
            end
        end

        % Callback function: duplicateJob
        function duplicateJobCallback(app, event)
            % Auxiliar variable
            aux = app.job;

            if numel(aux) < 1
                uialert(app.UIFigure,'No job is selected','FFproc - Job Editor','Icon','error')
                return
            else
                % Add duplicate job below the selected job
                app.job = [aux(1:app.jobind),aux(app.jobind),aux(app.jobind+1:end)];
                
                % Populate job table
                FFproc_filljobtable(app,app.job);
            end      
        end

        % Callback function: deleteJob
        function deleteJobCallback(app, event)
            if isempty(app.jobind)
                uialert(app.UIFigure,'No job is selected','FFproc - Job Editor','Icon','error')
                return
            else
                % Delete selected job
                app.job(app.jobind) = [];

                % Update station info
                app.stationtable.Data = [];
                app.paramtable.Data = [];
            
                % Populate job table
                FFproc_filljobtable(app,app.job);              
            end
        end

        % Callback function: downwardsJob
        function DownwardsJobCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job is selected/loaded','FFproc - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one job is loaded or the index on the table
            % is on the first position
            if numel(app.job) == 1 || numel(app.job)
                uialert(app.UIFigure,'Job can not be moved Downwards','FFproc - Job Editor','Icon','Warning');
                return
            end
            
            % ID of selected time job
            id = app.jobind;
            % Moving selected job upwards
            aux = app.job(id);
            app.job(id) = app.job(id-1);
            app.job(id-1) = aux;

            app.jobind = app.jobind + 1;
            
            % Populate job table
            FFproc_filljobtable(app,app.job);
        end

        % Callback function: upwardsJob
        function UpwardsJobCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job is selected/loaded','FFproc - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one ts is loaded or the index on the table
            % is on the first position
            if numel(app.job) == 1 || app.jobind == 1
                uialert(app.UIFigure,'Job can not be moved Upwards','FFproc - Job Editor','Icon','Warning');
                return
            end
            
            % ID of selected time series in the table
            id = app.jobind;
            % Moving selected time series upwards
            aux = app.job(id);
            app.job(id) = app.job(id-1);
            app.job(id-1) = aux;
            app.jobind = app.jobind - 1;
            
            % Populate job table
            FFproc_filljobtable(app,app.job);
        end

        % Callback function: saveJob
        function saveJobCallback(app, event)
            % Update job structure            
            FFproc_datafromjob(app,app.job)

            % Update name of station in edited job station name
            for i = 1:numel(app.job)
                name = cell(1,numel(app.job(i).ffts));
                for j = 1:numel(app.job(i).ffts)
                    name{j} = app.job(i).ffts(j).name;
                end
                app.job(i).name = name;
            end
            
            if app.folder.State == true
                if ~isempty(app.pathtext.Text)
                    newdir = app.pathtext.Text;
                    cd(newdir)
                    tmp = genpath(newdir);
                    addpath(tmp);

                    for i = 1:numel(app.job)
                        for j = 1:numel(app.job(i).ffts)
                            if iscell(app.job(i).ffts(j).file)
                                folderjob = which(app.job(i).ffts(j).file{1});
                            else
                                folderjob = which(app.job(i).ffts(j).file);
                            end
                            [foldersite,~,~] = fileparts(folderjob);
                            app.job(i).ffts(j).path = fullfile(foldersite);
                        end
                    end
                    rmpath(tmp);
                end
            end
            cd(app.dirname);
            [file,dir] = uiputfile('*.mat','Save job list in *.mat file',app.filename);

            if ~isempty(dir) || dir ~= 0
                job = app.job;
                save(fullfile(dir,file),'job','-v7.3');
            end
            figure(app.UIFigure);   
        end

        % Callback function: infoJob
        function infoJobCallback(app, event)
            % Updating job structure
            FFproc_datafromjob(app,app.job)
            
            txt = FFproc_printparameters(app.job(app.jobind));
            FFproc_Printjobparam(txt);
        end

        % Cell selection callback: jobtable
        function jobtableCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices)
                return
            end

            % Job index
            app.jobind = unique(indices(:,1));    

            % Update ffts structure
            app.ffts = app.job(app.jobind).ffts;

            % Populate station table
                FFproc_stationtable(app,app.ffts);

            % Populate parameters table 
            FFproc_paramtable(app,app.ffts);
        end

        % Cell edit callback: jobtable
        function jobtableCellEdit(app, event)
            ren = event.Indices(1);
            col = event.Indices(2);
            if ~ismember(col,4:6)
                answer = uiconfirm(app.UIFigure,'Modify all jobs', ...
                                                'FFproc - Job Editor', ...
                                                'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2);
                switch answer
                    case 'Yes'                    
                        if ismember(col,[3,14,16,17,22])
                            app.jobtable.Data(:,col) = num2cell(event.NewData);
                        elseif ismember(col,7)
                            switch app.jobtable.Data{ren,7}
                                case 'EV Decomp'
                                    app.jobtable.Data{:,12} = 'PCC';
                                    app.jobtable.Data{:,13} = 'PCC';
                                    app.jobtable.Data{:,15} = cat(1,app.job.eind);
                                    app.jobtable.Data{:,16} = 1;
                                case 'Bivariate'
                                    app.jobtable.Data{:,12} = 'PCC';
                                    app.jobtable.Data{:,13} = 'PCC';
                                    app.jobtable.Data{:,15} = 'N/A';
                                    app.jobtable.Data{:,16} = 0;
                            end
                        elseif ismember(col,10)
                            if app.jobtable.Data{ren,10}
                                app.jobtable.Data(:,col) = {event.NewData};
                                app.jobtable.Data(:,11) = cellstr([cat(1,app.job.remmethod)]);
                            else
                                app.jobtable.Data(:,col) = {event.NewData};
                                app.jobtable.Data(:,11) = {'N/A'};
                            end
                        elseif ismember(col,11)
                            app.jobtable.Data(:,11) = {event.NewData};
                        else
                            app.jobtable.Data(:,col) = {event.NewData};
                        end
                    otherwise
                        if app.jobtable.Data{ren,7}
                            switch app.jobtable.Data{ren,7}
                                case 'EV Decomp'
                                    app.jobtable.Data{ren,12} = 'PCC';
                                    app.jobtable.Data{ren,13} = 'PCC';
                                    app.jobtable.Data{ren,15} = app.job(ren).eind;
                                    app.jobtable.Data{ren,16} = 1;
                                case 'Bivariate'
                                    app.jobtable.Data{ren,12} = 'PCC';
                                    app.jobtable.Data{ren,13} = 'PCC';
                                    app.jobtable.Data{ren,15} = 'N/A';
                                    app.jobtable.Data{ren,16} = 0;
                            end
                        end
                        if app.jobtable.Data{ren,10}
                            app.jobtable.Data(ren,11) = {app.job(ren).remmethod};
                        else
                            app.jobtable.Data(ren,11) = {'N/A'};
                        end
                end
            end
            
            % Update job structure
            FFproc_datafromjob(app,app.job)
        end

        % Cell selection callback: paramtable, stationtable
        function stationtableCellSelection(app, event)
            indices = event.Indices;
            try
                app.tsind = unique(indices(:,1));
            catch
            end
        end

        % Cell edit callback: paramtable, stationtable
        function stationtableCellEdit(app, event)
            % Updating table
            if event.Indices(2) == 1
                app.stationtable.Data{event.Indices(1),event.Indices(2)} = event.NewData;
            end

            % Update ffts structure
            FFproc_datafromtable(app,app.ffts)

            % Update ffts structure in job entry
            app.job(app.jobind).ffts = app.ffts;

            % Update name of station in edited job
            % Station name
            name = cell(1,numel(app.ffts));
            for i = 1:numel(app.ffts)
                name{i} = app.ffts(i).name;
            end
            app.job(app.jobind).name = name;

            % Populate station table
            FFproc_stationtable(app,app.ffts);

            % Populate parameters table 
            FFproc_paramtable(app,app.ffts);

            % Populate job table
            FFproc_filljobtable(app,app.job);

            % Update job structure
            FFproc_datafromjob(app,app.job)
        end

        % Callback function: reset
        function ResetCallback(app, event)
            startupFcn(app)
        end

        % Menu selected function: UpdateJobstructureMenu
        function UpdateJobstructureMenuSelected(app, event)
            checkjobs(app)
        end

        % Menu selected function: ConcatenateJobsMenu
        function ConcatenateJobsMenuSelected(app, event)
            catjobs
        end

        % Menu selected function: SplitJobsMenu
        function SplitJobsMenuSelected(app, event)
            path = uigetdir(pwd,'Select directory to save jobs');
            for i = 1:numel(app.job)
                job = app.job(i);
                if numel(job.name) > 1
                else
                    name = job.name{:};
                end
                
                save(fullfile(path,[name,'.mat']),'job','-mat')
            end

            try 
                winopen(path)
            catch
            end
        end

        % Menu selected function: CreateMultisitefromSinglesiteMenu
        function CreateMultisitefromSinglesiteMenuSelected(app, event)
            tf = true;
            while tf
                sites2job
                answer = uiconfirm(app.UIFigure,'Create other job?', ...
                                                'FFproc - Job Editor: Single-site to Multi-site', ...
                                                'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2);
                switch answer
                    case 'Yes'
                        tf = true;
                    otherwise
                        tf = false;
                end
            end
        end

        % Menu selected function: MetronixADU07eMenu
        function MetronixADU07eMenuSelected(app, event)
            read2job('Metronix ADU07-e')
        end

        % Menu selected function: PhoenixMTU5AMenu
        function PhoenixMTU5AMenuSelected(app, event)
            read2job('Phoenix MTU5-A')
        end

        % Menu selected function: PhoenixMTU5CMenu
        function PhoenixMTU5CMenuSelected(app, event)
            read2job('Phoenix MTU5-C')
        end

        % Menu selected function: GetRecordingTimesMenu
        function GetRecordingTimesMenuSelected(app, event)
            ReadTS_GetTimes
        end

        % Selection changed function: jobtable
        function jobtableSelectionChanged(app, event)
            selection = app.jobtable.Selection;            
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            trimtimeseries(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Job Editor';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UpdateJobstructureMenu = uimenu(app.ToolsMenu);
            app.UpdateJobstructureMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateJobstructureMenuSelected, true);
            app.UpdateJobstructureMenu.Text = 'Update Job structure';
            app.ConcatenateJobsMenu = uimenu(app.ToolsMenu);
            app.ConcatenateJobsMenu.MenuSelectedFcn = createCallbackFcn(app, @ConcatenateJobsMenuSelected, true);
            app.ConcatenateJobsMenu.Separator = 'on';
            app.ConcatenateJobsMenu.Text = 'Concatenate Jobs';
            app.SplitJobsMenu = uimenu(app.ToolsMenu);
            app.SplitJobsMenu.MenuSelectedFcn = createCallbackFcn(app, @SplitJobsMenuSelected, true);
            app.SplitJobsMenu.Text = 'Split Jobs';
            app.CreateMultisitefromSinglesiteMenu = uimenu(app.ToolsMenu);
            app.CreateMultisitefromSinglesiteMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateMultisitefromSinglesiteMenuSelected, true);
            app.CreateMultisitefromSinglesiteMenu.Text = 'Create Multi-site from Single-site';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.CreateautomaticallyjobsMenu = uimenu(app.OptionsMenu);
            app.CreateautomaticallyjobsMenu.Text = 'Create automatically jobs';
            app.MetronixADU07eMenu = uimenu(app.CreateautomaticallyjobsMenu);
            app.MetronixADU07eMenu.MenuSelectedFcn = createCallbackFcn(app, @MetronixADU07eMenuSelected, true);
            app.MetronixADU07eMenu.Text = 'Metronix ADU07-e';
            app.PhoenixMTU5AMenu = uimenu(app.CreateautomaticallyjobsMenu);
            app.PhoenixMTU5AMenu.MenuSelectedFcn = createCallbackFcn(app, @PhoenixMTU5AMenuSelected, true);
            app.PhoenixMTU5AMenu.Text = 'Phoenix MTU5-A';
            app.PhoenixMTU5CMenu = uimenu(app.CreateautomaticallyjobsMenu);
            app.PhoenixMTU5CMenu.MenuSelectedFcn = createCallbackFcn(app, @PhoenixMTU5CMenuSelected, true);
            app.PhoenixMTU5CMenu.Text = 'Phoenix MTU5-C';
            app.ExtrasMenu = uimenu(app.UIFigure);
            app.ExtrasMenu.Text = 'Extras';
            app.GetRecordingTimesMenu = uimenu(app.ExtrasMenu);
            app.GetRecordingTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @GetRecordingTimesMenuSelected, true);
            app.GetRecordingTimesMenu.Text = 'Get Recording Times';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.folder = uitoggletool(app.Toolbar);
            app.folder.Tooltip = {'Change Stations Folder'};
            app.folder.ClickedCallback = createCallbackFcn(app, @BrowseStateCallback, true);
            app.folder.Icon = 'FFproc_browse.png';
            app.upTS = uipushtool(app.Toolbar);
            app.upTS.Tooltip = {'Move Station Upwards'};
            app.upTS.ClickedCallback = createCallbackFcn(app, @UpStationCallback, true);
            app.upTS.Icon = 'FFproc_up.png';
            app.upTS.Separator = 'on';
            app.downTS = uipushtool(app.Toolbar);
            app.downTS.Tooltip = {'Move Station Downwards'};
            app.downTS.ClickedCallback = createCallbackFcn(app, @DownCallback, true);
            app.downTS.Icon = 'FFproc_down.png';
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Sort Single Site/Multi Site'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @SortCallback, true);
            app.PushTool.Icon = 'FFproc_sort.png';
            app.deleteTS = uipushtool(app.Toolbar);
            app.deleteTS.Tooltip = {'Delete Station'};
            app.deleteTS.ClickedCallback = createCallbackFcn(app, @DeleteStationCallback, true);
            app.deleteTS.Icon = 'FFproc_minus.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @ScheduleCallback, true);
            app.PushTool2.Icon = 'FFproc_time.png';
            app.PushTool2.Separator = 'on';
            app.loadJob = uipushtool(app.Toolbar);
            app.loadJob.Tooltip = {'Load Job'};
            app.loadJob.ClickedCallback = createCallbackFcn(app, @loadJobCallback, true);
            app.loadJob.Icon = 'FFproc_addjob.png';
            app.loadJob.Separator = 'on';
            app.duplicateJob = uipushtool(app.Toolbar);
            app.duplicateJob.Tooltip = {'Duplicate job'};
            app.duplicateJob.ClickedCallback = createCallbackFcn(app, @duplicateJobCallback, true);
            app.duplicateJob.Icon = 'FFproc_reset.png';
            app.deleteJob = uipushtool(app.Toolbar);
            app.deleteJob.Tooltip = {'Delete Job'};
            app.deleteJob.ClickedCallback = createCallbackFcn(app, @deleteJobCallback, true);
            app.deleteJob.Icon = 'FFproc_deletejob.png';
            app.upwardsJob = uipushtool(app.Toolbar);
            app.upwardsJob.Tooltip = {'Move Job Upwards'};
            app.upwardsJob.ClickedCallback = createCallbackFcn(app, @UpwardsJobCallback, true);
            app.upwardsJob.Icon = 'FFproc_rwd.png';
            app.downwardsJob = uipushtool(app.Toolbar);
            app.downwardsJob.Tooltip = {'Move Job Downwards'};
            app.downwardsJob.ClickedCallback = createCallbackFcn(app, @DownwardsJobCallback, true);
            app.downwardsJob.Icon = 'FFproc_fwd.png';
            app.infoJob = uipushtool(app.Toolbar);
            app.infoJob.Tooltip = {'Print Job Information'};
            app.infoJob.ClickedCallback = createCallbackFcn(app, @infoJobCallback, true);
            app.infoJob.Icon = 'FFproc_info.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Trim times'};
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'FFproc_trimtimes.png';
            app.saveJob = uipushtool(app.Toolbar);
            app.saveJob.Tooltip = {'Save Job'};
            app.saveJob.ClickedCallback = createCallbackFcn(app, @saveJobCallback, true);
            app.saveJob.Icon = 'FFproc_savejob.png';
            app.reset = uipushtool(app.Toolbar);
            app.reset.Tooltip = {'Reset Window'};
            app.reset.ClickedCallback = createCallbackFcn(app, @ResetCallback, true);
            app.reset.Icon = 'FFproc_update.png';
            app.reset.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.pathtext = uilabel(app.GridLayout4);
            app.pathtext.Text = '';
            app.browse = uibutton(app.GridLayout4, 'push');
            app.browse.ButtonPushedFcn = createCallbackFcn(app, @BrowseCallback, true);
            app.browse.Icon = 'FFproc_browse.png';
            app.browse.Text = '';
            app.NewDirectoryLabel = uilabel(app.GridLayout4);
            app.NewDirectoryLabel.Text = 'New Directory:';
            app.paramtable = uitable(app.GridLayout);
            app.paramtable.ColumnName = {'Site'; 'EX (m)'; 'EY (m)'; 'E-Rot (°)'; 'Bx'; 'By'; 'Bz'; 'RAP'; 'Lat (dec)'; 'Lon (dec)'; 'Elev (m)'};
            app.paramtable.SelectionType = 'row';
            app.paramtable.ColumnEditable = true;
            app.paramtable.CellEditCallback = createCallbackFcn(app, @stationtableCellEdit, true);
            app.paramtable.CellSelectionCallback = createCallbackFcn(app, @stationtableCellSelection, true);
            app.stationtable = uitable(app.GridLayout);
            app.stationtable.ColumnName = {'Site'; 'Device'; 'ext'; 'sr (Hz)'; 'Start date/time'; 'End date/time'; 'Ex'; 'Ey'; 'Bx'; 'By'; 'Bz'; 'Bx Ref'; 'By Ref'; 'Ex swap'; 'Ey swap'; 'Bx swap'; 'By swap'};
            app.stationtable.SelectionType = 'row';
            app.stationtable.ColumnEditable = [true false false false true true true true true true true true true true true true true];
            app.stationtable.CellEditCallback = createCallbackFcn(app, @stationtableCellEdit, true);
            app.stationtable.CellSelectionCallback = createCallbackFcn(app, @stationtableCellSelection, true);
            app.jobtable = uitable(app.GridLayout);
            app.jobtable.ColumnName = {'Stations'; 'sr (Hz)'; 'Freq/Decade'; 'Hi-Freq (Hz)'; 'Lo-Freq (Hz)'; 'Oscillations'; 'Approach'; 'Noise model'; 'Regression'; 'Outlier'; 'Method'; 'Z Rank'; 'T Rank'; 'Rank %'; 'EI'; 'EI Pow'; 'PCC Pow'; 'Polyfit'; 'OPT'; 'RNK'; 'RAW'; 'FC'; 'Cores'};
            app.jobtable.SelectionType = 'row';
            app.jobtable.ColumnEditable = [false false true true true true true true true true true true true true true true true true false true true true true];
            app.jobtable.CellEditCallback = createCallbackFcn(app, @jobtableCellEdit, true);
            app.jobtable.CellSelectionCallback = createCallbackFcn(app, @jobtableCellSelection, true);
            app.jobtable.SelectionChangedFcn = createCallbackFcn(app, @jobtableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_JobEditor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end