classdef FFproc_NewJob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadTSMenu                     matlab.ui.container.Menu
        PhoenixGeophysicsMenu          matlab.ui.container.Menu
        MTU5MTU5AV8Menu                matlab.ui.container.Menu
        MTU5CMTU8AMenu                 matlab.ui.container.Menu
        MetronixMenu                   matlab.ui.container.Menu
        LEMIMenu                       matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Menu_3                         matlab.ui.container.Menu
        Menu_4                         matlab.ui.container.Menu
        GEOLOREMenu                    matlab.ui.container.Menu
        V10Menu                        matlab.ui.container.Menu
        V20Menu                        matlab.ui.container.Menu
        V30Menu                        matlab.ui.container.Menu
        ReadTSMenu                     matlab.ui.container.Menu
        GetRecordingTimesMenu          matlab.ui.container.Menu
        ChangeRecordingTimesMenu       matlab.ui.container.Menu
        MergeTimeSeriesMenu            matlab.ui.container.Menu
        FFprocMenu_3                   matlab.ui.container.Menu
        JobEditorMenu                  matlab.ui.container.Menu
        FieldSettingsMenu              matlab.ui.container.Menu
        CombineMenu                    matlab.ui.container.Menu
        PeriodsMenu                    matlab.ui.container.Menu
        StationsMenu                   matlab.ui.container.Menu
        ResultsMenu                    matlab.ui.container.Menu
        RAWMenu                        matlab.ui.container.Menu
        SpectraMenu                    matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        DocumentationMenu              matlab.ui.container.Menu
        FFprocMenu_2                   matlab.ui.container.Menu
        FFMTMenu_2                     matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        reset                          matlab.ui.container.toolbar.PushTool
        addTS                          matlab.ui.container.toolbar.PushTool
        deleteTS                       matlab.ui.container.toolbar.PushTool
        up                             matlab.ui.container.toolbar.PushTool
        down                           matlab.ui.container.toolbar.PushTool
        plotsite                       matlab.ui.container.toolbar.PushTool
        time                           matlab.ui.container.toolbar.ToggleTool
        timeconfig                     matlab.ui.container.toolbar.PushTool
        exclude                        matlab.ui.container.toolbar.ToggleTool
        submitJob                      matlab.ui.container.toolbar.PushTool
        editJob                        matlab.ui.container.toolbar.PushTool
        deleteJob                      matlab.ui.container.toolbar.PushTool
        infoJob                        matlab.ui.container.toolbar.PushTool
        saveJob                        matlab.ui.container.toolbar.PushTool
        loadJob                        matlab.ui.container.toolbar.PushTool
        createCTS                      matlab.ui.container.toolbar.PushTool
        parallel                       matlab.ui.container.toolbar.ToggleTool
        fast                           matlab.ui.container.toolbar.ToggleTool
        proclog                        matlab.ui.container.toolbar.ToggleTool
        exe                            matlab.ui.container.toolbar.PushTool
        GridLayout1x1                  matlab.ui.container.GridLayout
        OutterGrid                     matlab.ui.container.GridLayout
        jobtable                       matlab.ui.control.Table
        GridLayout32                   matlab.ui.container.GridLayout
        SavePanel                      matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        RNKButton                      matlab.ui.control.StateButton
        FCButton                       matlab.ui.control.StateButton
        RAWButton                      matlab.ui.control.StateButton
        OPTButton                      matlab.ui.control.StateButton
        TabGroup2                      matlab.ui.container.TabGroup
        NoiseModelEstimationandLinearRegressionSettingsTab  matlab.ui.container.Tab
        GridLayout33                   matlab.ui.container.GridLayout
        OutlierDetectionPanel_2        matlab.ui.container.Panel
        GridLayout38_4                 matlab.ui.container.GridLayout
        remmethod                      matlab.ui.control.DropDown
        DetectionMethodDropDownLabel   matlab.ui.control.Label
        OutlierButton                  matlab.ui.control.StateButton
        LinearRegressionPanel          matlab.ui.container.Panel
        GridLayout38_3                 matlab.ui.container.GridLayout
        text8_6                        matlab.ui.control.Label
        text8_5                        matlab.ui.control.Label
        text8_4                        matlab.ui.control.Label
        LRPCAButton                    matlab.ui.control.StateButton
        LRRobustButton                 matlab.ui.control.StateButton
        LRLSQButton                    matlab.ui.control.StateButton
        NoiseModelPanel                matlab.ui.container.Panel
        GridLayout38_2                 matlab.ui.container.GridLayout
        text28_3                       matlab.ui.control.Label
        text29_3                       matlab.ui.control.Label
        NMAdvancedButton               matlab.ui.control.StateButton
        NMStandardButton               matlab.ui.control.StateButton
        TFEstimationApproachPanel      matlab.ui.container.Panel
        GridLayout38                   matlab.ui.container.GridLayout
        text28_2                       matlab.ui.control.Label
        text29_2                       matlab.ui.control.Label
        EigenvalueDecompositionButton  matlab.ui.control.StateButton
        BivariateLinearRegressionButton  matlab.ui.control.StateButton
        EigenvalueCriterionPartialCorrelationCoefficientsTab  matlab.ui.container.Tab
        GridLayout34                   matlab.ui.container.GridLayout
        EigenvalueIndexPanel           matlab.ui.container.Panel
        GridLayout38_5                 matlab.ui.container.GridLayout
        EI0Button                      matlab.ui.control.StateButton
        EI4Button                      matlab.ui.control.StateButton
        EI3Button                      matlab.ui.control.StateButton
        EI1Button                      matlab.ui.control.StateButton
        EI2Button                      matlab.ui.control.StateButton
        EIpower                        matlab.ui.control.NumericEditField
        EVCriteriaLabel_3              matlab.ui.control.Label
        PartialCoherencesPanel         matlab.ui.container.Panel
        GridLayout35_3                 matlab.ui.container.GridLayout
        GridLayout36_3                 matlab.ui.container.GridLayout
        PCCpower                       matlab.ui.control.NumericEditField
        EVCriteriaLabel_2              matlab.ui.control.Label
        AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel_2  matlab.ui.control.Label
        TimesegmentsRankingPanel       matlab.ui.container.Panel
        GridLayout35_2                 matlab.ui.container.GridLayout
        GridLayout36_2                 matlab.ui.container.GridLayout
        ranking                        matlab.ui.control.NumericEditField
        EVCriteriaLabel                matlab.ui.control.Label
        AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel  matlab.ui.control.Label
        RankingCriterionPanel          matlab.ui.container.Panel
        GridLayout35                   matlab.ui.container.GridLayout
        GridLayout36_4                 matlab.ui.container.GridLayout
        PCCTButton                     matlab.ui.control.StateButton
        PCCZButton                     matlab.ui.control.StateButton
        EVTButton                      matlab.ui.control.StateButton
        EVZButton                      matlab.ui.control.StateButton
        TipperLabel                    matlab.ui.control.Label
        ImpedanceLabel                 matlab.ui.control.Label
        PolynomialFitParallelComputingTab  matlab.ui.container.Tab
        GridLayout37                   matlab.ui.container.GridLayout
        ParallelComputingPanel_2       matlab.ui.container.Panel
        GridLayout27                   matlab.ui.container.GridLayout
        ncoresbox                      matlab.ui.control.EditField
        text20                         matlab.ui.control.Label
        ncorestext                     matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        text16                         matlab.ui.control.Label
        PolynomialFitPanel             matlab.ui.container.Panel
        GridLayout27_2                 matlab.ui.container.GridLayout
        PolyfitButton                  matlab.ui.control.StateButton
        text16_2                       matlab.ui.control.Label
        ExcludeFrequenciesPanel        matlab.ui.container.Panel
        GridLayout28_2                 matlab.ui.container.GridLayout
        phmultbox                      matlab.ui.control.EditField
        text11                         matlab.ui.control.Label
        multbox                        matlab.ui.control.EditField
        text10                         matlab.ui.control.Label
        phsingbox                      matlab.ui.control.EditField
        text13                         matlab.ui.control.Label
        singfbox                       matlab.ui.control.EditField
        text12                         matlab.ui.control.Label
        TargetFrequenciesPanel         matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        oscbox                         matlab.ui.control.NumericEditField
        OscillationsLabel              matlab.ui.control.Label
        targfreqbox                    matlab.ui.control.NumericEditField
        FreqDecadeLabel                matlab.ui.control.Label
        minfreqbox                     matlab.ui.control.NumericEditField
        MinFreqHzLabel                 matlab.ui.control.Label
        maxfreqbox                     matlab.ui.control.NumericEditField
        MaxFreqHzLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SiteInformationTab             matlab.ui.container.Tab
        GridLayout13                   matlab.ui.container.GridLayout
        stationtable                   matlab.ui.control.Table
        SiteParametersTab              matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout31                   matlab.ui.container.GridLayout
        downsettings                   matlab.ui.control.Button
        upsettings                     matlab.ui.control.Button
        deletesettings                 matlab.ui.control.Button
        addsettings                    matlab.ui.control.Button
        calmenu                        matlab.ui.control.DropDown
        ConfigButton                   matlab.ui.control.StateButton
        paramtable                     matlab.ui.control.Table
        TimeSynopsisTab                matlab.ui.container.Tab
    end

    properties (Access = public)
        ffts, job
        tsaxes
        oncolor, offcolor
        schedule
        ind
        info
        procprogress
        log
        map
    end
    methods (Access = private)
        
        function resetfreqs(app)            
            % Maximum and Minimum Target Frequencies
            sr = min(cat(1,app.ffts.sr));
            switch app.ffts(1).device
                case 'Phoenix MTU5-A'
                    if sr == 24000
                        app.maxfreqbox.Value = 9000;
                        app.minfreqbox.Value = 400;
                        app.oscbox.Value = 2000;
                    elseif sr == 2400
                        app.maxfreqbox.Value = 600;
                        app.minfreqbox.Value = 40;
                        app.oscbox.Value = 1000;
                    elseif sr == 150
                        app.maxfreqbox.Value = 60;
                        app.minfreqbox.Value = 4;
                        app.oscbox.Value = 250;
                    elseif sr == 15
                        app.maxfreqbox.Value = 6;
                        app.minfreqbox.Value = 0.0004;
                        app.oscbox.Value = 50;
                    end
                case 'Phoenix MTU5-C'
                    if sr == 94000
                        app.maxfreqbox.Value = 40000;
                        app.minfreqbox.Value = 400;
                        app.oscbox.Value = 2000;
                    elseif sr == 24000
                        app.maxfreqbox.Value = 10000;
                        app.minfreqbox.Value = 40;
                        app.oscbox.Value = 2000;
                    elseif sr == 2400
                        app.maxfreqbox.Value = 600;
                        app.minfreqbox.Value = 4;
                        app.oscbox.Value = 1000;
                    elseif sr == 150
                        app.maxfreqbox.Value = 60;
                        app.minfreqbox.Value = 0.0004;
                        app.oscbox.Value = 250;
                    elseif sr == 30
                        app.maxfreqbox.Value = 6;
                        app.minfreqbox.Value = 0.0004;
                        app.oscbox.Value = 100;
                    end
                case {'LEMI-417','LEMI-420'}
                    if sr == 4
                        app.maxfreqbox.Value = 1;
                    else
                        app.maxfreqbox.Value = 0.1;
                    end                        
                    app.minfreqbox.Value = 0.00004;
                    app.oscbox.Value = 50;
                otherwise
                    if sr > 20
                        app.maxfreqbox.Value = sr/2.8;
                        app.minfreqbox.Value = sr/200;
                        app.oscbox.Value = 2000;
                    else
                        app.maxfreqbox.Value = sr/2.8;
                        app.minfreqbox.Value = sr/200;
                        app.oscbox.Value = 500;
                    end   
                if isempty(app.ffts) && isempty(app.job) 
                    startupFcn(app)
                elseif isempty(app.ffts) && ~isempty(app.job)
                    app.ffts = [];
                    app.stationtable.Data = [];
                    app.stationtable.RowName = 'numbered';
                    app.paramtable.Data = [];
                    app.jobtable.Data = [];
                    
                    cla(app.tsaxes)
                    app.tsaxes.Visible = 'off';
                end            
            end
        end
        
        function populatetable(app)
            % Populating station table
            FFproc_stationtable(app,app.ffts);
            % Populating parameters table 
            FFproc_paramtable(app,app.ffts);
            % Populating time table
            FFproc_plot_timetable(app,app.ffts);           
        end    
        
        function checkschedule(app)            
            if isempty(app.ffts)
                app.time.Enable = 'off';
                app.time.State = false;
                app.timeconfig.Enable = 'off';
            else
                app.time.Enable = 'on';
                app.time.State = false;
                app.timeconfig.Enable = 'off';
            end
            global schedule
            schedule = [];
        end

        function checkev(app)
            EI0ButtonValueChanged(app)
            EI1ButtonValueChanged(app)
            EI2ButtonValueChanged(app)
            EI3ButtonValueChanged(app)
            EI4ButtonValueChanged(app)
        end

        function checkrnk(app)
            EVZButtonValueChanged(app)
            EVTButtonValueChanged(app)
            PCCZButtonValueChanged(app)
            PCCTButtonValueChanged(app)
        end

        function checkstats(app)
            BivariateLinearRegressionButtonValueChanged(app)
            EigenvalueDecompositionButtonValueChanged(app)
            LRLSQButtonValueChanged(app)
            LRRobustButtonValueChanged(app)
            NMStandardButtonValueChanged(app)
            NMAdvancedButtonValueChanged(app)
            checkev(app)
            checkrnk(app)
            OutlierButtonValueChanged(app)
            PolyfitButtonValueChanged(app)
            OPTButtonValueChanged(app)
            RNKButtonValueChanged(app)
            RAWButtonValueChanged(app)
            FCButtonValueChanged(app)
        end

        function answer = checkjobs(app)
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Execute','Message','Check Error in job list...','Indeterminate','on');
            txt = cell(0);
                n = 0;
                for i = 1:numel(app.job)
                    for j = 1:numel(app.job(i).ffts)
                        switch app.job(i).ffts(j).mode
                            case 'single'
                                try
                                    switch app.job(i).ffts(j).device
                                        case 'Phoenix MTU5-C'
                                            cd(app.job(i).ffts(j).path)
                                        otherwise
                                            app.job(i).ffts(j).path;
                                            app.job(i).ffts(j).file;
                                            cd(app.job(i).ffts(j).path)
                                            if ~isfile(app.job(i).ffts(j).file)
                                                txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | File was not found'];
                                            n = n + 1;
                                            end
                                    end
                                catch
                                    txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | Directory was not found'];
                                    n = n + 1;
                                end
                            case 'merge'
                                filemerge = strsplit(app.job(i).ffts(j).file,',');
                                pathmerge = strsplit(app.job(i).ffts(j).path,',');
                                for k = 1:numel(filemerge)
                                    try
                                        cd(pathmerge{k})
                                        if ~isfile(filemerge{k})
                                            txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | File was not found'];
                                            n = n + 1;
                                        end
                                    catch
                                        txt{n + 1,1} = ['Job: ',num2str(i),' | Time Series: ',num2str(j),' | Directory was not found'];
                                        n = n + 1;
                                    end
                                end
                        end
                    end
                end
            close(dlg)
            if n > 0
                m = [{[num2str(n),' Job entries have errors']};...
                    {'FFproc will not be able to process this jobs:'};...
                    {''}];
                for i = 1:numel(txt)
                    m{end+1} = txt{i};
                end
                answer = uiconfirm(app.UIFigure,m,...
                                                 'FFproc - Execute', ...
                                                 'Options',{'Execute','Return'},'DefaultOption',2,'CancelOption',2,'Icon','Error');
            else
                answer = 'Execute';
            end
        end
    end        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'units','pixels')
            ss = get(0,'ScreenSize');
            app.UIFigure.Position = [(ss(3)/2) - (app.UIFigure.Position(3)/2),...
                                     (ss(4)/2) - (app.UIFigure.Position(4)/2),...
                                                  app.UIFigure.Position(3),...
                                                  app.UIFigure.Position(4)];
            
            app.job = [];   
            app.ffts = [];  app.schedule = [];
            app.stationtable.Data = [];
            app.stationtable.RowName = 'numbered';
            app.paramtable.Data = [];
            app.jobtable.Data = [];

            app.oncolor = [0.78,0.98,0.99];
            app.offcolor = [0.96,0.96,0.96];
            checkstats(app)
            
            td = tiledlayout(app.TimeSynopsisTab,1,1,'Padding','tight','TileSpacing','none');
            app.tsaxes = nexttile(td);
            cla(app.tsaxes)
            app.tsaxes.Visible = 'off';
            
            app.info.stind = 1;
            app.info.jobind = 1;      
            
            ncores = feature('numcores');
            app.info.ncores = ncores - 1;
            app.ncorestext.Text = num2str(ncores);
            app.ncoresbox.Value = num2str(app.info.ncores);
            
            app.log = false;            
            app.map = [];
            
            % Check if time schedule is active
            checkschedule(app)
        end

        % Callback function: reset
        function ResetCallback(app, event)
            answer = uiconfirm(app.UIFigure,'Do you want restart the window?','FFproc - New Job','DefaultOption',1);
            switch answer
                case 'Cancel'
                    return
                otherwise
                    startupFcn(app)    
                    figure(app.UIFigure);     
            end
        end

        % Callback function: addTS
        function LoadTimeSeriesCallback(app, event)
            keepload = true;
            tsmerge = [];
            n = 0;
            while keepload
                % Reading Time Series...
                [file,path] = uigetfile({'*.*', 'All Files (*.*)'; ...
                                         '*.ts2;*.ts3;*.ts4;*.ts5;','Phoenix (*.ts*)';
                                         '*.ats','Metronix (*.ats)';
                                         '*.ts10','LEMI (*.t10)';
                                         '*.RAW','EMERALD (*.RAW)';
                                         '*.ML1;*.ML2;','Geolore (*.ML*)';
                                         '*.asc','Uppsala ASCII (*.asc)';
                                         '*.sec;*.min;','Intermagnet Observatory (*.sec,*.min)';
                                         '*.mat','MatTS (*.mat)'},...
                                         'Select input Time Series');     
                
                % If user press cancel or close button
                if ~file
                    figure(app.UIFigure);
                    return
                else
                    figure(app.UIFigure);
                end    
                               
                % Load a new time series
                if n > 0
                    ts = ts_input(path,file,false,tsmerge(1));
                else
                    ts = ts_input(path,file,false,[]);
                end

                % Concatenating time series
                tsmerge = [tsmerge,ts];

                % Asking user to read another run
                answer = uiconfirm(app.UIFigure,'Do you want to load another run?','FFproc - Load Time Series','DefaultOption',1);

                switch answer
                    case 'Cancel'
                        keepload = false;   
                    otherwise
                        % Another time series...
                        n = n + 1;
                end

            end
            
            % Merging time series in case different runs were loaded
            if numel(tsmerge) > 1
                ts = ts_merge([],tsmerge,'FFproc');
            else
                ts = tsmerge;
            end


            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - New Job','Message','Loading new time series...','Indeterminate','on');
    
                % Concatenating with previous time series
                app.ffts = [app.ffts,ts];
                
                % Asigning new indices
                for i = 1:numel(app.ffts)
                    app.ffts(i).ID = i;
                end
                
                % If any error occurs during time series loading process...
                if ~isfield(app.ffts,'ID')
                    if isempty(app.ffts)
                        app.ffts = [];
                    end
                    return
                end
                
                % Check if time schedule is active
                checkschedule(app)
                
                % Finding time gaps
                app.ffts = findgaps(app.ffts);
                
                % Populating tables
                populatetable(app)       
            
                % Maximum and Minimum Target Frequencies
                resetfreqs(app)
                
            close(dlg)
        end

        % Callback function: deleteTS
        function DeleteTimeSeriesCallback(app, event)
            % Warning dialog when no time series is loaded
            if isempty(app.ffts)
                uialert(app.UIFigure,'No time series is loaded!','FFproc - Warning','Icon','Warning');
                return
            else
                
                % Deleting selected time series ffts
                uialert(app.UIFigure,{['File: ',app.ffts(app.info.stind).file,];['ID: ',num2str(app.info.stind)];'was deleted'},...
                                       'FFproc - Delete','Icon','info');
                app.ffts(app.info.stind) = [];        
                
                if isempty(app.ffts) && isempty(app.job) 
                    % Restarting Window
                    startupFcn(app)
                    
                    % Maximum and Minimum Target Frequencies
                    resetfreqs(app)
                else
                    for i = 1:length(app.ffts)
                        app.ffts(i).ID = i;
                    end
                    
                    % Populating tables
                    populatetable(app)
                    
                    % Maximum and Minimum Target Frequencies
                    resetfreqs(app)
                end              
            end           
        end

        % Callback function: up
        function TSUpCallback(app, event)
            if isempty(app.ffts) == 1
                uialert(app.UIFigure,'No time series is loaded!','FFproc - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one ts is loaded or the index on the table
            % is on the first position
            if numel(app.ffts) == 1 || app.info.stind == 1
                uialert(app.UIFigure,'Time series can not be moved upwards','Up - Warning','Icon','Warning');
                return
            end
            
            % ID of selected time series in the table
            id = app.info.stind;
            % Moving selected time series upwards
            aux = app.ffts(id);
            app.ffts(id) = app.ffts(id-1);
            app.ffts(id-1) = aux;
            for i = 1:length(app.ffts)
                app.ffts(i).ID = i;
            end
            
            % Populating tables
            populatetable(app)          
        end

        % Callback function: down
        function TSDownCallback(app, event)
            if isempty(app.ffts) == 1
                uialert(app.UIFigure,'No time series is loaded!','Down - Warning','Icon','Warning');
                return
            end
            % Warning dialog when just one ts is loaded or the index on the table
            % is on the first position
            if numel(app.ffts) == 1 || app.info.stind == numel(app.ffts)
                uialert(app.UIFigure,'Time series can not be moved downwards','Down - Warning','Icon','Warning');
                return
            end
            
            % ID of selected time series in the table
            id = app.info.stind;
            % Moving selected time series upwards
            aux = app.ffts(id);
            app.ffts(id) = app.ffts(id+1);
            app.ffts(id+1) = aux;
            for i = 1:length(app.ffts)
                app.ffts(i).ID = i;
            end   
            
            % Populating tables
            populatetable(app)          
        end

        % Callback function: time
        function ScheduleCallback(app, event)
            if app.time.State
                app.timeconfig.Enable = true;
            else
                app.timeconfig.Enable = false;
            end
        end

        % Callback function: timeconfig
        function ScheduleConfigCallback(app, event)
            FFproc_SetTimes(app.ffts,'newjob')
        end

        % Callback function: exclude
        function ExcludeCallback(app, event)
            if app.exclude.State
                app.singfbox.Enable = 'on';
                app.phsingbox.Enable = 'on';
                app.multbox.Enable = 'on';
                app.phmultbox.Enable = 'on';
            else
                app.singfbox.Enable = 'off';
                app.phsingbox.Enable = 'off';
                app.multbox.Enable = 'off';
                app.phmultbox.Enable = 'off';
            end
        end

        % Callback function: fast
        function FastCallback(app, event)
            if app.fast.State
                app.proclog.State = false;
            else 
                app.proclog.State = true;
            end
        end

        % Callback function: plotsite
        function PlotSitesMapCallback(app, event)
            app.map = uifigure('Name','FFproc - Site Location Map','Icon','FFproc_Logo.png','Color',[1,1,1]);
            coors = cell2mat(app.paramtable.Data(:,[9,10]));
            site = app.paramtable.Data(:,1);
            tbl = table(categorical(site),coors(:,1),coors(:,2),'VariableNames',{'Name','Latitude','Longitude'});
            geobubble(app.map,tbl,'Latitude','Longitude','Basemap','streets','FontName','Verdana','ColorVariable','Name','ColorLegendTitle','Site');
        end

        % Callback function: submitJob
        function SetJobCallback(app, event)
            answer = uiconfirm(app.UIFigure,{'Set a new job?';...
                                             'Information will be transfered to Job/Properties List'}, ...
                                             'FFproc - Set New Job', ...
                                             'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2);
            switch answer
                case 'Yes'
                    % Load and move data
                    FFproc_datafromtable(app,app.ffts);
                    global schedule
                    app.schedule = schedule;
                    FFproc_station2job(app,app.job,app.ffts,app.schedule);
                    FFproc_filljobtable(app,app.job);
                    
                    % Delete existing data from tables and clears global ffts                    
                    app.stationtable.Data = [];
                    app.paramtable.Data = [];
                    
                    % Reseting field parameters section
                    app.calmenu.Enable = 'off';
                    app.ConfigButton.Value = false;
                    app.calmenu.Items = {'Site Cofiguration'};
                    app.maxfreqbox.Value = 0;
                    app.minfreqbox.Value = 0;
                    app.info.tableind = 1;
                    cla(app.tsaxes)
                    app.tsaxes.Visible = 'off';
                    app.ffts = [];
                    
                case 'No'
                    return
            end
            
            % Check if time schedule is active
            checkschedule(app)
            
            figure(app.UIFigure);
        end

        % Callback function: editJob
        function EditJobCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job has been set!','Edit Job - Warning','Icon','Warning');
                return
            end
            
            answer = uiconfirm(app.UIFigure,{['Edit job ',num2str(app.info.jobind(1,1)),'?'];...
                                              'Information will be removed and';...
                                              'transfered to Station Parameters'}, ...
                                              'FFproc - Edit Job', ...
                                              'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2);
            switch answer
                case 'Yes'
                    if isempty(app.ffts) ~= 0
                        % Selecting ffts
                        app.ffts = app.job(app.info.jobind(1)).ffts;
                       
                        % Populating tables
                        populatetable(app)
                        
                        app.job(app.info.jobind) = [];
                        if isempty(app.job) || numel(app.job) == 0
                            app.jobtable.Data = [];
                        else
                            FFproc_filljobtable(app,app.job);
                        end
                        
                        % Maximum and Minimum Target Frequencies
                        resetfreqs(app)
                    else
                        uialert(app.UIFigure,'Station Parameters List is being used','FFproc - Warning','Icon','Warning');
                        return
                    end
                case 'No'
                    return
            end
            
            % Check if time schedule is active
            checkschedule(app)
            
            figure(app.UIFigure);
        end

        % Callback function: deleteJob
        function DeleteJobCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job has been set!','FFproc - Warning','Icon','Warning');
                return
            end

            answer = uiconfirm(app.UIFigure,{'Delecte selected jobs?'}, ...
                                              'FFproc - Delete Job', ...
                                              'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2);
            switch answer
                case 'Yes'            
                    if numel(app.job) == 1
                        app.jobtable.Data = [];
                        app.job(app.info.jobind) = [];
                        uialert(app.UIFigure,{'Job: 1 was deleted'},'Delete Job','Icon','Info')
                    else
                        if numel(app.info.jobind) == 1
                            uialert(app.UIFigure,{['Job: ',num2str(app.info.jobind)];'was deleted'},'Delete Job','Icon','Info')
                        else
                            uialert(app.UIFigure,{[num2str(numel(app.info.jobind)),' Jobs'];'were deleted'},'Delete Job','Icon','Info')
                        end
                        app.job(app.info.jobind) = [];
                        FFproc_filljobtable(app,app.job);
                    end
            end
            figure(app.UIFigure);
        end

        % Callback function: infoJob
        function InfoJobCallback(app, event)
            txt = FFproc_printparameters(app.job(app.info.jobind));
            FFproc_Printjobparam(txt);
        end

        % Callback function: loadJob
        function LoadJobCallback(app, event)
            % Identifying Operative System
            [user,~] = getuser; 
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Jobs'))
            
            if isempty(app.job) == false
                answer = uiconfirm(app.UIFigure,'Job List is beign used', ...
                                       'FFproc - Load Job', ...
                                       'Options',{'Overwrite','Append','Back'},'DefaultOption',2,'CancelOption',3);
                switch answer
                    case 'Overwrite'
                        [file,path] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                                 'Load Job List');
                        load(fullfile(path,file))
                        app.job = job;
                    case 'Append'
                        job_old = app.job;
                        [file,path] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                                 'Load Job List');
                        load(fullfile(path,file))
                        job_new = job;
                        app.job = cat(1,[job_old,job_new]);
                end
            else
                [file,path] = uigetfile({'*.mat','MAT files (*.mat)'},...
                                         'Load Job List');
                load(fullfile(path,file))
                app.job = job;
            end
            FFproc_filljobtable(app,app.job);        
            figure(app.UIFigure);            
        end

        % Callback function: saveJob
        function SaveJobCallback(app, event)
            if isempty(app.job)
                uialert(app.UIFigure,'No job has been set!','FFproc - Warning','Icon','Warning');
                return
            end
            
            % Identifying Operative System
            [user,~] = getuser; 
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Jobs'))
            
            % Saving *.mat variable
            FFproc_datafromjob(app,app.job);
            outname = 'job.mat';
            [outname,path] = uiputfile(outname,'Save job list in *.mat file');
            
            if isempty(path)
                return
            else
                job = app.job;
                save(fullfile(path,outname),'job');
                uialert(app.UIFigure,{'Job saved in:';path},'FFproc - Save Job','Icon','success')
            end
            figure(app.UIFigure);            
        end

        % Callback function: createCTS
        function CreateCTSCallback(app, event)
            CreateCTS_            
        end

        % Callback function: exe
        function EXECallback(app, event)
            if isempty(app.job) == false
                answer = uiconfirm(app.UIFigure,[num2str(numel(app.job)),' Jobs will be executed'], ...
                                               'FFproc - Execute', ...
                                               'Options',{'Execute','Return'},'DefaultOption',1,'CancelOption',2);
                switch answer
                    case 'Execute'
                        % Updating job with possibles changes in Job table
                        FFproc_datafromjob(app,app.job)
                        figure(app.UIFigure);

                        % Check if all the time series are detected in the
                        % computer
                        jobans = checkjobs(app);
                        switch jobans
                            case 'Return'
                                return
                        end

                        % Starting Parallel pool, if necessary
                        app.procprogress = uiprogressdlg(app.UIFigure,'Title','FFproc','Message',['Executing ',num2str(numel(app.job)),' job(s)'],'Indeterminate','on','Cancelable','on');
                        cleanup = onCleanup(@() close(app.procprogress));
                        if all(cat(1,app.job.ncores) == 0)                            
                            parproc = false;
                        else
                            if isempty(gcp('nocreate'))
                                try
                                    app.procprogress.Message = 'Starting parallel pool...';
                                    parpool(app.job(1).ncores,'IdleTimeout',60);
                                catch ME
                                    switch ME.identifier
                                        case 'parallel:cluster:LocalProfileNumWorkersExceeded'
                                            ncores = feature('numcores');
                                            parpool(ncores-1,'IdleTimeout',60);
                                            for i = 1:numel(app.job)
                                                app.job(i).ncores = ncores-1;
                                            end                                            
                                    end
                                end
                            end
                            parproc = true;
                        end
                        if app.procprogress.CancelRequested
                            close(app.procprogress)
                            return         
                        end

                        % Last chance to cancel processing before starting
                        % program
                        if app.procprogress.CancelRequested
                            status = FFproc_cancelrequest(app);
                            if ~isnan(status)
                                uialert(app.UIFigure,'Operation cancelled by user','FFproc - Job Aborted','Icon','Warning')
                                close(app.procprogress)
                                return    
                            end
                        end                       
                        
                        % ----------  STARTING WITH CALCULATIONS! ---------
                        % Opening log window
                        if app.proclog.State
                            textlog = FFproc_Log;
                            pause(0.1)
                        end
                        % Preallocating status variable
                        status = zeros(numel(app.job),1);
                        for i = 1:numel(app.job)                            
                            app.procprogress.Title = ['FFproc - Processing job ',num2str(i),' of ',num2str(numel(app.job))];
                            try
                                if app.fast.State
                                    app.procprogress.Message = ['Processing job ',num2str(i),' of ',num2str(numel(app.job))];
                                    
                                    status(i) = FFproc_Process_fast([],app.job(i),[],parproc,[]);  
                                    
                                    if app.procprogress.CancelRequested    
                                        status(i) = FFproc_cancelrequest(app);
                                        if ~isnan(status(i))
                                            return    
                                        end
                                    end
                                    
                                    if status(i) == 1
                                        % ---------------------------- FINISHED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' have finished!'],...
                                                              'FFproc','Icon','Success')
                                    elseif status(i) == 0
                                        % ----------------------- ALL CANCELLED
                                        uialert(app.UIFigure,'All jobs cancelled by user','FFproc - Job Aborted','Icon','Warning')
                                        break
                                    elseif status(i) == 999
                                        % ----------------------- JOB CANCELLED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' cancelled'],...
                                                              'FFproc','Icon','Warning')
                                    elseif status(i) == -999
                                        % -------------------------- JOB FAILED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' have failed!'],...
                                                              'FFproc','Icon','Error')
                                    end
                                else
                                    status(i) = FFproc_Process(app,app.job(i),i,parproc,textlog);
                                    if status(i) == 1
                                        % ---------------------------- FINISHED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' have finished!'],...
                                                              'FFproc','Icon','Success')
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {' ************************ FINISHED ************************ '};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                    elseif status(i) == 0
                                        % ----------------------- ALL CANCELLED
                                        uialert(app.UIFigure,'All jobs cancelled by user','FFproc - Job Aborted','Icon','Warning')
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {' ************************ CANCELLED *********************** '};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        break
                                    elseif status(i) == 999
                                        % ----------------------- JOB CANCELLED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' cancelled'],...
                                                              'FFproc','Icon','Warning')
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {' ************************ CANCELLED *********************** '};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                    elseif status(i) == -999
                                        % -------------------------- JOB FAILED
                                        uialert(app.UIFigure,['Calculations for job: ',num2str(i),' have failed!'],...
                                                              'FFproc','Icon','Error')
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {' ************************** FAILED ************************ '};
                                        textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                    end
                                end
                            catch ME
                                uialert(app.UIFigure,['Calculations for job: ',num2str(i),' have failed!'],...
                                                      'FFproc','Icon','Error')
                                switch ME.message
                                    case 'Invalid or deleted object.'
                                        report = getReport(ME);       
                                        uialert(app.UIFigure,report,['FFproc - Error in job: ',num2str(i)],'Icon','Error','Interpreter','html')
                                    otherwise
                                        report = getReport(ME);       
                                        uialert(app.UIFigure,report,['FFproc - Error in job: ',num2str(i)],'Icon','Error','Interpreter','html')
                                        if ~app.fast.State
                                            textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                            textlog.ProgresslogTextArea.Value(end+1,1) = {' ************************** FAILED ************************ '};
                                            textlog.ProgresslogTextArea.Value(end+1,1) = {''};
                                        end
                                end
                            end
                        end                        
                        % ------------- END OF CALCULATIONS ---------------
                        
                        % Evaluating sucess of calcuations in order to shut
                        % down the parallel pool
                        if sum(status) == numel(app.job)
                            if app.parallel.State
                                app.procprogress.Title = 'FFproc - Finishing Process';
                                app.procprogress.Message = 'Shutting down parallel pool...';
                                delete(gcp('nocreate'))
                            end
                            % Opening Folder containing results
                            [user,~] = getuser;
                            % Path for each FFproc user (Documents)
                            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
                        end
                        close(app.procprogress)
                    case 'Return'
                        return
                end
            else
                uialert(app.UIFigure,'No job is loaded!','FFproc - Warning','Icon','Warning');
            end
            figure(app.UIFigure);            
        end

        % Button pushed function: addsettings
        function AddParameterCallback(app, event)
            if isempty(app.paramtable.Data)
                app.paramtable.Data = ['Site 1',num2cell([50,50,0]),[{'bx'},{'by'},{'bz'},false],num2cell([0,0,0])];
            else
                n = size(app.paramtable.Data,1);
                app.paramtable.Data(n+1,:) = [['Site ',num2str(n+1)],num2cell([50,50,0]),[{'bx'},{'by'},{'bz'},false],num2cell([0,0,0])];
            end
        end

        % Button pushed function: deletesettings
        function DeleteParameterCallback(app, event)
            app.paramtable.Data(app.ind,:) = [];
            app.ind = size(app.paramtable.Data,1);
        end

        % Button pushed function: upsettings
        function UpParameterCallback(app, event)
            % Saving data in auxiliar variables
            auxparam = app.paramtable.Data(app.ind);
            % Moving data upwards
            app.paramtable.Data(app.ind) = app.paramtable.Data(app.ind-1);
            app.paramtable.Data(app.ind-1) = auxparam;           
        end

        % Button pushed function: downsettings
        function DownParameterCallback(app, event)
            % Saving data in auxiliar variables
            auxparam = app.paramtable.Data(app.ind);
            % Moving data upwards
            app.paramtable.Data(app.ind) = app.paramtable.Data(app.ind+1);
            app.paramtable.Data(app.ind+1) = auxparam;
        end

        % Value changed function: ConfigButton
        function ParameterListChBoxCallback(app, event)
            % Read Calibration files from "Calibration Files folder"
            if app.ConfigButton.Value
                app.calmenu.Enable = 'on';
                
                % Identifying Operative System
                [user,~] = getuser;            
                settings = dir(fullfile(user,'Documents','FFMT','FFproc','Field Settings','*.mat'));
                app.calmenu.Items = {'Field Configuration',settings(:).name};
            else                
                if ~isempty(app.paramtable.Data)
                    app.paramtable.Data = [];
                end
                app.calmenu.Enable = 'off';
                app.calmenu.Items = {'Site Configuration'};
            end            
        end

        % Value changed function: calmenu
        function ParameterPopUpCallback(app, event)
            value = app.calmenu.Value;
            switch value
                case 'Field Settings'
                    app.paramtable.Data = [];
                    return
            end

            % Identifying Operative System
            [user,~] = getuser;             
            % Loading file 
            load(fullfile(user,'Documents','FFMT','FFproc','Field Settings',value));                    
            
            % Name
            name = extractfield(param,'name')';    
            % Electrodes and dipole rotation
            dip = cat(1,param.diplen);
            ex = dip(:,1);
            ey = dip(:,2);
            e_rot = cat(1,param.rot);      
            % Coil Serial Number and RAP
            coil = cat(1,param.coil);
            RAP = cat(1,param.RAP);
            % Latitude, longitude and elevation
            coor = cat(1,param.GPS);
            fill = [name,num2cell(ex),num2cell(ey),num2cell(e_rot),...
                    cellstr(coil),num2cell(RAP),num2cell(coor)];  
            % Populating table
            set(app.paramtable,'Data',fill)  
        end

        % Callback function
        function ThresholdChBoxCallback(app, event)
            if app.threschbox.Value
                app.thres2box.Enable = 'on';
                app.thres3box.Enable = 'on';
            else
                app.thres2box.Enable = 'off';
                app.thres3box.Enable = 'off';
            end            
        end

        % Callback function
        function EVCriteriaCallback(app, event)
            sel = app.eigbuttongroup.SelectedObject;
            switch sel
                case '1'    
                    app.info.eig = 1;
                case '2'
                    app.info.eig = 2;
            end            
        end

        % Callback function
        function RegresionCallback(app, event)
            sel = app.regbuttongroup.SelectedObject;
            switch sel
                case 'Robust'
                    app.info.rob = 'Robust';
                case 'LSQ'
                    app.info.rob = 'LSQ';
            end           
        end

        % Callback function
        function OutlierButtonCallback(app, event)
            if app.OutlierButton.Value
                app.remmethod.Enable = 'on';                
            else
                app.remmethod.Enable = 'off';
            end
        end

        % Cell selection callback: stationtable
        function StationCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices)
                return
            else
                app.info.stind = indices(1,1);       
            end                 
        end

        % Cell edit callback: stationtable
        function StationTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if indices(2) == 5
                % tstart changed
                try            
                    if datenum(newData) < app.ffts(indices(1)).t(1)
                        uialert(app.UIFigure,{'New start time is lower than recorded times','The original value was restored'},'FFproc - New Job','Icon','Error')
                        app.stationtable.Data{indices(1),indices(2)} = datestr(app.ffts(indices(1)).t(1));
                    end
                catch
                    uialert(app.UIFigure,{'New start time is not on the correct format','The original value was restored'},'FFproc - New Job','Icon','Error')
                    app.stationtable.Data{indices(1),indices(2)} = datestr(app.ffts(indices(1)).t(1));
                end
            elseif indices(2) == 6
                % tend changed
                try
                    if datenum(newData) > app.ffts(indices(1)).t(end)
                        uialert(app.UIFigure,{'New start time is higher than recorded times','The original value was restored'},'FFproc - New Job','Icon','Error')
                        app.stationtable.Data{indices(1),indices(2)} = datestr(app.ffts(indices(1)).t(end));
                    end
                catch
                    uialert(app.UIFigure,{'New end time is not on the correct format','The original value was restored'},'FFproc - New Job','Icon','Error')
                    app.stationtable.Data{indices(1),indices(2)} = datestr(app.ffts(indices(1)).t(end));
                end
            elseif indices(2) == 1
                app.paramtable.Data(:,1) = app.stationtable.Data(:,1);
            end
        end

        % Cell selection callback: paramtable
        function ParameterCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices)
                return
            else
                app.ind = indices(:,1);
                app.info.stind = indices(1,1);
            end            
        end

        % Cell edit callback: paramtable
        function ParamtableCellEdit(app, event)
            indices = event.Indices;
            if indices(2) == 1
                app.stationtable.Data(:,1) = app.paramtable.Data(:,1);
            end
        end

        % Cell selection callback: jobtable
        function JobCellSelection(app, event)
            indices = event.Indices;
            if isempty(indices)
                return
            end
            app.info.jobind = unique(indices(:,1));            
        end

        % Menu selected function: MTU5CMTU8AMenu
        function Load_PhoenixMTU5CCallback(app, event)
            % Reading Time Series...
            path = uigetdir(pwd,'Select folder containing MTU5-C Time Series');
            
            % If user press cancel or close button
            if ~path
                figure(app.UIFigure);
                return
            else
                figure(app.UIFigure);
            end            
            
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - New Job','Message','Loading new time series...','Indeterminate','on'); 
            
                % Load a new time series
                ts = read_MTU5C(path,[],false); 
                
                % Concatenating with previous time series
                app.ffts = [app.ffts,ts];
                
                % Asigning new indices
                for i = 1:numel(app.ffts)
                    app.ffts(i).ID = i;
                end
                
                % If any error occurs during time series loading process...
                if ~isfield(app.ffts,'ID')
                    if isempty(app.ffts)
                        app.ffts = [];
                    end
                    return
                end
                
                % Check if time schedule is active
                checkschedule(app)
                
                % Finding time gaps
                app.ffts = findgaps(app.ffts);
                
                % Populating tables
                populatetable(app)       
            
                % Maximum and Minimum Target Frequencies
                resetfreqs(app)
                
            close(dlg)
        end

        % Menu selected function: Menu_3
        function Load_LEMI420Callback(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - New Job','Message','Loading new time series...','Indeterminate','on'); 
            
                % Load a new time series
                ts = read_LEMI420([],[],true,false,[]);
                ts.device = 'LEMI';
                ts.ext = '.TXT';
                
                % Concatenating with previous time series
                app.ffts = [app.ffts,ts];
                
                % Asigning new indices
                for i = 1:numel(app.ffts)
                    app.ffts(i).ID = i;
                end
                
                % If any error occurs during time series loading process...
                if ~isfield(app.ffts,'ID')
                    if isempty(app.ffts)
                        app.ffts = [];
                    end
                    return
                end
                
                % Check if time schedule is active
                checkschedule(app)
                
                % Finding time gaps
                app.ffts = findgaps(app.ffts);
                
                % Populating tables
                populatetable(app)       
            
                % Maximum and Minimum Target Frequencies
                resetfreqs(app)
                
            close(dlg)
        end

        % Menu selected function: JobEditorMenu
        function JobEditorCallback(app, event)
            FFproc_JobEditor
        end

        % Menu selected function: PeriodsMenu
        function CombinePeriodsCallback(app, event)
            FFproc_CombinePeriods
        end

        % Menu selected function: StationsMenu
        function CombineStationsCallback(app, event)
            FFproc_CombineStations
        end

        % Menu selected function: FieldSettingsMenu
        function FieldSettingsCallback(app, event)
            FFproc_FieldSettings
        end

        % Menu selected function: ResultsMenu
        function ResultsCallback(app, event)
            FFproc_Results
        end

        % Menu selected function: RAWMenu
        function RAWCallback(app, event)
            FFproc_RAW
        end

        % Menu selected function: SpectraMenu
        function SpectraCallback(app, event)
            FFproc_Spectra
        end

        % Menu selected function: ChangeRecordingTimesMenu
        function ChangeRecordingTimesCallback(app, event)
            ReadTS_ChangeTime
        end

        % Menu selected function: MergeTimeSeriesMenu
        function MergeTimeSeriesCallback(app, event)
            ReadTS_MergeTS
        end

        % Menu selected function: GetRecordingTimesMenu
        function GetRecordingTimesCallback(app, event)
            ReadTS_GetTimes
        end

        % Value changed function: BivariateLinearRegressionButton
        function BivariateLinearRegressionButtonValueChanged(app, event)
            if app.BivariateLinearRegressionButton.Value
                app.BivariateLinearRegressionButton.BackgroundColor = app.oncolor;
                app.EigenvalueDecompositionButton.BackgroundColor = app.offcolor;
                app.EigenvalueDecompositionButton.Value = false;
                app.PCCZButton.Value = true;
                app.PCCTButton.Value = true;
                app.EVZButton.Value = false;
                app.EVTButton.Value = false;
                app.EVZButton.Enable = 'off';
                app.EVTButton.Enable = 'off';

                app.EI0Button.Value = false;
                app.EI1Button.Value = false;
                app.EI2Button.Value = false;
                app.EI3Button.Value = false;
                app.EI4Button.Value = false;
                app.EI0Button.Enable = 'off';
                app.EI1Button.Enable = 'off';
                app.EI2Button.Enable = 'off';
                app.EI3Button.Enable = 'off';
                app.EI4Button.Enable = 'off';
                app.EIpower.Enable = 'off';
                app.EIpower.Value = 0;
            else
                app.BivariateLinearRegressionButton.BackgroundColor = app.offcolor;
                app.EigenvalueDecompositionButton.BackgroundColor = app.oncolor;
                app.EigenvalueDecompositionButton.Value = true;
                app.PCCZButton.Value = false;
                app.PCCTButton.Value = false;
                app.EVZButton.Value = true;
                app.EVTButton.Value = true;
                app.EVZButton.Enable = 'on';
                app.EVTButton.Enable = 'on';

                app.EI0Button.Value = false;
                app.EI1Button.Value = false;
                app.EI2Button.Value = false;
                app.EI3Button.Value = true;
                app.EI4Button.Value = false;
                app.EI0Button.Enable = 'on';
                app.EI1Button.Enable = 'on';
                app.EI2Button.Enable = 'on';
                app.EI3Button.Enable = 'on';
                app.EI4Button.Enable = 'on';
                app.EIpower.Enable = 'on';
                app.EIpower.Value = 1;
            end
            checkev(app)
            checkrnk(app)
        end

        % Value changed function: EigenvalueDecompositionButton
        function EigenvalueDecompositionButtonValueChanged(app, event)
            if app.EigenvalueDecompositionButton.Value
                app.BivariateLinearRegressionButton.BackgroundColor = app.offcolor;
                app.EigenvalueDecompositionButton.BackgroundColor = app.oncolor;
                app.BivariateLinearRegressionButton.Value = false;
                app.PCCZButton.Value = false;
                app.PCCTButton.Value = false;
                app.EVZButton.Value = true;
                app.EVTButton.Value = true;
                app.EVZButton.Enable = 'on';
                app.EVTButton.Enable = 'on';
                
                app.EI0Button.Value = false;
                app.EI1Button.Value = false;
                app.EI2Button.Value = false;
                app.EI3Button.Value = true;
                app.EI4Button.Value = false;
                app.EI0Button.Enable = 'on';
                app.EI1Button.Enable = 'on';
                app.EI2Button.Enable = 'on';
                app.EI3Button.Enable = 'on';
                app.EI4Button.Enable = 'on';
                app.EIpower.Enable = 'on';
                app.EIpower.Value = 1;
            else
                app.BivariateLinearRegressionButton.BackgroundColor = app.oncolor;
                app.EigenvalueDecompositionButton.BackgroundColor = app.offcolor;
                app.BivariateLinearRegressionButton.Value = true;
                app.PCCZButton.Value = true;
                app.PCCTButton.Value = true;
                app.EVZButton.Value = false;
                app.EVTButton.Value = false;
                app.EVZButton.Enable = 'off';
                app.EVTButton.Enable = 'off';

                app.EI0Button.Value = false;
                app.EI1Button.Value = false;
                app.EI2Button.Value = false;
                app.EI3Button.Value = false;
                app.EI4Button.Value = false;
                app.EI0Button.Enable = 'off';
                app.EI1Button.Enable = 'off';
                app.EI2Button.Enable = 'off';
                app.EI3Button.Enable = 'off';
                app.EI4Button.Enable = 'off';
                app.EIpower.Enable = 'off';
                app.EIpower.Value = 0;
            end  
            checkev(app)
            checkrnk(app)
        end

        % Value changed function: LRLSQButton
        function LRLSQButtonValueChanged(app, event)
            if app.LRLSQButton.Value
                app.LRLSQButton.BackgroundColor = app.oncolor;
                app.LRRobustButton.BackgroundColor = app.offcolor;
                app.LRRobustButton.Value = false;
                app.LRPCAButton.Value = false;
            else
                app.LRLSQButton.BackgroundColor = app.offcolor;
                app.LRRobustButton.BackgroundColor = app.oncolor;
                app.LRRobustButton.Value = true;
            end
        end

        % Value changed function: LRRobustButton
        function LRRobustButtonValueChanged(app, event)
            if app.LRRobustButton.Value
                app.LRLSQButton.BackgroundColor = app.offcolor;
                app.LRRobustButton.BackgroundColor = app.oncolor;
                app.LRLSQButton.Value = false;
                app.LRPCAButton.Value = false;
            else
                app.LRLSQButton.BackgroundColor = app.oncolor;
                app.LRRobustButton.BackgroundColor = app.offcolor;
                app.LRLSQButton.Value = true;
            end            
        end

        % Value changed function: LRPCAButton
        function LRPCAButtonValueChanged(app, event)
            if app.LRPCAButton.Value
                app.LRLSQButton.Value = false;
                app.LRRobustButton.Value = false;
            else
                app.LRLSQButton.Value = true;
            end           
        end

        % Value changed function: NMStandardButton
        function NMStandardButtonValueChanged(app, event)
            if app.NMStandardButton.Value
                app.NMStandardButton.BackgroundColor = app.oncolor;
                app.NMAdvancedButton.BackgroundColor = app.offcolor;
                app.NMAdvancedButton.Value = false;
            else
                app.NMStandardButton.BackgroundColor = app.offcolor;
                app.NMAdvancedButton.BackgroundColor = app.oncolor;
                app.NMAdvancedButton.Value = true;
            end            
        end

        % Value changed function: NMAdvancedButton
        function NMAdvancedButtonValueChanged(app, event)
            if app.NMAdvancedButton.Value
                app.NMStandardButton.BackgroundColor = app.offcolor;
                app.NMAdvancedButton.BackgroundColor = app.oncolor;
                app.NMStandardButton.Value = false;
            else
                app.NMStandardButton.BackgroundColor = app.oncolor;
                app.NMAdvancedButton.BackgroundColor = app.offcolor;
                app.NMStandardButton.Value = true;
            end            
        end

        % Cell edit callback: jobtable
        function jobtableCellEdit(app, event)
            indices = event.Indices;
            if indices(1,2) == 7
                switch app.jobtable.Data{indices(1),indices(2)}
                    case 'EV Decomp'
                        app.jobtable.Data{indices(1),12} = 'PCC';
                        app.jobtable.Data{indices(1),13} = 'PCC';
                        app.jobtable.Data{indices(1),15} = 'EVI';
                        app.jobtable.Data{indices(1),16} = 1;
                    case 'Bivariate'
                        app.jobtable.Data{indices(1),12} = 'PCC';
                        app.jobtable.Data{indices(1),13} = 'PCC';
                        app.jobtable.Data{indices(1),15} = 'N/A';
                        app.jobtable.Data{indices(1),16} = 0;
                end
            elseif indices(1,2) == 10
                if app.jobtable.Data{indices(1),indices(2)}
                    app.jobtable.Data{indices(1),indices(2) + 1} = app.job(indices(1)).remmethod;
                else
                    app.jobtable.Data{indices(1),indices(2) + 1} = 'N/A';
                end
            else
                app.jobtable.Data{indices(1),indices(2)} = event.NewData;
            end            
        end

        % Value changed function: OPTButton
        function OPTButtonValueChanged(app, event)
            if app.OPTButton.Value
                app.OPTButton.BackgroundColor = app.oncolor;
            else
                app.OPTButton.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: RNKButton
        function RNKButtonValueChanged(app, event)
            if app.RNKButton.Value
                app.RNKButton.BackgroundColor = app.oncolor;
            else
                app.RNKButton.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: RAWButton
        function RAWButtonValueChanged(app, event)
            if app.RAWButton.Value
                app.RAWButton.BackgroundColor = app.oncolor;
            else
                app.RAWButton.BackgroundColor = app.offcolor;
            end   
        end

        % Value changed function: FCButton
        function FCButtonValueChanged(app, event)
            if app.FCButton.Value
                app.FCButton.BackgroundColor = app.oncolor;
            else
                app.FCButton.BackgroundColor = app.offcolor;
            end  
        end

        % Value changed function: EVZButton
        function EVZButtonValueChanged(app, event)
            if app.EVZButton.Value
                app.EVZButton.BackgroundColor = app.oncolor;
            else
                app.EVZButton.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: EVTButton
        function EVTButtonValueChanged(app, event)
            if app.EVTButton.Value
                app.EVTButton.BackgroundColor = app.oncolor;
            else
                app.EVTButton.BackgroundColor = app.offcolor;
            end           
        end

        % Value changed function: PCCZButton
        function PCCZButtonValueChanged(app, event)
            if app.PCCZButton.Value
                app.PCCZButton.BackgroundColor = app.oncolor;
            else
                app.PCCZButton.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: PCCTButton
        function PCCTButtonValueChanged(app, event)
            if app.PCCTButton.Value
                app.PCCTButton.BackgroundColor = app.oncolor;
            else
                app.PCCTButton.BackgroundColor = app.offcolor;
            end          
        end

        % Value changed function: EI0Button
        function EI0ButtonValueChanged(app, event)
            if app.EI0Button.Value
                app.EI0Button.BackgroundColor = app.oncolor;
            else
                app.EI0Button.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: EI1Button
        function EI1ButtonValueChanged(app, event)
            if app.EI1Button.Value
                app.EI1Button.BackgroundColor = app.oncolor;
            else
                app.EI1Button.BackgroundColor = app.offcolor;
            end                   
        end

        % Value changed function: EI2Button
        function EI2ButtonValueChanged(app, event)
            if app.EI2Button.Value
                app.EI2Button.BackgroundColor = app.oncolor;
            else
                app.EI2Button.BackgroundColor = app.offcolor;
            end                
        end

        % Value changed function: EI3Button
        function EI3ButtonValueChanged(app, event)
            if app.EI3Button.Value
                app.EI3Button.BackgroundColor = app.oncolor;
            else
                app.EI3Button.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: EI4Button
        function EI4ButtonValueChanged(app, event)
            if app.EI4Button.Value
                app.EI4Button.BackgroundColor = app.oncolor;
            else
                app.EI4Button.BackgroundColor = app.offcolor;
            end            
        end

        % Value changed function: OutlierButton
        function OutlierButtonValueChanged(app, event)
            if app.OutlierButton.Value
                app.OutlierButton.BackgroundColor = app.oncolor;
                app.remmethod.Enable = true;
            else
                app.OutlierButton.BackgroundColor = app.offcolor;
                app.remmethod.Enable = false;
            end            
        end

        % Value changed function: PolyfitButton
        function PolyfitButtonValueChanged(app, event)
            if app.PolyfitButton.Value
                app.PolyfitButton.BackgroundColor = app.oncolor;
            else
                app.PolyfitButton.BackgroundColor = app.offcolor;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - New Job';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadTSMenu = uimenu(app.FileMenu);
            app.LoadTSMenu.Text = 'Load TS';
            app.PhoenixGeophysicsMenu = uimenu(app.LoadTSMenu);
            app.PhoenixGeophysicsMenu.Text = 'Phoenix Geophysics';
            app.MTU5MTU5AV8Menu = uimenu(app.PhoenixGeophysicsMenu);
            app.MTU5MTU5AV8Menu.Enable = 'off';
            app.MTU5MTU5AV8Menu.Text = 'MTU5 / MTU5-A / V8';
            app.MTU5CMTU8AMenu = uimenu(app.PhoenixGeophysicsMenu);
            app.MTU5CMTU8AMenu.MenuSelectedFcn = createCallbackFcn(app, @Load_PhoenixMTU5CCallback, true);
            app.MTU5CMTU8AMenu.Text = 'MTU5-C / MTU8-A';
            app.MetronixMenu = uimenu(app.LoadTSMenu);
            app.MetronixMenu.Enable = 'off';
            app.MetronixMenu.Text = 'Metronix';
            app.LEMIMenu = uimenu(app.LoadTSMenu);
            app.LEMIMenu.Text = 'LEMI';
            app.Menu_2 = uimenu(app.LEMIMenu);
            app.Menu_2.Enable = 'off';
            app.Menu_2.Text = '417';
            app.Menu_3 = uimenu(app.LEMIMenu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Load_LEMI420Callback, true);
            app.Menu_3.Text = '420';
            app.Menu_4 = uimenu(app.LEMIMenu);
            app.Menu_4.Enable = 'off';
            app.Menu_4.Text = '424';
            app.GEOLOREMenu = uimenu(app.LoadTSMenu);
            app.GEOLOREMenu.Enable = 'off';
            app.GEOLOREMenu.Text = 'GEOLORE';
            app.V10Menu = uimenu(app.GEOLOREMenu);
            app.V10Menu.Text = 'V 1.0';
            app.V20Menu = uimenu(app.GEOLOREMenu);
            app.V20Menu.Text = 'V 2.0';
            app.V30Menu = uimenu(app.GEOLOREMenu);
            app.V30Menu.Text = 'V 3.0';
            app.ReadTSMenu = uimenu(app.UIFigure);
            app.ReadTSMenu.Text = 'ReadTS';
            app.GetRecordingTimesMenu = uimenu(app.ReadTSMenu);
            app.GetRecordingTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @GetRecordingTimesCallback, true);
            app.GetRecordingTimesMenu.Text = 'Get Recording Times';
            app.ChangeRecordingTimesMenu = uimenu(app.ReadTSMenu);
            app.ChangeRecordingTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRecordingTimesCallback, true);
            app.ChangeRecordingTimesMenu.Text = 'Change Recording Times';
            app.MergeTimeSeriesMenu = uimenu(app.ReadTSMenu);
            app.MergeTimeSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeTimeSeriesCallback, true);
            app.MergeTimeSeriesMenu.Text = 'Merge Time Series';
            app.FFprocMenu_3 = uimenu(app.UIFigure);
            app.FFprocMenu_3.Text = 'FFproc';
            app.JobEditorMenu = uimenu(app.FFprocMenu_3);
            app.JobEditorMenu.MenuSelectedFcn = createCallbackFcn(app, @JobEditorCallback, true);
            app.JobEditorMenu.Text = 'Job Editor';
            app.FieldSettingsMenu = uimenu(app.FFprocMenu_3);
            app.FieldSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @FieldSettingsCallback, true);
            app.FieldSettingsMenu.Separator = 'on';
            app.FieldSettingsMenu.Text = 'Field Settings';
            app.CombineMenu = uimenu(app.FFprocMenu_3);
            app.CombineMenu.Text = 'Combine';
            app.PeriodsMenu = uimenu(app.CombineMenu);
            app.PeriodsMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinePeriodsCallback, true);
            app.PeriodsMenu.Text = 'Periods';
            app.StationsMenu = uimenu(app.CombineMenu);
            app.StationsMenu.MenuSelectedFcn = createCallbackFcn(app, @CombineStationsCallback, true);
            app.StationsMenu.Text = 'Stations';
            app.ResultsMenu = uimenu(app.FFprocMenu_3);
            app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsCallback, true);
            app.ResultsMenu.Text = 'Results';
            app.RAWMenu = uimenu(app.FFprocMenu_3);
            app.RAWMenu.MenuSelectedFcn = createCallbackFcn(app, @RAWCallback, true);
            app.RAWMenu.Text = 'RAW';
            app.SpectraMenu = uimenu(app.FFprocMenu_3);
            app.SpectraMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectraCallback, true);
            app.SpectraMenu.Text = 'Spectra';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Enable = 'off';
            app.DocumentationMenu.Text = 'Documentation';
            app.FFprocMenu_2 = uimenu(app.DocumentationMenu);
            app.FFprocMenu_2.Text = 'FFproc';
            app.FFMTMenu_2 = uimenu(app.DocumentationMenu);
            app.FFMTMenu_2.Text = 'FFMT';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.reset = uipushtool(app.Toolbar);
            app.reset.Tooltip = {'Restart'};
            app.reset.ClickedCallback = createCallbackFcn(app, @ResetCallback, true);
            app.reset.Icon = 'FFproc_update.png';
            app.addTS = uipushtool(app.Toolbar);
            app.addTS.Tooltip = {'Load Station'};
            app.addTS.ClickedCallback = createCallbackFcn(app, @LoadTimeSeriesCallback, true);
            app.addTS.Icon = 'FFproc_plus.png';
            app.addTS.Separator = 'on';
            app.deleteTS = uipushtool(app.Toolbar);
            app.deleteTS.Tooltip = {'Delete Selected Station'};
            app.deleteTS.ClickedCallback = createCallbackFcn(app, @DeleteTimeSeriesCallback, true);
            app.deleteTS.Icon = 'FFproc_minus.png';
            app.up = uipushtool(app.Toolbar);
            app.up.Tooltip = {'TS Up'};
            app.up.ClickedCallback = createCallbackFcn(app, @TSUpCallback, true);
            app.up.Icon = 'FFproc_up.png';
            app.up.Separator = 'on';
            app.down = uipushtool(app.Toolbar);
            app.down.Tooltip = {'TS Down'};
            app.down.ClickedCallback = createCallbackFcn(app, @TSDownCallback, true);
            app.down.Icon = 'FFproc_down.png';
            app.plotsite = uipushtool(app.Toolbar);
            app.plotsite.Tooltip = {'Plot site locations'};
            app.plotsite.ClickedCallback = createCallbackFcn(app, @PlotSitesMapCallback, true);
            app.plotsite.Icon = 'FFproc_map.png';
            app.plotsite.Separator = 'on';
            app.time = uitoggletool(app.Toolbar);
            app.time.Tooltip = {'Schedule'};
            app.time.ClickedCallback = createCallbackFcn(app, @ScheduleCallback, true);
            app.time.Icon = 'FFproc_time.png';
            app.time.Separator = 'on';
            app.timeconfig = uipushtool(app.Toolbar);
            app.timeconfig.Tooltip = {'Configure Schedule'};
            app.timeconfig.ClickedCallback = createCallbackFcn(app, @ScheduleConfigCallback, true);
            app.timeconfig.Enable = 'off';
            app.timeconfig.Icon = 'FFproc_timeconfig.png';
            app.exclude = uitoggletool(app.Toolbar);
            app.exclude.Tooltip = {'Exclude Frequencies'};
            app.exclude.ClickedCallback = createCallbackFcn(app, @ExcludeCallback, true);
            app.exclude.Icon = 'FFproc_exclude.png';
            app.exclude.Separator = 'on';
            app.exclude.State = 'on';
            app.submitJob = uipushtool(app.Toolbar);
            app.submitJob.Tooltip = {'Submit Job'};
            app.submitJob.ClickedCallback = createCallbackFcn(app, @SetJobCallback, true);
            app.submitJob.Icon = 'FFproc_setjob.png';
            app.submitJob.Separator = 'on';
            app.editJob = uipushtool(app.Toolbar);
            app.editJob.Tooltip = {'Edit Job'};
            app.editJob.ClickedCallback = createCallbackFcn(app, @EditJobCallback, true);
            app.editJob.Icon = 'FFproc_editjob.png';
            app.deleteJob = uipushtool(app.Toolbar);
            app.deleteJob.Tooltip = {'Delete Job'};
            app.deleteJob.ClickedCallback = createCallbackFcn(app, @DeleteJobCallback, true);
            app.deleteJob.Icon = 'FFproc_deletejob.png';
            app.infoJob = uipushtool(app.Toolbar);
            app.infoJob.Tooltip = {'Print Job Information'};
            app.infoJob.ClickedCallback = createCallbackFcn(app, @InfoJobCallback, true);
            app.infoJob.Icon = 'FFproc_info.png';
            app.saveJob = uipushtool(app.Toolbar);
            app.saveJob.Tooltip = {'Save Job'};
            app.saveJob.ClickedCallback = createCallbackFcn(app, @SaveJobCallback, true);
            app.saveJob.Icon = 'FFproc_savejob.png';
            app.loadJob = uipushtool(app.Toolbar);
            app.loadJob.Tooltip = {'Load Job'};
            app.loadJob.ClickedCallback = createCallbackFcn(app, @LoadJobCallback, true);
            app.loadJob.Icon = 'FFproc_addjob.png';
            app.createCTS = uipushtool(app.Toolbar);
            app.createCTS.Tooltip = {'Create CTS files using SysCal application from Phoenix Geophysics'};
            app.createCTS.ClickedCallback = createCallbackFcn(app, @CreateCTSCallback, true);
            app.createCTS.Icon = 'Phoenix_Logo.png';
            app.createCTS.Separator = 'on';
            app.parallel = uitoggletool(app.Toolbar);
            app.parallel.Tooltip = {'Shut down parpool after finishing job'};
            app.parallel.Icon = 'FFproc_parallel.png';
            app.parallel.Separator = 'on';
            app.fast = uitoggletool(app.Toolbar);
            app.fast.Tooltip = {'Run Processing '; 'without GUI features'};
            app.fast.ClickedCallback = createCallbackFcn(app, @FastCallback, true);
            app.fast.Icon = 'FFproc_fast.png';
            app.proclog = uitoggletool(app.Toolbar);
            app.proclog.Tooltip = {'Show progress dialogues'};
            app.proclog.Icon = 'FFproc_log.png';
            app.proclog.State = 'on';
            app.exe = uipushtool(app.Toolbar);
            app.exe.Tooltip = {'Execute jobs'};
            app.exe.ClickedCallback = createCallbackFcn(app, @EXECallback, true);
            app.exe.Icon = 'FFproc_exe.png';
            app.exe.Separator = 'on';
            app.GridLayout1x1 = uigridlayout(app.UIFigure);
            app.OutterGrid = uigridlayout(app.GridLayout1x1);
            app.TabGroup = uitabgroup(app.OutterGrid);
            app.SiteInformationTab = uitab(app.TabGroup);
            app.SiteInformationTab.Title = '                              Site Information                              ';
            app.GridLayout13 = uigridlayout(app.SiteInformationTab);
            app.stationtable = uitable(app.GridLayout13);
            app.stationtable.ColumnName = {'Site'; 'Device'; 'ext'; 'sr (Hz)'; 'Start date/time'; 'End date/time'; 'Ex'; 'Ey'; 'Bx'; 'By'; 'Bz'; 'Bx Ref'; 'By Ref'; 'Ex swap'; 'Ey swap'; 'Bx swap'; 'By swap'};
            app.stationtable.SelectionType = 'row';
            app.stationtable.ColumnEditable = [true false false false true true true true true true true true true true true true true];
            app.stationtable.CellEditCallback = createCallbackFcn(app, @StationTableCellEdit, true);
            app.stationtable.CellSelectionCallback = createCallbackFcn(app, @StationCellSelection, true);
            app.SiteParametersTab = uitab(app.TabGroup);
            app.SiteParametersTab.Title = '                              Site Parameters                              ';
            app.GridLayout3 = uigridlayout(app.SiteParametersTab);
            app.paramtable = uitable(app.GridLayout3);
            app.paramtable.ColumnName = {'Site'; 'EX (m)'; 'EY (m)'; 'E-Rot (°)'; 'BX'; 'BY'; 'BZ'; 'RAP'; 'Lat (dec)'; 'Lon (dec)'; 'Elev (m)'};
            app.paramtable.SelectionType = 'row';
            app.paramtable.ColumnEditable = true;
            app.paramtable.CellEditCallback = createCallbackFcn(app, @ParamtableCellEdit, true);
            app.paramtable.CellSelectionCallback = createCallbackFcn(app, @ParameterCellSelection, true);
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout31 = uigridlayout(app.Panel);
            app.ConfigButton = uibutton(app.GridLayout31, 'state');
            app.ConfigButton.ValueChangedFcn = createCallbackFcn(app, @ParameterListChBoxCallback, true);
            app.ConfigButton.Text = 'Use configuration file';
            app.calmenu = uidropdown(app.GridLayout31);
            app.calmenu.Items = {'Field Configuration'};
            app.calmenu.ValueChangedFcn = createCallbackFcn(app, @ParameterPopUpCallback, true);
            app.calmenu.Enable = 'off';
            app.calmenu.Value = 'Field Configuration';
            app.addsettings = uibutton(app.GridLayout31, 'push');
            app.addsettings.ButtonPushedFcn = createCallbackFcn(app, @AddParameterCallback, true);
            app.addsettings.Icon = 'FFproc_plus.png';
            app.addsettings.Text = '';
            app.deletesettings = uibutton(app.GridLayout31, 'push');
            app.deletesettings.ButtonPushedFcn = createCallbackFcn(app, @DeleteParameterCallback, true);
            app.deletesettings.Icon = 'FFproc_minus.png';
            app.deletesettings.Text = '';
            app.upsettings = uibutton(app.GridLayout31, 'push');
            app.upsettings.ButtonPushedFcn = createCallbackFcn(app, @UpParameterCallback, true);
            app.upsettings.Icon = 'FFproc_up.png';
            app.upsettings.Text = '';
            app.downsettings = uibutton(app.GridLayout31, 'push');
            app.downsettings.ButtonPushedFcn = createCallbackFcn(app, @DownParameterCallback, true);
            app.downsettings.Icon = 'FFproc_down.png';
            app.downsettings.Text = '';
            app.TimeSynopsisTab = uitab(app.TabGroup);
            app.TimeSynopsisTab.Title = '                              Time Synopsis                              ';
            app.GridLayout32 = uigridlayout(app.OutterGrid);
            app.TargetFrequenciesPanel = uipanel(app.GridLayout32);
            app.TargetFrequenciesPanel.Title = 'Target Frequencies';
            app.GridLayout28 = uigridlayout(app.TargetFrequenciesPanel);
            app.MaxFreqHzLabel = uilabel(app.GridLayout28);
            app.MaxFreqHzLabel.Text = 'Max Freq (Hz):';
            app.maxfreqbox = uieditfield(app.GridLayout28, 'numeric');
            app.maxfreqbox.Limits = [0 Inf];
            app.maxfreqbox.Tooltip = {'Maximum frequency range'; 'fmax = sr/2.8'};
            app.MinFreqHzLabel = uilabel(app.GridLayout28);
            app.MinFreqHzLabel.Text = 'Min Freq (Hz):';
            app.minfreqbox = uieditfield(app.GridLayout28, 'numeric');
            app.minfreqbox.Limits = [0 Inf];
            app.minfreqbox.Tooltip = {'Minimum frequency range'; 'fmin = sr/200'};
            app.FreqDecadeLabel = uilabel(app.GridLayout28);
            app.FreqDecadeLabel.Text = 'Freq/Decade:';
            app.targfreqbox = uieditfield(app.GridLayout28, 'numeric');
            app.targfreqbox.Limits = [1 Inf];
            app.targfreqbox.Value = 6;
            app.OscillationsLabel = uilabel(app.GridLayout28);
            app.OscillationsLabel.Text = 'Oscillations:';
            app.oscbox = uieditfield(app.GridLayout28, 'numeric');
            app.oscbox.Limits = [0 Inf];
            app.oscbox.RoundFractionalValues = 'on';
            app.oscbox.Tooltip = {'Length of time windows'; 'for each target frequency'};
            app.oscbox.Value = 1000;
            app.ExcludeFrequenciesPanel = uipanel(app.GridLayout32);
            app.ExcludeFrequenciesPanel.Title = 'Exclude Frequencies';
            app.GridLayout28_2 = uigridlayout(app.ExcludeFrequenciesPanel);
            app.text12 = uilabel(app.GridLayout28_2);
            app.text12.Text = 'Single frequencies (Hz):';
            app.singfbox = uieditfield(app.GridLayout28_2, 'text');
            app.singfbox.Tooltip = {'To add more than one value'; 'just leave an empty space'; 'e.g. 50 16.66'};
            app.singfbox.Value = '60';
            app.text13 = uilabel(app.GridLayout28_2);
            app.text13.Text = 'Single freq. width (Hz):';
            app.phsingbox = uieditfield(app.GridLayout28_2, 'text');
            app.phsingbox.Value = '4';
            app.text10 = uilabel(app.GridLayout28_2);
            app.text10.Text = 'Multiples (Hz):';
            app.multbox = uieditfield(app.GridLayout28_2, 'text');
            app.multbox.Tooltip = {'To add more than one value'; 'just leave an empty space'; 'e.g. 50 16.66'};
            app.multbox.Value = '60';
            app.text11 = uilabel(app.GridLayout28_2);
            app.text11.Text = 'Multple freq. width (Hz):';
            app.phmultbox = uieditfield(app.GridLayout28_2, 'text');
            app.phmultbox.Value = '4';
            app.TabGroup2 = uitabgroup(app.GridLayout32);
            app.NoiseModelEstimationandLinearRegressionSettingsTab = uitab(app.TabGroup2);
            app.NoiseModelEstimationandLinearRegressionSettingsTab.Title = 'Noise Model Estimation and Linear Regression Settings                      ';
            app.GridLayout33 = uigridlayout(app.NoiseModelEstimationandLinearRegressionSettingsTab);
            app.TFEstimationApproachPanel = uipanel(app.GridLayout33);
            app.TFEstimationApproachPanel.Title = 'TF Estimation Approach';
            app.GridLayout38 = uigridlayout(app.TFEstimationApproachPanel);
            app.BivariateLinearRegressionButton = uibutton(app.GridLayout38, 'state');
            app.BivariateLinearRegressionButton.ValueChangedFcn = createCallbackFcn(app, @BivariateLinearRegressionButtonValueChanged, true);
            app.BivariateLinearRegressionButton.Text = {'Bivariate'; 'Linear Regression'};
            app.EigenvalueDecompositionButton = uibutton(app.GridLayout38, 'state');
            app.EigenvalueDecompositionButton.ValueChangedFcn = createCallbackFcn(app, @EigenvalueDecompositionButtonValueChanged, true);
            app.EigenvalueDecompositionButton.Text = {'Eigenvalue'; 'Decomposition'};
            app.EigenvalueDecompositionButton.Value = true;
            app.text29_2 = uilabel(app.GridLayout38);
            app.text29_2.Text = {'Following'; '- Vozoff (1978)'; '- Simpson and Bahr (2005)'};
            app.text28_2 = uilabel(app.GridLayout38);
            app.text28_2.Text = {'Following'; '- Hering et al. (2023)'; '- Hering (2019)'; '- Egbert (1997)'};
            app.NoiseModelPanel = uipanel(app.GridLayout33);
            app.NoiseModelPanel.Title = 'Noise Model';
            app.GridLayout38_2 = uigridlayout(app.NoiseModelPanel);
            app.NMStandardButton = uibutton(app.GridLayout38_2, 'state');
            app.NMStandardButton.ValueChangedFcn = createCallbackFcn(app, @NMStandardButtonValueChanged, true);
            app.NMStandardButton.Text = 'Standard';
            app.NMAdvancedButton = uibutton(app.GridLayout38_2, 'state');
            app.NMAdvancedButton.ValueChangedFcn = createCallbackFcn(app, @NMAdvancedButtonValueChanged, true);
            app.NMAdvancedButton.Text = 'Advanced';
            app.NMAdvancedButton.Value = true;
            app.text29_3 = uilabel(app.GridLayout38_2);
            app.text29_3.Text = 'Variances';
            app.text28_3 = uilabel(app.GridLayout38_2);
            app.text28_3.Text = {'Variances and '; 'Covariances'};
            app.LinearRegressionPanel = uipanel(app.GridLayout33);
            app.LinearRegressionPanel.Title = 'Linear Regression';
            app.GridLayout38_3 = uigridlayout(app.LinearRegressionPanel);
            app.LRLSQButton = uibutton(app.GridLayout38_3, 'state');
            app.LRLSQButton.ValueChangedFcn = createCallbackFcn(app, @LRLSQButtonValueChanged, true);
            app.LRLSQButton.Text = 'LSQ';
            app.LRRobustButton = uibutton(app.GridLayout38_3, 'state');
            app.LRRobustButton.ValueChangedFcn = createCallbackFcn(app, @LRRobustButtonValueChanged, true);
            app.LRRobustButton.Text = 'Robust';
            app.LRRobustButton.Value = true;
            app.LRPCAButton = uibutton(app.GridLayout38_3, 'state');
            app.LRPCAButton.ValueChangedFcn = createCallbackFcn(app, @LRPCAButtonValueChanged, true);
            app.LRPCAButton.Enable = 'off';
            app.LRPCAButton.Text = 'PCA';
            app.text8_4 = uilabel(app.GridLayout38_3);
            app.text8_4.Text = 'Least Squeares';
            app.text8_5 = uilabel(app.GridLayout38_3);
            app.text8_5.Text = {'Huber Weighted '; 'Least Squeares'};
            app.text8_6 = uilabel(app.GridLayout38_3);
            app.text8_6.Text = {'Principal Component '; 'Analysis'};
            app.OutlierDetectionPanel_2 = uipanel(app.GridLayout33);
            app.OutlierDetectionPanel_2.Title = 'Outlier Detection';
            app.GridLayout38_4 = uigridlayout(app.OutlierDetectionPanel_2);
            app.OutlierButton = uibutton(app.GridLayout38_4, 'state');
            app.OutlierButton.ValueChangedFcn = createCallbackFcn(app, @OutlierButtonValueChanged, true);
            app.OutlierButton.Text = 'Active';
            app.DetectionMethodDropDownLabel = uilabel(app.GridLayout38_4);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.remmethod = uidropdown(app.GridLayout38_4);
            app.remmethod.Items = {'mean', 'median', 'grubbs', 'gesd'};
            app.remmethod.Value = 'gesd';
            app.EigenvalueCriterionPartialCorrelationCoefficientsTab = uitab(app.TabGroup2);
            app.EigenvalueCriterionPartialCorrelationCoefficientsTab.Title = 'Eigenvalue Criterion/Partial Correlation Coefficients';
            app.GridLayout34 = uigridlayout(app.EigenvalueCriterionPartialCorrelationCoefficientsTab);
            app.RankingCriterionPanel = uipanel(app.GridLayout34);
            app.RankingCriterionPanel.Title = 'Ranking Criterion';
            app.GridLayout35 = uigridlayout(app.RankingCriterionPanel);
            app.GridLayout36_4 = uigridlayout(app.GridLayout35);
            app.GridLayout36_4.Tooltip = {'When EV (Eigenvalue Index) and PCC (Partial Correlation Coefficients)'; 'are selected, the EV Index is weighted by the PCC for each component (Z or T).'; 'Ranked time segments may not be the same for each Z or T component.'};
            app.ImpedanceLabel = uilabel(app.GridLayout36_4);
            app.ImpedanceLabel.Text = 'Impedance';
            app.TipperLabel = uilabel(app.GridLayout36_4);
            app.TipperLabel.Text = 'Tipper';
            app.EVZButton = uibutton(app.GridLayout36_4, 'state');
            app.EVZButton.ValueChangedFcn = createCallbackFcn(app, @EVZButtonValueChanged, true);
            app.EVZButton.Tooltip = {'Eigenvalue Index calculated from Eigenvalue Criterion:'; 'Ranked Time segments are the '; 'same for each Impedance Tensor component'};
            app.EVZButton.Text = 'EV';
            app.EVZButton.Value = true;
            app.EVTButton = uibutton(app.GridLayout36_4, 'state');
            app.EVTButton.ValueChangedFcn = createCallbackFcn(app, @EVTButtonValueChanged, true);
            app.EVTButton.Tooltip = {'Eigenvalue Index calculated from Eigenvalue Criterion:'; 'Ranked Time segments are the '; 'same for each Tipper Vector component'};
            app.EVTButton.Text = 'EV';
            app.EVTButton.Value = true;
            app.PCCZButton = uibutton(app.GridLayout36_4, 'state');
            app.PCCZButton.ValueChangedFcn = createCallbackFcn(app, @PCCZButtonValueChanged, true);
            app.PCCZButton.Tooltip = {'Partial Correlation Coefficients:'; 'Ranked time segments may not be the '; 'same for each Impedance Tensor component'};
            app.PCCZButton.Text = 'PCC';
            app.PCCTButton = uibutton(app.GridLayout36_4, 'state');
            app.PCCTButton.ValueChangedFcn = createCallbackFcn(app, @PCCTButtonValueChanged, true);
            app.PCCTButton.Tooltip = {'Partial Correlation Coefficients:'; 'Ranked time segments may not be the '; 'same for each Tipper Vector component'};
            app.PCCTButton.Text = 'PCC';
            app.TimesegmentsRankingPanel = uipanel(app.GridLayout34);
            app.TimesegmentsRankingPanel.Title = 'Time segments Ranking';
            app.GridLayout35_2 = uigridlayout(app.TimesegmentsRankingPanel);
            app.GridLayout36_2 = uigridlayout(app.GridLayout35_2);
            app.GridLayout36_2.Tooltip = {'When EV (Eigenvalue Index) and PCC (Partial Correlation Coefficients)'; 'are selected, the EV Index is weighted by the PCC for each component (Z or T).'; 'Ranked time segments may not be the same for each Z or T component.'};
            app.AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel = uilabel(app.GridLayout36_2);
            app.AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel.Text = {'Ammount of "Best Ranked" '; 'time segments based on'; 'Ranking Criterion:'; ''; 'TF estimations are produced'; 'for each %'};
            app.EVCriteriaLabel = uilabel(app.GridLayout36_2);
            app.EVCriteriaLabel.Text = 'Ammount of time segments (%):';
            app.ranking = uieditfield(app.GridLayout36_2, 'numeric');
            app.ranking.Limits = [1 100];
            app.ranking.RoundFractionalValues = 'on';
            app.ranking.Tooltip = {'Amount of best EV ranked data'; 'to be considered for the estimation'; 'of final transfer functions'};
            app.ranking.Value = 25;
            app.PartialCoherencesPanel = uipanel(app.GridLayout34);
            app.PartialCoherencesPanel.Title = 'Partial Coherences';
            app.GridLayout35_3 = uigridlayout(app.PartialCoherencesPanel);
            app.GridLayout36_3 = uigridlayout(app.GridLayout35_3);
            app.GridLayout36_3.Tooltip = {'When EV (Eigenvalue Index) and PCC (Partial Correlation Coefficients)'; 'are selected, the EV Index is weighted by the PCC for each component (Z or T).'; 'Ranked time segments may not be the same for each Z or T component.'};
            app.AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel_2 = uilabel(app.GridLayout36_3);
            app.AmmountofBestRankedtimesegmentsbasedonRankingCriterionLabel_2.Text = {'Power of PCC'; 'Criterion'};
            app.EVCriteriaLabel_2 = uilabel(app.GridLayout36_3);
            app.EVCriteriaLabel_2.Text = 'PCC Power:';
            app.PCCpower = uieditfield(app.GridLayout36_3, 'numeric');
            app.PCCpower.Limits = [0 9];
            app.PCCpower.Tooltip = {'Amount of best EV ranked data'; 'to be considered for the estimation'; 'of final transfer functions'};
            app.PCCpower.Value = 1;
            app.EigenvalueIndexPanel = uipanel(app.GridLayout34);
            app.EigenvalueIndexPanel.Title = 'Eigenvalue Index';
            app.GridLayout38_5 = uigridlayout(app.EigenvalueIndexPanel);
            app.EVCriteriaLabel_3 = uilabel(app.GridLayout38_5);
            app.EVCriteriaLabel_3.Text = 'EI Power:';
            app.EIpower = uieditfield(app.GridLayout38_5, 'numeric');
            app.EIpower.Limits = [0 9];
            app.EIpower.Tooltip = {'Amount of best EV ranked data'; 'to be considered for the estimation'; 'of final transfer functions'};
            app.EIpower.Value = 1;
            app.EI2Button = uibutton(app.GridLayout38_5, 'state');
            app.EI2Button.ValueChangedFcn = createCallbackFcn(app, @EI2ButtonValueChanged, true);
            app.EI2Button.Text = 'EI2: 1/|λ3|';
            app.EI1Button = uibutton(app.GridLayout38_5, 'state');
            app.EI1Button.ValueChangedFcn = createCallbackFcn(app, @EI1ButtonValueChanged, true);
            app.EI1Button.Text = 'EI1: 1 - |λ3|/|λ2|';
            app.EI3Button = uibutton(app.GridLayout38_5, 'state');
            app.EI3Button.ValueChangedFcn = createCallbackFcn(app, @EI3ButtonValueChanged, true);
            app.EI3Button.Text = 'EI3: |λ2|/|λ3| - |λ3|^2';
            app.EI3Button.Value = true;
            app.EI4Button = uibutton(app.GridLayout38_5, 'state');
            app.EI4Button.ValueChangedFcn = createCallbackFcn(app, @EI4ButtonValueChanged, true);
            app.EI4Button.Text = 'EI4: |λ2|/|λ3| - |λ1|/|λ2|';
            app.EI0Button = uibutton(app.GridLayout38_5, 'state');
            app.EI0Button.ValueChangedFcn = createCallbackFcn(app, @EI0ButtonValueChanged, true);
            app.EI0Button.Text = 'EI0: |λ2|/|λ1|';
            app.PolynomialFitParallelComputingTab = uitab(app.TabGroup2);
            app.PolynomialFitParallelComputingTab.Title = 'Polynomial Fit/Parallel Computing';
            app.GridLayout37 = uigridlayout(app.PolynomialFitParallelComputingTab);
            app.PolynomialFitPanel = uipanel(app.GridLayout37);
            app.PolynomialFitPanel.Title = 'Polynomial Fit';
            app.GridLayout27_2 = uigridlayout(app.PolynomialFitPanel);
            app.text16_2 = uilabel(app.GridLayout27_2);
            app.text16_2.Text = {'Final TF is obatined by fitting a polynom'; 'to all the estimations useing different ammount of best ranked time segments.'; 'All the estimations have the same weight'; ''; 'Deactivate this option if the final transfer function should be'; 'obtained by a weighted median based on the Time segments Ranking'; ''};
            app.PolyfitButton = uibutton(app.GridLayout27_2, 'state');
            app.PolyfitButton.ValueChangedFcn = createCallbackFcn(app, @PolyfitButtonValueChanged, true);
            app.PolyfitButton.Text = 'Active';
            app.PolyfitButton.Value = true;
            app.ParallelComputingPanel_2 = uipanel(app.GridLayout37);
            app.ParallelComputingPanel_2.Title = 'Parallel Computing';
            app.GridLayout27 = uigridlayout(app.ParallelComputingPanel_2);
            app.text16 = uilabel(app.GridLayout27);
            app.text16.Text = {'Parallel Computing'; 'is active by default'; ' '; 'To deactivate'; 'change number of '; 'MATLAB workers (MW) to 0'};
            app.text19 = uilabel(app.GridLayout27);
            app.text19.Text = 'Available:';
            app.ncorestext = uilabel(app.GridLayout27);
            app.ncorestext.Text = '';
            app.text20 = uilabel(app.GridLayout27);
            app.text20.Text = 'Used:';
            app.ncoresbox = uieditfield(app.GridLayout27, 'text');
            app.SavePanel = uipanel(app.GridLayout32);
            app.SavePanel.Title = 'Save';
            app.GridLayout26 = uigridlayout(app.SavePanel);
            app.OPTButton = uibutton(app.GridLayout26, 'state');
            app.OPTButton.ValueChangedFcn = createCallbackFcn(app, @OPTButtonValueChanged, true);
            app.OPTButton.Enable = 'off';
            app.OPTButton.Text = 'OPT';
            app.OPTButton.Value = true;
            app.RAWButton = uibutton(app.GridLayout26, 'state');
            app.RAWButton.ValueChangedFcn = createCallbackFcn(app, @RAWButtonValueChanged, true);
            app.RAWButton.Text = 'RAW';
            app.FCButton = uibutton(app.GridLayout26, 'state');
            app.FCButton.ValueChangedFcn = createCallbackFcn(app, @FCButtonValueChanged, true);
            app.FCButton.Text = 'FC';
            app.RNKButton = uibutton(app.GridLayout26, 'state');
            app.RNKButton.ValueChangedFcn = createCallbackFcn(app, @RNKButtonValueChanged, true);
            app.RNKButton.Text = 'RNK';
            app.jobtable = uitable(app.OutterGrid);
            app.jobtable.ColumnName = {'Stations'; 'sr (Hz)'; 'Freq/Decade'; 'Hi-Freq (Hz)'; 'Lo-Freq (Hz)'; 'Oscillations'; 'Approach'; 'Noise model'; 'Regression'; 'Outlier'; 'Method'; 'Z Rank'; 'T Rank'; 'Rank %'; 'EI'; 'EI Pow'; 'PCC Pow'; 'Polyfit'; 'OPT'; 'RNK'; 'RAW'; 'FC'; 'Cores'};
            app.jobtable.SelectionType = 'row';
            app.jobtable.ColumnEditable = [false false true true true true true true true true true true true true true true true true true true true true];
            app.jobtable.CellEditCallback = createCallbackFcn(app, @jobtableCellEdit, true);
            app.jobtable.CellSelectionCallback = createCallbackFcn(app, @JobCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_NewJob

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end