classdef FFproc_SetTimes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Toolbar                  matlab.ui.container.Toolbar
        schedule                 matlab.ui.container.toolbar.ToggleTool
        update                   matlab.ui.container.toolbar.PushTool
        settime                  matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        GridLayout3              matlab.ui.container.GridLayout
        Panel_6                  matlab.ui.container.Panel
        GridLayout4_4            matlab.ui.container.GridLayout
        EndDateTime              matlab.ui.control.Table
        EndDateTimeUTCLabel_3    matlab.ui.control.Label
        Panel_5                  matlab.ui.container.Panel
        GridLayout4_3            matlab.ui.container.GridLayout
        StartDateTime            matlab.ui.control.Table
        StartDateTimeUTCLabel_3  matlab.ui.control.Label
        GridLayout2              matlab.ui.container.GridLayout
        EndLabel                 matlab.ui.control.Label
        StartLabel               matlab.ui.control.Label
        SliderEnd                matlab.ui.control.Slider
        SliderStart              matlab.ui.control.Slider
        SecondLabel_2            matlab.ui.control.Label
        MinuteLabel_2            matlab.ui.control.Label
        HourLabel_2              matlab.ui.control.Label
        DayLabel_2               matlab.ui.control.Label
        MonthLabel_2             matlab.ui.control.Label
        YearLabel_2              matlab.ui.control.Label
        e_ss                     matlab.ui.control.NumericEditField
        e_mm                     matlab.ui.control.NumericEditField
        e_hh                     matlab.ui.control.NumericEditField
        e_DD                     matlab.ui.control.NumericEditField
        e_MM                     matlab.ui.control.NumericEditField
        e_YY                     matlab.ui.control.NumericEditField
        s_ss                     matlab.ui.control.NumericEditField
        s_mm                     matlab.ui.control.NumericEditField
        s_hh                     matlab.ui.control.NumericEditField
        s_DD                     matlab.ui.control.NumericEditField
        s_MM                     matlab.ui.control.NumericEditField
        s_YY                     matlab.ui.control.NumericEditField
        EndDateTimeUTCLabel_2    matlab.ui.control.Label
        StartDateTimeUTCLabel_2  matlab.ui.control.Label
        SecondLabel              matlab.ui.control.Label
        MinuteLabel              matlab.ui.control.Label
        HourLabel                matlab.ui.control.Label
        DayLabel                 matlab.ui.control.Label
        MonthLabel               matlab.ui.control.Label
        YearLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        ts % Description
        source
        appschedule
    end
    
    methods (Access = public)
        
        function populatedatetime(app)
            tstart = datevec(max(cat(1,app.ts.tstart)));                         
            app.s_YY.Value = tstart(1);
            app.s_MM.Value = tstart(2);
            app.s_DD.Value = tstart(3);
            app.s_hh.Value = tstart(4);
            app.s_mm.Value = tstart(5);
            app.s_ss.Value = tstart(6);
            
            tend = datevec(min(cat(1,app.ts.tend)));  
            app.e_YY.Value = tend(1);
            app.e_MM.Value = tend(2);
            app.e_DD.Value = tend(3);
            app.e_hh.Value = tend(4);
            app.e_mm.Value = tend(5);
            app.e_ss.Value = tend(6);
        end
        
        function populatedatetime_selected(app)
            tstart = datevec(datenum([app.s_YY.Value,app.s_MM.Value,app.s_DD.Value,app.s_hh.Value,app.s_mm.Value,app.s_ss.Value]));
            tend = datevec(datenum([app.e_YY.Value,app.e_MM.Value,app.e_DD.Value,app.e_hh.Value,app.e_mm.Value,app.e_ss.Value]));  
            
            % How many days have been measuring?
            d = daysdif(datetime(tstart(1:3)),datetime(tend(1:3))) + 1;
            
            app.StartDateTime.Data = [];
            app.EndDateTime.Data = [];

            SameStartEndTimeforalldaysCheckBoxValueChanged(app)

            for i = 1:d
                if i == 1
                    app.StartDateTime.Data(i,:) = tstart;
                    app.EndDateTime.Data(i,:) = datevec(datenum([app.s_YY.Value,app.s_MM.Value,app.s_DD.Value,00,00,00]) + datenum([0,0,1,0,0,0]));
                elseif i == d
                    app.StartDateTime.Data(i,:) = datevec(datenum([app.e_YY.Value,app.e_MM.Value,app.e_DD.Value,00,00,00]));
                    app.EndDateTime.Data(i,:) = tend;
                else
                    tcurrent = app.EndDateTime.Data(i-1,:);
                    dt_start = datevec(datenum(tcurrent) + datenum([0,0,0,0,0,0]));
                    app.StartDateTime.Data(i,:) = dt_start;
                    dt_end = datevec(datenum(tcurrent) + datenum([0,0,1,0,0,0]));
                    app.EndDateTime.Data(i,:) = dt_end;
                end
            end
        end

        function populatesliders(app)
            tstart = max(cat(1,app.ts.tstart));    
            app.StartLabel.Text = datestr(tstart);

            tend = min(cat(1,app.ts.tend));
            app.EndLabel.Text = datestr(tend);            

            app.SliderStart.Limits = [tstart,tend];
            app.SliderStart.Value = tstart;
            app.SliderStart.MajorTicks = [tstart,tend];
            app.SliderStart.MajorTickLabels = cellstr(datestr([tstart,tend]));
            
            app.SliderEnd.Limits = [tstart,tend];
            app.SliderEnd.Value = tend;
            app.SliderEnd.MajorTicks = [tstart,tend];
            app.SliderEnd.MajorTickLabels = cellstr(datestr([tstart,tend]));
        end

        function updatesliders(app)
            
            tstart(1) = app.s_YY.Value;
            tstart(2) = app.s_MM.Value;
            tstart(3) = app.s_DD.Value;
            tstart(4) = app.s_hh.Value;
            tstart(5) = app.s_mm.Value;
            tstart(6) = app.s_ss.Value;
            app.SliderStart.Value = datenum(tstart);

            tend(1) = app.e_YY.Value;
            tend(2) = app.e_MM.Value;
            tend(3) = app.e_DD.Value;
            tend(4) = app.e_hh.Value;
            tend(5) = app.e_mm.Value;
            tend(6) = app.e_ss.Value;
            app.SliderEnd.Value = datenum(tend);
            
        end
        
        function datetimelimits(app)
            
            tstart = max(cat(1,app.ts.tstart));   
            tend = min(cat(1,app.ts.tend));    
            
            tstartnew = datenum([app.s_YY.Value,app.s_MM.Value,app.s_DD.Value,app.s_hh.Value,app.s_mm.Value,app.s_ss.Value]);
            tendnew = datenum([app.e_YY.Value,app.e_MM.Value,app.e_DD.Value,app.e_hh.Value,app.e_mm.Value,app.e_ss.Value]);  
            
            if tstartnew < tstart || tstartnew > tend || tendnew < tstart || tendnew > tend
                uialert(app.UIFigure,{'Schedule is above/beyond recording times','Original values are restored'},'Error','Icon','error')
                populatedatetime(app)
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ts, source, appschedule)
            app.ts = ts;
            app.source = source;
            app.appschedule = appschedule;

            SameStartEndTimeforalldaysCheckBoxValueChanged(app)            
            populatedatetime(app)
            populatedatetime_selected(app)
            populatesliders(app)
            datetimelimits(app)
        end

        % Callback function: schedule
        function SameStartEndTimeforalldaysCheckBoxValueChanged(app, event)
            value = app.schedule.State;
            if value
                app.StartDateTime.Enable = 'on';
                app.EndDateTime.Enable = 'on';
                app.update.Enable = true;
            else
                app.StartDateTime.Enable = 'off';
                app.EndDateTime.Enable = 'off';
                app.update.Enable = false;
            end
        end

        % Callback function: update
        function UpdateScheduleButtonPushed(app, event)
            app.StartDateTime.Data = [];
            app.EndDateTime.Data = [];
            datetimelimits(app)
            populatedatetime_selected(app)
        end

        % Value changed function: e_DD, e_MM, e_YY, e_hh, e_mm, e_ss, 
        % ...and 6 other components
        function DateTimeValueChanged(app, event)
            datetimelimits(app)
            updatesliders(app)
            populatedatetime_selected(app)
        end

        % Callback function: settime
        function settimeClicked(app, event)
            timetbl.days = size(app.StartDateTime.Data,1);
            timetbl.tstart = datenum(app.StartDateTime.Data);
            timetbl.tend = datenum(app.EndDateTime.Data);

            switch app.source
                case 'jobeditor'
                    for i = 1:numel(app.appschedule.job(app.appschedule.jobind).ffts)
                        app.appschedule.job(app.appschedule.jobind).ffts(i).tstart = timetbl.tstart(1);
                        app.appschedule.job(app.appschedule.jobind).ffts(i).tend = timetbl.tend(end);
                    end                    
            end
            % Enable scheduled processing
            timetbl.state = true;

            app.appschedule.job(app.appschedule.jobind).schedule = timetbl;
            close(app.UIFigure)
        end

        % Value changed function: SliderStart
        function SliderStartValueChanged(app, event)
            tstart = round(app.SliderStart.Value,5);
            app.SliderStart.Value = tstart;
            app.StartLabel.Text = datestr(tstart);
            
            tstartvec = datevec(tstart);   

            app.s_YY.Value = tstartvec(1);
            app.s_MM.Value = tstartvec(2);
            app.s_DD.Value = tstartvec(3);
            app.s_hh.Value = tstartvec(4);
            app.s_mm.Value = tstartvec(5);
            app.s_ss.Value = floor(tstartvec(6));   

            populatedatetime_selected(app)
        end

        % Value changed function: SliderEnd
        function SliderEndValueChanged(app, event)
            tend = round(app.SliderEnd.Value,5);
            app.SliderEnd.Value = tend;
            app.EndLabel.Text = datestr(tend);

            tendvec = datevec(tend);  

            app.e_YY.Value = tendvec(1);
            app.e_MM.Value = tendvec(2);
            app.e_DD.Value = tendvec(3);
            app.e_hh.Value = tendvec(4);
            app.e_mm.Value = tendvec(5);
            app.e_ss.Value = floor(tendvec(6));

            populatedatetime_selected(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Set Processing Schedule';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.schedule = uitoggletool(app.Toolbar);
            app.schedule.Tooltip = {'Set schedule'};
            app.schedule.ClickedCallback = createCallbackFcn(app, @SameStartEndTimeforalldaysCheckBoxValueChanged, true);
            app.schedule.Icon = 'FFproc_timeschedule.png';
            app.update = uipushtool(app.Toolbar);
            app.update.Tooltip = {'Update schedule'};
            app.update.ClickedCallback = createCallbackFcn(app, @UpdateScheduleButtonPushed, true);
            app.update.Icon = 'FFproc_timerefresh.png';
            app.settime = uipushtool(app.Toolbar);
            app.settime.Tooltip = {'Apply schedule'};
            app.settime.ClickedCallback = createCallbackFcn(app, @settimeClicked, true);
            app.settime.Icon = 'FFproc_timeset.png';
            app.settime.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.YearLabel = uilabel(app.GridLayout2);
            app.YearLabel.Text = 'Year';
            app.MonthLabel = uilabel(app.GridLayout2);
            app.MonthLabel.Text = 'Month';
            app.DayLabel = uilabel(app.GridLayout2);
            app.DayLabel.Text = 'Day';
            app.HourLabel = uilabel(app.GridLayout2);
            app.HourLabel.Text = 'Hour';
            app.MinuteLabel = uilabel(app.GridLayout2);
            app.MinuteLabel.Text = 'Minute';
            app.SecondLabel = uilabel(app.GridLayout2);
            app.SecondLabel.Text = 'Second';
            app.StartDateTimeUTCLabel_2 = uilabel(app.GridLayout2);
            app.StartDateTimeUTCLabel_2.Text = 'Start Date/Time (UTC):';
            app.EndDateTimeUTCLabel_2 = uilabel(app.GridLayout2);
            app.EndDateTimeUTCLabel_2.Text = 'End Date/Time (UTC):';
            app.s_YY = uieditfield(app.GridLayout2, 'numeric');
            app.s_YY.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.s_MM = uieditfield(app.GridLayout2, 'numeric');
            app.s_MM.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.s_DD = uieditfield(app.GridLayout2, 'numeric');
            app.s_DD.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.s_hh = uieditfield(app.GridLayout2, 'numeric');
            app.s_hh.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.s_mm = uieditfield(app.GridLayout2, 'numeric');
            app.s_mm.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.s_ss = uieditfield(app.GridLayout2, 'numeric');
            app.s_ss.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_YY = uieditfield(app.GridLayout2, 'numeric');
            app.e_YY.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_MM = uieditfield(app.GridLayout2, 'numeric');
            app.e_MM.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_DD = uieditfield(app.GridLayout2, 'numeric');
            app.e_DD.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_hh = uieditfield(app.GridLayout2, 'numeric');
            app.e_hh.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_mm = uieditfield(app.GridLayout2, 'numeric');
            app.e_mm.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.e_ss = uieditfield(app.GridLayout2, 'numeric');
            app.e_ss.ValueChangedFcn = createCallbackFcn(app, @DateTimeValueChanged, true);
            app.YearLabel_2 = uilabel(app.GridLayout2);
            app.YearLabel_2.Text = 'Year';
            app.MonthLabel_2 = uilabel(app.GridLayout2);
            app.MonthLabel_2.Text = 'Month';
            app.DayLabel_2 = uilabel(app.GridLayout2);
            app.DayLabel_2.Text = 'Day';
            app.HourLabel_2 = uilabel(app.GridLayout2);
            app.HourLabel_2.Text = 'Hour';
            app.MinuteLabel_2 = uilabel(app.GridLayout2);
            app.MinuteLabel_2.Text = 'Minute';
            app.SecondLabel_2 = uilabel(app.GridLayout2);
            app.SecondLabel_2.Text = 'Second';
            app.SliderStart = uislider(app.GridLayout2);
            app.SliderStart.Limits = [0 1];
            app.SliderStart.MajorTicks = [];
            app.SliderStart.ValueChangedFcn = createCallbackFcn(app, @SliderStartValueChanged, true);
            app.SliderStart.MinorTicks = [];
            app.SliderEnd = uislider(app.GridLayout2);
            app.SliderEnd.Limits = [0 1];
            app.SliderEnd.MajorTicks = [];
            app.SliderEnd.ValueChangedFcn = createCallbackFcn(app, @SliderEndValueChanged, true);
            app.SliderEnd.MinorTicks = [];
            app.SliderEnd.Value = 1;
            app.StartLabel = uilabel(app.GridLayout2);
            app.StartLabel.Text = '';
            app.EndLabel = uilabel(app.GridLayout2);
            app.EndLabel.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel_5 = uipanel(app.GridLayout3);
            app.GridLayout4_3 = uigridlayout(app.Panel_5);
            app.StartDateTimeUTCLabel_3 = uilabel(app.GridLayout4_3);
            app.StartDateTimeUTCLabel_3.Text = 'Start Date/Time (UTC)';
            app.StartDateTime = uitable(app.GridLayout4_3);
            app.StartDateTime.ColumnName = {'YY'; 'MM'; 'DD'; 'hh'; 'mm'; 'ss'};
            app.StartDateTime.ColumnEditable = true;
            app.Panel_6 = uipanel(app.GridLayout3);
            app.GridLayout4_4 = uigridlayout(app.Panel_6);
            app.EndDateTimeUTCLabel_3 = uilabel(app.GridLayout4_4);
            app.EndDateTimeUTCLabel_3.Text = 'End Date/Time (UTC)';
            app.EndDateTime = uitable(app.GridLayout4_4);
            app.EndDateTime.ColumnName = {'YY'; 'MM'; 'DD'; 'hh'; 'mm'; 'ss'};
            app.EndDateTime.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_SetTimes(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end