classdef FFproc_Log < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Toolbar              matlab.ui.container.Toolbar
        PushTool             matlab.ui.container.toolbar.PushTool
        PushTool2            matlab.ui.container.toolbar.PushTool
        GridLayout           matlab.ui.container.GridLayout
        ProgresslogTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            [file,path] = uiputfile('*.txt','Save FFproc log file',strrep(strrep(['FFproc_',datestr(now),'.txt'],' ','_'),':',''));
            fid = fopen(fullfile(path,file),'w');
            for i = 1:numel(app.ProgresslogTextArea.Value)
                fprintf(fid,[app.ProgresslogTextArea.Value{i},'\n']);
            end
            fclose(fid);
            
            try 
                winopen(path)
            catch                
            end
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            open 'FFproc_Error.pdf'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Log';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Save Log as .TXT'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'FFproc_save2.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Click for Common Errors'};
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'FFproc_help.png';
            app.PushTool2.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ProgresslogTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_Log

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end