classdef FFproc_Progress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ProgresslogTextAreaLabel  matlab.ui.control.Label
        ProgresslogTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        bar % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.tsaxes,'box','on','xtick',[],'ytick',[],...
                     'color',[1 1 1],...
                     'xlim',[0,1],'ylim',[0,1]);
            
            set(app.winaxes,'box','on','xtick',[],'ytick',[],...
                     'color',[1 1 1],...
                     'xlim',[0,1],'ylim',[0,1]);
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Log';
            app.UIFigure.Icon = 'Icon_FFproc.png';
            app.ProgresslogTextAreaLabel = uilabel(app.UIFigure);
            app.ProgresslogTextAreaLabel.Text = 'Progress log...';
            app.ProgresslogTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_Progress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end