classdef FFproc_Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Toolbar      matlab.ui.container.Toolbar
        PushTool     matlab.ui.container.toolbar.PushTool
        PushTool2    matlab.ui.container.toolbar.PushTool
        PushTool5    matlab.ui.container.toolbar.PushTool
        PushTool6    matlab.ui.container.toolbar.PushTool
        PushTool3    matlab.ui.container.toolbar.PushTool
        PushTool4    matlab.ui.container.toolbar.PushTool
        GridLayout   matlab.ui.container.GridLayout
        Panel        matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        infotable    matlab.ui.control.Table
    end

    properties (Access = public)
        ind % Description
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: PushTool
        function addbuttonButtonPushed(app, event)
            if isempty(app.infotable.Data)
                app.infotable.Data{1,1} = 'Site 1';
                app.infotable.Data(1,2:4) = num2cell([50,50,0]);
                app.infotable.Data(1,5:7) = [{'bx'},{'by'},{'bz'}];
                app.infotable.Data(1,8) = {false};
                app.infotable.Data(1,9:11) = num2cell([0,0,0]);
                
            else
                n = size(app.infotable.Data,1);
                app.infotable.Data{n+1,1} = ['Site ',num2str(n+1)];
                app.infotable.Data(n+1,2:4) = num2cell([50,50,0]);
                app.infotable.Data(n+1,5:7) = [{'bx'},{'by'},{'bz'}];
                app.infotable.Data(n+1,8) = {false};
                app.infotable.Data(n+1,9:11) = num2cell([0,0,0]);
                
            end            
        end

        % Clicked callback: PushTool2
        function deletebuttonButtonPushed(app, event)
            if isempty(app.ind)
                uialert(app.UIFigure,'No Settings file is selected','Delete Settings - Warning');
                return
            else
                app.infotable.Data(app.ind,:) = [];
            end
        end

        % Cell selection callback: infotable
        function infotableCellSelection(app, event)
            indices = event.Indices;
            app.ind = indices(1);
        end

        % Clicked callback: PushTool4
        function savebuttonButtonPushed(app, event)
            info = get(app.infotable,'Data');
            name = info(:,1);
            elec = cell2mat(info(:,2:4));
            coil = info(:,5:7);
            RAP = info(:,8);
            coor = cell2mat(info(:,9:11));            
            param = struct;
            for i = 1:numel(name)
                param(i).name = name{i,1};
                param(i).diplen = elec(i,1:2);
                param(i).rot = elec(i,3);
                param(i).coil = coil(i,:);
                param(i).GPS = coor(i,:);
                param(i).RAP = RAP{i,:};
            end
            
            user = getuser;
            cd(fullfile(user,'Documents','FFMT','FFproc','Field Settings'));
            outname = 'Site_Settings.mat';
            [output,~] = uiputfile(outname,'Save *.mat file');
            if output == 0
                figure(app.UIFigure)
                return
            else
                save(output,'param');
                figure(app.UIFigure)
                uialert(app.UIFigure,['File: ',output,' is saved'],'FFproc - Field Settings','Icon','success');
            end
        end

        % Clicked callback: PushTool3
        function LoadParametersMenuSelected(app, event)
            % Removing previous information
            app.infotable.Data = [];
            
            % Loading Field Settings File 
            filetarg = 'FFproc_';
            pathtarg = which(filetarg);
            pathtarg = strrep(pathtarg,[filetarg,'.mlapp'],'');
            [file,path] = uigetfile([pathtarg,'\Field Settings\','*.mat']);
            load([path,file])
            
            %  Populating tables
            for i = 1:numel(param)
                app.infotable.Data{i,1} = param(i).name;
            end
            app.infotable.Data(:,2:4) = num2cell([cat(1,param.diplen),cat(1,param.rot)]);
            app.infotable.Data(:,5:7) = cat(1,param.coil);
            app.infotable.Data(:,8) = num2cell(cat(1,param.RAP));
            app.infotable.Data(:,9:11) = num2cell(cat(1,param.GPS));            
        end

        % Clicked callback: PushTool5
        function upbuttonButtonPushed(app, event)
            % Saving data in auxiliar variables
            aux = app.infotable.Data(app.ind,:);

            % Moving data upwards
            app.infotable.Data(app.ind,:) = app.infotable.Data(app.ind-1,:);
            app.infotable.Data(app.ind-1,:) = aux;
        end

        % Clicked callback: PushTool6
        function downbuttonButtonPushed(app, event)
            % Saving data in auxiliar variables
            aux = app.infotable.Data(app.ind,:);
            
            % Moving data upwards
            app.infotable.Data(app.ind,:) = app.infotable.Data(app.ind+1,:);
            app.infotable.Data(app.ind+1,:) = aux;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Field Settings';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @addbuttonButtonPushed, true);
            app.PushTool.Icon = 'plus.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @deletebuttonButtonPushed, true);
            app.PushTool2.Icon = 'minus.png';
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @upbuttonButtonPushed, true);
            app.PushTool5.Icon = 'icon_up.png';
            app.PushTool5.Separator = 'on';
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @downbuttonButtonPushed, true);
            app.PushTool6.Icon = 'icon_down.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @LoadParametersMenuSelected, true);
            app.PushTool3.Icon = 'load.png';
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @savebuttonButtonPushed, true);
            app.PushTool4.Icon = 'save.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.infotable = uitable(app.GridLayout2);
            app.infotable.ColumnName = {'Site'; 'EX (m)'; 'EY (m)'; 'E-Rot(°)'; 'BX'; 'BY'; 'BZ'; 'RAP'; 'Lat (dec)'; 'Lon (dec)'; 'Elev (m)'};
            app.infotable.ColumnEditable = true;
            app.infotable.CellSelectionCallback = createCallbackFcn(app, @infotableCellSelection, true);
            app.infotable.Tag = 'diptable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_Settings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end