classdef FFproc_RAW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadRAWFileMenu       matlab.ui.container.Menu
        MergeRAWFilesMenu     matlab.ui.container.Menu
        FFprocMenu            matlab.ui.container.Menu
        NewJobMenu            matlab.ui.container.Menu
        CombinePeriodsMenu    matlab.ui.container.Menu
        ResultsMenu           matlab.ui.container.Menu
        HelpMenu              matlab.ui.container.Menu
        DocumentationMenu     matlab.ui.container.Menu
        Toolbar               matlab.ui.container.Toolbar
        update                matlab.ui.container.toolbar.PushTool
        best                  matlab.ui.container.toolbar.PushTool
        polyfit               matlab.ui.container.toolbar.PushTool
        save                  matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        GridLayout10          matlab.ui.container.GridLayout
        GridLayout9           matlab.ui.container.GridLayout
        ptmaxEditFieldLabel   matlab.ui.control.Label
        ptmax                 matlab.ui.control.NumericEditField
        ptminEditFieldLabel   matlab.ui.control.Label
        ptmin                 matlab.ui.control.NumericEditField
        PolyfitorderLabel     matlab.ui.control.Label
        polyorder             matlab.ui.control.NumericEditField
        BestEVLabel           matlab.ui.control.Label
        rankingperc           matlab.ui.control.Spinner
        phimaxLabel           matlab.ui.control.Label
        phimax                matlab.ui.control.NumericEditField
        phiminLabel           matlab.ui.control.Label
        phimin                matlab.ui.control.NumericEditField
        rhomaxEditFieldLabel  matlab.ui.control.Label
        rhomax                matlab.ui.control.NumericEditField
        rhominEditFieldLabel  matlab.ui.control.Label
        rhomin                matlab.ui.control.NumericEditField
        permaxEditFieldLabel  matlab.ui.control.Label
        permax                matlab.ui.control.NumericEditField
        perminEditFieldLabel  matlab.ui.control.Label
        permin                matlab.ui.control.NumericEditField
        GridLayout11          matlab.ui.container.GridLayout
        SiteLabel             matlab.ui.control.Label
        popsite               matlab.ui.control.DropDown
        TabGroup              matlab.ui.container.TabGroup
        RAWResultsTab         matlab.ui.container.Tab
        GridLayout2           matlab.ui.container.GridLayout
        rhophipanel           matlab.ui.container.Panel
        ZEstimationsTab       matlab.ui.container.Tab
        GridLayout5           matlab.ui.container.GridLayout
        Zpanel                matlab.ui.container.Panel
        TEstimationsTab       matlab.ui.container.Tab
        GridLayout6           matlab.ui.container.GridLayout
        Tpanel                matlab.ui.container.Panel
        OutputResultsTab      matlab.ui.container.Tab
        GridLayout7           matlab.ui.container.GridLayout
        TipperPanel           matlab.ui.container.Panel
        PTPanel               matlab.ui.container.Panel
        GridLayout8           matlab.ui.container.GridLayout
        TFPanel               matlab.ui.container.Panel
        ImpedancePanel        matlab.ui.container.Panel
        ContextMenu           matlab.ui.container.ContextMenu
        ExportMenu            matlab.ui.container.Menu
        FiguresMenu           matlab.ui.container.Menu
    end

    properties (Access = public)
        name, a, ID
        ranking
        mode
        EV, FFsave, cf, id, job             % OPT
        EIGVAL, RAW, act_chan, target_freq  % RAW
        Results                             % MERGE
    end
    
    methods (Access = public)        
        function plot_estimates(app)
            global font
            if ~isempty(app.mode)

                app.TabGroup.SelectedTab = app.OutputResultsTab;
                info = getinfo(app); 
                 
                % Impedances Panel            
                td = tiledlayout(1,4,'Parent',app.ImpedancePanel,'TileSpacing','compact','Padding','compact');
                switch app.mode
                    case 'selected'
                        title(td,['Estimations for selected EV: ',num2str(app.rankingperc.Value),' (%)'],'FontName',font,'FontSize',14) 
                    case 'fitted'
                        title(td,['Polyfitted estimations using EV: ',num2str(app.rankingperc.Value),' (%)'],'FontName',font,'FontSize',14)
                end
                plot_zxx(info,app.FFsave,app.a,td,[])
                plot_zxy(info,app.FFsave,app.a,td,[])
                plot_zyx(info,app.FFsave,app.a,td,[])
                plot_zyy(info,app.FFsave,app.a,td,[])
                % Transfer Functions Panel
                td = tiledlayout(2,2,'Parent',app.TFPanel,'TileSpacing','compact','Padding','compact');
                plot_rho(info,app.FFsave,app.a,td,[],[])
                plot_txz(info,app.FFsave,app.a,td,[],[])
                plot_phi(info,app.FFsave,app.a,td,[],[])                    
                plot_tyz(info,app.FFsave,app.a,td,[],[]) 
                % Phase Tensor Panel
                td = tiledlayout(1,1,'Parent',app.PTPanel,'TileSpacing','compact','Padding','compact');
                plot_pt(info,app.FFsave,app.a,td,[],1)
                % Tipper Panel
                td = tiledlayout(1,1,'Parent',app.TipperPanel,'TileSpacing','compact','Padding','compact');
                plot_tip(info,app.FFsave,app.a,td,[],1)

            end                        
        end           
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global font
            font = 'Verdana';
        end

        % Menu selected function: LoadRAWFileMenu
        function LoadFileCallback(app, event)
            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            
            [file,path] = uigetfile({'*.mat','FFproc - Load RAW Files (*.mat)';
                                     '(*.*)','All Files (*.*)'},'Select RAW File');
            cd(path)
            
            % If user press cancel or close button
            if file == 0
                return
            else
                % Loading results data
                load(fullfile(path,file));
                figure(app.UIFigure)
                app.a = 1;

                if exist('RAW','var')
                    app.RAW = RAW;
                    app.EV = EV;
%                     app.EIGVAL = EIGVAL;                    
                elseif exist('Results','var')
                    app.RAW = Results;
                    app.EV = EV;
                end

                app.act_chan = act_chan;
                app.target_freq = target_freq;
                app.job = job;

                app.ranking = numel(app.RAW);
                app.rankingperc.Value = app.ranking;
                app.rankingperc.Limits = [1,app.ranking];                
            end   

            info = getinfo(app);
            % Site names
            app.name = cell(1);
            app.ID = zeros(numel(app.RAW(2).site),1);
            for i = 1:numel(app.RAW(2).site)
                app.name(i) = app.RAW(2).site(i);
                app.ID(i) = i;
            end
            app.popsite.Items = app.name;

            % Plot Resistivity, Phase and Eigenvalues
            rankingpercValueChanged(app)

            % Plot Z and T estimations
            plot_Zev(app,info,app.RAW,app.ranking,app.Zpanel,[],[])
            plot_Tev(app,info,app.RAW,app.ranking,app.Tpanel,[],[]) 
        end

        % Menu selected function: MergeRAWFilesMenu
        function MergeRAWCallback(app, event)
            answer = true;
            merge = [];
            i = 1;  
            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))  
            
            while answer                   
                [filemerge{i},pathmerge{i}] = uigetfile({'*.mat','FFproc - Load RAW Files (*.mat)';
                                                         '(*.*)','All Files (*.*)'},'Select RAW File');
                if isempty(merge)
                   cd(pathmerge{i})
                end 
                if filemerge{i} == 0
                    return
                else
                    if i >= 2    
                        figure(app.UIFigure)
                        answer = uiconfirm(app.UIFigure,'Load another RAW file to merge?',...
                                                        'FFproc - Merge RAW',...
                                                        'Options',{'Yes','No'},'DefaultOption',1,'CancelOption',2,...
                                                        'Icon','Info');
                        switch answer
                            case 'Yes'
                                answer = true;
                            case 'No'
                                answer = false;
                        end
                    end  
                end
                i = i+1;
            end
            
            figure(app.UIFigure)
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - RAW','Message',[{'Merging RAW files...'};{''};filemerge';{''}],'Indeterminate','on');
                FFproc_merge_RAW(app,filemerge,pathmerge)
            close(dlg)
            figure(app.UIFigure)
        end

        % Value changed function: rankingperc
        function rankingpercValueChanged(app, event)
            app.ranking = app.rankingperc.Value;
            info = getinfo(app);  

            plot_rhophiRAW(app,info,app.ranking,app.RAW,app.rhophipanel)
            
            % Everytime the amount of data is changed, the estimations are
            % changing and therefore, no need to show previous estimations.
            % Deletion of graphics in Output Results tab to avoid any
            % confussion for user.
            delete(findall(app.ImpedancePanel,'type','TiledLayout'))  
            delete(findall(app.TFPanel,'type','axes'))  
            delete(findall(app.PTPanel,'type','axes'))  
            delete(findall(app.TipperPanel,'type','axes'))          
        end

        % Clicked callback: update
        function UpdateEstimatesCallback(app, event)
            info = getinfo(app);
            value = app.popsite.Value;
            app.a = find(strcmp(value,app.name)); 

            % Plotting Z and T estimations
            plot_Zev(app,info,app.RAW,app.ranking,app.Zpanel,[],[])
            plot_Tev(app,info,app.RAW,app.ranking,app.Tpanel,[],[]) 
        end

        % Value changed function: popsite
        function SelectSiteCallback(app, event)
            info = getinfo(app);
            value = app.popsite.Value;
            app.a = find(strcmp(value,app.name)); 

            % Plotting Z and T estimations
            plot_Zev(app,info,app.RAW,app.ranking,app.Zpanel,[],[])
            plot_Tev(app,info,app.RAW,app.ranking,app.Tpanel,[],[]) 

            plot_estimates(app)
        end

        % Clicked callback: best
        function CurrentEstimatesCallback(app, event)
            % Choose the estimates for selected best ranked time segments      
            app.mode = 'selected';    

            % Extracting estimations
            select = app.RAW(app.rankingperc.Value);
            app.FFsave = create_ffsave;              
            for i = 1:numel(select.site)
                app.FFsave(i).site = select.site(i);
                app.FFsave(i).lonlat = select.lonlat(i,:);
                app.FFsave(i).UTM = select.UTM(i,:);
                app.FFsave(i).z = select.z(i);
                app.FFsave(i).nfreq = select.nfreq;
                app.FFsave(i).freq = select.freq;         
                app.FFsave(i).per = 1./select.freq;
                % Impedances
                app.FFsave(i).Zxx = select.Zxx(:,i);        app.FFsave(i).Zxx_Err = select.Zxx_Err(:,i);
                app.FFsave(i).Zxy = select.Zxy(:,i);        app.FFsave(i).Zxy_Err = select.Zxy_Err(:,i);
                app.FFsave(i).Zyx = select.Zyx(:,i);        app.FFsave(i).Zyx_Err = select.Zyx_Err(:,i);
                app.FFsave(i).Zyy = select.Zyy(:,i);        app.FFsave(i).Zyy_Err = select.Zyy_Err(:,i);
                % Resistivities
                app.FFsave(i).rhoxx = select.rhoxx(:,i);    app.FFsave(i).rhoxx_Err = select.rhoxx_Err(:,i);       
                app.FFsave(i).rhoxy = select.rhoxy(:,i);    app.FFsave(i).rhoxy_Err = select.rhoxy_Err(:,i);  
                app.FFsave(i).rhoyx = select.rhoyx(:,i);    app.FFsave(i).rhoyx_Err = select.rhoyx_Err(:,i);     
                app.FFsave(i).rhoyy = select.rhoyy(:,i);    app.FFsave(i).rhoyy_Err = select.rhoyy_Err(:,i);            
                % Phases
                app.FFsave(i).phixx = select.phixx(:,i);    app.FFsave(i).phixx_Err = select.phixx_Err(:,i);       
                app.FFsave(i).phixy = select.phixy(:,i);    app.FFsave(i).phixy_Err = select.phixy_Err(:,i);  
                app.FFsave(i).phiyx = select.phiyx(:,i);    app.FFsave(i).phiyx_Err = select.phiyx_Err(:,i);     
                app.FFsave(i).phiyy = select.phiyy(:,i);    app.FFsave(i).phiyy_Err = select.phiyy_Err(:,i); 
                % Tipper
                app.FFsave(i).txz = select.txz(:,i);        app.FFsave(i).txz_Err = select.txz_Err(:,i);
                app.FFsave(i).tyz = select.tyz(:,i);        app.FFsave(i).tyz_Err = select.tyz_Err(:,i);
                % Phase Tensor
                app.FFsave(i).phi11 = select.phi11(:,i);    
                app.FFsave(i).phi12 = select.phi12(:,i);
                app.FFsave(i).phi21 = select.phi21(:,i);
                app.FFsave(i).phi22 = select.phi22(:,i);
                app.FFsave(i).beta = select.beta(:,i);      app.FFsave(i).beta_Err = select.beta_Err(:,i);
                app.FFsave(i).alpha = select.alpha(:,i);    app.FFsave(i).alpha_Err = select.alpha_Err(:,i);
                app.FFsave(i).theta = select.theta(:,i);    app.FFsave(i).theta_Err = select.theta_Err(:,i);
                app.FFsave(i).lambda = select.lambda(:,i);  app.FFsave(i).lambda_Err = select.lambda_Err(:,i);
                app.FFsave(i).phimax = select.phimax(:,i);  app.FFsave(i).phimax_Err = select.phimax_Err(:,i);
                app.FFsave(i).phimin = select.phimin(:,i);  app.FFsave(i).phimin_Err = select.phimin_Err(:,i);
            end

            plot_estimates(app)
        end

        % Clicked callback: polyfit
        function PolyfitCallback(app, event)
            % Polyfit over the selected best ranked time segments
            app.mode = 'fitted';

            % Polyfit 
            app.job.ranking = app.rankingperc.Value;
            app.job.polyord = app.polyorder.Value;
            final = FFproc_TFpolyfit(app.job,app.target_freq,[],[],app.act_chan,app.RAW,app.EIGVAL,[],[]); 

            % Re-assigne variables
            app.FFsave = final.FFsave;   
            app.id = final.id;
            
            % Plot polyfit
            info = getinfo(app);
            app.TabGroup.SelectedTab = app.ZEstimationsTab;
            plot_Zev(app,info,app.RAW,app.ranking,app.Zpanel,final.id,final.cf)
            drawnow    
            app.TabGroup.SelectedTab = app.TEstimationsTab;
            plot_Tev(app,info,app.RAW,app.ranking,app.Tpanel,final.id,final.cf)
            drawnow

            plot_estimates(app)
        end

        % Clicked callback: save
        function SaveEstimatesCallback(app, event)
            if isempty(app.mode)
                figure(app.UIFigure)
                uialert(app.UIFigure,{'Nothing to save!','Extract estimations or run Polyfit to retrieve results'},'FFproc - RAW','Icon','Warning');
                return                
            else
                % Magnetic reference channels
                for j = 1:numel(app.job.ffts)
                    magref(j,1) = app.job.ffts(j).rsbx;
                    magref(j,2) = app.job.ffts(j).rsby;
                end
                % Remote Reference indicator
                if  numel(magref(:,1)) ~= numel(unique(magref(:,1))) || ...
                    numel(magref(:,2)) ~= numel(unique(magref(:,2)))
                    remote = '_RR';
                else
                    remote = '';
                end
                
                freq = app.FFsave(1).freq;
                % Retrieving target frequency range label: seconds or Hz
                if max(freq) >= 1
                    f1 = round(max(freq));
                    unit1 = 'Hz';
                else
                    f1 = round(1./max(freq));
                    unit1 = 's';
                end
                if min(freq) >= 1
                    f2 = round(min(freq));
                    unit2 = 'Hz';
                else
                    f2 = round(1./min(freq));
                    unit2 = 's';
                end

                switch app.job.model
                    case 'Standard'
                        model = 'STD_';
                    case 'Advanced'
                        model = 'ANM_';
                end
            
                switch app.job.reg
                    case 'Robust'
                        reg = 'ROB_';
                    case 'LSQ'
                        reg = 'LSQ_';
                end
                
                % Assembling output FFsave file name
                switch app.mode
                    case 'selected'
                        outname = [model,reg,num2str(f1),unit1,'-',num2str(f2),unit2,remote,'_SEL',num2str(app.job.eigperc),'%'];
                    case 'fitted'
                        outname = [model,reg,num2str(f1),unit1,'-',num2str(f2),unit2,remote,'_OPT',num2str(app.job.eigperc),'%'];
                end            

                job = app.job;
                id = app.id;
                FFsave = app.FFsave;
                [file,path] = uiputfile([outname,'.mat'],'Save estimations in *.mat file');
                if path ~= 0
                    save(fullfile(path,file),'job','FFsave','id','-v7.3')
                else
                    return
                end
                figure(app.UIFigure)
            end
        end

        % Menu selected function: FiguresMenu
        function FiguresMenuSelected(app, event)
            try
                f = figure('Name','Captured Snapshots - RAW: Impedances','WindowStyle','docked');
                copyobj(app.Zpanel.Children,f);
            catch
            end
            try
                f = figure('Name','Captured Snapshots - RAW: Tipper','WindowStyle','docked');
                copyobj(app.Tpanel.Children,f);
            catch
            end
        end

        % Value changed function: permax, permin
        function PeriodChangeCallback(app, event)
            ax = findall(app.rhophipanel.Children,'type','axes');
            set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))
            ax = findall(app.ZEstimationsTab.Children,'type','axes');
            set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))
            ax = findall(app.TEstimationsTab.Children,'type','axes');
            set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))
            try
                ax = findall(app.ImpedancePanel.Children,'type','axes');
                set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))
                ax = findall(app.TFPanel.Children,'type','axes');
                set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))
                info = getinfo(app);  
                % Phase Tensor Panel
                td = tiledlayout(1,1,'Parent',app.PTPanel,'TileSpacing','compact','Padding','compact');
                plot_pt(info,app.FFsave,app.a,td,[],1)
                % Tipper Panel
                td = tiledlayout(1,1,'Parent',app.TipperPanel,'TileSpacing','compact','Padding','compact');
                plot_tip(info,app.FFsave,app.a,td,[],1)
            catch
            end
        end

        % Value changed function: rhomax, rhomin
        function ResistivityChangeCallback(app, event)
            ax = findall(app.rhophipanel.Children,'type','axes');
            set(ax(2),'YLim',10.^[app.rhomin.Value,app.rhomax.Value])
            try 
                ax = findall(app.TFPanel.Children,'type','axes');
                set(ax(4),'YLim',10.^[app.rhomin.Value,app.rhomax.Value])
            catch
            end
        end

        % Value changed function: phimax, phimin
        function PhaseChangeCallback(app, event)
            ax = findall(app.rhophipanel.Children,'type','axes');
            set(ax(1),'YLim',[app.phimin.Value,app.phimax.Value])
            try 
                ax = findall(app.TFPanel.Children,'type','axes');
                set(ax(2),'YLim',[app.phimin.Value,app.phimax.Value])
            catch
            end    
        end

        % Value changed function: ptmax, ptmin
        function PTChangeCallback(app, event)
            ax = findall(app.PTPanel.Children,'type','axes');
            caxis(ax,[app.ptmin.Value,app.ptmax.Value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - RAW';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadRAWFileMenu = uimenu(app.FileMenu);
            app.LoadRAWFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileCallback, true);
            app.LoadRAWFileMenu.Text = 'Load RAW File';
            app.MergeRAWFilesMenu = uimenu(app.FileMenu);
            app.MergeRAWFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeRAWCallback, true);
            app.MergeRAWFilesMenu.Text = 'Merge RAW Files';
            app.FFprocMenu = uimenu(app.UIFigure);
            app.FFprocMenu.Text = 'FFproc';
            app.NewJobMenu = uimenu(app.FFprocMenu);
            app.NewJobMenu.Text = 'New Job';
            app.CombinePeriodsMenu = uimenu(app.FFprocMenu);
            app.CombinePeriodsMenu.Text = 'Combine Periods';
            app.ResultsMenu = uimenu(app.FFprocMenu);
            app.ResultsMenu.Text = 'Results';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.update = uipushtool(app.Toolbar);
            app.update.Tooltip = {'Update Estimations'};
            app.update.ClickedCallback = createCallbackFcn(app, @UpdateEstimatesCallback, true);
            app.update.Icon = 'FFproc_update.png';
            app.best = uipushtool(app.Toolbar);
            app.best.Tooltip = {'Extract estimates for current value EV (%)'};
            app.best.ClickedCallback = createCallbackFcn(app, @CurrentEstimatesCallback, true);
            app.best.Icon = 'FFproc_EVselect.png';
            app.best.Separator = 'on';
            app.polyfit = uipushtool(app.Toolbar);
            app.polyfit.Tooltip = {'Polyfit over current value EV (%)'};
            app.polyfit.ClickedCallback = createCallbackFcn(app, @PolyfitCallback, true);
            app.polyfit.Icon = 'FFproc_polyfit.png';
            app.save = uipushtool(app.Toolbar);
            app.save.Tooltip = {'Save estimates'};
            app.save.ClickedCallback = createCallbackFcn(app, @SaveEstimatesCallback, true);
            app.save.Icon = 'FFproc_save2.png';
            app.save.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RAWResultsTab = uitab(app.TabGroup);
            app.RAWResultsTab.Title = 'RAW Results';
            app.GridLayout2 = uigridlayout(app.RAWResultsTab);
            app.rhophipanel = uipanel(app.GridLayout2);
            app.ZEstimationsTab = uitab(app.TabGroup);
            app.ZEstimationsTab.Title = 'Z-Estimations';
            app.GridLayout5 = uigridlayout(app.ZEstimationsTab);
            app.Zpanel = uipanel(app.GridLayout5);
            app.TEstimationsTab = uitab(app.TabGroup);
            app.TEstimationsTab.Title = 'T-Estimations';
            app.GridLayout6 = uigridlayout(app.TEstimationsTab);
            app.Tpanel = uipanel(app.GridLayout6);
            app.OutputResultsTab = uitab(app.TabGroup);
            app.OutputResultsTab.Title = 'Output Results';
            app.GridLayout7 = uigridlayout(app.OutputResultsTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.ImpedancePanel = uipanel(app.GridLayout8);
            app.TFPanel = uipanel(app.GridLayout8);
            app.PTPanel = uipanel(app.GridLayout7);
            app.TipperPanel = uipanel(app.GridLayout7);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.popsite = uidropdown(app.GridLayout11);
            app.popsite.Items = {};
            app.popsite.ValueChangedFcn = createCallbackFcn(app, @SelectSiteCallback, true);
            app.popsite.Value = {};
            app.SiteLabel = uilabel(app.GridLayout11);
            app.SiteLabel.Text = 'Site:';
            app.GridLayout9 = uigridlayout(app.GridLayout10);
            app.permin = uieditfield(app.GridLayout9, 'numeric');
            app.permin.ValueChangedFcn = createCallbackFcn(app, @PeriodChangeCallback, true);
            app.permin.Value = -4;
            app.perminEditFieldLabel = uilabel(app.GridLayout9);
            app.perminEditFieldLabel.Text = 'per min';
            app.permax = uieditfield(app.GridLayout9, 'numeric');
            app.permax.ValueChangedFcn = createCallbackFcn(app, @PeriodChangeCallback, true);
            app.permax.Value = 3;
            app.permaxEditFieldLabel = uilabel(app.GridLayout9);
            app.permaxEditFieldLabel.Text = 'per max';
            app.rhomin = uieditfield(app.GridLayout9, 'numeric');
            app.rhomin.ValueChangedFcn = createCallbackFcn(app, @ResistivityChangeCallback, true);
            app.rhominEditFieldLabel = uilabel(app.GridLayout9);
            app.rhominEditFieldLabel.Text = 'rho min';
            app.rhomax = uieditfield(app.GridLayout9, 'numeric');
            app.rhomax.ValueChangedFcn = createCallbackFcn(app, @ResistivityChangeCallback, true);
            app.rhomax.Value = 4;
            app.rhomaxEditFieldLabel = uilabel(app.GridLayout9);
            app.rhomaxEditFieldLabel.Text = 'rho max';
            app.phimin = uieditfield(app.GridLayout9, 'numeric');
            app.phimin.ValueChangedFcn = createCallbackFcn(app, @PhaseChangeCallback, true);
            app.phiminLabel = uilabel(app.GridLayout9);
            app.phiminLabel.Text = 'phi min';
            app.phimax = uieditfield(app.GridLayout9, 'numeric');
            app.phimax.ValueChangedFcn = createCallbackFcn(app, @PhaseChangeCallback, true);
            app.phimax.Value = 90;
            app.phimaxLabel = uilabel(app.GridLayout9);
            app.phimaxLabel.Text = 'phi max';
            app.rankingperc = uispinner(app.GridLayout9);
            app.rankingperc.Limits = [1 100];
            app.rankingperc.RoundFractionalValues = 'on';
            app.rankingperc.ValueChangedFcn = createCallbackFcn(app, @rankingpercValueChanged, true);
            app.rankingperc.Tooltip = {'Values between 0 and 80'};
            app.rankingperc.Value = 100;
            app.BestEVLabel = uilabel(app.GridLayout9);
            app.BestEVLabel.Text = 'Ranking';
            app.polyorder = uieditfield(app.GridLayout9, 'numeric');
            app.polyorder.Limits = [0 9];
            app.polyorder.Tooltip = {'Recommended value = 2*number of decades'};
            app.polyorder.Value = 6;
            app.PolyfitorderLabel = uilabel(app.GridLayout9);
            app.PolyfitorderLabel.Text = 'Polyfit order:';
            app.ptmin = uieditfield(app.GridLayout9, 'numeric');
            app.ptmin.Limits = [0 90];
            app.ptmin.ValueChangedFcn = createCallbackFcn(app, @PTChangeCallback, true);
            app.ptminEditFieldLabel = uilabel(app.GridLayout9);
            app.ptminEditFieldLabel.Text = 'pt min';
            app.ptmax = uieditfield(app.GridLayout9, 'numeric');
            app.ptmax.Limits = [0 90];
            app.ptmax.ValueChangedFcn = createCallbackFcn(app, @PTChangeCallback, true);
            app.ptmax.Value = 90;
            app.ptmaxEditFieldLabel = uilabel(app.GridLayout9);
            app.ptmaxEditFieldLabel.Text = 'pt max';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportMenu = uimenu(app.ContextMenu);
            app.ExportMenu.Text = 'Export';
            app.FiguresMenu = uimenu(app.ExportMenu);
            app.FiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @FiguresMenuSelected, true);
            app.FiguresMenu.Text = 'Figures';
            % Assign app.ContextMenu
            app.Zpanel.ContextMenu = app.ContextMenu;
            app.Tpanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_RAW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end