classdef FFproc_RNK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        LoadRNKFileMenu         matlab.ui.container.Menu
        ResetWindowMenu         matlab.ui.container.Menu
        OutliersMenu            matlab.ui.container.Menu
        RemoveOutliersMenu      matlab.ui.container.Menu
        MethodMenu              matlab.ui.container.Menu
        mahaMenu                matlab.ui.container.Menu
        meanMenu                matlab.ui.container.Menu
        medianMenu              matlab.ui.container.Menu
        grubbsMenu              matlab.ui.container.Menu
        gesdMenu                matlab.ui.container.Menu
        ExecuteMenu             matlab.ui.container.Menu
        CalculateresponsesMenu  matlab.ui.container.Menu
        PlotresponsesMenu       matlab.ui.container.Menu
        WeightsMenu             matlab.ui.container.Menu
        CalculateweightsMenu    matlab.ui.container.Menu
        EigenvaluesMenu         matlab.ui.container.Menu
        FlatMenu                matlab.ui.container.Menu
        RampMenu                matlab.ui.container.Menu
        CosineMenu              matlab.ui.container.Menu
        HammingMenu             matlab.ui.container.Menu
        ChiMenu                 matlab.ui.container.Menu
        ShrinkWeightsMenu       matlab.ui.container.Menu
        EstimationMenu          matlab.ui.container.Menu
        SLDMenu                 matlab.ui.container.Menu
        FITMenu                 matlab.ui.container.Menu
        ExtrasMenu              matlab.ui.container.Menu
        ColormapMenu            matlab.ui.container.Menu
        viridisMenu             matlab.ui.container.Menu
        infernoMenu             matlab.ui.container.Menu
        boneMenu                matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        tfw                     matlab.ui.container.toolbar.ToggleTool
        curvew                  matlab.ui.container.toolbar.ToggleTool
        histow                  matlab.ui.container.toolbar.ToggleTool
        calculateresponses      matlab.ui.container.toolbar.PushTool
        exportpng               matlab.ui.container.toolbar.PushTool
        exportedi               matlab.ui.container.toolbar.PushTool
        exportmtdim             matlab.ui.container.toolbar.PushTool
        exportpdf               matlab.ui.container.toolbar.PushTool
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        tipmaxEditField         matlab.ui.control.NumericEditField
        tipmaxLabel             matlab.ui.control.Label
        tipminEditField         matlab.ui.control.NumericEditField
        tipminLabel             matlab.ui.control.Label
        phimaxEditField         matlab.ui.control.NumericEditField
        phimaxLabel             matlab.ui.control.Label
        phiminEditField         matlab.ui.control.NumericEditField
        phiminLabel             matlab.ui.control.Label
        rhomaxEditField         matlab.ui.control.NumericEditField
        rhomaxLabel             matlab.ui.control.Label
        rhominEditField         matlab.ui.control.NumericEditField
        rhominLabel             matlab.ui.control.Label
        permaxEditField         matlab.ui.control.NumericEditField
        permaxLabel             matlab.ui.control.Label
        perminEditField         matlab.ui.control.NumericEditField
        perminLabel             matlab.ui.control.Label
        cmaxEditField           matlab.ui.control.NumericEditField
        cmaxLabel               matlab.ui.control.Label
        cminEditField           matlab.ui.control.NumericEditField
        cminLabel               matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        Panel                   matlab.ui.container.Panel
        ButtonGroup             matlab.ui.container.ButtonGroup
        rhoyyphiyyButton        matlab.ui.control.RadioButton
        rhoyxphiyxButton        matlab.ui.control.RadioButton
        rhoxyphixyButton        matlab.ui.control.RadioButton
        rhoxxphixxButton        matlab.ui.control.RadioButton
        tyzButton               matlab.ui.control.RadioButton
        txzButton               matlab.ui.control.RadioButton
        ZyyButton               matlab.ui.control.RadioButton
        ZyxButton               matlab.ui.control.RadioButton
        ZxyButton               matlab.ui.control.RadioButton
        ZxxButton               matlab.ui.control.RadioButton
        GridLayout2_2           matlab.ui.container.GridLayout
        RNKCriterionTEditField  matlab.ui.control.Label
        RNKCriterionTLabel      matlab.ui.control.Label
        RNKCriterionZEditField  matlab.ui.control.Label
        RNKCriterionZLabel      matlab.ui.control.Label
        RankingEditField        matlab.ui.control.NumericEditField
        RankingEditFieldLabel   matlab.ui.control.Label
        GridLayout2             matlab.ui.container.GridLayout
        TipperLabel             matlab.ui.control.Label
        ImpedanceLabel          matlab.ui.control.Label
        Tpow                    matlab.ui.control.NumericEditField
        EI4powT                 matlab.ui.control.NumericEditField
        EI3powT                 matlab.ui.control.NumericEditField
        EI2powT                 matlab.ui.control.NumericEditField
        EI1powT                 matlab.ui.control.NumericEditField
        EI0powT                 matlab.ui.control.NumericEditField
        EI4powZ                 matlab.ui.control.NumericEditField
        EI3powZ                 matlab.ui.control.NumericEditField
        EI2powZ                 matlab.ui.control.NumericEditField
        EI1powZ                 matlab.ui.control.NumericEditField
        EI0powZ                 matlab.ui.control.NumericEditField
        EI4ButtonT              matlab.ui.control.StateButton
        EI3ButtonT              matlab.ui.control.StateButton
        EI2ButtonT              matlab.ui.control.StateButton
        EI1ButtonT              matlab.ui.control.StateButton
        EI0ButtonT              matlab.ui.control.StateButton
        Zpow                    matlab.ui.control.NumericEditField
        TpowButton              matlab.ui.control.StateButton
        ZpowButton              matlab.ui.control.StateButton
        EI4ButtonZ              matlab.ui.control.StateButton
        EI3ButtonZ              matlab.ui.control.StateButton
        EI2ButtonZ              matlab.ui.control.StateButton
        EI1ButtonZ              matlab.ui.control.StateButton
        EI0ButtonZ              matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        job,name,RNK,tfreq,tfreq_select,tfreq_label,time,doc
        weight
        TF,SRT,RES
        font, fitcolor, sldcolor
        mts,mtf
    end
    
    methods (Access = public)

        function check_buttons(app)

            % Impedances
            if app.ZpowButton.Value
                app.Zpow.Enable = 'on';
                app.Zpow.Value = 1;
                ZPC = true;
            else
                app.Zpow.Enable = 'off';
                app.Zpow.Value = 0;
                ZPC = false;
            end
            if app.EI0ButtonZ.Value
                app.EI0powZ.Enable = 'on';
                app.EI0powZ.Value = 1;
                ei0Z = ['(EI0^',num2str(app.EI0powZ.Value),')'];
            else
                app.EI0powZ.Enable = 'off';
                app.EI0powZ.Value = 0;
                ei0Z = [];
            end
            if app.EI1ButtonZ.Value
                app.EI1powZ.Enable = 'on';
                app.EI1powZ.Value = 1;
                ei1Z = ['(EI1^',num2str(app.EI1powZ.Value),')'];
            else
                app.EI1powZ.Enable = 'off';
                app.EI1powZ.Value = 0;
                ei1Z = [];
            end
            if app.EI2ButtonZ.Value
                app.EI2powZ.Enable = 'on';
                app.EI2powZ.Value = 1;
                ei2Z = ['(EI2^',num2str(app.EI2powZ.Value),')'];
            else
                app.EI2powZ.Enable = 'off';
                app.EI2powZ.Value = 0;
                ei2Z = [];
            end
            if app.EI3ButtonZ.Value
                app.EI3powZ.Enable = 'on';
                app.EI3powZ.Value = 1;
                ei3Z = ['(EI3^',num2str(app.EI3powZ.Value),')'];
            else
                app.EI3powZ.Enable = 'off';
                app.EI3powZ.Value = 0;
                ei3Z = [];
            end
            if app.EI4ButtonZ.Value
                app.EI4powZ.Enable = 'on';
                app.EI4powZ.Value = 1;
                ei4Z = ['(EI4^',num2str(app.EI4powZ.Value),')'];
            else
                app.EI4powZ.Enable = 'off';
                app.EI4powZ.Value = 0;
                ei4Z = [];
            end

            if isempty(ei0Z) && isempty(ei1Z) && isempty(ei2Z) && isempty(ei3Z) && isempty(ei4Z)
                ei = [];
            else
                ei = [ei0Z,ei1Z,ei2Z,ei3Z,ei4Z];
            end
            if isempty(ei)
                if ZPC
                    zpc = ['(ZPC^',num2str(app.Zpow.Value),')'];
                else
                    zpc = [];
                end
            else
                if ZPC
                    zpc = ['(ZPC^',num2str(app.Zpow.Value),') '];
                else
                    zpc = [];
                end
            end
            if isempty([zpc,ei])
                app.RNKCriterionZEditField.Text = '';
            else
                app.RNKCriterionZEditField.Text = [zpc,ei];
            end

            % Tippers
            if app.TpowButton.Value
                app.Tpow.Enable = 'on';
                app.Tpow.Value = 1;
                TPC = true;
            else
                app.Tpow.Enable = 'off';
                app.Tpow.Value = 0;
                TPC = false;
            end
            if app.EI0ButtonT.Value
                app.EI0powT.Enable = 'on';
                app.EI0powT.Value = 1;
                ei0T = ['(EI0^',num2str(app.EI0powT.Value),')'];
            else
                app.EI0powT.Enable = 'off';
                app.EI0powT.Value = 0;
                ei0T = [];
            end
            if app.EI1ButtonT.Value
                app.EI1powT.Enable = 'on';
                app.EI1powT.Value = 1;
                ei1T = ['(EI1^',num2str(app.EI1powT.Value),')'];
            else
                app.EI1powT.Enable = 'off';
                app.EI1powT.Value = 0;
                ei1T = [];
            end
            if app.EI2ButtonT.Value
                app.EI2powT.Enable = 'on';
                app.EI2powT.Value = 1;
                ei2T = ['(EI2^',num2str(app.EI2powT.Value),')'];
            else
                app.EI2powT.Enable = 'off';
                app.EI2powT.Value = 0;
                ei2T = [];
            end
            if app.EI3ButtonT.Value
                app.EI3powT.Enable = 'on';
                app.EI3powT.Value = 1;
                ei3T = ['(EI3^',num2str(app.EI3powT.Value),')'];
            else
                app.EI3powT.Enable = 'off';
                app.EI3powT.Value = 0;
                ei3T = [];
            end
            if app.EI4ButtonT.Value
                app.EI4powT.Enable = 'on';
                app.EI4powT.Value = 1;
                ei4T = ['(EI4^',num2str(app.EI4powT.Value),')'];
            else
                app.EI4powT.Enable = 'off';
                app.EI4powT.Value = 0;
                ei4T = [];
            end

            if isempty(ei0T) && isempty(ei1T) && isempty(ei2T) && isempty(ei3T) && isempty(ei4T)
                ei = [];
            else
                ei = [ei0T,ei1T,ei2T,ei3T,ei4T];
            end
            if isempty(ei)
                if TPC
                    tpc = ['(TPC^',num2str(app.Tpow.Value),')'];
                else
                    tpc = [];
                end
            else
                if TPC
                    tpc = ['(TPC^',num2str(app.Tpow.Value),') '];
                else
                    tpc = [];
                end
            end
            if isempty([tpc,ei])
                app.RNKCriterionTEditField.Text = '';
            else
                app.RNKCriterionTEditField.Text = [tpc,ei];
            end
        end
        
        function check_weight(app)
            if isequal(app.EigenvaluesMenu.Checked,'on')
                app.weight.name = 'ev';
            elseif isequal(app.FlatMenu.Checked,'on')
                app.weight.name = 'flat';
            elseif isequal(app.RampMenu.Checked,'on')
                app.weight.name = 'ramp';
            elseif isequal(app.CosineMenu.Checked,'on')
                app.weight.name = 'cos';
            elseif isequal(app.HammingMenu.Checked,'on')
                app.weight.name = 'ham';
            elseif isequal(app.ChiMenu.Checked,'on')
                app.weight.name = 'chi';
            end
        end

        function check_rnk(app)
            % Impedances
            if app.ZpowButton.Value
                ZPC = true;
            else
                ZPC = false;
            end
            if app.EI0ButtonZ.Value
                ei0Z = ['(EI0^',num2str(app.EI0powZ.Value),')'];
            else
                ei0Z = [];
            end
            if app.EI1ButtonZ.Value
                ei1Z = ['(EI1^',num2str(app.EI1powZ.Value),')'];
            else
                ei1Z = [];
            end
            if app.EI2ButtonZ.Value
                ei2Z = ['(EI2^',num2str(app.EI2powZ.Value),')'];
            else
                ei2Z = [];
            end
            if app.EI3ButtonZ.Value
                ei3Z = ['(EI3^',num2str(app.EI3powZ.Value),')'];
            else
                ei3Z = [];
            end
            if app.EI4ButtonZ.Value
                ei4Z = ['(EI4^',num2str(app.EI4powZ.Value),')'];
            else
                ei4Z = [];
            end

            if isempty(ei0Z) && isempty(ei1Z) && isempty(ei2Z) && isempty(ei3Z) && isempty(ei4Z)
                ei = [];
            else
                ei = [ei0Z,ei1Z,ei2Z,ei3Z,ei4Z];
            end
            if isempty(ei)
                if ZPC
                    zpc = ['(ZPC^',num2str(app.Zpow.Value),')'];
                else
                    zpc = [];
                end
            else
                if ZPC
                    zpc = ['(ZPC^',num2str(app.Zpow.Value),') '];
                else
                    zpc = [];
                end
            end
            if isempty([zpc,ei])
                app.RNKCriterionZEditField.Text = '';
            else
                app.RNKCriterionZEditField.Text = [zpc,ei];
            end

            % Tippers
            if app.TpowButton.Value
                TPC = true;
            else
                TPC = false;
            end
            if app.EI0ButtonT.Value
                ei0T = ['(EI0^',num2str(app.EI0powT.Value),')'];
            else
                ei0T = [];
            end
            if app.EI1ButtonT.Value
                ei1T = ['(EI1^',num2str(app.EI1powT.Value),')'];
            else
                ei1T = [];
            end
            if app.EI2ButtonT.Value
                ei2T = ['(EI2^',num2str(app.EI2powT.Value),')'];
            else
                ei2T = [];
            end
            if app.EI3ButtonT.Value
                ei3T = ['(EI3^',num2str(app.EI3powT.Value),')'];
            else
                ei3T = [];
            end
            if app.EI4ButtonT.Value
                ei4T = ['(EI4^',num2str(app.EI4powT.Value),')'];
            else
                ei4T = [];
            end

            if isempty(ei0T) && isempty(ei1T) && isempty(ei2T) && isempty(ei3T) && isempty(ei4T)
                ei = [];
            else
                ei = [ei0T,ei1T,ei2T,ei3T,ei4T];
            end
            if isempty(ei)
                if TPC
                    tpc = ['(TPC^',num2str(app.Tpow.Value),')'];
                else
                    tpc = [];
                end
            else
                if TPC
                    tpc = ['(TPC^',num2str(app.Tpow.Value),') '];
                else
                    tpc = [];
                end
            end
            if isempty([tpc,ei])
                app.RNKCriterionTEditField.Text = '';
            else
                app.RNKCriterionTEditField.Text = [tpc,ei];
            end
        end
        
        function extract_tf(app)

            tf = struct;

            % Remove outliers? Not while extracting
            tfout = false;
           
            % Arrange data
            for f = 1:numel(app.tfreq)

                % Extract ranking for each target frequency
                rnk = app.RNK{f};

                % Target frequency
                freq = app.tfreq(f);

                % Extract transfer functions: Impedances
                Zxx = cat(1,rnk.Zxx);
                Zxy = cat(1,rnk.Zxy);
                Zyx = cat(1,rnk.Zyx);
                Zyy = cat(1,rnk.Zyy);              
                tf(f).Zxx = Zxx;
                tf(f).Zxy = Zxy;
                tf(f).Zyx = Zyx;
                tf(f).Zyy = Zyy;
                % Extract transfer functions: Resistivity and Phase
                [rho,phi] = rhoandphi(Zxx,Zxy,Zyx,Zyy,freq);
                tf(f).rhoxx = rho.rhoxx;
                tf(f).rhoxy = rho.rhoxy;
                tf(f).rhoyx = rho.rhoyx;
                tf(f).rhoyy = rho.rhoyy;
                tf(f).phixx = phi.phixx;
                tf(f).phixy = phi.phixy;
                tf(f).phiyx = phi.phiyx;
                tf(f).phiyy = phi.phiyy;
                % Extract transfer functions: Tippers
                tf(f).txz = cat(1,rnk.txz);
                tf(f).tyz = cat(1,rnk.tyz);

                % Check for Impedances
                chZ = cat(1,rnk.Zxx_pcorr);
                if all(isnan(chZ))
                    nanZ = true;
                else
                    nanZ = false;
                end

                if nanZ
                    tf(f).Zxx_pcorr = zeros(numel(rnk,1));
                    tf(f).Zxy_pcorr = zeros(numel(rnk,1));
                    tf(f).Zyx_pcorr = zeros(numel(rnk,1));
                    tf(f).Zyy_pcorr = zeros(numel(rnk,1));
                else    
                    tf(f).Zxx_pcorr = cat(1,rnk.Zxx_pcorr);
                    tf(f).Zxy_pcorr = cat(1,rnk.Zxy_pcorr);
                    tf(f).Zyx_pcorr = cat(1,rnk.Zyx_pcorr);
                    tf(f).Zyy_pcorr = cat(1,rnk.Zyy_pcorr);
                end

                % Check for Tipper
                chT = cat(1,rnk.txz_pcorr);
                if all(isnan(chT))
                    nanT = true;
                else
                    nanT = false;
                end

                if nanT
                    tf(f).txz_pcorr = zeros(numel(rnk,1));
                    tf(f).tyz_pcorr = zeros(numel(rnk,1));
                else
                    tf(f).txz_pcorr = cat(1,rnk.txz_pcorr);
                    tf(f).tyz_pcorr = cat(1,rnk.tyz_pcorr);
                end

                % Extracting Eigenvalues
                EV = cat(2,rnk.EVal).';             

                % Eigenvalue Indices
                if all(isnan(EV))
                    tf(f).EI0 = ones(size(EV,1),1);
                    tf(f).EI1 = ones(size(EV,1),1);
                    tf(f).EI2 = ones(size(EV,1),1);
                    tf(f).EI3 = ones(size(EV,1),1);
                    tf(f).EI4 = ones(size(EV,1),1);
                else
                    % Eigenvalue 0: |λ2|/|λ1|
                    tf(f).EI0 = calcevindex(EV,0,tfout);
                    % Eigenvalue 1: 1 - |λ3|/|λ2|
                    tf(f).EI1 = calcevindex(EV,1,tfout);
                    % Eigenvalue 2: 1/|λ3|
                    tf(f).EI2 = calcevindex(EV,2,tfout);
                    % Eigenvalue 3: |λ2|/|λ3| - |λ3|^2                    
                    tf(f).EI3 = calcevindex(EV,3,tfout);
                    % Eigenvalue 4: |λ2|/|λ3| - |λ1|/|λ2|
                    tf(f).EI4 = calcevindex(EV,4,tfout);
                end
            end
            % Asigning TF as app variable
            app.TF = tf;
            
        end

        function update_tf(app)
            
            % Extracting Transfer Functions from 'RNK'. It means that
            % everytime the update_tf function is executed, the TFs are
            % re-extracted from the original calculations.
            extract_tf(app)
            
            % Check for outlier detection method
            if isequal(app.medianMenu.Checked,'on')
                outmet = 'median';
            elseif isequal(app.meanMenu.Checked,'on')
                outmet = 'mean';
            elseif isequal(app.grubbsMenu.Checked,'on')
                outmet = 'grubbs';
            elseif isequal(app.gesdMenu.Checked,'on')
                outmet = 'gesd';
            elseif isequal(app.mahaMenu.Checked,'on')
                outmet = 'maha';    
            end

            for f = 1:numel(app.tfreq)
                if ismember(f,app.tfreq_select)
                    % Eigenvalue Index for Impedances
                    EIZ = (app.TF(f).EI0.^app.EI0powZ.Value).*...
                          (app.TF(f).EI1.^app.EI1powZ.Value).*...
                          (app.TF(f).EI2.^app.EI2powZ.Value).*...
                          (app.TF(f).EI3.^app.EI3powZ.Value).*...
                          (app.TF(f).EI4.^app.EI4powZ.Value);
                    EIZ(isnan(EIZ)) = 0;
                    % Eigenvalue Index for Tipper
                    EIT = (app.TF(f).EI0.^app.EI0powT.Value).*...
                          (app.TF(f).EI1.^app.EI1powT.Value).*...
                          (app.TF(f).EI2.^app.EI2powT.Value).*...
                          (app.TF(f).EI3.^app.EI3powT.Value).*...
                          (app.TF(f).EI4.^app.EI4powT.Value);    
                    EIT(isnan(EIT)) = 0;
    
                    % Ranking for Impedances
                    app.TF(f).Zxx_RNK = (app.TF(f).Zxx_pcorr.^app.Zpow.Value).*EIZ;
                    app.TF(f).Zxy_RNK = (app.TF(f).Zxy_pcorr.^app.Zpow.Value).*EIZ;
                    app.TF(f).Zyx_RNK = (app.TF(f).Zyx_pcorr.^app.Zpow.Value).*EIZ;
                    app.TF(f).Zyy_RNK = (app.TF(f).Zyy_pcorr.^app.Zpow.Value).*EIZ;
                    % Ranking for Tipper
                    app.TF(f).txz_RNK = (app.TF(f).txz_pcorr.^app.Tpow.Value).*EIT;
                    app.TF(f).tyz_RNK = (app.TF(f).tyz_pcorr.^app.Tpow.Value).*EIT;
    
                    if app.RemoveOutliersMenu.Checked                    
                        % Impedance Zxx
                        tf = TF_downweight(app.TF(f).Zxx,[],outmet);
                        app.TF(f).Zxx_RNK(tf) = 0;
                        % Impedance Zxy
                        tf = TF_downweight(app.TF(f).Zxy,[],outmet);
                        app.TF(f).Zxy_RNK(tf) = 0;                    
                        % Impedance Zyx
                        tf = TF_downweight(app.TF(f).Zyx,[],outmet);
                        app.TF(f).Zyx_RNK(tf) = 0;                    
                        % Impedance Zyy
                        tf = TF_downweight(app.TF(f).Zyy,[],outmet);
                        app.TF(f).Zyy_RNK(tf) = 0;                    
                        % Impedance txz
                        tf = TF_downweight(app.TF(f).txz,[],outmet);
                        app.TF(f).txz_RNK(tf) = 0;                    
                        % Impedance tyz
                        tf = TF_downweight(app.TF(f).tyz,[],outmet);
                        app.TF(f).tyz_RNK(tf) = 0;       
                    end
    
                    % Check if only 1 time segment is evaluated. If yes, place 
                    % 1 as the weight
                    if numel(app.TF(f).Zxx_RNK) == 1 && isnan(app.TF(f).Zxx_RNK)
                        app.TF(f).Zxx_RNK = 1;
                    end
                    if numel(app.TF(f).Zxy_RNK) == 1 && isnan(app.TF(f).Zxy_RNK)
                        app.TF(f).Zxy_RNK = 1;
                    end
                    if numel(app.TF(f).Zyx_RNK) == 1 && isnan(app.TF(f).Zyx_RNK)
                        app.TF(f).Zyx_RNK = 1;
                    end
                    if numel(app.TF(f).Zyy_RNK) == 1 && isnan(app.TF(f).Zyy_RNK)
                        app.TF(f).Zyy_RNK = 1;
                    end
                    if numel(app.TF(f).txz_RNK) == 1 && isnan(app.TF(f).txz_RNK)
                        app.TF(f).txz_RNK = 1;
                    end
                    if numel(app.TF(f).tyz_RNK) == 1 && isnan(app.TF(f).tyz_RNK)
                        app.TF(f).tyz_RNK = 1;
                    end
                end
            end
        end

        function select_tf(app)
            tf = app.ButtonGroup.SelectedObject.Text;
            if isequal(app.tfw.State,'on')
                plot_tf(app,tf)
            elseif isequal(app.curvew.State,'on')                
                if ~isempty(app.RES)
                    tf = app.ButtonGroup.SelectedObject.Text;
                    plot_tfoverlap(app,tf)
                else
                    plot_curvew(app,tf)
                end
            elseif isequal(app.histow.State,'on')
                plot_rnk(app,tf)
            end   
        end

        function calculate_SRT(app)            

            for f = 1:numel(app.tfreq)

                if ismember(f,app.tfreq_select)

                    % Amount (percetage) of time windows to use for estimations
                    r = app.RankingEditField.Value;
    
                    % Number of time segments
                    ntseg = numel(app.TF(f).Zxx);
    
                    switch app.weight.name
                        case 'ev'
    
                            % ZXX
                            [app.SRT(f).Zxx_RNK,id_Zxx] = sort(app.TF(f).Zxx_RNK,'descend');
                            app.SRT(f).Zxx = app.TF(f).Zxx(id_Zxx);   
                            app.SRT(f).rhoxx = app.TF(f).rhoxx(id_Zxx);
                            app.SRT(f).phixx = app.TF(f).phixx(id_Zxx);  
                            app.SRT(f).id_Zxx = id_Zxx;
    
                            % ZXY
                            [app.SRT(f).Zxy_RNK,id_Zxy] = sort(app.TF(f).Zxy_RNK,'descend');
                            app.SRT(f).Zxy = app.TF(f).Zxy(id_Zxy);       
                            app.SRT(f).id_Zxy = id_Zxy;
                            app.SRT(f).rhoxy = app.TF(f).rhoxy(id_Zxy);
                            app.SRT(f).phixy = app.TF(f).phixy(id_Zxy);
    
                            % ZYX
                            [app.SRT(f).Zyx_RNK,id_Zyx] = sort(app.TF(f).Zyx_RNK,'descend');
                            app.SRT(f).Zyx = app.TF(f).Zyx(id_Zyx);  
                            app.SRT(f).rhoyx = app.TF(f).rhoyx(id_Zyx);
                            app.SRT(f).phiyx = app.TF(f).phiyx(id_Zyx);
                            app.SRT(f).id_Zyx = id_Zyx;
    
                            % ZYY
                            [app.SRT(f).Zyy_RNK,id_Zyy] = sort(app.TF(f).Zyy_RNK,'descend');
                            app.SRT(f).Zyy = app.TF(f).Zyy(id_Zyy);
                            app.SRT(f).rhoyy = app.TF(f).rhoyy(id_Zyy);
                            app.SRT(f).phiyy = app.TF(f).phiyy(id_Zyy);
                            app.SRT(f).id_Zyy = id_Zyy;
    
                            % TXZ
                            [app.SRT(f).txz_RNK,id_txz] = sort(app.TF(f).txz_RNK,'descend');
                            app.SRT(f).txz = app.TF(f).txz(id_txz);  
                            app.SRT(f).id_txz = id_txz;
    
                            % TYZ
                            [app.SRT(f).tyz_RNK,id_tyz] = sort(app.TF(f).tyz_RNK,'descend');
                            app.SRT(f).tyz = app.TF(f).tyz(id_tyz); 
                            app.SRT(f).id_tyz = id_tyz;
    
                        otherwise
    
                            seg_eval = ceil(r*ntseg/100);
                            if seg_eval > ntseg
                                seg_eval = ntseg;            
                            end
    
                            % For cases: 'flat', 'ramp', 'cos', 'han', 'chi'
                            w = createweights(ntseg,seg_eval,app.weight.name,app.weight.perc,app.weight.w0,app.weight.pow);
    
                            [~,id_Zxx] = sort(app.TF(f).Zxx_RNK,'descend');
                            app.SRT(f).Zxx = app.TF(f).Zxx(id_Zxx);
                            app.SRT(f).rhoxx = app.TF(f).rhoxx(id_Zxx);
                            app.SRT(f).phixx = app.TF(f).phixx(id_Zxx);  
                            app.SRT(f).Zxx_RNK = w;
                            app.SRT(f).id_Zxx = id_Zxx;
    
                            [~,id_Zxy] = sort(app.TF(f).Zxy_RNK,'descend');
                            app.SRT(f).Zxy = app.TF(f).Zxy(id_Zxy);  
                            app.SRT(f).rhoxy = app.TF(f).rhoxy(id_Zxy);
                            app.SRT(f).phixy = app.TF(f).phixy(id_Zxy);
                            app.SRT(f).Zxy_RNK = w;
                            app.SRT(f).id_Zxy = id_Zxy;
    
                            [~,id_Zyx] = sort(app.TF(f).Zyx_RNK,'descend');
                            app.SRT(f).Zyx = app.TF(f).Zyx(id_Zyx);   
                            app.SRT(f).rhoyx = app.TF(f).rhoyx(id_Zyx);
                            app.SRT(f).phiyx = app.TF(f).phiyx(id_Zyx);
                            app.SRT(f).Zyx_RNK = w;
                            app.SRT(f).id_Zyx = id_Zyx;
    
                            [~,id_Zyy] = sort(app.TF(f).Zyy_RNK,'descend');
                            app.SRT(f).Zyy = app.TF(f).Zyy(id_Zyy);    
                            app.SRT(f).rhoyy = app.TF(f).rhoyy(id_Zyy);
                            app.SRT(f).phiyy = app.TF(f).phiyy(id_Zyy);
                            app.SRT(f).Zyy_RNK = w;
                            app.SRT(f).id_Zyy = id_Zyy;
    
                            [~,id_txz] = sort(app.TF(f).txz_RNK,'descend');
                            app.SRT(f).txz = app.TF(f).txz(id_txz);  
                            app.SRT(f).txz_RNK = w;
                            app.SRT(f).id_txz = id_txz;
    
                            [~,id_tyz] = sort(app.TF(f).tyz_RNK,'descend');
                            app.SRT(f).tyz = app.TF(f).tyz(id_tyz);   
                            app.SRT(f).tyz_RNK = w;
                            app.SRT(f).id_tyz = id_tyz;                        
    
                    end
                    if isequal(app.ShrinkWeightsMenu.Checked,'on')
                        if r ~= 100
                            % Shrink weights to selected ammount of data
                            app.SRT(f).Zxx_RNK = shrink(app.SRT(f).Zxx_RNK,r,app.weight.perc);
                            app.SRT(f).Zxy_RNK = shrink(app.SRT(f).Zxy_RNK,r,app.weight.perc);
                            app.SRT(f).Zyx_RNK = shrink(app.SRT(f).Zyx_RNK,r,app.weight.perc);
                            app.SRT(f).Zyy_RNK = shrink(app.SRT(f).Zyy_RNK,r,app.weight.perc);
                            app.SRT(f).txz_RNK = shrink(app.SRT(f).txz_RNK,r,app.weight.perc);
                            app.SRT(f).tyz_RNK = shrink(app.SRT(f).tyz_RNK,r,app.weight.perc);
                        end    
                    end
    
                    if app.weight.perc > 0 & ntseg > 2
                        % Number of points to downweight to 0
                        n = ceil(ntseg*app.weight.perc/100);
                        % Zxx
                        app.SRT(f).Zxx_RNK(1:n) = 0;
                        app.SRT(f).Zxx_RNK(app.SRT(f).Zxx_RNK<0) = 0;
                        app.SRT(f).Zxx_RNK(isnan(app.SRT(f).Zxx_RNK) | isinf(app.SRT(f).Zxx_RNK)) = 0;
                        % Zxy
                        app.SRT(f).Zxy_RNK(1:n) = 0;
                        app.SRT(f).Zxy_RNK(app.SRT(f).Zxy_RNK<0) = 0;
                        app.SRT(f).Zxy_RNK(isnan(app.SRT(f).Zxy_RNK) | isinf(app.SRT(f).Zxy_RNK)) = 0;
                        % Zyx
                        app.SRT(f).Zyx_RNK(1:n) = 0;
                        app.SRT(f).Zyx_RNK(app.SRT(f).Zyx_RNK<0) = 0;
                        app.SRT(f).Zyx_RNK(isnan(app.SRT(f).Zyx_RNK) | isinf(app.SRT(f).Zyx_RNK)) = 0;
                        % Zyy
                        app.SRT(f).Zyy_RNK(1:n) = 0;
                        app.SRT(f).Zyy_RNK(app.SRT(f).Zyy_RNK<0) = 0;
                        app.SRT(f).Zyy_RNK(isnan(app.SRT(f).Zyy_RNK) | isinf(app.SRT(f).Zyy_RNK)) = 0;
                        % txz
                        app.SRT(f).txz_RNK(1:n) = 0;
                        app.SRT(f).txz_RNK(app.SRT(f).txz_RNK<0) = 0;
                        app.SRT(f).txz_RNK(isnan(app.SRT(f).txz_RNK) | isinf(app.SRT(f).txz_RNK)) = 0;
                        % tyz
                        app.SRT(f).tyz_RNK(1:n) = 0;
                        app.SRT(f).tyz_RNK(app.SRT(f).tyz_RNK<0) = 0;
                        app.SRT(f).tyz_RNK(isnan(app.SRT(f).tyz_RNK) | isinf(app.SRT(f).tyz_RNK)) = 0;
                    end
                end
            end
        end
        
        function calculate_RES(app)

            rnk = app.RankingEditField.Value;
            results = struct;
            
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis: Calculate Responses'); 
                for f = 1:numel(app.tfreq_select)
    
                    dlg.Message = ['Calculations for target frequency ',num2str(app.tfreq(app.tfreq_select(f))),': ',num2str(f),' of ',num2str(numel(app.tfreq_select))];
                    dlg.Value = f/numel(app.tfreq_select);
    
                    % Extracting srt structure for each target frequency
                    srt = app.SRT(app.tfreq_select(f));

                    % Selected target frequency
                    freq = app.tfreq(app.tfreq_select(f));  
    
                    % Number of time segments
                    ntseg = numel(srt.Zxx);  

                    % Weigths for certain amount of data based on Partial
                    % Coherences and Eigenvalue Criterion
                    wxx = srt.Zxx_RNK;
                    wxy = srt.Zxy_RNK;
                    wyx = srt.Zyx_RNK;
                    wyy = srt.Zyy_RNK;
                    wxz = srt.txz_RNK;
                    wyz = srt.tyz_RNK;  
                    
                    for r = 1:rnk                        
                        % ntseg:    number of total time segments
                        % seg_eval: number of time segments evaluated for each ranking
                        seg_eval = ceil(r*ntseg/100);
                        if seg_eval > ntseg
                            seg_eval = ntseg;            
                        end
    
                        % Weigths for certain amount of data based on Partial
                        % Coherences and Eigenvalue Criterion
                        Wxx = wxx(1:seg_eval);
                        Wxy = wxy(1:seg_eval);
                        Wyx = wyx(1:seg_eval);
                        Wyy = wyy(1:seg_eval);
                        Wxz = wxz(1:seg_eval);
                        Wyz = wyz(1:seg_eval);
    
                        Zxx_srt = srt.Zxx(1:seg_eval);
                        Zxy_srt = srt.Zxy(1:seg_eval);
                        Zyx_srt = srt.Zyx(1:seg_eval);
                        Zyy_srt = srt.Zyy(1:seg_eval);
                        txz_srt = srt.txz(1:seg_eval);
                        tyz_srt = srt.tyz(1:seg_eval);
        
                        % Calculation for Z
                        [Zxx,Zxx_Err] = TF_weightederrors(Zxx_srt,seg_eval,Wxx);
                        [Zxy,Zxy_Err] = TF_weightederrors(Zxy_srt,seg_eval,Wxy);
                        [Zyx,Zyx_Err] = TF_weightederrors(Zyx_srt,seg_eval,Wyx);
                        [Zyy,Zyy_Err] = TF_weightederrors(Zyy_srt,seg_eval,Wyy);
                        % Calculation for T
                        [txz,txz_Err] = TF_weightederrors(txz_srt,seg_eval,Wxz);
                        [tyz,tyz_Err] = TF_weightederrors(tyz_srt,seg_eval,Wyz);
    
                        % Restistivity, phase and Errors calculation
                        [rho,phi] = rhoandphi(Zxx,Zxy,Zyx,Zyy,Zxx_Err,Zxy_Err,Zyx_Err,Zyy_Err,freq);
      
                        results(r).Zxx(f,1) = Zxx;
                        results(r).Zxx_Err(f,1) = Zxx_Err;
                        results(r).Zxy(f,1) = Zxy;
                        results(r).Zxy_Err(f,1) = Zxy_Err;
                        results(r).Zyx(f,1) = Zyx;
                        results(r).Zyx_Err(f,1) = Zyx_Err;
                        results(r).Zyy(f,1) = Zyy;                    
                        results(r).Zyy_Err(f,1) = Zyy_Err;
                        results(r).rhoxx(f,1) = rho.rhoxx;
                        results(r).rhoxx_Err(f,1) = rho.rhoxx_Err;
                        results(r).rhoxy(f,1) = rho.rhoxy;
                        results(r).rhoxy_Err(f,1) = rho.rhoxy_Err;
                        results(r).rhoyx(f,1) = rho.rhoyx;
                        results(r).rhoyx_Err(f,1) = rho.rhoyx_Err;
                        results(r).rhoyy(f,1) = rho.rhoyy;
                        results(r).rhoyy_Err(f,1) = rho.rhoyy_Err;
                        results(r).phixx(f,1) = phi.phixx;
                        results(r).phixx_Err(f,1) = phi.phixx_Err;
                        results(r).phixy(f,1) = phi.phixy;
                        results(r).phixy_Err(f,1) = phi.phixy_Err;
                        results(r).phiyx(f,1) = phi.phiyx;
                        results(r).phiyx_Err(f,1) = phi.phiyx_Err;
                        results(r).phiyy(f,1) = phi.phiyy;
                        results(r).phiyy_Err(f,1) = phi.phiyy_Err;
                        results(r).txz(f,1) = txz;
                        results(r).txz_Err(f,1) = txz_Err;
                        results(r).tyz(f,1) = tyz;
                        results(r).tyz_Err(f,1) = tyz_Err;
    
                    end
                    
                end
            close(dlg)

            app.RES = results;

            calculate_SLD(app)
            calculate_FIT(app)
            
            if app.curvew.State
                tf = app.ButtonGroup.SelectedObject.Text;
                plot_tfoverlap(app,tf)
            elseif app.tfw.State
                tf = app.ButtonGroup.SelectedObject.Text;
                plot_tf(app,tf)
            end

            answer = uiconfirm(app.UIFigure,'Plot Responses?','FFproc - Ranking Analysis','DefaultOption',1);
            switch answer
                case 'OK'
                    PlotResponsesCallback(app)
            end

        end
        
        function calculate_SLD(app)

            job = app.doc.job;
            RAW = app.RES(app.RankingEditField.Value);
            
            FFsave = create_mt;            
            target_freq = app.tfreq(app.tfreq_select);

            % Transfer Function selection for Z
            Zxx = RAW.Zxx;          Zxx_Err = RAW.Zxx_Err;
            Zxy = RAW.Zxy;          Zxy_Err = RAW.Zxy_Err;
            Zyx = RAW.Zyx;          Zyx_Err = RAW.Zyx_Err;
            Zyy = RAW.Zyy;          Zyy_Err = RAW.Zyy_Err; 
            
            % Transfer Function selection for T
            txz = RAW.txz;          txz_Err = RAW.txz_Err;
            tyz = RAW.tyz;          tyz_Err = RAW.tyz_Err;                 
            
            % Saving number of frequencies, frequency and period vector
            FFsave.nfreq = numel(target_freq);
            FFsave.freq = target_freq;
            FFsave.per = 1./(target_freq);

            % Saving transfer functions in FFsave structure: Z
            FFsave.Zxx = Zxx;       FFsave.Zxx_Err = Zxx_Err;
            FFsave.Zxy = Zxy;       FFsave.Zxy_Err = Zxy_Err;
            FFsave.Zyx = Zyx;       FFsave.Zyx_Err = Zyx_Err;
            FFsave.Zyy = Zyy;       FFsave.Zyy_Err = Zyy_Err;

            % Saving transfer functions in FFsave structure: T
            FFsave.txz = txz;       FFsave.txz_Err = txz_Err;
            FFsave.tyz = tyz;       FFsave.tyz_Err = tyz_Err;

            FFsave = FFproc_OPT_rhoandphi(FFsave);            
            FFsave = FFproc_OPT_metainfo(FFsave,job);
            FFsave.source = {'data'};
            FFsave = CART(FFsave);
            FFsave = calc_PT(FFsave);
            app.mts = FFsave;

        end

        function calculate_FIT(app)

            job = app.doc.job;
            RAW = app.RES(1:app.RankingEditField.Value);

            FFsave = create_mt;            
            target_freq = app.tfreq(app.tfreq_select);

            polyord = round(1.25*(ceil(max(log10(target_freq))) - floor(min(log10(target_freq)))));   
            if polyord > 9
                polyord = 9;
            end

            ZXX = cat(2,RAW.Zxx);       ZXX_Err = cat(2,RAW.Zxx_Err);
            ZXY = cat(2,RAW.Zxy);       ZXY_Err = cat(2,RAW.Zxy_Err);
            ZYX = cat(2,RAW.Zyx);       ZYX_Err = cat(2,RAW.Zyx_Err);
            ZYY = cat(2,RAW.Zyy);       ZYY_Err = cat(2,RAW.Zyy_Err);
            TXZ = cat(2,RAW.txz);       TXZ_Err = cat(2,RAW.txz_Err);
            TYZ = cat(2,RAW.tyz);       TYZ_Err = cat(2,RAW.tyz_Err);

            % Polynom fitting for Z
            [Zxx,Zxx_Err] = polynomialfit(target_freq,ZXX,ZXX_Err,'ZXX',polyord);
            [Zxy,Zxy_Err] = polynomialfit(target_freq,ZXY,ZXY_Err,'ZXY',polyord);
            [Zyx,Zyx_Err] = polynomialfit(target_freq,ZYX,ZYX_Err,'ZYX',polyord);
            [Zyy,Zyy_Err] = polynomialfit(target_freq,ZYY,ZYY_Err,'ZYY',polyord);

            % Polynom fitting for T
            [txz,txz_Err] = polynomialfit(target_freq,TXZ,TXZ_Err,'TXZ',polyord);
            [tyz,tyz_Err] = polynomialfit(target_freq,TYZ,TYZ_Err,'TYZ',polyord);
            
            % Saving number of frequencies, frequency and period vector
            FFsave.nfreq = numel(target_freq);
            FFsave.freq = target_freq;
            FFsave.per = 1./(target_freq);

            % Saving transfer functions in FFsave structure: Z
            FFsave.Zxx = Zxx;       FFsave.Zxx_Err = Zxx_Err;
            FFsave.Zxy = Zxy;       FFsave.Zxy_Err = Zxy_Err;
            FFsave.Zyx = Zyx;       FFsave.Zyx_Err = Zyx_Err;
            FFsave.Zyy = Zyy;       FFsave.Zyy_Err = Zyy_Err;

            % Saving transfer functions in FFsave structure: T
            FFsave.txz = txz;       FFsave.txz_Err = txz_Err;
            FFsave.tyz = tyz;       FFsave.tyz_Err = tyz_Err;

            FFsave = FFproc_OPT_rhoandphi(FFsave);            
            FFsave = FFproc_OPT_metainfo(FFsave,job);
            FFsave.source = {'data'};
            FFsave = CART(FFsave);
            FFsave = calc_PT(FFsave);
            app.mtf = FFsave;

        end
        
        function plot_tf(app,tf)

            ind = app.tfreq_select;
            rnk = app.RankingEditField.Value;
            
            td = tiledlayout('flow','Parent',app.Panel,'Padding','compact','TileSpacing','compact');
            for f = 1:numel(ind)
                ax = nexttile(td);
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        c = app.SRT(ind(f)).([tf,'_RNK']);
                    otherwise
                        prop = ['Z',tf(end-1:end)];
                        c = app.SRT(ind(f)).([prop,'_RNK']);
                end

                % Masking data points beyond ranking percentage
                ntseg = numel(app.SRT(ind(f)).Zxx);
                seg_eval = ceil(rnk*ntseg/100);
                if seg_eval > ntseg
                    seg_eval = ntseg;            
                end
                c(c<=0) = NaN;
                c(seg_eval:end) = NaN;

                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        x = real(app.SRT(ind(f)).(tf));   
                        x(isnan(c)) = NaN;                
                        y = imag(app.SRT(ind(f)).(tf));
                        y(isnan(c)) = NaN;
                    otherwise
                        y = app.SRT(ind(f)).(tf);  
                        x = 1:numel(y)/numel(y);
                end
                
                scatter(ax,x,y,c*25,c,'filled');

                title(ax,[app.tfreq_label(ind(f))])
                set(ax,'FontName',app.font,'Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                clim(ax,[app.cminEditField.Value,app.cmaxEditField.Value])

%                 xLim = abs(ax.XLim);
%                 yLim = abs(ax.YLim);
%                 Lim = min([xLim,yLim]);
% 
%                 ax.XLim = [-Lim,Lim];
%                 ax.YLim = [-Lim,Lim];
                axis(ax,'square')
                daspect(ax,[1,1,1])

                if ~isempty(app.RES)
                    fit_l = xline(ax,real(app.mtf.(tf)(f)),':','Color',[0 0.4470 0.7410],'LineWidth',1.5);
                    sel_l = xline(ax,real(app.mts.(tf)(f)),':','Color',[0.6350 0.0780 0.1840],'LineWidth',1.5);
                            yline(ax,imag(app.mtf.(tf)(f)),':','Color',[0 0.4470 0.7410],'LineWidth',1.5)
                            yline(ax,imag(app.mts.(tf)(f)),':','Color',[0.6350 0.0780 0.1840],'LineWidth',1.5)                       
                end

                if isequal(app.viridisMenu.Checked,'on')
                    colormap(ax,flipud(colors('viridis')))
                elseif isequal(app.infernoMenu.Checked,'on')
                    colormap(ax,colors('inferno'))
                elseif isequal(app.boneMenu.Checked,'on')
                    colormap(ax,bone)
                end
            end

            if ~isempty(app.RES)
                lg = legend(ax,[fit_l,sel_l],'FIT','SLD','NumColumns',2,'Color','w','FontSize',11,'Box','off');
                lg.Layout.Tile = 'North';
            end
            cb = colorbar(ax);
            cb.Layout.Tile = 'east';
            cb.Label.String = 'Normalized Ranking';
            cb.FontName = app.font;
            cb.FontSize = 12;           

            title(td,['Transfer function: ',upper(app.ButtonGroup.SelectedObject.Text)],'FontName',app.font,'FontSize',14)
            xlabel(td,['Real part: ' upper(app.ButtonGroup.SelectedObject.Text)],'FontName',app.font,'FontSize',14)
            ylabel(td,['Imaginary part: ' upper(app.ButtonGroup.SelectedObject.Text)],'FontName',app.font,'FontSize',14)
        end

        function plot_curvew(app,tf)

            ind = app.tfreq_select;            

            permin = app.perminEditField.Value;
            permax = app.permaxEditField.Value;
            
            if ~isempty(app.RES)
                td = tiledlayout(2,2,'Parent',app.Panel,'TileSpacing','tight','Padding','compact');
                ax1 = nexttile(td);
                hold(ax1,'on')
                grid(ax1,'on')
                ax3 = nexttile(td);
                hold(ax3,'on')
                grid(ax3,'on')
                ax2 = nexttile(td);
                hold(ax2,'on')
                grid(ax2,'on')
                ax4 = nexttile(td);
                hold(ax4,'on')
                grid(ax4,'on')
            else
                td = tiledlayout(2,2,'Parent',app.Panel,'TileSpacing','tight','Padding','compact');
                ax1 = nexttile(td);
                hold(ax1,'on')
                grid(ax1,'on')
                ax3 = nexttile(td);
                ax3.Visible = "off";
                ax2 = nexttile(td);
                hold(ax2,'on')
                grid(ax2,'on')
                ax4 = nexttile(td);
                ax4.Visible = "off";                
            end

            for i = 1:numel(ind)                
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        c = app.SRT(ind(i)).([tf,'_RNK']);
                        N = numel(c);
                    otherwise
                        prop = ['Z',tf(end-1:end)];
                        c = app.SRT(ind(i)).([prop,'_RNK']);
                        N = numel(c);
                end
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        x = 1./repmat(app.tfreq(ind(i)),N,1);
                        yre = real(app.SRT(ind(i)).(tf));   
                        yre(c == 0) = NaN;
                        yrecol = app.SRT(ind(i)).([tf,'_RNK']);
                        yrecol(c == 0) = NaN;
                        yim = imag(app.SRT(ind(i)).(tf));
                        yim(c == 0) = NaN;
                        yimcol = app.SRT(ind(i)).([tf,'_RNK']);
                        yimcol(c == 0) = NaN;
                        scatter(ax1,x,yre,40*yrecol.^5,yrecol,'filled');
                        scatter(ax2,x,yim,40*yimcol.^5,yimcol,'filled');
                    otherwise
                        prop = ['Z',tf(end-1:end)];
                        tfcomp = tf(end-1:end);
                        x = 1./repmat(app.tfreq(ind(i)),N,1);
                        yrho = app.SRT(ind(i)).(['rho',tfcomp]);
                        yrho(c == 0) = NaN;
                        yrhocol = app.SRT(ind(i)).([prop,'_RNK']);
                        yrhocol(c == 0) = NaN;
                        yphi = app.SRT(ind(i)).(['phi',tfcomp]);
                        switch tfcomp
                            case 'yx'
                                yphi = yphi + 180;
                        end
                        yphi(c == 0) = NaN;
                        yphicol = app.SRT(ind(i)).([prop,'_RNK']);
                        yphicol(c == 0) = NaN;
                        scatter(ax1,x,yrho,40*yrhocol.^5,yrhocol,'filled');
                        scatter(ax2,x,yphi,40*yrhocol.^5,yphicol,'filled');
                end                          
            end

            if ~isempty(app.RES)
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        N = app.RankingEditField.Value;
                        Nvec = 1:N;
                    otherwise
                        N = app.RankingEditField.Value;
                        Nvec = 1:N;
                end
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                        Nmat = repmat(Nvec(:),1,numel(ind));
                        x = 1./repmat(app.tfreq(ind)',N,1);
                        y = cat(2,app.RES(1:N).(tf)).';
                        yre = real(y);   
                        yrecol = Nmat;
                        yim = imag(y);
                        yimcol = Nmat;
                        scatter(ax3,x(:),yre(:),30,yrecol(:),'filled');
                        scatter(ax4,x(:),yim(:),30,yimcol(:),'filled');
                    otherwise
                        tfcomp = tf(end-1:end);
                        Nmat = repmat(Nvec(:),1,numel(ind));
                        x = 1./repmat(app.tfreq(ind)',N,1);
                        yrho = cat(2,app.RES(1:N).(['rho',tfcomp])).';
                        yrhocol = Nmat;
                        yphi = cat(2,app.RES(1:N).(['phi',tfcomp])).';
                        switch tfcomp
                            case 'yx'
                                yphi = yphi + 180;
                        end
                        yphicol = Nmat;
                        scatter(ax3,x(:),yrho(:),30,yrhocol(:),'filled');
                        scatter(ax4,x(:),yphi(:),30,yphicol(:),'filled');
                end
            end

            % Axes properties
            switch tf
                case {'Zxx','Zxy','Zyx','Zyy'}
                    set(ax1,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    set(ax2,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    if ~isempty(app.RES)
                        set(ax3,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                        set(ax4,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    end
                case {'txz','tyz'}
                    set(ax1,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    set(ax2,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    if ~isempty(app.RES)
                        set(ax3,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                        set(ax4,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    end
                otherwise
                    set(ax1,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Log','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    set(ax2,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    if ~isempty(app.RES)
                        set(ax3,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Log','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                        set(ax4,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','MinorGridLineStyle','none','Box','on','BoxStyle','full','Color',[0.7,0.7,0.7])
                    end
            end
            % Plot limits
            xlim(ax1,[10^permin,10^permax])
            xlim(ax2,[10^permin,10^permax])
            if ~isempty(app.RES)
                xlim(ax3,[10^permin,10^permax])
                xlim(ax4,[10^permin,10^permax])
            end
            switch tf
                case {'Zxx','Zxy','Zyx','Zyy'}
                    yLimmin = min([ax1.YLim(1),ax2.YLim(1)]);
                    yLimmax = max([ax1.YLim(2),ax2.YLim(2)]);                    
                    ylim(ax1,[yLimmin,yLimmax])
                    ylim(ax2,[yLimmin,yLimmax])
                    if ~isempty(app.RES)
                        ylim(ax3,[yLimmin,yLimmax])
                        ylim(ax4,[yLimmin,yLimmax])
                    end
                case {'txz','tyz'}
                    yLimmin = app.tipminEditField.Value;
                    yLimmax = app.tipmaxEditField.Value;
                    ylim(ax1,[yLimmin,yLimmax])
                    ylim(ax2,[yLimmin,yLimmax])
                    if ~isempty(app.RES)
                        ylim(ax3,[yLimmin,yLimmax])
                        ylim(ax4,[yLimmin,yLimmax])
                    end
                otherwise
                    ylim(ax1,10.^[app.rhominEditField.Value,app.rhomaxEditField.Value])
                    ylim(ax2,[app.phiminEditField.Value,app.phimaxEditField.Value])
                    if ~isempty(app.RES)
                        ylim(ax3,10.^[app.rhominEditField.Value,app.rhomaxEditField.Value])
                    ylim(ax4,[app.phiminEditField.Value,app.phimaxEditField.Value])
                    end
            end
            
            % Labels
            if ~isempty(app.RES)
                xlabel(ax2,'period (s)')
                xlabel(ax4,'period (s)')
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy'}
                        ylabel(ax1,[tf,' [mV/km nT]'])
                        ylabel(ax2,[tf,' [mV/km nT]'])
                    case {'txz','tyz'}
                        ylabel(ax1,[tf,' amplitude'])
                        ylabel(ax2,[tf,' amplitude'])
                    otherwise
                        switch tf
                            case 'rhoxx/phixx'
                                tf = 'Zxx';
                            case 'rhoxy/phixy'
                                tf = 'Zxy';
                            case 'rhoyx/phiyx'
                                tf = 'Zyx';
                            case 'rhoyy/phiyy'
                                tf = 'Zyy';
                        end
                        ylabel(ax1,[tf,' Apparent Resistivity (\Omegam)'])
                        ylabel(ax2,[tf,' Phase (deg)'])
                end
            else
                xlabel(ax2,'period (s)')
                switch tf
                    case {'Zxx','Zxy','Zyx','Zyy'}
                        ylabel(ax1,[tf,' [mV/km nT]'])
                        ylabel(ax2,[tf,' [mV/km nT]'])
                    case {'txz','tyz'}
                        ylabel(ax1,[tf,' amplitude'])
                        ylabel(ax2,[tf,' amplitude'])
                    otherwise
                        switch tf
                            case 'rhoxx/phixx'
                                tf = 'Zxx';
                            case 'rhoxy/phixy'
                                tf = 'Zxy';
                            case 'rhoyx/phiyx'
                                tf = 'Zyx';
                            case 'rhoyy/phiyy'
                                tf = 'Zyy';
                        end
                        ylabel(ax1,[tf,' Apparent Resistivity (\Omegam)'])
                        ylabel(ax2,[tf,' Phase (deg)'])
                end
            end
            % Colormap
            if isequal(app.viridisMenu.Checked,'on')
                colormap(ax1,flipud(colors('viridis',100)))
                colormap(ax2,flipud(colors('viridis',100)))
                if ~isempty(app.RES)
                    colormap(ax3,flipud(colors('viridis',N)))
                    colormap(ax4,flipud(colors('viridis',N)))
                end
            elseif isequal(app.infernoMenu.Checked,'on')
                colormap(ax1,colors('inferno',100))
                colormap(ax2,colors('inferno',100))
                if ~isempty(app.RES)
                    colormap(ax3,colors('inferno',N))
                    colormap(ax4,colors('inferno',N))
                end
            elseif isequal(app.boneMenu.Checked,'on')
                colormap(ax1,bone(100))
                colormap(ax2,bone(100))
                if ~isempty(app.RES)
                    colormap(ax3,bone(N))
                    colormap(ax4,bone(N))
                end
            end
            % Colorbar
            cb1 = colorbar(ax1,'eastoutside');
            cb2 = colorbar(ax2,'eastoutside');
            if ~isempty(app.RES)
                cb3 = colorbar(ax3,'eastoutside');
                cb4 = colorbar(ax4,'eastoutside');
            end
            cb1.Label.String = 'norm. Ranking';
            cb2.Label.String = 'norm. Ranking';
            if ~isempty(app.RES)
                cb3.Label.String = 'Data Selected (%)';
                cb4.Label.String = 'Data Selected (%)';
            end
            % Colormap limits
            clim(ax1,[app.cminEditField.Value,app.cmaxEditField.Value])
            clim(ax2,[app.cminEditField.Value,app.cmaxEditField.Value])
            if ~isempty(app.RES)
                clim(ax3,[0,N])
                clim(ax4,[0,N])
            end
        end

        function plot_rnk(app,tf)

            ind = app.tfreq_select;
            
            td = tiledlayout('flow','Parent',app.Panel,'Padding','compact','TileSpacing','compact');
            for i = 1:numel(ind)
                ax = nexttile(td);
                id = app.SRT(ind(i)).(['id_',tf]);
                wsort = app.SRT(ind(i)).([tf,'_RNK']);
                x = (1:numel(app.SRT(ind(i)).(tf)))./numel(app.SRT(ind(i)).(tf));
                scatter(ax,x,wsort,15,x(id),'filled')
                xline(ax,app.RankingEditField.Value/100,':',{['ranking: ',num2str(app.RankingEditField.Value),'%']},'FontName',app.font,'FontSize',7)

                title(ax,[app.tfreq_label(ind(i))])

                if isequal(app.viridisMenu.Checked,'on')
                    colormap(ax,colors('viridis'))
                elseif isequal(app.infernoMenu.Checked,'on')
                    colormap(ax,flipud(colors('inferno')))
                elseif isequal(app.boneMenu.Checked,'on')
                    colormap(ax,flipud(bone))
                end

                set(ax,'FontName',app.font,'FontSize',8,'Box','on','BoxStyle','full','Color',[0.7,0.7,0.7],...
                       'XLim',[0 1],'YLim',[0 1],'Xtick',[0 1],'XTickLabels',{'0','100%'})                
                clim(ax,[app.cminEditField.Value,app.cmaxEditField.Value])                
            end
            cb = colorbar(ax);
            cb.Layout.Tile = 'east';
            cb.Label.String = 'Time segment recorded';
            cb.Ticks = [0 1];
            cb.TickLabels = {'start','end'};
            cb.Ruler.TickLabelRotation = 90;
            cb.FontName = app.font;
            cb.FontSize = 12;

            title(td,['Transfer function: ',upper(app.ButtonGroup.SelectedObject.Text)],'FontName',app.font)
            xlabel(td,'Amount of time segments','FontName',app.font)
            ylabel(td,'Normalized Ranking','FontName',app.font)
        end

        function plot_tfoverlap(app,tf)

            plot_curvew(app,tf)

            ax1 = app.Panel.Children.Children(8);
            ax2 = app.Panel.Children.Children(6);
            ax3 = app.Panel.Children.Children(7);
            ax4 = app.Panel.Children.Children(5);

            switch tf
                case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                    % Plot 1
                    errorplot(app.mtf.per,real(app.mtf.(tf)),...
                                  app.mtf.([tf,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,[],ax1);
                    errorplot(app.mts.per,real(app.mts.(tf)),...
                                  app.mts.([tf,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,[],ax1);
                    lg = legend(ax1,[ax1.Children(1),ax1.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    errorplot(app.mtf.per,real(app.mtf.(tf)),...
                                  app.mtf.([tf,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,[],ax3);
                    errorplot(app.mts.per,real(app.mts.(tf)),...
                                  app.mts.([tf,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,[],ax3);
                    lg = legend(ax3,[ax3.Children(1),ax3.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    
                    % Plot 2
                    errorplot(app.mtf.per,imag(app.mtf.(tf)),...
                                  app.mtf.([tf,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,[],ax2)
                    errorplot(app.mts.per,imag(app.mts.(tf)),...
                                  app.mts.([tf,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,[],ax2)
                    lg = legend(ax2,[ax2.Children(1),ax2.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    errorplot(app.mtf.per,imag(app.mtf.(tf)),...
                                  app.mtf.([tf,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,[],ax4)
                    errorplot(app.mts.per,imag(app.mts.(tf)),...
                                  app.mts.([tf,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,[],ax4)
                    lg = legend(ax4,[ax4.Children(1),ax4.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                otherwise
                    tfcomp = tf(end-1:end);
                    % Plot 1
                    errorplot(app.mtf.per,app.mtf.(['rho',tfcomp]),...
                                  app.mtf.(['rho',tfcomp,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,'rho',ax1)
                    errorplot(app.mts.per,app.mts.(['rho',tfcomp]),...
                                  app.mts.(['rho',tfcomp,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,'rho',ax1)
                    lg = legend(ax1,[ax1.Children(1),ax1.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    errorplot(app.mtf.per,app.mtf.(['rho',tfcomp]),...
                                  app.mtf.(['rho',tfcomp,'_Err']),...
                                  true,false,app.fitcolor,app.fitcolor,'rho',ax3)
                    errorplot(app.mts.per,app.mts.(['rho',tfcomp]),...
                                  app.mts.(['rho',tfcomp,'_Err']),...
                                  true,false,app.sldcolor,app.sldcolor,'rho',ax3)
                    lg = legend(ax3,[ax3.Children(1),ax3.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    % Plot 2                    
                    switch tfcomp
                        case 'yx'
                            errorplot(app.mtf.per,app.mtf.(['phi',tfcomp]) + 180,...
                                      app.mtf.(['phi',tfcomp,'_Err']),...
                                      true,false,app.fitcolor,app.fitcolor,[],ax2)
                            errorplot(app.mts.per,app.mts.(['phi',tfcomp]) + 180,...
                                      app.mts.(['phi',tfcomp,'_Err']),...
                                      true,false,app.sldcolor,app.sldcolor,[],ax2)
                            lg = legend(ax2,[ax2.Children(1),ax2.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                            errorplot(app.mtf.per,app.mtf.(['phi',tfcomp]) + 180,...
                                      app.mtf.(['phi',tfcomp,'_Err']),...
                                      true,false,app.fitcolor,app.fitcolor,[],ax4)
                            errorplot(app.mts.per,app.mts.(['phi',tfcomp]) + 180,...
                                      app.mts.(['phi',tfcomp,'_Err']),...
                                      true,false,app.sldcolor,app.sldcolor,[],ax4)
                            lg = legend(ax4,[ax4.Children(1),ax4.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                        otherwise
                            errorplot(app.mtf.per,app.mtf.(['phi',tfcomp]),...
                                      app.mtf.(['phi',tfcomp,'_Err']),...
                                      true,false,app.fitcolor,app.fitcolor,[],ax2)
                            errorplot(app.mts.per,app.mts.(['phi',tfcomp]),...
                                      app.mts.(['phi',tfcomp,'_Err']),...
                                      true,false,app.sldcolor,app.sldcolor,[],ax2)
                            lg = legend(ax2,[ax2.Children(1),ax2.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                            errorplot(app.mtf.per,app.mtf.(['phi',tfcomp]),...
                                      app.mtf.(['phi',tfcomp,'_Err']),...
                                      true,false,app.fitcolor,app.fitcolor,[],ax4)
                            errorplot(app.mts.per,app.mts.(['phi',tfcomp]),...
                                      app.mts.(['phi',tfcomp,'_Err']),...
                                      true,false,app.sldcolor,app.sldcolor,[],ax4)
                            lg = legend(ax4,[ax4.Children(1),ax4.Children(2)],'FIT','SLD','NumColumns',2,'Color','w');
                    end                   
            end
        end

        function plot_RES(app)
            RAW = app.RES(1:app.RankingEditField.Value);
            R = numel(RAW);
            N = numel(RAW(1).Zxx);
            id = repmat(linspace(0,1,R),N,1);
            msize = 25;
            color1 = [1,0.25,0.25];
            color2 = [0.1 0.6 0.9];
            cpow = 1;
           
            per = 1./app.tfreq(app.tfreq_select);
            per = per(:);
            per = fliplr(repmat(per,1,R));
            
            rhoxy = cat(1,RAW.rhoxy);
            rhoxy = reshape(rhoxy,N,[]);
            rhoxy = fliplr(rhoxy);
            
            rhoyx = cat(1,RAW.rhoyx);
            rhoyx = reshape(rhoyx,N,[]);
            rhoyx = fliplr(rhoyx);
            
            phixy = cat(1,RAW.phixy);
            phixy = reshape(phixy,N,[]);
            phixy = fliplr(phixy);
            
            phiyx = cat(1,RAW.phiyx);
            phiyx = reshape(phiyx,N,[]);
            phiyx = fliplr(phiyx) + 180;
            
            txz = cat(1,RAW.txz);
            txz = reshape(txz,N,[]);
            txz = fliplr(txz);
            
            tyz = cat(1,RAW.tyz);
            tyz = reshape(tyz,N,[]);
            tyz = fliplr(tyz);   

            permin = app.perminEditField.Value;
            permax = app.permaxEditField.Value;
            rhomin = app.rhominEditField.Value;
            rhomax = app.rhomaxEditField.Value;
            phimin = app.phiminEditField.Value;
            phimax = app.phimaxEditField.Value;
            tipmin = app.tipminEditField.Value;
            tipmax = app.tipmaxEditField.Value;
            
            s1 = 18;
            s2 = 7;
            figure('WindowStyle','docked')
            td = tiledlayout(2,50,'TileSpacing','compact','Padding','compact');

            title(td,{['Z Criterion: ',app.RNKCriterionZEditField.Text];...
                      ['T Criterion: ',app.RNKCriterionTEditField.Text]},...
                      'FontName',app.font,'FontSize',14)

            % -------------------------------------------- App. Resistivity
            ax = nexttile(td,[1,s1]);
            hold(ax,'on')
            grid(ax,'on')
            if isequal(app.SLDMenu.Checked,'on')
                errorplot(app.mts.per,...
                          app.mts.rhoxy,...
                          app.mts.rhoxy_Err,...
                          true,false,[0,0,0],[0,0,0],'rho',ax)
                
                errorplot(app.mts.per,...
                          app.mts.rhoyx,...
                          app.mts.rhoyx_Err,...
                          true,false,[0.5,0.5,0.5],[0.5 0.5 0.5],'rho',ax)
                sld = true;
            else
                sld = false;
            end
            if isequal(app.FITMenu.Checked,'on')
                errorplot(app.mtf.per,...
                          app.mtf.rhoxy,...
                          app.mtf.rhoxy_Err,...
                          true,false,[0.7 0.1 0.1],[1,0.25,0.25],'rho',ax)
                
                errorplot(app.mtf.per,...
                          app.mtf.rhoyx,...
                          app.mtf.rhoyx_Err,...
                          true,false,[0.25,0.25,0.8],[0.1 0.6 0.9],'rho',ax)
                pfit = true;
            else
                pfit = false;
            end
            s = scatter(ax,per(:),rhoxy(:),msize,color1,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';  
            s.AlphaDataMapping = 'none';
            s = scatter(ax,per(:),rhoyx(:),msize,color2,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';
            s.AlphaDataMapping = 'none';

            if sld && pfit
                lg = legend(ax,'SLD Zxy','SLD Zyx','FIT Zxy','FIT Zyx','Zxy','Zyx','NumColumns',6);
            elseif sld
                lg = legend(ax,'SLD Zxy','SLD Zyx','Zxy','Zyx','NumColumns',4);
            elseif pfit
                lg = legend(ax,'FIT Zxy','FIT Zyx','Zxy','Zyx','NumColumns',4);
            else
                lg = legend(ax,'Zxy','Zyx','NumColumns',2);
            end
            lg.Location = 'Northeast';
            lg.FontSize = 8;
            lg.FontName = app.font;
            lg.EdgeColor = [1 1 1];

            set(ax,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Log','Box','on','MinorGridLineStyle','none')
            xlim(ax,[10^permin 10^permax])
            ylim(ax,[10^rhomin 10^rhomax])
            xlabel(ax,'period (s)')
            ylabel(ax,'Apparent Resistivity (\Omegam)')            

            % --------------------------------------------------------- TXZ
            ax = nexttile(td,[1,s1]);
            hold(ax,'on')
            grid(ax,'on')
            if isequal(app.SLDMenu.Checked,'on')
                errorplot(app.mts.per,...
                          real(app.mts.txz),...
                          app.mts.txz_Err,...
                          true,false,[0,0,0],[0,0,0],[],ax)
                
                errorplot(app.mts.per,...
                          imag(app.mts.txz),...
                          app.mts.txz_Err,...
                          true,false,[0.5,0.5,0.5],[0.5 0.5 0.5],[],ax)
                sld = true;
            else
                sld = false;
            end
            if isequal(app.FITMenu.Checked,'on')
                errorplot(app.mtf.per,...
                          real(app.mtf.txz),...
                          app.mtf.txz_Err,...
                          true,false,[0.7 0.1 0.1],[1,0.25,0.25],[],ax)
                
                errorplot(app.mtf.per,...
                          imag(app.mtf.txz),...
                          app.mtf.txz_Err,...
                          true,false,[0.25,0.25,0.8],[0.1 0.6 0.9],[],ax)
                pfit = true;
            else
                pfit = false;
            end
            s = scatter(ax,per(:),real(txz(:)),msize,color1,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';  
            s.AlphaDataMapping = 'none';
            s = scatter(ax,per(:),imag(txz(:)),msize,color2,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';
            s.AlphaDataMapping = 'none';

            if sld && pfit
                lg = legend(ax,'SLD Real','SLD Imag','FIT Real','FIT Imag','Real','Imag','NumColumns',6);
            elseif sld
                lg = legend(ax,'SLD Real','SLD Imag','Real','Imag','NumColumns',4);
            elseif pfit
                lg = legend(ax,'FIT Real','FIT Imag','Real','Imag','NumColumns',4);
            else
                lg = legend(ax,'Real','Imag','NumColumns',2);
            end
            lg.Location = 'Northeast';
            lg.FontSize = 8;
            lg.FontName = app.font;
            lg.EdgeColor = [1 1 1];

            set(ax,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','Box','on','MinorGridLineStyle','none')
            xlim(ax,[10^permin 10^permax])
            ylim(ax,[tipmin tipmax])
            xlabel(ax,'period (s)')
            ylabel(ax,'TXZ')                         

            % ------------------------------------------- Induction vectors

            limmax = 90;
            limmin = 0;
            position = 0.25;

            pertick = floor(permin):ceil(permax);
            for i = 1:numel(pertick)
                perticklabel(i) = {['10^{',num2str(pertick(i)),'}']};
            end
                    
            xtlab = {'FIT','SLD'};

            ax = nexttile(td,[2,s2]);
            hold(ax,'on')
            grid(ax,'on') 
            
            l = 0.125;
            
            if std(abs(real(app.mtf.tyz))) > l || std(abs(real(app.mtf.txz))) > l ||...
               std(abs(imag(app.mtf.tyz))) > l || std(abs(real(app.mtf.txz))) > l
                fact1 = ceil(max([std(abs(real(app.mtf.tyz))),std(abs(real(app.mtf.txz))),...
                                  std(abs(imag(app.mtf.tyz))),std(abs(real(app.mtf.txz)))])/l);                     
            else
                fact1 = 2*l;
            end
            if std(abs(real(app.mts.tyz))) > 2*l || std(abs(real(app.mts.txz))) > 2*l ||...
               std(abs(imag(app.mts.tyz))) > 2*l || std(abs(real(app.mts.txz))) > 2*l
                fact2 = ceil(max([std(abs(real(app.mts.tyz))),std(abs(real(app.mts.txz))),...
                                  std(abs(imag(app.mts.tyz))),std(abs(real(app.mts.txz)))])/l);                     
            else
                fact2 = 2*l;
            end
            
            fact = ceil(abs(mean([fact1,fact2])));
            
            % Site 1
            quiver(ax,-2*l,log10(app.mtf.per),...
                   real(app.mtf.tyz)/fact,...
                  -real(app.mtf.txz)/fact,...
                   'color',[1,0.25,0.25],'linewidth',1,'maxheadsize',0.05,'autoscale','off')    
            quiver(ax,-2*l,log10(app.mtf.per),...
                   imag(app.mtf.tyz)/fact,...
                  -imag(app.mtf.txz)/fact,....
                   'color',[0.1 0.6 0.9],'linewidth',1,'maxheadsize',0.05,'autoscale','off')
               
            % Site 2
            quiver(ax,2*l,log10(app.mts.per),...
                   real(app.mts.tyz)/fact,...
                  -real(app.mts.txz)/fact,...
                   'color',[1,0.25,0.25],'linewidth',1,'maxheadsize',0.05,'autoscale','off')
            quiver(ax,2*l,log10(app.mts.per),...
                   imag(app.mts.tyz)/fact,...
                  -imag(app.mts.txz)/fact,....
                   'color',[0.1 0.6 0.9],'linewidth',1,'maxheadsize',0.05,'autoscale','off')
            l1 = xline(ax,0.25);
            l1.Alpha = 0.15;
            l2 = xline(ax,-0.25);
            l2.Alpha = 0.15;
        
            
            title(ax,'Tipper')
            xlabel(ax,'amplitude')
            ylabel(ax,'period (s)')    
            legend(ax,'Real','Imag','location','south','FontName',app.font,'FontSize',8,'EdgeColor',[1 1 1])
            set(ax,'Fontname',app.font,'Fontsize',12,...
                   'ydir','reverse',...
                   'dataaspectratio',[1 1 1],...
                   'xlim',[-4*l - l 4*l + l],'xtick',(-5:4*l:5),...
                   'xticklabel',string(abs(-5:4*l:5)*fact),...
                   'ylim',[permin permax],'box','on',...
                   'ytick',floor(permin):ceil(permax),'yticklabel',perticklabel)
          
            % ------------------------------------------------ Phase Tensor
            ax = nexttile(td,[2,s2]);
            hold(ax,'on')
            grid(ax,'on')
            if isequal(app.SLDMenu.Checked,'on')                
                sld = true;
            else
                sld = false;
            end
            if isequal(app.FITMenu.Checked,'on')
                pfit = true;
            else
                pfit = false;
            end

            hold(ax,'on')
            grid(ax,'on')          
            
            % Ellipse size
            scale_ell = 1/7.5;
                
            % Site 1
            for i = 1:app.mtf.nfreq
                if 3*abs(atan(app.mtf.phimin(i))) < abs(atan(app.mtf.phimax(i)))
                    ellmin = tan(atan(app.mtf.phimax(i))/3);
                else
                    ellmin = app.mtf.phimin(i);
                end
                e1 = ellipse(scale_ell*atan(app.mtf.phimax(i))/atan(app.mtf.phimax(i))...
                            ,scale_ell*atan(ellmin)/atan(app.mtf.phimax(i))...
                            ,-position,log10(app.mtf.per(i))...
                            ,rad2deg(-app.mtf.theta(i))...
                            ,'k',ax);        
                set(e1,'facecolor','flat','facevertexcdata',rad2deg(atan(app.mtf.phimax(i))))
                
                b1 = minbar(0.9*scale_ell*atan(ellmin)/atan(app.mtf.phimax(i)),0.3*scale_ell,...
                           -position,log10(app.mtf.per(i)),...
                            rad2deg(-app.mtf.theta(i)),...
                           'k',ax);
                set(b1,'facecolor','flat','facevertexcdata',rad2deg(atan(app.mtf.phimin(i)))) 
            end    
                     
            % Site 2
            for i = 1:app.mts.nfreq
                if 3*abs(atan(app.mts.phimin(i))) < abs(atan(app.mts.phimax(i)))
                    ellmin = tan(atan(app.mts.phimax(i))/3);
                else
                    ellmin = app.mts.phimin(i);
                end
                e2 = ellipse(scale_ell*atan(app.mts.phimax(i))/atan(app.mts.phimax(i))...
                            ,scale_ell*atan(ellmin)/atan(app.mts.phimax(i))...
                            ,position,log10(app.mts.per(i))...
                            ,rad2deg(-app.mts.theta(i))...
                            ,'k',ax);
        
                set(e2,'facecolor','flat','facevertexcdata',rad2deg(atan(app.mts.phimax(i))))
                
                b2 = minbar(0.9*scale_ell*atan(ellmin)/atan(app.mts.phimax(i)),0.3*scale_ell,...
                           position,log10(app.mts.per(i)),...
                           rad2deg(-app.mts.theta(i)),...
                           'k',ax);
                set(b2,'facecolor','flat','facevertexcdata',rad2deg(atan(app.mts.phimin(i)))) 
            end            
                
            set(ax,'ydir','reverse',...
                         'dataaspectratio',[1 1 1],...                         
                         'xtick',[-position,position],...
                         'xlim',[-2*position 2*position],...
                         'xticklabel',xtlab,'xticklabelrotation',90,...
                         'ylim',[permin permax],'yticklabel',perticklabel,...
                         'ytick',floor(permin):ceil(permax),...
                         'box','on',...
                         'fontname',app.font,'fontsize',12)
                     
            colormap(ax,colors('pt'))
            caxis(ax,[phimin phimax])
            
            % Colorbar
            cb = colorbar(ax);
            cb.Location = 'eastoutside';
            cb.Label.String = 'Phase Tensor \Phi (°)';
            cb.FontSize = 10;
            cb.Label.FontName = app.font;
            % Colorbar Ticks
            if numel(cb.TickLabels) < 4
                cb.Ticks = linspace(limmin,limmax,3);
                cb.TickLabels = num2cell(round(linspace(limmin,limmax,3)));
            elseif numel(cb.TickLabels) > 4 && numel(cb.TickLabels) < 6
                cb.Ticks = linspace(limmin,limmax,5);
                cb.TickLabels = num2cell(round(linspace(limmin,limmax,5)));
            elseif numel(cb.TickLabels) > 6
                cb.Ticks = linspace(limmin,limmax,7);
                cb.TickLabels = num2cell(round(linspace(limmin,limmax,7)));
            end


            % ------------------------------------------------------- Phase
            ax = nexttile(td,[1,s1]);
            hold(ax,'on')
            grid(ax,'on')
            if isequal(app.SLDMenu.Checked,'on')
                errorplot(app.mts.per,...
                          app.mts.phixy,...
                          app.mts.phixy_Err,...
                          true,false,[0,0,0],[0,0,0],[],ax)
                
                errorplot(app.mts.per,...
                          app.mts.phiyx + 180,...
                          app.mts.phiyx_Err,...
                          true,false,[0.5,0.5,0.5],[0.5 0.5 0.5],[],ax)
                sld = true;
            else
                sld = false;
            end
            if isequal(app.FITMenu.Checked,'on')
                errorplot(app.mtf.per,...
                          app.mtf.phixy,...
                          app.mtf.phixy_Err,...
                          true,false,[0.7 0.1 0.1],[1,0.25,0.25],[],ax)
                
                errorplot(app.mtf.per,...
                          app.mtf.phiyx + 180,...
                          app.mtf.phiyx_Err,...
                          true,false,[0.25,0.25,0.8],[0.1 0.6 0.9],[],ax)
                pfit = true;
            else
                pfit = false;
            end
            s = scatter(ax,per(:),phixy(:),msize,color1,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';
            s.AlphaDataMapping = 'none';
            s = scatter(ax,per(:),phiyx(:),msize,color2,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';
            s.AlphaDataMapping = 'none';
            set(ax,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','Box','on','MinorGridLineStyle','none')
            xlim(ax,[10^permin 10^permax])
            ylim(ax,[-phimin phimax])
            xlabel(ax,'period (s)')
            ylabel(ax,'Phase (deg)')     

            colormap(ax,colors('rw',R))  
            caxis(ax,[0,R])
            cb = colorbar(ax,'Location','SouthOutside'); 
            cb.FontSize = 10;
            try
                cb.Ticks = round(cb.Ticks);
            catch
                cb.Ticks = [0,1];
            end
            for i =  1:numel(cb.Ticks)
                cb.TickLabels{i} = [cb.TickLabels{i},' %'];
            end
            cb.Label.String = {'Amount of best-ranked time segments';'XY / Real part'};

            % --------------------------------------------------------- TYZ
            ax = nexttile(td,[1,s1]);
            hold(ax,'on')
            grid(ax,'on')
            if isequal(app.SLDMenu.Checked,'on')
                errorplot(app.mts.per,...
                          real(app.mts.tyz),...
                          app.mts.tyz_Err,...
                          true,false,[0,0,0],[0,0,0],[],ax)
                
                errorplot(app.mts.per,...
                          imag(app.mts.tyz),...
                          app.mts.tyz_Err,...
                          true,false,[0.5,0.5,0.5],[0.5 0.5 0.5],[],ax)
                sld = true;
            else
                sld = false;
            end
            if isequal(app.FITMenu.Checked,'on')
                errorplot(app.mtf.per,...
                          real(app.mtf.tyz),...
                          app.mtf.tyz_Err,...
                          true,false,[0.7 0.1 0.1],[1,0.25,0.25],[],ax)
                
                errorplot(app.mtf.per,...
                          imag(app.mtf.tyz),...
                          app.mtf.tyz_Err,...
                          true,false,[0.25,0.25,0.8],[0.1 0.6 0.9],[],ax)
                pfit = true;
            else
                pfit = false;
            end
            s = scatter(ax,per(:),real(tyz(:)),msize,color1,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat'; 
            s.AlphaDataMapping = 'none';
            s = scatter(ax,per(:),imag(tyz(:)),msize,color2,'filled','Marker','o');
            s.AlphaData = id(:).^cpow;
            s.MarkerFaceAlpha = 'flat';
            s.AlphaDataMapping = 'none';
            set(ax,'FontName',app.font,'FontSize',12,'XScale','Log','YScale','Linear','Box','on','MinorGridLineStyle','none')
            xlim(ax,[10^permin 10^permax])
            ylim(ax,[tipmin tipmax])
            xlabel(ax,'period (s)')
            ylabel(ax,'TYZ')           
            
            colormap(ax,colors('bw',R))
            clim(ax,[0,R])
            cb = colorbar(ax,'Location','SouthOutside'); 
            cb.FontSize = 10;
            try
                cb.Ticks = round(cb.Ticks);
            catch
                cb.Ticks = [0,1];
            end
            cb.Ticks = round(cb.Ticks);
            for i =  1:numel(cb.Ticks)
                cb.TickLabels{i} = [cb.TickLabels{i},' %'];
            end
            cb.Label.String = {'Amount of best-ranked time segments';'YX / Imaginary part'};     
        end
        
        function conf = saverankingparams(app)
            % Outlier paramters
            if isequal(app.RemoveOutliersMenu.Checked,'on')
                conf.outlier = true;
                if isequal(app.meanMenu.Checked,'on')
                    conf.outmethod = 'mean';
                elseif isequal(app.medianMenu.Checked,'on')
                    conf.outmethod = 'median';
                elseif isequal(app.grubbsMenu.Checked,'on')
                    conf.outmethod = 'grubbs';
                elseif isequal(app.gesdMenu.Checked,'on')
                    conf.outmethod = 'gesd';
                end
            else
                conf.outlier = false;
                conf.outmethod = 'N/A';
            end
            % Ranking percentage
            conf.rnk = app.RankingEditField.Value;
            % Ranking parameters (Impedance)
            conf.rZ = app.ZpowButton.Value;
            conf.rZpow = app.Zpow.Value;
            conf.EI0Z = app.EI0ButtonZ.Value;
            conf.EI0Zpow = app.EI0powZ.Value;
            conf.EI1Z = app.EI1ButtonZ.Value;
            conf.EI1Zpow = app.EI1powZ.Value;
            conf.EI2Z = app.EI2ButtonZ.Value;
            conf.EI2Zpow = app.EI2powZ.Value;
            conf.EI3Z = app.EI3ButtonZ.Value;
            conf.EI3Zpow = app.EI3powZ.Value;
            conf.EI4Z = app.EI4ButtonZ.Value;
            conf.EI4Zpow = app.EI4powZ.Value;
            conf.criterionZ = app.RNKCriterionZEditField.Text;
            % Ranking parameters (Tipper)
            conf.rT = app.TpowButton.Value;
            conf.rTpow = app.Tpow.Value;
            conf.EI0T = app.EI0ButtonT.Value;
            conf.EI0Tpow = app.EI0powT.Value;
            conf.EI1T = app.EI1ButtonT.Value;
            conf.EI1Tpow = app.EI1powT.Value;
            conf.EI2T = app.EI2ButtonT.Value;
            conf.EI2Tpow = app.EI2powT.Value;
            conf.EI3T = app.EI3ButtonT.Value;
            conf.EI3Tpow = app.EI3powT.Value;
            conf.EI4T = app.EI4ButtonT.Value;
            conf.EI4Tpow = app.EI4powT.Value;
            conf.criterionT = app.RNKCriterionTEditField.Text;
        end
              
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.font = 'Verdana';
            
            app.weight.name = 'ev';
            app.weight.w0 = [];
            app.weight.perc = 0;
            app.weight.pow = 1;  

            app.fitcolor = [0 0.4470 0.7410];
            app.sldcolor = [0.6350 0.0780 0.1840];

            check_buttons(app)
        end

        % Menu selected function: LoadRNKFileMenu
        function LoadFileCallback(app, event)
            % When loading the input .rnk files, the output is an structure
            % variable called 'RNK' which contain: Zxx, Zxy, Zyx, Zyy, txz,
            % tyz, Zxx_pcorr, Zxy_pcorr, Zyx_pcorr, Zyy_pcorr, txz_pcorr, 
            % tyz_pcorr, EI0, EI1, EI2, EI3, EI4, EI for each time window.
            % 'doc' structure is another output, which contains details
            % like: file, path, nsite, name, id, job, sr, tfreq, time and
            % RNK

            keepload = true;
            n = 1;
            doc = struct;

            while keepload 

                [doc(n).file,doc(n).path] = uigetfile({'*RNK100%.mat','Ranking Files RNK'},'Select RNK file');
                if doc(n).file ~= 0
                    cd(doc(n).path)
                    figure(app.UIFigure)
                    % Loading only job information
                    load(fullfile(doc(n).path,doc(n).file),'job')
                    doc(n).nsite = numel(job.name);
                    if doc(n).nsite > 1
                        namelist = {string(cat(1,job.name))};
                        id = uiconfirm(app.UIFigure,'Select station to be loaded:','FFproc - Ranking Analysis','Options',namelist{:},'DefaultOption',1);
                        sel = find(ismember(namelist{:},id));
                        doc(n).name = job.name(sel);                    
                        doc(n).id = (sel);
                    else
                        doc(n).name = job.name;
                        doc(n).id = 1;
                    end
                else
                    doc(n) = [];
                    n = n - 1;
                end
                % Asking user to read another run
                answer = uiconfirm(app.UIFigure,'Do you want to load another RNK file?','FFproc - Ranking Analysis','DefaultOption',1);
                switch answer
                    case 'Cancel'
                        keepload = false;
                    otherwise                        
                        n = n + 1;
                end
            end
            figure(app.UIFigure)

            % Load data has finished. Continue retrieving data from RNK
            % structure and calculating EV Indices            
            for n = 1:numel(doc)
                
                dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis','Message',{'Loading RNK file: '; doc(n).file},'Indeterminate','on');
                    % Open RNK file
                    load(fullfile(doc(n).path,doc(n).file))
                close(dlg)

                % Site name of selected station
                job.name = job.name(doc(n).id);
                % Responses of selected station
                job.ffts = job.ffts(doc(n).id);
                % job structure of selected station
                doc(n).job = job;
                % Sampling rate of selected station
                doc(n).sr = job.sr;
                % Calculated target frequencies
                doc(n).tfreq = target_freq;
                % Time vector associated to time segment vector
                doc(n).time = time;

                % Sorting target frequencies
                [~,id] = sort(cat(1,doc.tfreq),'descend');
                app.tfreq = cat(1,doc.tfreq);
                app.tfreq = app.tfreq(id);

                 % Target frequencies labels
                for f = 1:numel(app.tfreq)
                    if app.tfreq(f) < 1
                        app.tfreq_label{f} = [num2str(round(1./app.tfreq(f),2)),' (s)'];
                    else
                        app.tfreq_label{f} = [num2str(round(app.tfreq(f),2)),' (Hz)'];
                    end
                end            
                app.ListBox.Items = app.tfreq_label;   

                % Updating RNK structure
                dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis');
                for f = 1:numel(RNK)

                    dlg.Message = {'Retrieving Transfer Functions and Rankings for';...
                                  ['target frequency ',num2str(f),' of ',num2str(numel(app.tfreq)),': ',app.tfreq_label{f}]};
                    
                    for w = 1:numel(RNK{f})
                        % Transfer Functions
                        RNK{f}(w).Zxx = RNK{f}(w).Zxx(doc(n).id);
                        RNK{f}(w).Zxy = RNK{f}(w).Zxy(doc(n).id);
                        RNK{f}(w).Zyx = RNK{f}(w).Zyx(doc(n).id);
                        RNK{f}(w).Zyy = RNK{f}(w).Zyy(doc(n).id);
                        RNK{f}(w).txz = RNK{f}(w).txz(doc(n).id);
                        RNK{f}(w).tyz = RNK{f}(w).tyz(doc(n).id);
                        % Partial Coherences
                        RNK{f}(w).Zxx_pcorr = RNK{f}(w).Zxx_pcorr(doc(n).id);
                        RNK{f}(w).Zxy_pcorr = RNK{f}(w).Zxy_pcorr(doc(n).id);
                        RNK{f}(w).Zyx_pcorr = RNK{f}(w).Zyx_pcorr(doc(n).id);
                        RNK{f}(w).Zyy_pcorr = RNK{f}(w).Zyy_pcorr(doc(n).id);
                        RNK{f}(w).txz_pcorr = RNK{f}(w).txz_pcorr(doc(n).id);
                        RNK{f}(w).tyz_pcorr = RNK{f}(w).tyz_pcorr(doc(n).id);
                        % Eigenvalues
                        RNK{f}(w).EVal = RNK{f}(w).EVal;
                    end               
                    dlg.Value = f/numel(RNK);
                end
                
                doc(n).RNK = RNK;   
                close(dlg)
            end            

            % Saving RNK, time and doc variables as app
            app.RNK = [doc.RNK];            app.RNK = app.RNK(id);            
            app.time = [doc.time];          app.time = app.time(id);
            app.doc = doc;

            % Extract transfer functions (TF structure is created)
            extract_tf(app)
         
            % Updating transfer functions (TF structure is updated)
            update_tf(app)

            % Calculate weights (SRT structure is created)
            calculate_SRT(app)
        end

        % Menu selected function: ResetWindowMenu
        function ResetWindowCallback(app, event)
            % Reset app variables
            app.job = [];
            app.name = [];
            app.RNK = [];
            app.tfreq = [];
            app.tfreq_select = [];
            app.tfreq_label = [];
            app.time = [];
            app.doc = [];
            app.weight = struct;        
            app.TF = [];
            app.SRT = [];
            app.RES = [];
            app.font = [];
            app.mts = [];
            app.mtf = [];

            % Deleting everything on the screen
            app.ListBox.Value = {};
            app.ListBox.Items = {};
            app.RankingEditField.Value = 100;
            
            % Ranking parameters (Impedance)
            app.ZpowButton.Value = false;
            app.Zpow.Value = 0;
            app.EI0ButtonZ.Value = false;
            app.EI0powZ.Value = 0;
            app.EI1ButtonZ.Value = false;
            app.EI1powZ.Value = 0;
            app.EI2ButtonZ.Value = false;
            app.EI2powZ.Value = 0;
            app.EI3ButtonZ.Value = false;
            app.EI3powZ.Value = 0;
            app.EI4ButtonZ.Value = false;
            app.EI4powZ.Value = 0;
            app.RNKCriterionZEditField.Text = '';

            % Ranking parameters (Tipper)
            app.TpowButton.Value = false;
            app.Tpow.Value = 0;
            app.EI0ButtonT.Value = false;
            app.EI0powT.Value = 0;
            app.EI1ButtonT.Value = false;
            app.EI1powT.Value = 0;
            app.EI2ButtonT.Value = false;
            app.EI2powT.Value = 0;
            app.EI3ButtonT.Value = false;
            app.EI3powT.Value = 0;
            app.EI4ButtonT.Value = false;
            app.EI4powT.Value = 0;
            app.RNKCriterionTEditField.Text = '';

            delete(findobj(app.Panel.Children,'type','axes'))
            delete(findobj(app.Panel.Children,'type','sgtitle'))

            startupFcn(app)
        end

        % Menu selected function: RemoveOutliersMenu
        function RemoveOutliersCallback(app, event)
            isChecked = app.RemoveOutliersMenu.Checked;
            if isequal(isChecked,'on')
                app.RemoveOutliersMenu.Checked = 'off';
            elseif isequal(isChecked,'off')
                app.RemoveOutliersMenu.Checked = 'on';
            end
            pause(0.2)
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis: Remove Outliers','Message','Updating estimations','Indeterminate','on');
                update_tf(app)
            close(dlg)
            SelectPeriodsCallback(app) 
        end

        % Menu selected function: mahaMenu
        function mahaMenuSelected(app, event)
            app.mahaMenu.Checked = 'on';
            app.meanMenu.Checked = 'off';
            app.medianMenu.Checked = 'off';
            app.grubbsMenu.Checked = 'off';
            app.gesdMenu.Checked = 'off';

            pause(0.2)
            SelectPeriodsCallback(app)
        end

        % Menu selected function: meanMenu
        function meanMenuSelected(app, event)
            app.mahaMenu.Checked = 'off';
            app.meanMenu.Checked = 'on';
            app.medianMenu.Checked = 'off';
            app.grubbsMenu.Checked = 'off';
            app.gesdMenu.Checked = 'off';

            pause(0.2)
            SelectPeriodsCallback(app)
        end

        % Menu selected function: medianMenu
        function medianMenuSelected(app, event)
            app.mahaMenu.Checked = 'off';
            app.meanMenu.Checked = 'off';
            app.medianMenu.Checked = 'on';
            app.grubbsMenu.Checked = 'off';
            app.gesdMenu.Checked = 'off';

            pause(0.2)
            SelectPeriodsCallback(app)
        end

        % Menu selected function: grubbsMenu
        function grubbsMenuSelected(app, event)
            app.mahaMenu.Checked = 'off';
            app.meanMenu.Checked = 'off';
            app.medianMenu.Checked = 'off';
            app.grubbsMenu.Checked = 'on';
            app.gesdMenu.Checked = 'off';

            pause(0.2)
            SelectPeriodsCallback(app)
        end

        % Menu selected function: gesdMenu
        function gesdMenuSelected(app, event)
            app.mahaMenu.Checked = 'off';
            app.meanMenu.Checked = 'off';
            app.medianMenu.Checked = 'off';
            app.grubbsMenu.Checked = 'off';
            app.gesdMenu.Checked = 'on';

            pause(0.2)
            SelectPeriodsCallback(app)
        end

        % Callback function: CalculateresponsesMenu, calculateresponses
        function CalculateResponsesCallback(app, event)
            calculate_RES(app)
        end

        % Menu selected function: PlotresponsesMenu
        function PlotResponsesCallback(app, event)
            plot_RES(app)       
        end

        % Menu selected function: CalculateweightsMenu
        function CalculateWeightsCallback(app, event)
            switch app.weight.name
                case 'ev'
                    prompt = {'Cut-off Percentage (%):'};
                    dlgtitle = 'FFproc - Ranking Analysis';
                    dims = [1 85];
                    definput = {'0'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.weight.w0 = [];
                    app.weight.perc = str2double(answer(1));
                    app.weight.pow = [];   
                case 'chi'
                    prompt = {'Initial Weight Level','Cut-off Percentage (%):'};
                    dlgtitle = 'FFproc - Ranking Analysis';
                    dims = [1 85];
                    definput = {'0.5','2.5'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.weight.w0 = str2double(answer(1));
                    app.weight.perc = str2double(answer(2));
                    app.weight.pow = [];
                case {'cos','ham'}
                    prompt = {'Power:'};
                    dlgtitle = 'FFproc - Ranking Analysis';
                    dims = [1 85];
                    definput = {'1'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    app.weight.w0 = [];
                    app.weight.perc = [];
                    app.weight.pow = str2double(answer(1));                    
                otherwise
                    app.weight.w0 = [];
                    app.weight.perc = [];
                    app.weight.pow = [];
            end
            
            SelectPeriodsCallback(app)
        end

        % Menu selected function: EigenvaluesMenu
        function EigenvaluesMenuSelected(app, event)
            app.EigenvaluesMenu.Checked = 'on';
            if isequal(app.EigenvaluesMenu.Checked,'on')
                app.FlatMenu.Checked = 'off';
                app.RampMenu.Checked = 'off';
                app.CosineMenu.Checked = 'off';
                app.HammingMenu.Checked = 'off';
                app.ChiMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: FlatMenu
        function FlatMenuSelected(app, event)
            app.FlatMenu.Checked = 'on';
            if isequal(app.FlatMenu.Checked,'on')
                app.EigenvaluesMenu.Checked = 'off';
                app.RampMenu.Checked = 'off';
                app.CosineMenu.Checked = 'off';
                app.HammingMenu.Checked = 'off';
                app.ChiMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: RampMenu
        function RampMenuSelected(app, event)
            app.RampMenu.Checked = 'on';
            if isequal(app.RampMenu.Checked,'on')
                app.EigenvaluesMenu.Checked = 'off';
                app.FlatMenu.Checked = 'off';
                app.CosineMenu.Checked = 'off';
                app.HammingMenu.Checked = 'off';
                app.ChiMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: CosineMenu
        function CosineMenuSelected(app, event)
            app.CosineMenu.Checked = 'on';
            if isequal(app.CosineMenu.Checked,'on')
                app.EigenvaluesMenu.Checked = 'off';
                app.RampMenu.Checked = 'off';
                app.FlatMenu.Checked = 'off';
                app.HammingMenu.Checked = 'off';
                app.ChiMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: HammingMenu
        function HammingMenuSelected(app, event)
            app.HammingMenu.Checked = 'on';
            if isequal(app.HammingMenu.Checked,'on')
                app.EigenvaluesMenu.Checked = 'off';
                app.RampMenu.Checked = 'off';
                app.CosineMenu.Checked = 'off';
                app.ChiMenu.Checked = 'off';
                app.FlatMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: ChiMenu
        function ChiMenuSelected(app, event)
            app.ChiMenu.Checked = 'on';
            if isequal(app.ChiMenu.Checked,'on')
                app.EigenvaluesMenu.Checked = 'off';
                app.RampMenu.Checked = 'off';
                app.CosineMenu.Checked = 'off';
                app.HammingMenu.Checked = 'off';
                app.FlatMenu.Checked = 'off';
            end

            check_weight(app);
            SelectPeriodsCallback(app)
        end

        % Menu selected function: ShrinkWeightsMenu
        function ShrinkWeightsMenuSelected(app, event)
            if isequal(app.ShrinkWeightsMenu.Checked,'on')
                app.ShrinkWeightsMenu.Checked = 'off';
            else
                app.ShrinkWeightsMenu.Checked = 'on';
            end
        end

        % Menu selected function: SLDMenu
        function SLDMenuSelected(app, event)
            if isequal(app.SLDMenu.Checked,'on')
                app.SLDMenu.Checked = 'off';
            else
                app.SLDMenu.Checked = 'on';
            end
        end

        % Menu selected function: FITMenu
        function FITMenuSelected(app, event)
            if isequal(app.FITMenu.Checked,'on')
                app.FITMenu.Checked = 'off';
            else
                app.FITMenu.Checked = 'on';
            end
        end

        % Menu selected function: viridisMenu
        function viridisMenuSelected(app, event)
            app.viridisMenu.Checked = 'on';
            app.infernoMenu.Checked = 'off';
            app.boneMenu.Checked = 'off';
                    
            pause(0.2)
            select_tf(app)
        end

        % Menu selected function: infernoMenu
        function infernoMenuSelected(app, event)
            app.viridisMenu.Checked = 'off';
            app.infernoMenu.Checked = 'on';
            app.boneMenu.Checked = 'off';

            pause(0.2)
            select_tf(app)
        end

        % Menu selected function: boneMenu
        function boneMenuSelected(app, event)
            app.viridisMenu.Checked = 'off';
            app.infernoMenu.Checked = 'off';
            app.boneMenu.Checked = 'on';

            pause(0.2)
            select_tf(app)
        end

        % Callback function: tfw
        function tfwClicked(app, event)
            if app.tfw.State
                app.histow.State = 'off';
                app.curvew.State = 'off';

                % Turn off Buttons
                app.rhoxxphixxButton.Enable = 'off';
                app.rhoxyphixyButton.Enable = 'off';
                app.rhoyxphiyxButton.Enable = 'off';
                app.rhoyyphiyyButton.Enable = 'off';
                switch app.ButtonGroup.SelectedObject.Text
                    case {'rhoxx/phixx','rhoxy/phixy','rhoyx/phiyx','rhoyy/phiyy'}
                        app.ButtonGroup.SelectedObject = app.ButtonGroup.Buttons(1);
                end
            else
                % Turn on Buttons
                app.rhoxxphixxButton.Enable = 'on';
                app.rhoxyphixyButton.Enable = 'on';
                app.rhoyxphiyxButton.Enable = 'on';
                app.rhoyyphiyyButton.Enable = 'on';
            end

            pause(0.2)
            select_tf(app)
        end

        % Callback function: curvew
        function curvewClicked(app, event)
            if app.curvew.State
                app.tfw.State = 'off';
                app.histow.State = 'off';

                % Turn on Buttons
                app.rhoxxphixxButton.Enable = 'on';
                app.rhoxyphixyButton.Enable = 'on';
                app.rhoyxphiyxButton.Enable = 'on';
                app.rhoyyphiyyButton.Enable = 'on';
            end

            pause(0.2)
            select_tf(app)
        end

        % Callback function: histow
        function histowClicked(app, event)
            if app.histow.State
                app.tfw.State = 'off';
                app.curvew.State = 'off';

                % Turn off Buttons
                app.rhoxxphixxButton.Enable = 'off';
                app.rhoxyphixyButton.Enable = 'off';
                app.rhoyxphiyxButton.Enable = 'off';
                app.rhoyyphiyyButton.Enable = 'off';
                switch app.ButtonGroup.SelectedObject.Text
                    case {'rhoxx/phixx','rhoxy/phixy','rhoyx/phiyx','rhoyy/phiyy'}
                        app.ButtonGroup.SelectedObject = app.ButtonGroup.Buttons(1);
                end
            else
                % Turn on Buttons
                app.rhoxxphixxButton.Enable = 'on';
                app.rhoxyphixyButton.Enable = 'on';
                app.rhoyxphiyxButton.Enable = 'on';
                app.rhoyyphiyyButton.Enable = 'on';
            end

            pause(0.2)
            select_tf(app)
        end

        % Callback function: exportpng
        function toFigureCallback(app, event)
            f = figure('Name','Captured Snapshots - FFproc: RNK','WindowStyle','docked');  
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - RNK','Message','Capturing Snapshots','Indeterminate','on');
                copyobj(app.Panel.Children,f)
            close(dlg)     
        end

        % Callback function: exportedi
        function toEDICallback(app, event)
            path = uigetdir(cd,'Select EDI output folder');
            if path == 0
                figure(app.UIFigure)
                return
            else
                figure(app.UIFigure)
                ffmt2EDI(app,'none',app.mtf,path,'-FIT')  
                ffmt2EDI(app,'none',app.mts,path,'-SLD')  
            end
            try
                winopen(path)
            catch
            end
        end

        % Callback function: exportmtdim
        function toMTDIMCallback(app, event)
            mt = [app.mtf,app.mts];
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis: Exporting to MTDIM',...
                                             'Message',{'Calculating Phase Tensor and Errors...';'(Caldwell et al, 2004; Bibby et al., 2005; Booker, 2012)';...
                                             ' ';'Calculating CART, RT, RPT and Errors...';'(Hering et al., 2019)'});
                % Removing previous CART values
                mt = reset_CART(mt);
                for i = 1:numel(mt)
                    mt(i).source = {'data'};
                    mt(i) = CART(mt(i));                        
                    if numel(mt)>1
                        dlg.Value = i/numel(mt);
                    end
                end
            close(dlg)

            outname = mt(1).site{:};

            [outname,path] = uiputfile([upper(strtok(outname,'_')),'.mat'],'FFproc2MTDIM - Save MT-site variable in *.mat file');
            mt(1).site = [outname,'-FIT'];
            mt(2).site = [outname,'-SLD'];    
            
            conf = saverankingparams(app);

            cd(path)                
            save(outname,'mt','conf');                
            figure(app.UIFigure)
            answer = uiconfirm(app.UIFigure,{['File: ',outname,' has been saved in:'];path},...
                                    'FFproc - Ranking Analysis: Exporting to MTDIM',...
                                    'Icon','success',...
                                    'Options',{'View File','Close'},...
                                    'DefaultOption',2);
            switch answer
                case 'View File'
                    try
                        winopen(path)
                    catch
                        return
                    end
                otherwise
                    return
            end
        end

        % Callback function: exportpdf
        function toPDFCallback(app, event)
            try
                PlotResponsesCallback(app)
    
                % To save a snapshot as PDF
                f = gcf;
    
                try
                    site = app.mts.site{:};
                catch
                    site = app.mtf.site{:};
                end         
                 
                sgtitle(f,site,'FontName','Verdana','FontSize',20)
    
                % Saving PDF File 
                pdfname = [site,'.pdf'];
                [pdfname,pdfpath] = uiputfile('*.pdf','Save PDF File',pdfname);
                figure(app.UIFigure)
                dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Ranking Analysis: Exporting to PDF','Message','Saving PDF file...','Indeterminate','on');
                    exportgraphics(f,fullfile(pdfpath,pdfname),'ContentType','vector')               
                close(dlg)
                answer = uiconfirm(app.UIFigure,{['File: ',pdfname,' has been saved in:'];pdfpath},...
                                        'FFproc - Ranking Analysis: Exporting to PDF',...
                                        'Icon','success',...
                                        'Options',{'View File','Close'},...
                                        'DefaultOption',2);
                switch answer
                    case 'View File'
                        try
                            winopen(pdfpath)
                        catch
                            return
                        end
                    otherwise
                        return
                end
            catch
                if isempty(app.mts) || isempty(app.mtf)
                    uialert(app.UIFigure,'No frequencies have been selected','FFproc - Ranking Analysis: Export to PDF','Icon','warning')
                else
                    uialert(app.UIFigure,'Error - Try to recalculate responses','FFproc - Ranking Analysis: Export to PDF','Icon','error')
                end                
            end
        end

        % Value changed function: EI0ButtonT, EI0ButtonZ, EI1ButtonT, 
        % ...and 9 other components
        function CriterionButtonCallback(app, event)
            try
                check_buttons(app)
                check_rnk(app)
                SelectPeriodsCallback(app)      
            catch
            end
        end

        % Value changed function: EI0powT, EI0powZ, EI1powT, EI1powZ, 
        % ...and 8 other components
        function CriterionValueCallback(app, event)
            try
                check_rnk(app)
                SelectPeriodsCallback(app)  
            catch
            end
        end

        % Value changed function: RankingEditField
        function RankingChangedCallback(app, event)
            SelectPeriodsCallback(app)
            CalculateResponsesCallback(app)         
        end

        % Selection changed function: ButtonGroup
        function SelectTFCallback(app, event)
            switch app.ButtonGroup.SelectedObject
                case {'Zxx','Zxy','Zyx','Zyy','txz','tyz'}
                    app.rhoxxphixxButton.Enable = 'on';
                otherwise
            end
            % Select Transfer Function to be plotted
            select_tf(app)
        end

        % Value changed function: ListBox
        function SelectPeriodsCallback(app, event)
            % Restart results structure
            app.RES = [];
            % Find selected target frequencies
            app.tfreq_select = find(ismember(app.ListBox.Items,app.ListBox.Value));
            % Update selected transfer frequencies 
            update_tf(app)
            % Calculate rankings
            calculate_SRT(app)
            % Select Transfer Function to be plotted
            select_tf(app)
        end

        % Value changed function: cmaxEditField, cminEditField
        function ColorChangedCallback(app, event)
            SelectPeriodsCallback(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Ranking Criterion Analysis';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadRNKFileMenu = uimenu(app.FileMenu);
            app.LoadRNKFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileCallback, true);
            app.LoadRNKFileMenu.Text = 'Load RNK File';
            app.ResetWindowMenu = uimenu(app.FileMenu);
            app.ResetWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetWindowCallback, true);
            app.ResetWindowMenu.Separator = 'on';
            app.ResetWindowMenu.Text = 'Reset Window';
            app.OutliersMenu = uimenu(app.UIFigure);
            app.OutliersMenu.Text = 'Outliers';
            app.RemoveOutliersMenu = uimenu(app.OutliersMenu);
            app.RemoveOutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveOutliersCallback, true);
            app.RemoveOutliersMenu.Text = 'Remove Outliers';
            app.MethodMenu = uimenu(app.OutliersMenu);
            app.MethodMenu.Separator = 'on';
            app.MethodMenu.Text = 'Method';
            app.mahaMenu = uimenu(app.MethodMenu);
            app.mahaMenu.MenuSelectedFcn = createCallbackFcn(app, @mahaMenuSelected, true);
            app.mahaMenu.Text = 'maha';
            app.meanMenu = uimenu(app.MethodMenu);
            app.meanMenu.MenuSelectedFcn = createCallbackFcn(app, @meanMenuSelected, true);
            app.meanMenu.Text = 'mean';
            app.medianMenu = uimenu(app.MethodMenu);
            app.medianMenu.MenuSelectedFcn = createCallbackFcn(app, @medianMenuSelected, true);
            app.medianMenu.Checked = 'on';
            app.medianMenu.Text = 'median';
            app.grubbsMenu = uimenu(app.MethodMenu);
            app.grubbsMenu.MenuSelectedFcn = createCallbackFcn(app, @grubbsMenuSelected, true);
            app.grubbsMenu.Text = 'grubbs';
            app.gesdMenu = uimenu(app.MethodMenu);
            app.gesdMenu.MenuSelectedFcn = createCallbackFcn(app, @gesdMenuSelected, true);
            app.gesdMenu.Text = 'gesd';
            app.ExecuteMenu = uimenu(app.UIFigure);
            app.ExecuteMenu.Text = 'Execute';
            app.CalculateresponsesMenu = uimenu(app.ExecuteMenu);
            app.CalculateresponsesMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateResponsesCallback, true);
            app.CalculateresponsesMenu.Text = 'Calculate responses';
            app.PlotresponsesMenu = uimenu(app.ExecuteMenu);
            app.PlotresponsesMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotResponsesCallback, true);
            app.PlotresponsesMenu.Text = 'Plot responses';
            app.WeightsMenu = uimenu(app.ExecuteMenu);
            app.WeightsMenu.Separator = 'on';
            app.WeightsMenu.Text = 'Weights';
            app.CalculateweightsMenu = uimenu(app.WeightsMenu);
            app.CalculateweightsMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateWeightsCallback, true);
            app.CalculateweightsMenu.Separator = 'on';
            app.CalculateweightsMenu.Text = 'Calculate weights';
            app.EigenvaluesMenu = uimenu(app.WeightsMenu);
            app.EigenvaluesMenu.MenuSelectedFcn = createCallbackFcn(app, @EigenvaluesMenuSelected, true);
            app.EigenvaluesMenu.Separator = 'on';
            app.EigenvaluesMenu.Checked = 'on';
            app.EigenvaluesMenu.Text = 'Eigenvalues';
            app.FlatMenu = uimenu(app.WeightsMenu);
            app.FlatMenu.MenuSelectedFcn = createCallbackFcn(app, @FlatMenuSelected, true);
            app.FlatMenu.Text = 'Flat';
            app.RampMenu = uimenu(app.WeightsMenu);
            app.RampMenu.MenuSelectedFcn = createCallbackFcn(app, @RampMenuSelected, true);
            app.RampMenu.Text = 'Ramp';
            app.CosineMenu = uimenu(app.WeightsMenu);
            app.CosineMenu.MenuSelectedFcn = createCallbackFcn(app, @CosineMenuSelected, true);
            app.CosineMenu.Text = 'Cosine';
            app.HammingMenu = uimenu(app.WeightsMenu);
            app.HammingMenu.MenuSelectedFcn = createCallbackFcn(app, @HammingMenuSelected, true);
            app.HammingMenu.Text = 'Hamming';
            app.ChiMenu = uimenu(app.WeightsMenu);
            app.ChiMenu.MenuSelectedFcn = createCallbackFcn(app, @ChiMenuSelected, true);
            app.ChiMenu.Text = 'Chi';
            app.ShrinkWeightsMenu = uimenu(app.WeightsMenu);
            app.ShrinkWeightsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShrinkWeightsMenuSelected, true);
            app.ShrinkWeightsMenu.Separator = 'on';
            app.ShrinkWeightsMenu.Text = 'Shrink Weights';
            app.EstimationMenu = uimenu(app.ExecuteMenu);
            app.EstimationMenu.Text = 'Estimation';
            app.SLDMenu = uimenu(app.EstimationMenu);
            app.SLDMenu.MenuSelectedFcn = createCallbackFcn(app, @SLDMenuSelected, true);
            app.SLDMenu.Checked = 'on';
            app.SLDMenu.Text = 'SLD';
            app.FITMenu = uimenu(app.EstimationMenu);
            app.FITMenu.MenuSelectedFcn = createCallbackFcn(app, @FITMenuSelected, true);
            app.FITMenu.Checked = 'on';
            app.FITMenu.Text = 'Polyfit';
            app.ExtrasMenu = uimenu(app.UIFigure);
            app.ExtrasMenu.Text = 'Extras';
            app.ColormapMenu = uimenu(app.ExtrasMenu);
            app.ColormapMenu.Text = 'Colormap';
            app.viridisMenu = uimenu(app.ColormapMenu);
            app.viridisMenu.MenuSelectedFcn = createCallbackFcn(app, @viridisMenuSelected, true);
            app.viridisMenu.Checked = 'on';
            app.viridisMenu.Text = 'viridis';
            app.infernoMenu = uimenu(app.ColormapMenu);
            app.infernoMenu.MenuSelectedFcn = createCallbackFcn(app, @infernoMenuSelected, true);
            app.infernoMenu.Text = 'inferno';
            app.boneMenu = uimenu(app.ColormapMenu);
            app.boneMenu.MenuSelectedFcn = createCallbackFcn(app, @boneMenuSelected, true);
            app.boneMenu.Text = 'bone';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.tfw = uitoggletool(app.Toolbar);
            app.tfw.Tooltip = {'Scatter View'};
            app.tfw.ClickedCallback = createCallbackFcn(app, @tfwClicked, true);
            app.tfw.Icon = 'FFproc_scatter.png';
            app.curvew = uitoggletool(app.Toolbar);
            app.curvew.Tooltip = {'Curve View'};
            app.curvew.ClickedCallback = createCallbackFcn(app, @curvewClicked, true);
            app.curvew.Icon = 'FFproc_polyfit.png';
            app.curvew.State = 'on';
            app.histow = uitoggletool(app.Toolbar);
            app.histow.Tooltip = {'Weight View'};
            app.histow.ClickedCallback = createCallbackFcn(app, @histowClicked, true);
            app.histow.Icon = 'FFproc_starthisto.png';
            app.calculateresponses = uipushtool(app.Toolbar);
            app.calculateresponses.Tooltip = {'Calculate Responses'};
            app.calculateresponses.ClickedCallback = createCallbackFcn(app, @CalculateResponsesCallback, true);
            app.calculateresponses.Icon = 'FFproc_calculate.png';
            app.calculateresponses.Separator = 'on';
            app.exportpng = uipushtool(app.Toolbar);
            app.exportpng.Tooltip = {'Capture Snapshot'};
            app.exportpng.ClickedCallback = createCallbackFcn(app, @toFigureCallback, true);
            app.exportpng.Icon = 'FFproc_export.png';
            app.exportpng.Separator = 'on';
            app.exportedi = uipushtool(app.Toolbar);
            app.exportedi.Tooltip = {'Export to EDI'};
            app.exportedi.ClickedCallback = createCallbackFcn(app, @toEDICallback, true);
            app.exportedi.Icon = 'FFproc_edi.png';
            app.exportmtdim = uipushtool(app.Toolbar);
            app.exportmtdim.Tooltip = {'Export to MTDIM'};
            app.exportmtdim.ClickedCallback = createCallbackFcn(app, @toMTDIMCallback, true);
            app.exportmtdim.Icon = 'FFproc_mtdim.png';
            app.exportpdf = uipushtool(app.Toolbar);
            app.exportpdf.Tooltip = {'Export to PDF'};
            app.exportpdf.ClickedCallback = createCallbackFcn(app, @toPDFCallback, true);
            app.exportpdf.Icon = 'FFproc_pdf.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EI0ButtonZ = uibutton(app.GridLayout2, 'state');
            app.EI0ButtonZ.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI0ButtonZ.Tooltip = {'|λ2|/|λ1|'};
            app.EI0ButtonZ.Text = 'EI0';
            app.EI1ButtonZ = uibutton(app.GridLayout2, 'state');
            app.EI1ButtonZ.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI1ButtonZ.Tooltip = {'1 - |λ3|/|λ2|'};
            app.EI1ButtonZ.Text = 'EI1';
            app.EI2ButtonZ = uibutton(app.GridLayout2, 'state');
            app.EI2ButtonZ.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI2ButtonZ.Tooltip = {'1/|λ3|'};
            app.EI2ButtonZ.Text = 'EI2';
            app.EI3ButtonZ = uibutton(app.GridLayout2, 'state');
            app.EI3ButtonZ.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI3ButtonZ.Tooltip = {'|λ2|/|λ3| - |λ3|^2'};
            app.EI3ButtonZ.Text = 'EI3';
            app.EI4ButtonZ = uibutton(app.GridLayout2, 'state');
            app.EI4ButtonZ.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI4ButtonZ.Tooltip = {'|λ2|/|λ3| - |λ1|/|λ2|'};
            app.EI4ButtonZ.Text = 'EI4';
            app.ZpowButton = uibutton(app.GridLayout2, 'state');
            app.ZpowButton.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.ZpowButton.Text = 'Z pow';
            app.TpowButton = uibutton(app.GridLayout2, 'state');
            app.TpowButton.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.TpowButton.Text = 'Tpow';
            app.Zpow = uieditfield(app.GridLayout2, 'numeric');
            app.Zpow.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.Zpow.Value = 1;
            app.EI0ButtonT = uibutton(app.GridLayout2, 'state');
            app.EI0ButtonT.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI0ButtonT.Tooltip = {'|λ2|/|λ1|'};
            app.EI0ButtonT.Text = 'EI0';
            app.EI1ButtonT = uibutton(app.GridLayout2, 'state');
            app.EI1ButtonT.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI1ButtonT.Tooltip = {'1 - |λ3|/|λ2|'};
            app.EI1ButtonT.Text = 'EI1';
            app.EI2ButtonT = uibutton(app.GridLayout2, 'state');
            app.EI2ButtonT.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI2ButtonT.Tooltip = {'1/|λ3|'};
            app.EI2ButtonT.Text = 'EI2';
            app.EI3ButtonT = uibutton(app.GridLayout2, 'state');
            app.EI3ButtonT.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI3ButtonT.Tooltip = {'|λ2|/|λ3| - |λ3|^2'};
            app.EI3ButtonT.Text = 'EI3';
            app.EI4ButtonT = uibutton(app.GridLayout2, 'state');
            app.EI4ButtonT.ValueChangedFcn = createCallbackFcn(app, @CriterionButtonCallback, true);
            app.EI4ButtonT.Tooltip = {'|λ2|/|λ3| - |λ1|/|λ2|'};
            app.EI4ButtonT.Text = 'EI4';
            app.EI0powZ = uieditfield(app.GridLayout2, 'numeric');
            app.EI0powZ.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI0powZ.Value = 1;
            app.EI1powZ = uieditfield(app.GridLayout2, 'numeric');
            app.EI1powZ.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI1powZ.Value = 1;
            app.EI2powZ = uieditfield(app.GridLayout2, 'numeric');
            app.EI2powZ.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI2powZ.Value = 1;
            app.EI3powZ = uieditfield(app.GridLayout2, 'numeric');
            app.EI3powZ.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI3powZ.Value = 1;
            app.EI4powZ = uieditfield(app.GridLayout2, 'numeric');
            app.EI4powZ.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI4powZ.Value = 1;
            app.EI0powT = uieditfield(app.GridLayout2, 'numeric');
            app.EI0powT.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI0powT.Value = 1;
            app.EI1powT = uieditfield(app.GridLayout2, 'numeric');
            app.EI1powT.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI1powT.Value = 1;
            app.EI2powT = uieditfield(app.GridLayout2, 'numeric');
            app.EI2powT.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI2powT.Value = 1;
            app.EI3powT = uieditfield(app.GridLayout2, 'numeric');
            app.EI3powT.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI3powT.Value = 1;
            app.EI4powT = uieditfield(app.GridLayout2, 'numeric');
            app.EI4powT.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.EI4powT.Value = 1;
            app.Tpow = uieditfield(app.GridLayout2, 'numeric');
            app.Tpow.ValueChangedFcn = createCallbackFcn(app, @CriterionValueCallback, true);
            app.Tpow.Value = 1;
            app.ImpedanceLabel = uilabel(app.GridLayout2);
            app.ImpedanceLabel.Text = 'Impedance';
            app.TipperLabel = uilabel(app.GridLayout2);
            app.TipperLabel.Text = 'Tipper';
            app.GridLayout2_2 = uigridlayout(app.GridLayout);
            app.RankingEditFieldLabel = uilabel(app.GridLayout2_2);
            app.RankingEditFieldLabel.Text = 'Ranking (%)';
            app.RankingEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.RankingEditField.Limits = [1 100];
            app.RankingEditField.ValueChangedFcn = createCallbackFcn(app, @RankingChangedCallback, true);
            app.RankingEditField.Value = 100;
            app.RNKCriterionZLabel = uilabel(app.GridLayout2_2);
            app.RNKCriterionZLabel.Text = 'RNK Criterion (Z)';
            app.RNKCriterionZEditField = uilabel(app.GridLayout2_2);
            app.RNKCriterionZEditField.Text = '';
            app.RNKCriterionTLabel = uilabel(app.GridLayout2_2);
            app.RNKCriterionTLabel.Text = 'RNK Criterion (T)';
            app.RNKCriterionTEditField = uilabel(app.GridLayout2_2);
            app.RNKCriterionTEditField.Text = '';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTFCallback, true);
            app.ZxxButton = uiradiobutton(app.ButtonGroup);
            app.ZxxButton.Text = 'Zxx';
            app.ZxxButton.Value = true;
            app.ZxyButton = uiradiobutton(app.ButtonGroup);
            app.ZxyButton.Text = 'Zxy';
            app.ZyxButton = uiradiobutton(app.ButtonGroup);
            app.ZyxButton.Text = 'Zyx';
            app.ZyyButton = uiradiobutton(app.ButtonGroup);
            app.ZyyButton.Text = 'Zyy';
            app.txzButton = uiradiobutton(app.ButtonGroup);
            app.txzButton.Text = 'txz';
            app.tyzButton = uiradiobutton(app.ButtonGroup);
            app.tyzButton.Text = 'tyz';
            app.rhoxxphixxButton = uiradiobutton(app.ButtonGroup);
            app.rhoxxphixxButton.Text = 'rhoxx/phixx';
            app.rhoxyphixyButton = uiradiobutton(app.ButtonGroup);
            app.rhoxyphixyButton.Text = 'rhoxy/phixy';
            app.rhoyxphiyxButton = uiradiobutton(app.ButtonGroup);
            app.rhoyxphiyxButton.Text = 'rhoyx/phiyx';
            app.rhoyyphiyyButton = uiradiobutton(app.ButtonGroup);
            app.rhoyyphiyyButton.Text = 'rhoyy/phiyy';
            app.Panel = uipanel(app.GridLayout);
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @SelectPeriodsCallback, true);
            app.ListBox.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.cminLabel = uilabel(app.GridLayout3);
            app.cminLabel.Text = 'cmin:';
            app.cminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.cminEditField.ValueChangedFcn = createCallbackFcn(app, @ColorChangedCallback, true);
            app.cmaxLabel = uilabel(app.GridLayout3);
            app.cmaxLabel.Text = 'cmax:';
            app.cmaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.cmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ColorChangedCallback, true);
            app.cmaxEditField.Value = 1;
            app.perminLabel = uilabel(app.GridLayout3);
            app.perminLabel.Text = 'per min:';
            app.perminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.perminEditField.Value = -4;
            app.permaxLabel = uilabel(app.GridLayout3);
            app.permaxLabel.Text = 'per max:';
            app.permaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.permaxEditField.Value = 4;
            app.rhominLabel = uilabel(app.GridLayout3);
            app.rhominLabel.Text = 'rho min:';
            app.rhominEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rhomaxLabel = uilabel(app.GridLayout3);
            app.rhomaxLabel.Text = 'rho max:';
            app.rhomaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rhomaxEditField.Value = 4;
            app.phiminLabel = uilabel(app.GridLayout3);
            app.phiminLabel.Text = 'phi min:';
            app.phiminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.phiminEditField.Value = -5;
            app.phimaxLabel = uilabel(app.GridLayout3);
            app.phimaxLabel.Text = 'phi max:';
            app.phimaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.phimaxEditField.Value = 95;
            app.tipminLabel = uilabel(app.GridLayout3);
            app.tipminLabel.Text = 'tip min:';
            app.tipminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.tipminEditField.Value = -1;
            app.tipmaxLabel = uilabel(app.GridLayout3);
            app.tipmaxLabel.Text = 'tip max:';
            app.tipmaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.tipmaxEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_RNK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end