classdef FFproc_Results2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadFileMenu                 matlab.ui.container.Menu
        ExporttoMTDIMMenu            matlab.ui.container.Menu
        FFprocMenu                   matlab.ui.container.Menu
        NewJobMenu                   matlab.ui.container.Menu
        CombineResultsMenu           matlab.ui.container.Menu
        RAWResultsMenu               matlab.ui.container.Menu
        ExtrasMenu                   matlab.ui.container.Menu
        PhaseTensorPropertiesMenu_2  matlab.ui.container.Menu
        EigenvaluePartialCorrelationCoefficientsSpectrogramsMenu  matlab.ui.container.Menu
        EigenvaluePartialCorrelationCoefficientsCriterionMenu_2  matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        DocumentationMenu            matlab.ui.container.Menu
        Toolbar                      matlab.ui.container.Toolbar
        points                       matlab.ui.container.toolbar.ToggleTool
        area                         matlab.ui.container.toolbar.ToggleTool
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout14                 matlab.ui.container.GridLayout
        GridLayout12_2               matlab.ui.container.GridLayout
        ptmax                        matlab.ui.control.NumericEditField
        ptmaxLabel                   matlab.ui.control.Label
        ptmin                        matlab.ui.control.NumericEditField
        ptminLabel                   matlab.ui.control.Label
        phimax                       matlab.ui.control.NumericEditField
        phimaxLabel                  matlab.ui.control.Label
        phimin                       matlab.ui.control.NumericEditField
        phiminLabel                  matlab.ui.control.Label
        rhomax                       matlab.ui.control.NumericEditField
        rhomaxLabel                  matlab.ui.control.Label
        rhomin                       matlab.ui.control.NumericEditField
        rhominLabel                  matlab.ui.control.Label
        permax                       matlab.ui.control.NumericEditField
        permaxLabel                  matlab.ui.control.Label
        permin                       matlab.ui.control.NumericEditField
        perminLabel                  matlab.ui.control.Label
        GridLayout13                 matlab.ui.container.GridLayout
        popsite                      matlab.ui.control.DropDown
        SiteLabel                    matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        TransferFunctionsTab         matlab.ui.container.Tab
        TipperandPhaseTensorTab      matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        PhaseTensorPanel             matlab.ui.container.Panel
        TipperPanel                  matlab.ui.container.Panel
        ContextMenu                  matlab.ui.container.ContextMenu
        ExportMenu                   matlab.ui.container.Menu
        FiguresMenu                  matlab.ui.container.Menu
        ContextMenu2                 matlab.ui.container.ContextMenu
        ExportMenu_2                 matlab.ui.container.Menu
        FigureMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        FFsave, EV
        a
        name, ID, perc
        zxxax, zxyax, zyxax, zyyax
        rhoax, phiax, rhodax, phidax
        tipax, skewax, ptax
        rhoptax, phiptax, lamax, betaax, thetaax, tipmax
        ptprofax, tipprofax
        leg
        eglineax
        tabgroup, tab
        evbar1, evbar2, evbar3        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global font
            font = 'Verdana';
            app.FFsave = [];      
        end

        % Menu selected function: LoadFileMenu
        function LoadFileCallback(app, event)
            startupFcn(app)

            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            
            [file,path] = uigetfile({'*.mat','FFsave (*.mat)';
                                     '(*.*)','All Files (*.*)'},...
                                     'Select Process results to be combined');
            % If user press cancel or close button
            if file == 0
                figure(app.UIFigure); 
                return
            else
                % Loading results data
                load(fullfile(path,file));
                figure(app.UIFigure)
                app.FFsave = FFsave;
            end
                        
            % If RAW file is loaded
            if exist('RAW','var')
               uialert(app.UIFigure,{'RAW File format is not supported in Results Module';...
                                     'Please open RAW module and load your RAW results'},'FFproc - Results','Icon','Warning');
               return
            end
            
            % Site names
            app.a = 1;
            app.name = cell(1);
            app.ID = zeros(numel(app.FFsave),1);
            for i = 1:numel(app.FFsave)
                app.name(i) = (app.FFsave(i).site);
                app.FFsave(i).ID = i;
                app.ID(i) = app.FFsave(i).ID;
            end
            app.popsite.Items = app.name;
            app.popsite.Value = app.popsite.Items(1);          
            
            info = getinfo(app);
            
            % Impedances Tab
            td = tiledlayout(4,5,'Parent',app.TransferFunctionsTab,'TileSpacing','loose','Padding','compact');  
            plot_zxx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_rho(info,app.FFsave,app.a,td,[2,2],0)
                plot_rhod(info,app.FFsave,app.a,td,[2,2],0)                
            elseif app.area.State
                plot_rhoarea(info,app.FFsave,app.a,td,[2,2],0)   
                plot_rhodarea(info,app.FFsave,app.a,td,[2,2],0)
            end          
            plot_zxy(info,app.FFsave,app.a,td,[])      
            plot_zyx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_phi(info,app.FFsave,app.a,td,[2,2],0)
                plot_phid(info,app.FFsave,app.a,td,[2,2],0)
            elseif app.area.State
                plot_phiarea(info,app.FFsave,app.a,td,[2,2],0)
                plot_phidarea(info,app.FFsave,app.a,td,[2,2],0)
            end           
            plot_zyy(info,app.FFsave,app.a,td,[])            
            drawnow
            
            % Phase Tensor and Tipper Profile
            td = tiledlayout(1,numel(app.FFsave),'Parent',app.PhaseTensorPanel,'TileSpacing','compact','Padding','compact');
            for i = 1:numel(app.FFsave)
                plot_pt(info,app.FFsave,i,td,[],1)
            end       
            td = tiledlayout(1,numel(app.FFsave),'Parent',app.TipperPanel,'TileSpacing','compact','Padding','compact');
            for i = 1:numel(app.FFsave)
                plot_tip(info,app.FFsave,i,td,[],1)
            end
            
            % Phase Tensor Tab
            plot_PTprop(info,app.FFsave,app.a,app.PhaseTensorTab)
            drawnow   
            
           
        end

        % Value changed function: popsite
        function ChangeSiteCallback(app, event)
            app.a = find(strcmp(app.popsite.Value,app.name));           
            info = getinfo(app);            
            
            % Impedances Tab
            td = tiledlayout(4,5,'Parent',app.TransferFunctionsTab,'TileSpacing','loose','Padding','compact');  
            plot_zxx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_rho(info,app.FFsave,app.a,td,[2,2],0)
                plot_rhod(info,app.FFsave,app.a,td,[2,2],0)                
            elseif app.area.State
                plot_rhoarea(info,app.FFsave,app.a,td,[2,2],0)   
                plot_rhodarea(info,app.FFsave,app.a,td,[2,2],0)
            end          
            plot_zxy(info,app.FFsave,app.a,td,[])      
            plot_zyx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_phi(info,app.FFsave,app.a,td,[2,2],0)
                plot_phid(info,app.FFsave,app.a,td,[2,2],0)
            elseif app.area.State
                plot_phiarea(info,app.FFsave,app.a,td,[2,2],0)
                plot_phidarea(info,app.FFsave,app.a,td,[2,2],0)
            end           
            plot_zyy(info,app.FFsave,app.a,td,[])            
            drawnow

            % Phase Tensor Tab
            plot_PTprop(info,app.FFsave,app.a,app.PhaseTensorTab)
            drawnow
        end

        % Value changed function: permax, permin
        function PeriodValueCallback(app, event)
            % Impedances Tab
            ax = findall(app.TransferFunctionsTab.Children,'type','axes');
            set(ax,'XLim',10.^[app.permin.Value,app.permax.Value],'XTick',10.^(floor(app.permin.Value):ceil(app.permax.Value)))            
            % PT and Tipper Tab
            ax = findall(app.PhaseTensorPanel.Children,'type','axes');
            set(ax,'YLim',[app.permin.Value,app.permax.Value],'YTick',floor(app.permin.Value):ceil(app.permax.Value))
            ax = findall(app.TipperPanel.Children,'type','axes');
            set(ax,'YLim',[app.permin.Value,app.permax.Value],'YTick',floor(app.permin.Value):ceil(app.permax.Value))
        end

        % Value changed function: rhomax, rhomin
        function RhoValueCallback(app, event)
            ax = findall(app.TransferFunctionsTab.Children,'type','axes');
            set(ax(4),'YLim',10.^[app.rhomin.Value,app.rhomax.Value],'YTick',10.^(floor(app.rhomin.Value):ceil(app.rhomax.Value)))
            ax = findall(app.PhaseTensorTab.Children,'type','axes');    
            set(ax(6),'YLim',10.^[app.rhomin.Value,app.rhomax.Value],'YTick',10.^(floor(app.rhomin.Value):ceil(app.rhomax.Value)))
        end

        % Value changed function: phimax, phimin
        function PhaseValueCallback(app, event)
            ax = findall(app.TransferFunctionsTab.Children,'type','axes');
            set(ax(3),'YLim',[app.phimin.Value,app.phimax.Value])
            ax = findall(app.PhaseTensorTab.Children,'type','axes');   
            set(ax(5),'YLim',[app.phimin.Value,app.phimax.Value])
        end

        % Value changed function: ptmax, ptmin
        function PTValueCallback(app, event)
            ax = findall(app.PhaseTensorPanel.Children,'type','axes');
            caxis(ax,[app.ptmin.Value,app.ptmax.Value])
        end

        % Callback function
        function PlotHistogramsCallback(app, event)
            tfreq = extractfield(app.EV,'tfreq')';            
            ntseg = extractfield(app.EV,'ntseg')';
            targ = cell(length(tfreq),1);
            for i = 1:length(tfreq)
                if tfreq(i) > 1
                    targ{i,1} = [num2str(round(tfreq(i))),' [Hz]'];
                    targ{i,2} = num2str(ceil(app.perc*ntseg(i)/100));
                    targ{i,3} = num2str(ntseg(i));
                else
                    targ{i,1} = [num2str(round(1./tfreq(i))),' [s]'];
                    targ{i,2} = num2str(ceil(app.perc*ntseg(i)/100));
                    targ{i,3} = num2str(ntseg(i));
                end
            end
            app.UITable.Data = targ;
            pause(0.2)
            % EV histogram Tab
            if app.log.State
                scale = 'log';
            else
                scale = 'linear';
            end
            plot_egbars(app,app.EV,scale,app.perc)
        end

        % Callback function
        function DotsAreaCallback(app, event)
            info = getinfo(app); 
            % Impedances Tab
            td = tiledlayout(4,5,'Parent',app.TransferFunctionsTab,'TileSpacing','loose','Padding','compact');  
            plot_zxx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_rho(info,app.FFsave,app.a,td,[2,2],0)
                plot_rhod(info,app.FFsave,app.a,td,[2,2],0)                
            elseif app.area.State
                plot_rhoarea(info,app.FFsave,app.a,td,[2,2],0)   
                plot_rhodarea(info,app.FFsave,app.a,td,[2,2],0)
            end          
            plot_zxy(info,app.FFsave,app.a,td,[])      
            plot_zyx(info,app.FFsave,app.a,td,[])
            if app.points.State
                plot_phi(info,app.FFsave,app.a,td,[2,2],0)
                plot_phid(info,app.FFsave,app.a,td,[2,2],0)
            elseif app.area.State
                plot_phiarea(info,app.FFsave,app.a,td,[2,2],0)
                plot_phidarea(info,app.FFsave,app.a,td,[2,2],0)
            end           
            plot_zyy(info,app.FFsave,app.a,td,[])            
            drawnow 
        end

        % Menu selected function: FiguresMenu
        function ExportEVMatrixCallback(app, event)
            try
                f = figure('Name',['Captured Snapshots - ',app.EVTabGroup.SelectedTab.Title],'WindowStyle','docked');
                copyobj(app.EVTabGroup.SelectedTab.Children,f);
            catch
            end
        end

        % Menu selected function: FigureMenu
        function ExportEVLineCallback(app, event)
            try
                f = figure('Name','Captured Snapshots - Eigenvalues','WindowStyle','docked');
                copyobj(app.EGlineTab.Children,f);
            catch
            end
        end

        % Callback function
        function ExportEVSelectedMatrixCallback(app, event)
            plot_egmat_selectedwindows([],app.EV,[],1)
        end

        % Clicked callback: points
        function pointsClicked(app, event)
            if app.points.State
                app.area.State = false;
            else
                app.area.State = true;               
            end
            pause(0.2)
            DotsAreaCallback(app)
        end

        % Clicked callback: area
        function areaClicked(app, event)
            if app.area.State
                app.points.State = false;
            else
                app.points.State = true;               
            end
            pause(0.2)
            DotsAreaCallback(app)
        end

        % Callback function
        function UITableCellSelection(app, event)
            indices = event.Indices;
            ind = indices(1,1);

            list = findall(app.evbargrid,'type','uitab');
            tablist = {(list.Title)}';

            targ = find(strcmp(app.UITable.Data(ind),tablist));
            app.tabgroup.SelectedTab = app.tab(targ);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Results';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadFileMenu = uimenu(app.FileMenu);
            app.LoadFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileCallback, true);
            app.LoadFileMenu.Text = 'Load File';
            app.ExporttoMTDIMMenu = uimenu(app.FileMenu);
            app.ExporttoMTDIMMenu.Text = 'Export to MTDIM';
            app.FFprocMenu = uimenu(app.UIFigure);
            app.FFprocMenu.Text = 'FFproc';
            app.NewJobMenu = uimenu(app.FFprocMenu);
            app.NewJobMenu.Text = 'New Job';
            app.CombineResultsMenu = uimenu(app.FFprocMenu);
            app.CombineResultsMenu.Text = 'Combine Results';
            app.RAWResultsMenu = uimenu(app.FFprocMenu);
            app.RAWResultsMenu.Text = 'RAW Results';
            app.ExtrasMenu = uimenu(app.UIFigure);
            app.ExtrasMenu.Text = 'Extras';
            app.PhaseTensorPropertiesMenu_2 = uimenu(app.ExtrasMenu);
            app.PhaseTensorPropertiesMenu_2.Enable = 'off';
            app.PhaseTensorPropertiesMenu_2.Text = 'Phase Tensor Properties';
            app.EigenvaluePartialCorrelationCoefficientsSpectrogramsMenu = uimenu(app.ExtrasMenu);
            app.EigenvaluePartialCorrelationCoefficientsSpectrogramsMenu.Enable = 'off';
            app.EigenvaluePartialCorrelationCoefficientsSpectrogramsMenu.Text = 'Eigenvalue/Partial Correlation Coefficients Spectrograms';
            app.EigenvaluePartialCorrelationCoefficientsCriterionMenu_2 = uimenu(app.ExtrasMenu);
            app.EigenvaluePartialCorrelationCoefficientsCriterionMenu_2.Enable = 'off';
            app.EigenvaluePartialCorrelationCoefficientsCriterionMenu_2.Text = 'Eigenvalue/Partial Correlation Coefficients Criterion';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.Text = 'Documentation';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.points = uitoggletool(app.Toolbar);
            app.points.Tooltip = {'Plot Errorbars'};
            app.points.ClickedCallback = createCallbackFcn(app, @pointsClicked, true);
            app.points.Icon = 'FFproc_points.png';
            app.points.State = 'on';
            app.area = uitoggletool(app.Toolbar);
            app.area.Tooltip = {'Plot Areas'};
            app.area.ClickedCallback = createCallbackFcn(app, @areaClicked, true);
            app.area.Icon = 'FFproc_area.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TransferFunctionsTab = uitab(app.TabGroup);
            app.TransferFunctionsTab.Title = 'Transfer Functions';
            app.TipperandPhaseTensorTab = uitab(app.TabGroup);
            app.TipperandPhaseTensorTab.Title = 'Tipper and Phase Tensor';
            app.GridLayout9 = uigridlayout(app.TipperandPhaseTensorTab);
            app.TipperPanel = uipanel(app.GridLayout9);
            app.TipperPanel.Title = 'Tipper';
            app.PhaseTensorPanel = uipanel(app.GridLayout9);
            app.PhaseTensorPanel.Title = 'Phase Tensor';
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.GridLayout14);
            app.SiteLabel = uilabel(app.GridLayout13);
            app.SiteLabel.Text = 'Site:';
            app.popsite = uidropdown(app.GridLayout13);
            app.popsite.Items = {};
            app.popsite.ValueChangedFcn = createCallbackFcn(app, @ChangeSiteCallback, true);
            app.popsite.Value = {};
            app.GridLayout12_2 = uigridlayout(app.GridLayout14);
            app.perminLabel = uilabel(app.GridLayout12_2);
            app.perminLabel.Text = 'per min';
            app.permin = uieditfield(app.GridLayout12_2, 'numeric');
            app.permin.ValueChangedFcn = createCallbackFcn(app, @PeriodValueCallback, true);
            app.permin.Value = -4;
            app.permaxLabel = uilabel(app.GridLayout12_2);
            app.permaxLabel.Text = 'per max';
            app.permax = uieditfield(app.GridLayout12_2, 'numeric');
            app.permax.ValueChangedFcn = createCallbackFcn(app, @PeriodValueCallback, true);
            app.permax.Value = 3;
            app.rhominLabel = uilabel(app.GridLayout12_2);
            app.rhominLabel.Text = 'rho min';
            app.rhomin = uieditfield(app.GridLayout12_2, 'numeric');
            app.rhomin.ValueChangedFcn = createCallbackFcn(app, @RhoValueCallback, true);
            app.rhomaxLabel = uilabel(app.GridLayout12_2);
            app.rhomaxLabel.Text = 'rho max';
            app.rhomax = uieditfield(app.GridLayout12_2, 'numeric');
            app.rhomax.ValueChangedFcn = createCallbackFcn(app, @RhoValueCallback, true);
            app.rhomax.Value = 4;
            app.phiminLabel = uilabel(app.GridLayout12_2);
            app.phiminLabel.Text = 'phi min';
            app.phimin = uieditfield(app.GridLayout12_2, 'numeric');
            app.phimin.ValueChangedFcn = createCallbackFcn(app, @PhaseValueCallback, true);
            app.phimaxLabel = uilabel(app.GridLayout12_2);
            app.phimaxLabel.Text = 'phi max';
            app.phimax = uieditfield(app.GridLayout12_2, 'numeric');
            app.phimax.ValueChangedFcn = createCallbackFcn(app, @PhaseValueCallback, true);
            app.phimax.Value = 90;
            app.ptminLabel = uilabel(app.GridLayout12_2);
            app.ptminLabel.Text = 'pt min';
            app.ptmin = uieditfield(app.GridLayout12_2, 'numeric');
            app.ptmin.ValueChangedFcn = createCallbackFcn(app, @PTValueCallback, true);
            app.ptmaxLabel = uilabel(app.GridLayout12_2);
            app.ptmaxLabel.Text = 'pt max';
            app.ptmax = uieditfield(app.GridLayout12_2, 'numeric');
            app.ptmax.ValueChangedFcn = createCallbackFcn(app, @PTValueCallback, true);
            app.ptmax.Value = 90;
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportMenu = uimenu(app.ContextMenu);
            app.ExportMenu.Text = 'Export';
            app.FiguresMenu = uimenu(app.ExportMenu);
            app.FiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportEVMatrixCallback, true);
            app.FiguresMenu.Text = 'Figures';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ExportMenu_2 = uimenu(app.ContextMenu2);
            app.ExportMenu_2.Text = 'Export';
            app.FigureMenu = uimenu(app.ExportMenu_2);
            app.FigureMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportEVLineCallback, true);
            app.FigureMenu.Text = 'Figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_Results2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end