classdef FFproc_Spectra < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        all                         matlab.ui.container.toolbar.ToggleTool
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3_3               matlab.ui.container.GridLayout
        StationSelectionLabel       matlab.ui.control.Label
        StationSelectionDropDown    matlab.ui.control.DropDown
        Panel_2                     matlab.ui.container.Panel
        GridLayout3_2               matlab.ui.container.GridLayout
        TimeWindowSelectionListBoxLabel  matlab.ui.control.Label
        TimeWindowSelectionListBox  matlab.ui.control.ListBox
        Panel                       matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        TargetFrequencySelectionDropDownLabel  matlab.ui.control.Label
        TargetFrequencySelectionDropDown  matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        FourierCoefficientsTab      matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        FCPanel                     matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        FittedButton                matlab.ui.control.StateButton
        MahalanobisButton           matlab.ui.control.StateButton
        OriginalButton              matlab.ui.control.StateButton
        NoiseCovarianceMatrixSpectralDensityMatrixTab  matlab.ui.container.Tab
        GridLayout6                 matlab.ui.container.GridLayout
        MATPanel                    matlab.ui.container.Panel
        EMFieldsInteractiveSelectionFDTab  matlab.ui.container.Tab
        GridLayout7                 matlab.ui.container.GridLayout
        FCInteractivePanel          matlab.ui.container.Panel
        GridLayout9                 matlab.ui.container.GridLayout
        YComponent                  matlab.ui.control.DropDown
        ComponentDropDown_2Label    matlab.ui.control.Label
        YChannel                    matlab.ui.control.DropDown
        ChannelDropDown_3Label      matlab.ui.control.Label
        YStation                    matlab.ui.control.DropDown
        StationDropDown_2Label      matlab.ui.control.Label
        GridLayout8                 matlab.ui.container.GridLayout
        XStation                    matlab.ui.control.DropDown
        StationDropDownLabel        matlab.ui.control.Label
        XComponent                  matlab.ui.control.DropDown
        ComponentDropDownLabel      matlab.ui.control.Label
        XChannel                    matlab.ui.control.DropDown
        ChannelDropDown_2Label      matlab.ui.control.Label
        TransferFunctionsInteractiveSelectionTab  matlab.ui.container.Tab
        GridLayout7_2               matlab.ui.container.GridLayout
        TFInteractivePanel          matlab.ui.container.Panel
        GridLayout9_2               matlab.ui.container.GridLayout
        YComponentTF                matlab.ui.control.DropDown
        ComponentDropDown_2Label_2  matlab.ui.control.Label
        YTF                         matlab.ui.control.DropDown
        TransferFunctionLabel       matlab.ui.control.Label
        YStationTF                  matlab.ui.control.DropDown
        StationDropDown_2Label_2    matlab.ui.control.Label
        GridLayout8_2               matlab.ui.container.GridLayout
        XStationTF                  matlab.ui.control.DropDown
        StationDropDownLabel_2      matlab.ui.control.Label
        XComponentTF                matlab.ui.control.DropDown
        ComponentDropDownLabel_2    matlab.ui.control.Label
        XTF                         matlab.ui.control.DropDown
        TransferFunctionLabel_2     matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        FigureMenu                  matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        ind_tf, ind_stat, ind_tw
    end
    
    methods (Access = private)
        
        function plotFC(app)
            global FC TF
            FFproc_plotFC(app,FC,app.ind_tf,app.ind_stat,app.ind_tw)
            FFproc_plotmatrix(app,FC,app.ind_tf,app.ind_tw)
            FFproc_FCInteractive(app,FC,app.ind_tf,app.ind_tw) 
            FFproc_TFInteractive(app,TF,app.ind_tf,app.ind_tw)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global font 
            font = 'Verdana';
            app.FCPanel.AutoResizeChildren = 'off';
            app.MATPanel.AutoResizeChildren = 'off';
            app.FCInteractivePanel.AutoResizeChildren = 'off';   
            app.TFInteractivePanel.AutoResizeChildren = 'off';   
        end

        % Value changed function: TargetFrequencySelectionDropDown
        function TargetFrequencySelectionDropDownValueChanged(app, event)
            global FC
            app.ind_tf = str2double(app.TargetFrequencySelectionDropDown.Value);
            app.TimeWindowSelectionListBox.Items = string((1:numel(FC{app.ind_tf})));            
            plotFC(app)
        end

        % Value changed function: StationSelectionDropDown
        function StationSelectionDropDownValueChanged(app, event)
            global FC
            app.ind_stat = str2double(app.StationSelectionDropDown.Value);
            FFproc_plotFC(app,FC,app.ind_tf,app.ind_stat,app.ind_tw)
        end

        % Value changed function: TimeWindowSelectionListBox
        function TimeWindowSelectionListBoxValueChanged(app, event)
            app.ind_tw = str2double(app.TimeWindowSelectionListBox.Value);
            plotFC(app)
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            global FC TF
            
            [user,~] = getuser;
            % Path for each FFproc user (Documents)
            cd(fullfile(user,'Documents','FFMT','FFproc','Saving','FFproc Processing'))
            
            [file,path] = uigetfile('*.mat','Open Fourier Coefficients output file');
            if file == 0
                figure(app.UIFigure)
                return
            else
                load(fullfile(path,file))
                figure(app.UIFigure)
                
                app.TargetFrequencySelectionDropDown.Items = string((1:numel(FC)));
                app.StationSelectionDropDown.Items = string((1:(size(FC{1}(1).NCM,1)/5)));
                app.XStation.Items = string((1:(size(FC{1}(1).NCM,1)/5)));
                app.YStation.Items = string((1:(size(FC{1}(1).NCM,1)/5)));
                app.XStationTF.Items = string((1:(size(FC{1}(1).NCM,1)/5)));
                app.YStationTF.Items = string((1:(size(FC{1}(1).NCM,1)/5)));
                app.TimeWindowSelectionListBox.Items = string((1:numel(FC{1}))); 
                app.ind_tf = 1;
                app.ind_stat = 1;
                app.ind_tw = 1;
    
                plotFC(app) 
            end                         
        end

        % Value changed function: FittedButton, MahalanobisButton, 
        % ...and 1 other component
        function OriginalButtonValueChanged(app, event)
            plotFC(app)            
        end

        % On callback: all
        function allOn(app, event)
            app.TimeWindowSelectionListBox.Enable = 'on';
            plotFC(app)
        end

        % Off callback: all
        function allOff(app, event)
            app.TimeWindowSelectionListBox.Enable = 'off';
            plotFC(app)
        end

        % Value changed function: XChannel, XComponent, XStation, 
        % ...and 3 other components
        function XStationValueChanged(app, event)
            global FC
            FFproc_FCInteractive(app,FC,app.ind_tf,app.ind_tw)            
        end

        % Value changed function: XComponentTF, XStationTF, XTF, 
        % ...and 3 other components
        function XStationTFValueChanged(app, event)
            global TF
            FFproc_TFInteractive(app,TF,app.ind_tf,app.ind_tw)
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            try
                f = figure('Name','Captured Snapshots - Spectra: Estimations','WindowStyle','docked');
                copyobj(app.FCPanel.Children,f);
            catch
            end
            try
                f = figure('Name','Captured Snapshots - Spectra: Matrices','WindowStyle','docked');
                copyobj(app.MATPanel.Children,f);
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Spectra';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'FFproc_plus.png';
            app.all = uitoggletool(app.Toolbar);
            app.all.Icon = 'FFproc_filter.png';
            app.all.Separator = 'on';
            app.all.OffCallback = createCallbackFcn(app, @allOff, true);
            app.all.OnCallback = createCallbackFcn(app, @allOn, true);
            app.all.State = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FourierCoefficientsTab = uitab(app.TabGroup);
            app.FourierCoefficientsTab.Title = 'Fourier Coefficients';
            app.GridLayout4 = uigridlayout(app.FourierCoefficientsTab);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.OriginalButton = uibutton(app.GridLayout5, 'state');
            app.OriginalButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.OriginalButton.Text = 'Original Signal';
            app.MahalanobisButton = uibutton(app.GridLayout5, 'state');
            app.MahalanobisButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.MahalanobisButton.Text = 'After Mahalanobis OutlierDetection';
            app.FittedButton = uibutton(app.GridLayout5, 'state');
            app.FittedButton.ValueChangedFcn = createCallbackFcn(app, @OriginalButtonValueChanged, true);
            app.FittedButton.Text = 'Estimated Signal';
            app.FittedButton.Value = true;
            app.FCPanel = uipanel(app.GridLayout4);
            app.NoiseCovarianceMatrixSpectralDensityMatrixTab = uitab(app.TabGroup);
            app.NoiseCovarianceMatrixSpectralDensityMatrixTab.Title = 'Noise Covariance Matrix/Spectral Density Matrix';
            app.GridLayout6 = uigridlayout(app.NoiseCovarianceMatrixSpectralDensityMatrixTab);
            app.MATPanel = uipanel(app.GridLayout6);
            app.EMFieldsInteractiveSelectionFDTab = uitab(app.TabGroup);
            app.EMFieldsInteractiveSelectionFDTab.Title = 'EM Fields Interactive Selection (FD)';
            app.GridLayout7 = uigridlayout(app.EMFieldsInteractiveSelectionFDTab);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.ChannelDropDown_2Label = uilabel(app.GridLayout8);
            app.ChannelDropDown_2Label.Text = 'Channel';
            app.XChannel = uidropdown(app.GridLayout8);
            app.XChannel.Items = {'Ex', 'Ey', 'Bx', 'By', 'Bz'};
            app.XChannel.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.XChannel.Value = 'Ex';
            app.ComponentDropDownLabel = uilabel(app.GridLayout8);
            app.ComponentDropDownLabel.Text = 'Component';
            app.XComponent = uidropdown(app.GridLayout8);
            app.XComponent.Items = {'Real', 'Imaginary'};
            app.XComponent.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.XComponent.Value = 'Real';
            app.StationDropDownLabel = uilabel(app.GridLayout8);
            app.StationDropDownLabel.Text = 'Station';
            app.XStation = uidropdown(app.GridLayout8);
            app.XStation.Items = {};
            app.XStation.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.XStation.Value = {};
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.StationDropDown_2Label = uilabel(app.GridLayout9);
            app.StationDropDown_2Label.Text = 'Station';
            app.YStation = uidropdown(app.GridLayout9);
            app.YStation.Items = {};
            app.YStation.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.YStation.Value = {};
            app.ChannelDropDown_3Label = uilabel(app.GridLayout9);
            app.ChannelDropDown_3Label.Text = 'Channel';
            app.YChannel = uidropdown(app.GridLayout9);
            app.YChannel.Items = {'Ex', 'Ey', 'Bx', 'By', 'Bz'};
            app.YChannel.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.YChannel.Value = 'Ex';
            app.ComponentDropDown_2Label = uilabel(app.GridLayout9);
            app.ComponentDropDown_2Label.Text = 'Component';
            app.YComponent = uidropdown(app.GridLayout9);
            app.YComponent.Items = {'Real', 'Imaginary'};
            app.YComponent.ValueChangedFcn = createCallbackFcn(app, @XStationValueChanged, true);
            app.YComponent.Value = 'Real';
            app.FCInteractivePanel = uipanel(app.GridLayout7);
            app.TransferFunctionsInteractiveSelectionTab = uitab(app.TabGroup);
            app.TransferFunctionsInteractiveSelectionTab.Title = 'Transfer Functions Interactive Selection';
            app.GridLayout7_2 = uigridlayout(app.TransferFunctionsInteractiveSelectionTab);
            app.GridLayout8_2 = uigridlayout(app.GridLayout7_2);
            app.TransferFunctionLabel_2 = uilabel(app.GridLayout8_2);
            app.TransferFunctionLabel_2.Text = 'Transfer Function';
            app.XTF = uidropdown(app.GridLayout8_2);
            app.XTF.Items = {'Zxx', 'Zxy', 'Zyx', 'Zyy', 'txz', 'tyz'};
            app.XTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.XTF.Value = 'Zxx';
            app.ComponentDropDownLabel_2 = uilabel(app.GridLayout8_2);
            app.ComponentDropDownLabel_2.Text = 'Component';
            app.XComponentTF = uidropdown(app.GridLayout8_2);
            app.XComponentTF.Items = {'Real', 'Imaginary'};
            app.XComponentTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.XComponentTF.Value = 'Real';
            app.StationDropDownLabel_2 = uilabel(app.GridLayout8_2);
            app.StationDropDownLabel_2.Text = 'Station';
            app.XStationTF = uidropdown(app.GridLayout8_2);
            app.XStationTF.Items = {};
            app.XStationTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.XStationTF.Value = {};
            app.GridLayout9_2 = uigridlayout(app.GridLayout7_2);
            app.StationDropDown_2Label_2 = uilabel(app.GridLayout9_2);
            app.StationDropDown_2Label_2.Text = 'Station';
            app.YStationTF = uidropdown(app.GridLayout9_2);
            app.YStationTF.Items = {};
            app.YStationTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.YStationTF.Value = {};
            app.TransferFunctionLabel = uilabel(app.GridLayout9_2);
            app.TransferFunctionLabel.Text = 'Transfer Function';
            app.YTF = uidropdown(app.GridLayout9_2);
            app.YTF.Items = {'Zxx', 'Zxy', 'Zyx', 'Zyy', 'txz', 'tyz'};
            app.YTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.YTF.Value = 'Zxx';
            app.ComponentDropDown_2Label_2 = uilabel(app.GridLayout9_2);
            app.ComponentDropDown_2Label_2.Text = 'Component';
            app.YComponentTF = uidropdown(app.GridLayout9_2);
            app.YComponentTF.Items = {'Real', 'Imaginary'};
            app.YComponentTF.ValueChangedFcn = createCallbackFcn(app, @XStationTFValueChanged, true);
            app.YComponentTF.Value = 'Real';
            app.TFInteractivePanel = uipanel(app.GridLayout7_2);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.TargetFrequencySelectionDropDown = uidropdown(app.GridLayout3);
            app.TargetFrequencySelectionDropDown.Items = {};
            app.TargetFrequencySelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetFrequencySelectionDropDownValueChanged, true);
            app.TargetFrequencySelectionDropDown.Value = {};
            app.TargetFrequencySelectionDropDownLabel = uilabel(app.GridLayout3);
            app.TargetFrequencySelectionDropDownLabel.Text = 'Target Frequency Selection:';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3_2 = uigridlayout(app.Panel_2);
            app.TimeWindowSelectionListBox = uilistbox(app.GridLayout3_2);
            app.TimeWindowSelectionListBox.Items = {};
            app.TimeWindowSelectionListBox.Multiselect = 'on';
            app.TimeWindowSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @TimeWindowSelectionListBoxValueChanged, true);
            app.TimeWindowSelectionListBox.Value = {};
            app.TimeWindowSelectionListBoxLabel = uilabel(app.GridLayout3_2);
            app.TimeWindowSelectionListBoxLabel.Text = 'Time Window Selection:';
            app.GridLayout3_3 = uigridlayout(app.GridLayout2);
            app.StationSelectionDropDown = uidropdown(app.GridLayout3_3);
            app.StationSelectionDropDown.Items = {};
            app.StationSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @StationSelectionDropDownValueChanged, true);
            app.StationSelectionDropDown.Value = {};
            app.StationSelectionLabel = uilabel(app.GridLayout3_3);
            app.StationSelectionLabel.Text = 'Station Selection:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.FigureMenu = uimenu(app.ContextMenu);
            app.FigureMenu.Text = 'Figure';
            app.ExportMenu = uimenu(app.FigureMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            % Assign app.ContextMenu
            app.FCPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_Spectra

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end