classdef FFproc_ChooseStatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        MultiSiteButton   matlab.ui.control.Button
        SingleSiteButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'units','pixels')
            ss = get(0,'ScreenSize');
            app.UIFigure.Position = [(ss(3)/2) - (app.UIFigure.Position(3)/2),...
                                     (ss(4)/2) - (app.UIFigure.Position(4)/2),...
                                      app.UIFigure.Position(3),...
                                      app.UIFigure.Position(4)];
        end

        % Button pushed function: SingleSiteButton
        function SingleSiteButtonPushed(app, event)
            FFproc_StatisticsSingle
        end

        % Button pushed function: MultiSiteButton
        function MultiSiteButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Statistics';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SingleSiteButton = uibutton(app.GridLayout, 'push');
            app.SingleSiteButton.ButtonPushedFcn = createCallbackFcn(app, @SingleSiteButtonPushed, true);
            app.SingleSiteButton.Text = 'Single - Site';
            app.MultiSiteButton = uibutton(app.GridLayout, 'push');
            app.MultiSiteButton.ButtonPushedFcn = createCallbackFcn(app, @MultiSiteButtonPushed, true);
            app.MultiSiteButton.Text = 'Multi - Site';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_ChooseStatistics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end