classdef FFproc_PlotStatistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Toolbar               matlab.ui.container.Toolbar
        PushTool              matlab.ui.container.toolbar.PushTool
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        zmax                  matlab.ui.control.EditField
        zmaxLabel             matlab.ui.control.Label
        zmin                  matlab.ui.control.EditField
        zminLabel             matlab.ui.control.Label
        ymax                  matlab.ui.control.EditField
        ymaxLabel             matlab.ui.control.Label
        ymin                  matlab.ui.control.EditField
        yminLabel             matlab.ui.control.Label
        xmax                  matlab.ui.control.EditField
        xmaxLabel             matlab.ui.control.Label
        xmin                  matlab.ui.control.EditField
        xminLabel             matlab.ui.control.Label
        ylabel                matlab.ui.control.EditField
        ylabelEditFieldLabel  matlab.ui.control.Label
        zlabel                matlab.ui.control.EditField
        zlabelEditFieldLabel  matlab.ui.control.Label
        xlabel                matlab.ui.control.EditField
        xlabelEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: xmax, xmin
        function XValueCallback(app, event)
            ax = findall(app.Panel.Children.Children,'Type','axes');

            if isa(ax(1).XTick(1),'datetime')
                ax(1).XLim = [datetime(datevec(datenum(app.xmin.Value))),datetime(datevec(datenum(app.xmax.Value)))];
            else
                ax(1).XLim = [app.xmin.Value,app.xmax.Value];
            end
        end

        % Value changed function: ymax, ymin
        function YValueCallback(app, event)
            ax = findall(app.Panel.Children.Children,'Type','axes');

            if isa(ax(1).YTick(1),'datetime')
                ax(1).YLim = [datetime(datevec(datenum(app.ymin.Value))),datetime(datevec(datenum(app.ymax.Value)))];
            else
                ax(1).YLim = [str2double(app.ymin.Value),str2double(app.ymax.Value)];
            end            
        end

        % Value changed function: zmax, zmin
        function ZValueCallback(app, event)
            ax = findall(app.Panel.Children.Children,'Type','axes');
            cb = findall(app.Panel.Children.Children,'Type','colorbar');

            for i = 1:numel(ax)
                if isa(cb.Ticks(1),'datetime')

                else
                    caxis(ax(i),[str2double(app.zmin.Value),str2double(app.zmax.Value)])
                end
            end

            if isa(cb.Ticks(1),'datetime')
                caxis(ax(1),[datetime(datevec(datenum(app.zmin.Value))),datetime(datevec(datenum(app.zmax.Value)))]);
            else
                caxis(ax(1),[str2double(app.zmin.Value),str2double(app.zmax.Value)]);
            end
        end

        % Value changed function: xlabel
        function xlabelValueCallback(app, event)
            ax = findall(app.Panel.Children.Children,'Type','axes');
            for i = 1:numel(ax)
                xlabel(ax(i),app.xlabel.Value);
            end
        end

        % Value changed function: ylabel
        function ylabelValueCallback(app, event)
            ax = findall(app.Panel.Children.Children,'Type','axes');
            for i = 1:numel(ax)
                ylabel(ax(i),app.ylabel.Value);
            end
        end

        % Value changed function: zlabel
        function zlabelValueCallback(app, event)
            cb = findall(app.Panel.Children.Children,'Type','colorbar');
            cb.Label.String = app.zlabel.Value;
        end

        % Clicked callback: PushTool
        function CaptureSnapshotCallback(app, event)
            % To capture an snapshot in different window
            f = figure('Name',['FFproc - Statistics: ',app.Panel.Children.Title.String(4:end)],'WindowStyle','docked');  
            dlg = uiprogressdlg(app.UIFigure,'Title','FFproc - Statistics','Message','Capturing Snapshots','Indeterminate','on');
            copyobj(app.Panel.Children,f)
            td = f.Children;
            td.Padding = 'loose';
            td.Children(1).Layout.Tile = 'east';
            close(dlg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Plot Statistics';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @CaptureSnapshotCallback, true);
            app.PushTool.Icon = 'FFproc_export.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.xlabelEditFieldLabel = uilabel(app.GridLayout2);
            app.xlabelEditFieldLabel.Text = 'xlabel';
            app.xlabel = uieditfield(app.GridLayout2, 'text');
            app.xlabel.ValueChangedFcn = createCallbackFcn(app, @xlabelValueCallback, true);
            app.zlabelEditFieldLabel = uilabel(app.GridLayout2);
            app.zlabelEditFieldLabel.Text = 'zlabel';
            app.zlabel = uieditfield(app.GridLayout2, 'text');
            app.zlabel.ValueChangedFcn = createCallbackFcn(app, @zlabelValueCallback, true);
            app.ylabelEditFieldLabel = uilabel(app.GridLayout2);
            app.ylabelEditFieldLabel.Text = 'ylabel';
            app.ylabel = uieditfield(app.GridLayout2, 'text');
            app.ylabel.ValueChangedFcn = createCallbackFcn(app, @ylabelValueCallback, true);
            app.xminLabel = uilabel(app.GridLayout2);
            app.xminLabel.Text = 'xmin';
            app.xmin = uieditfield(app.GridLayout2, 'text');
            app.xmin.ValueChangedFcn = createCallbackFcn(app, @XValueCallback, true);
            app.xmaxLabel = uilabel(app.GridLayout2);
            app.xmaxLabel.Text = 'xmax';
            app.xmax = uieditfield(app.GridLayout2, 'text');
            app.xmax.ValueChangedFcn = createCallbackFcn(app, @XValueCallback, true);
            app.yminLabel = uilabel(app.GridLayout2);
            app.yminLabel.Text = 'ymin';
            app.ymin = uieditfield(app.GridLayout2, 'text');
            app.ymin.ValueChangedFcn = createCallbackFcn(app, @YValueCallback, true);
            app.ymaxLabel = uilabel(app.GridLayout2);
            app.ymaxLabel.Text = 'ymax';
            app.ymax = uieditfield(app.GridLayout2, 'text');
            app.ymax.ValueChangedFcn = createCallbackFcn(app, @YValueCallback, true);
            app.zminLabel = uilabel(app.GridLayout2);
            app.zminLabel.Text = 'zmin';
            app.zmin = uieditfield(app.GridLayout2, 'text');
            app.zmin.ValueChangedFcn = createCallbackFcn(app, @ZValueCallback, true);
            app.zmaxLabel = uilabel(app.GridLayout2);
            app.zmaxLabel.Text = 'zmax';
            app.zmax = uieditfield(app.GridLayout2, 'text');
            app.zmax.ValueChangedFcn = createCallbackFcn(app, @ZValueCallback, true);
            app.Panel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_PlotStatistics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end