classdef FFproc_StatisticsSingle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Toolbar            matlab.ui.container.Toolbar
        PushTool           matlab.ui.container.toolbar.PushTool
        PushTool2          matlab.ui.container.toolbar.PushTool
        PushTool3          matlab.ui.container.toolbar.PushTool
        cloud              matlab.ui.container.toolbar.ToggleTool
        spectrogram        matlab.ui.container.toolbar.ToggleTool
        GridLayout         matlab.ui.container.GridLayout
        GridLayout28       matlab.ui.container.GridLayout
        segmenttable       matlab.ui.control.Table
        osc                matlab.ui.control.NumericEditField
        tfreq              matlab.ui.control.NumericEditField
        fmin               matlab.ui.control.NumericEditField
        fmax               matlab.ui.control.NumericEditField
        OscillationsLabel  matlab.ui.control.Label
        FreqDecadeLabel    matlab.ui.control.Label
        MinFreqHzLabel     matlab.ui.control.Label
        MaxFreqHzLabel     matlab.ui.control.Label
        infotable          matlab.ui.control.Table
        GridLayout29       matlab.ui.container.GridLayout
        AxisButtonGroup    matlab.ui.container.ButtonGroup
        zaxis              matlab.ui.control.Label
        yaxis              matlab.ui.control.Label
        xaxis              matlab.ui.control.Label
        ZButton            matlab.ui.control.RadioButton
        YButton            matlab.ui.control.RadioButton
        XButton            matlab.ui.control.RadioButton
        UnivariatePanel    matlab.ui.container.Panel
        GridLayout30       matlab.ui.container.GridLayout
        uvZYYimButton      matlab.ui.control.Button
        uvZYXimButton      matlab.ui.control.Button
        uvZXYimButton      matlab.ui.control.Button
        uvZXXimButton      matlab.ui.control.Button
        uvTYZimButton      matlab.ui.control.Button
        uvTXZimButton      matlab.ui.control.Button
        tButton            matlab.ui.control.Button
        polBButton         matlab.ui.control.Button
        polEButton         matlab.ui.control.Button
        uvTYZreButton      matlab.ui.control.Button
        uvTXZreButton      matlab.ui.control.Button
        powBZButton        matlab.ui.control.Button
        uvphiYYButton      matlab.ui.control.Button
        uvrhoaYYButton     matlab.ui.control.Button
        cohEYBYButton      matlab.ui.control.Button
        uvZYYreButton      matlab.ui.control.Button
        powBYButton        matlab.ui.control.Button
        uvphiYXButton      matlab.ui.control.Button
        uvrhoaYXButton     matlab.ui.control.Button
        cohEYBXButton      matlab.ui.control.Button
        uvZYXreButton      matlab.ui.control.Button
        powBXButton        matlab.ui.control.Button
        uvphiXYButton      matlab.ui.control.Button
        uvrhoaXYButton     matlab.ui.control.Button
        cohEXBYButton      matlab.ui.control.Button
        uvZXYreButton      matlab.ui.control.Button
        powEYButton        matlab.ui.control.Button
        uvphiXXButton      matlab.ui.control.Button
        uvrhoaXXButton     matlab.ui.control.Button
        cohEXBXButton      matlab.ui.control.Button
        uvZXXreButton      matlab.ui.control.Button
        powEXButton        matlab.ui.control.Button
        MultivariatePanel  matlab.ui.container.Panel
        GridLayout30_2     matlab.ui.container.GridLayout
        pcohBYBZButton     matlab.ui.control.Button
        pcohBXBZButton     matlab.ui.control.Button
        bvTYZimButton      matlab.ui.control.Button
        bvTXZimButton      matlab.ui.control.Button
        bvTYZreButton      matlab.ui.control.Button
        bvTXZreButton      matlab.ui.control.Button
        bvZYYimButton      matlab.ui.control.Button
        bvZYXimButton      matlab.ui.control.Button
        bvZXYimButton      matlab.ui.control.Button
        bvZXXimButton      matlab.ui.control.Button
        bcohEYButton       matlab.ui.control.Button
        bcohEXButton       matlab.ui.control.Button
        bvphiYYButton      matlab.ui.control.Button
        bvrhoaYYButton     matlab.ui.control.Button
        pcohEYBYButton     matlab.ui.control.Button
        bvZYYreButton      matlab.ui.control.Button
        bvphiYXButton      matlab.ui.control.Button
        bvrhoaYXButton     matlab.ui.control.Button
        pcohEYBXButton     matlab.ui.control.Button
        bvZYXreButton      matlab.ui.control.Button
        bvphiXYButton      matlab.ui.control.Button
        bvrhoaXYButton     matlab.ui.control.Button
        pcohEXBYButton     matlab.ui.control.Button
        bvZXYreButton      matlab.ui.control.Button
        bvphiXXButton      matlab.ui.control.Button
        bvrhoaXXButton     matlab.ui.control.Button
        pcohEXBXButton     matlab.ui.control.Button
        bvZXXreButton      matlab.ui.control.Button
    end

    properties (Access = private)
        ts
        FC
        tfreq_vect
        n_tseg, t_seg, n_samples, osc_opt
        tfreq_label
    end
    
    methods (Access = private)
        
        function calculateFC(app)
            % Input variables
            tfreq_lim = find_tfreq(app.tfreq.Value,app.fmin.Value,app.fmax.Value);
            app.tfreq_vect = mean(tfreq_lim,2);

            % Preallocating variables (vector)
            ntseg = NaN(numel(app.tfreq_vect),1);
            nsamples = NaN(numel(app.tfreq_vect),1);
            tseg = NaN(numel(app.tfreq_vect),1);
            oscopt = NaN(numel(app.tfreq_vect),1);
            
            app.FC = struct;
            for f = 1:numel(app.tfreq_vect)
                clear F 
                F = cell(numel(app.ts),max(cat(1,app.ts(1).nchan)));
                
                [D,ntseg(f),nsamples(f),tseg(f),oscopt(f),~,~,~,t] = ts_segment(app.ts,app.ts.sr,tfreq_lim(f,:),app.tfreq_vect(f),f,app.osc.Value,true);
            
                % Samples id of frequency vector used to estimate each target frequency
                freq_id = round(tfreq_lim(f,:)*nsamples(f)/app.ts(1).sr);               
                
                % Calculation of the Fourier Transform for each time segment (w), 
                % for each channel (k) and for each site (j)
                for j = 1:numel(app.ts)
                    % Data calibration
                    C = calibration(nsamples(f),app.ts.cal);
                    for k = 1:app.ts(j).nchan
                        % Extracting each channel with n_tseg time segments
                        dmat = D{j,k};     
                        % Fourier Transform of each data channel Dmat
                        Dmat = fft(dmat);
                        % Creating calibration matrix Cmat
                        Cmat = repmat(C{j},1,ntseg(f));
                        % Matrix with calibrated Fourier Coefficients
                        Fmat = Dmat.*Cmat;
                        % Taking only 1/2 of the fft
                        Fmat = Fmat(1:end/2,:);
                        % Cell array with j sites and k channels. Each entry
                        % has only Fourier Coefficients around the target
                        % frequency.
                        F{j,k} = Fmat(freq_id(2):freq_id(1),:);
                        % Releasing memory
                        clear dmat Fmat Dmat Cmat
                    end
                    app.FC(j).EX(f).data = F{1};
                    app.FC(j).EY(f).data = F{2};
                    app.FC(j).BX(f).data = F{3};
                    app.FC(j).BY(f).data = F{4};
                    app.FC(j).BZ(f).data = F{5};                    
                end
            
                % Time vector for segmentation
                app.FC(j).t(f).data = datetime(datevec(t));         
            end 

            % Saving segments output
            app.n_tseg = ntseg;
            app.n_samples = nsamples;
            app.t_seg = tseg;
            app.osc_opt = oscopt;

            % Creating frequency label
            for f = 1:numel(app.tfreq_vect)
                if app.tfreq_vect(f) < 1
                    app.tfreq_label{f} = [num2str(round(1./app.tfreq_vect(f),2)),' (s)'];
                else
                    app.tfreq_label{f} = [num2str(round(app.tfreq_vect(f),2)),' (Hz)'];
                end
            end            
        end

        function calculatePARAM(app)

            scale = 1000;

            for f = 1:numel(app.tfreq_vect)

                EX = app.FC.EX(f).data;
                EY = app.FC.EY(f).data;
                BX = app.FC.BX(f).data;
                BY = app.FC.BY(f).data;
                BZ = app.FC.BZ(f).data;

                % --------------------- UNIVARIATE ---------------------- %
                % --------------------- STATISTICS ---------------------- %                
                % powEX
                app.FC.powEX(f).data = pow(app,EX,app.t_seg(f));
                % powEY
                app.FC.powEY(f).data = pow(app,EY,app.t_seg(f));
                % powBX
                app.FC.powBX(f).data = pow(app,BX,app.t_seg(f));
                % powBY
                app.FC.powBY(f).data = pow(app,BY,app.t_seg(f));
                % powBZ
                app.FC.powBZ(f).data = pow(app,BZ,app.t_seg(f));

                % coh EXBX
                app.FC.cohEXBX(f).data = coh(app,EX,BX);
                % coh EXBY
                app.FC.cohEXBY(f).data = coh(app,EX,BY);
                % coh EYBX
                app.FC.cohEYBX(f).data = coh(app,EY,BX);
                % coh EYBY
                app.FC.cohEYBY(f).data = coh(app,EY,BY);

                % uvZXX
                app.FC.uvZXX(f).data = -imp(app,EX,BX)*scale;
                % uvZXXre
                app.FC.uvZXXre(f).data = real(app.FC.uvZXX(f).data);
                % uvZXXim
                app.FC.uvZXXim(f).data = imag(app.FC.uvZXX(f).data);

                % uvZXY  
                app.FC.uvZXY(f).data = -imp(app,EX,BY)*scale;
                % uvZXYre
                app.FC.uvZXYre(f).data = real(app.FC.uvZXY(f).data);
                % uvZXYim
                app.FC.uvZXYim(f).data = imag(app.FC.uvZXY(f).data);

                % uvZYX
                app.FC.uvZYX(f).data = -imp(app,EY,BX)*scale;
                % uvZYXre
                app.FC.uvZYXre(f).data = real(app.FC.uvZYX(f).data);
                % uvZYXim
                app.FC.uvZYXim(f).data = imag(app.FC.uvZYX(f).data);

                % uvZYY
                app.FC.uvZYY(f).data = -imp(app,EY,BY)*scale;
                % uvZYYre
                app.FC.uvZYYre(f).data = real(app.FC.uvZYY(f).data);
                % uvZYYim
                app.FC.uvZYYim(f).data = imag(app.FC.uvZYY(f).data);
                
                % uvTXZ
                app.FC.uvTXZ(f).data = tipper(app,BZ,BX);
                % uvTXZre
                app.FC.uvTXZre(f).data = real(app.FC.uvTXZ(f).data);
                % uvTXZim
                app.FC.uvTXZim(f).data = imag(app.FC.uvTXZ(f).data);

                % uvTYZ
                app.FC.uvTYZ(f).data = tipper(app,BZ,BY);
                % uvTYZre
                app.FC.uvTYZre(f).data = real(app.FC.uvTYZ(f).data);
                % uvTYZim
                app.FC.uvTYZim(f).data = imag(app.FC.uvTYZ(f).data);

                % polE
                app.FC.polE(f).data = pol(app,EX,EY);
                % polB
                app.FC.polB(f).data = pol(app,BX,BY);

                % uvrhoXX
                app.FC.uvrhoaXX(f).data = rho(app,app.FC.uvZXX(f).data,app.tfreq_vect(f));
                % uvrhoXY
                app.FC.uvrhoaXY(f).data = rho(app,app.FC.uvZXY(f).data,app.tfreq_vect(f));
                % uvrhoYX
                app.FC.uvrhoaYX(f).data = rho(app,app.FC.uvZYX(f).data,app.tfreq_vect(f));
                % uvrhoYY
                app.FC.uvrhoaYY(f).data = rho(app,app.FC.uvZYY(f).data,app.tfreq_vect(f));

                % uvphiXX
                app.FC.uvphiXX(f).data = phi(app,app.FC.uvZXX(f).data);
                % uvphiXY
                app.FC.uvphiXY(f).data = phi(app,app.FC.uvZXY(f).data);
                % uvphiYX
                app.FC.uvphiYX(f).data = phi(app,app.FC.uvZYX(f).data);
                % uvphiYY
                app.FC.uvphiYY(f).data = phi(app,app.FC.uvZYY(f).data);

                % bcohEX
                app.FC.bcohEX(f).data = bcoh(app,EX,BX,BY);
                % bcohEY
                app.FC.bcohEY(f).data = bcoh(app,EY,BX,BY);

                % ---------------------- BIVARIATE ---------------------- %
                % --------------------- STATISTICS ---------------------- %
                % Partial Correlation Coefficients
                pcohEXBX = zeros(app.n_tseg(f),1);
                pcohEXBY = zeros(app.n_tseg(f),1);
                pcohEYBX = zeros(app.n_tseg(f),1);
                pcohEYBY = zeros(app.n_tseg(f),1);
                pcohBXBZ = zeros(app.n_tseg(f),1);
                pcohBYBZ = zeros(app.n_tseg(f),1);
                for w = 1:app.n_tseg(f)
                    pcohEXBX(w,1) = abs(partialcorr(EX(:,w),BX(:,w),BY(:,w)));
                    pcohEXBY(w,1) = abs(partialcorr(EX(:,w),BY(:,w),BX(:,w)));
                    pcohEYBX(w,1) = abs(partialcorr(EY(:,w),BX(:,w),BY(:,w)));
                    pcohEYBY(w,1) = abs(partialcorr(EY(:,w),BY(:,w),BX(:,w)));
                    pcohBXBZ(w,1) = abs(partialcorr(BZ(:,w),BY(:,w),BX(:,w)));
                    pcohBYBZ(w,1) = abs(partialcorr(BZ(:,w),BX(:,w),BY(:,w)));
                end
                % pcohEXBX
                app.FC.pcohEXBX(f).data = pcohEXBX;
                % pcohEXBY
                app.FC.pcohEXBY(f).data = pcohEXBY;
                % pcohEYBX
                app.FC.pcohEYBX(f).data = pcohEYBX;
                % pcohEYBY
                app.FC.pcohEYBY(f).data = pcohEYBY;
                % pcohEYBX
                app.FC.pcohBXBZ(f).data = pcohBXBZ;
                % pcohEYBY
                app.FC.pcohBYBZ(f).data = pcohBYBZ;


                % bvZXX
                app.FC.bvZXX(f).data = -bivarZ(app,'XX',EX,EY,BX,BY,BX,BY)*scale;
                % bvZXXre
                app.FC.bvZXXre(f).data = real(app.FC.bvZXX(f).data);
                % bvZXXim
                app.FC.bvZXXim(f).data = imag(app.FC.bvZXX(f).data);

                % bvZXY
                app.FC.bvZXY(f).data = -bivarZ(app,'XY',EX,EY,BX,BY,BX,BY)*scale;
                % bvZXYre
                app.FC.bvZXYre(f).data = real(app.FC.bvZXY(f).data);
                % bvZXYim
                app.FC.bvZXYim(f).data = imag(app.FC.bvZXY(f).data);

                % bvZYX
                app.FC.bvZYX(f).data = -bivarZ(app,'YX',EX,EY,BX,BY,BX,BY)*scale;
                % bvZYXre
                app.FC.bvZYXre(f).data = real(app.FC.bvZYX(f).data);
                % bvZYXim
                app.FC.bvZYXim(f).data = imag(app.FC.bvZYX(f).data);

                % bvZYY
                app.FC.bvZYY(f).data = -bivarZ(app,'YY',EX,EY,BX,BY,BX,BY)*scale;
                % bvZYYre
                app.FC.bvZYYre(f).data = real(app.FC.bvZYY(f).data);
                % bvZYYim
                app.FC.bvZYYim(f).data = imag(app.FC.bvZYY(f).data);

                % bvTXZ
                app.FC.bvTXZ(f).data = bivarT(app,'XZ',BX,BY,BZ,BX,BY);
                % bvTXZre
                app.FC.bvTXZre(f).data = real(app.FC.bvTXZ(f).data);
                % bvTXZim
                app.FC.bvTXZim(f).data = imag(app.FC.bvTXZ(f).data);

                % bvTYZ
                app.FC.bvTYZ(f).data = bivarT(app,'YZ',BX,BY,BZ,BX,BY);
                % bvTYZre
                app.FC.bvTYZre(f).data = real(app.FC.bvTYZ(f).data);
                % bvTYZim
                app.FC.bvTYZim(f).data = imag(app.FC.bvTYZ(f).data);

                % bvrhoXX
                app.FC.bvrhoaXX(f).data = rho(app,app.FC.bvZXX(f).data,app.tfreq_vect(f));
                % bvrhoXY
                app.FC.bvrhoaXY(f).data = rho(app,app.FC.bvZXY(f).data,app.tfreq_vect(f));
                % bvrhoYX
                app.FC.bvrhoaYX(f).data = rho(app,app.FC.bvZYX(f).data,app.tfreq_vect(f));
                % bvrhoYY
                app.FC.bvrhoaYY(f).data = rho(app,app.FC.bvZYY(f).data,app.tfreq_vect(f));

                % bvphiXX
                app.FC.bvphiXX(f).data = phi(app,app.FC.bvZXX(f).data);
                % bvphiXY
                app.FC.bvphiXY(f).data = phi(app,app.FC.bvZXY(f).data);
                % bvphiYX
                app.FC.bvphiYX(f).data = phi(app,app.FC.bvZYX(f).data);
                % bvphiYY
                app.FC.bvphiYY(f).data = phi(app,app.FC.bvZYY(f).data);
            end
        end

        function Zij = imp(~,Ei,Bj)
            EconjB = sum(Ei.*conj(Bj),1);
            BB = sum(Bj.*Bj,1);
            Zij = EconjB./abs(BB);
        end

        function Tzh = tipper(~,Bz,Bh)
            BzconjBh = sum(Bz.*conj(Bh),1);
            BB = sum(Bh.*Bh,1);
            Tzh = BzconjBh./abs(BB);
        end

        function RHO = rho(~,Z,f)
            mu0 = 4*pi*1e-07;
            omega = 2*pi.*f;
            RHO = log10((mu0/omega)*(abs(Z)*1e03).^2);
        end

        function PHI = phi(~,Z)
            PHI = rad2deg(atan2(imag(Z),real(Z)));
        end

        function P = pow(~,A,t_seg)
            AconjA = sum(A.*conj(A),1);
            P = log10(sqrt(AconjA.^2/t_seg));
        end

        function C = coh(~,X,Y)
            % Univariate coherence following Jenkins ans Watts (1968)
            % coh2(X,Y) = abs(<XY*><XY*>)/<XX*><YY*>
            XconjY = sum(X.*conj(Y),1);
            XconjX = sum(X.*conj(X),1);
            YconjY = sum(Y.*conj(Y),1);
            C = abs(XconjY).^2./(XconjX.*YconjY);
        end        

        function C = bcoh(~,X,Y1,Y2,Z1,Z2)
            % Bivariate coherence between inputs Y1 and Y2 and output X
            % following Jenkins ans Watts (1968)
            % Ratio of predicted to measured signal energy between output
            % and input channels under the assumption of a linear
            % relationship between them.
            % bivarcoh2(A,B) = (<Y1X*>+<Y2X*>)/<XX*>
            Y1Xconj = sum(Y1.*conj(X),1);
            Y2Xconj = sum(Y2.*conj(X),1);
            XXconj = sum(X.*conj(X),1);
            C = 1;
%             C = ((Z1.*Y1Xconj) + (Z2.*Y2Xconj))./XXconj;
        end    

        function C = pcoh(~,rb,ru)
            % Partial coherence following Jenkins ans Watts (1968)
            % ru : Univariate coherence between X and Y
            % rb : Bivariate coherence between inputs Y1 and Y2 and output X 
            C = (rb - ru)./(1 - ru);
        end

        function P = pol(~,X1,X2)
            % Polarization direction of E or B wave field following Fowler
            % et al. (1967)
            % P = atan(2*real(X1X2*)/(X1X1* - X2X2*))
            X1conjX2 = sum(X1.*conj(X2));
            X1conjX1 = sum(X1.*conj(X1));
            X2conjX2 = sum(X2.*conj(X2));
            P = rad2deg(atan(2*real(X1conjX2)./(X1conjX1-X2conjX2)));
        end

        function Z = bivarZ(~,comp,EX,EY,BX,BY,BXR,BYR)
            % Following Vozoff (1972) and Simpson and Bahr (2005)
            BXconjBXR = sum(BX.*conj(BXR),1);
            BYconjBYR = sum(BY.*conj(BYR),1);
            BXconjBYR = sum(BX.*conj(BYR),1);
            BYconjBXR = sum(BY.*conj(BXR),1);
            DET  = (BXconjBXR.*BYconjBYR) - (BXconjBYR.*BYconjBXR);
            switch comp
                case 'XX'
                    % Nominator
                    EXconjBXR = sum(EX.*conj(BXR),1);
                    BYconjBYR = sum(BY.*conj(BYR),1);
                    EXconjBYR = sum(EX.*conj(BYR),1);
                    BYconjBXR = sum(BY.*conj(BXR),1);
                    % Impedance 
                    Z = ((EXconjBXR.*BYconjBYR) - (EXconjBYR.*BYconjBXR)) ./ DET;
                case 'XY'      
                    % Nominator
                    EXconjBYR = sum(EX.*conj(BYR),1);
                    BXconjBXR = sum(BX.*conj(BXR),1);
                    EXconjBXR = sum(EX.*conj(BXR),1);
                    BXconjBYR = sum(BX.*conj(BYR),1);                    
                    % Impedance  
                    Z = ((EXconjBYR.*BXconjBXR) - (EXconjBXR.*BXconjBYR)) ./ DET;
                case 'YX'
                    % Nominator
                    EYconjBXR = sum(EY.*conj(BXR),1);
                    BYconjBYR = sum(BY.*conj(BYR),1);                    
                    EYconjBYR = sum(EY.*conj(BYR),1);
                    BYconjBXR = sum(BY.*conj(BXR),1);
                    % Impedance 
                    Z = ((EYconjBXR.*BYconjBYR) - (EYconjBYR.*BYconjBXR)) ./ DET;
                case 'YY'
                    % Nominator                    
                    EYconjBYR = sum(EY.*conj(BYR),1);
                    BXconjBXR = sum(BX.*conj(BXR),1);
                    EYconjBXR = sum(EY.*conj(BXR),1);
                    BXconjBYR = sum(BX.*conj(BYR),1);
                    % Impedance 
                    Z = ((EYconjBYR.*BXconjBXR) - (EYconjBXR.*BXconjBYR)) ./ DET;
            end
        end

        function T = bivarT(~,comp,BX,BY,BZ,BXR,BYR)
            % Following Vozoff (1972) and Simpson and Bahr (2005)
            BXconjBXR = sum(BX.*conj(BXR),1);
            BYconjBYR = sum(BY.*conj(BYR),1);
            BXconjBYR = sum(BX.*conj(BYR),1);
            BYconjBXR = sum(BY.*conj(BXR),1);
            DET  = (BXconjBXR.*BYconjBYR) - (BXconjBYR.*BYconjBXR);
            switch comp
                case 'XZ'
                    % Nominator
                    BZconjBXR = sum(BZ.*conj(BXR),1);
                    BYconjBYR = sum(BY.*conj(BYR),1);
                    BZconjBYR = sum(BZ.*conj(BYR),1);
                    BYconjBXR = sum(BY.*conj(BXR),1);
                    % Impedance 
                    T = ((BZconjBXR.*BYconjBYR) - (BZconjBYR.*BYconjBXR)) ./ DET;
                case 'YZ'      
                    % Nominator
                    BZconjBYR = sum(BZ.*conj(BYR),1);
                    BXconjBXR = sum(BX.*conj(BXR),1);
                    BZconjBXR = sum(BZ.*conj(BXR),1);
                    BXconjBYR = sum(BX.*conj(BYR),1);                    
                    % Impedance  
                    T = ((BZconjBYR.*BXconjBXR) - (BZconjBXR.*BXconjBYR)) ./ DET;
            end
        end

        function populatesegmenttable(app)
            app.segmenttable.Data = [app.tfreq_label',num2cell(app.osc_opt(:)),num2cell(app.n_tseg(:)),num2cell(app.t_seg(:)),num2cell(app.n_samples(:))];
        end

        function populatesstationtable(app)
            % Update table
            ReadTS_filltable(app,app.ts,'FFproc-Statistics'); 
        end
        
        function plotscatter(~,freq,var1,var2,var3,lab1,lab2,lab3,inscb,td)
     
                ax = nexttile(td);
                scatter(ax,var1,var2,7.5,var3,'filled');
                title(ax,freq)
                xlabel(ax,lab1)
                ylabel(ax,lab2)
    
                set(ax,'FontName','DejaVu Sans','FontSize',11,'Box','on')
                
                % Color axis
                colormap(ax,flipud(colors('viridis',100)))

                if inscb
                    cb = colorbar(ax);                
                    cb.Layout.Tile = 'east';   
                    cb.TickDirection = 'both';
                    cb.Label.String = lab3;
                end
            
        end        
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'units','pixels')
            ss = get(0,'ScreenSize');
            app.UIFigure.Position = [(ss(3)/2) - (app.UIFigure.Position(3)/2),...
                                     (ss(4)/2) - (app.UIFigure.Position(4)/2),...
                                                  app.UIFigure.Position(3),...
                                                  app.UIFigure.Position(4)];
        end

        % Button pushed function: bcohEXButton, bcohEYButton, 
        % ...and 58 other components
        function SelectComponentCallback(app, event)
            opt = event.Source.Text;

            switch app.AxisButtonGroup.SelectedObject.Text
                case 'X'
                    app.xaxis.Text = opt;
                case 'Y'
                    app.yaxis.Text = opt;
                case 'Z'
                    app.zaxis.Text = opt;
            end
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            [file,path] = uigetfile({'*.*', 'All Files (*.*)'; ...
                         '*.ts2;*.ts3;*.ts4;*.ts5;','Phoenix (*.ts*)';
                         '*.ats','Metronix (*.ats)';
                         '*.ts10','LEMI (*.t10)';
                         '*.RAW','EMERALD (*.RAW)';
                         '*.ML1;*.ML2;','Geolore (*.ML*)';
                         '*.asc','Uppsala ASCII (*.asc)';
                         '*.sec;*.min;','Intermagnet Observatory (*.sec,*.min)';
                         '*.mat','MatTS (*.mat)'},...
                         'Select input Time Series');   

            figure(app.UIFigure)
            dlg = uiprogressdlg(app.UIFigure,'Indeterminate','on');
            dlg.Message = ['Loading Time Series: ',file];
            pause(0.2)

                % Load a new time series                
                app.ts = ts_input(path,file,true,[]);
    
                % Populate station table
                populatesstationtable(app)

                % Time Series gaps
                app.ts = findgaps(app.ts);   
    
                % Calculate Fourier Coefficients          
                dlg.Message = 'Fourier Transform of E and B';
                calculateFC(app)                

                % Populate segment table                
                pause(0.2)
                populatesegmenttable(app)

                % Calculate Statistics
                pause(0.2)
                dlg.Message = 'Calculating Statistics';
                calculatePARAM(app)                
                
            close(dlg)
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            dlg = uiprogressdlg(app.UIFigure,'Indeterminate','on');
            pause(0.2)

                % Time Series gaps
                app.ts = findgaps(app.ts);   
    
                % Calculate Fourier Coefficients          
                dlg.Message = 'Fourier Transform of E and B';
                calculateFC(app)                

                % Populate segment table                
                pause(0.2)
                populatesegmenttable(app)

                % Calculate Statistics
                pause(0.2)
                dlg.Message = 'Calculating Statistics';
                calculatePARAM(app)                
                
            close(dlg)
        end

        % Clicked callback: cloud
        function cloudClicked(app, event)
            
        end

        % Clicked callback: spectrogram
        function spectrogramClicked(app, event)
            
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            targ = FFproc_PlotStatistics;
            
            txt1 = app.xaxis.Text;
            txt2 = app.yaxis.Text;
            txt3 = app.zaxis.Text; 

            var1 = app.FC.(txt1);
            var2 = app.FC.(txt2);
            var3 = app.FC.(txt3);
            
            dlg = uiprogressdlg(targ.UIFigure,'Indeterminate','on');
            dlg.Message = 'Creating plots';
            pause(0.2)

                td = tiledlayout('flow','TileSpacing','tight','Padding','tight','Parent',targ.Panel);
    
                for f = 1:numel(app.tfreq_vect)
                    if f == numel(app.tfreq_vect)
                        inscb = true;
                    else
                        inscb = false;
                    end
                    if isa(var3(f).data,'datetime')
                        plotscatter(app,app.tfreq_label(f),var1(f).data,var2(f).data,datenum(var3(f).data),txt1,txt2,txt3,inscb,td)
                    else
                        plotscatter(app,app.tfreq_label(f),var1(f).data,var2(f).data,var3(f).data,txt1,txt2,txt3,inscb,td)
                    end    
                end

                try
                    minx = min(cat(1,var1.data));
                    maxx = max(cat(1,var1.data));
                catch
                    minx = min(extractfield(var1,'data'));
                    maxx = max(extractfield(var1,'data'));
                end
                try
                    miny = min(cat(1,var2.data));
                    maxy = max(cat(1,var2.data));
                catch
                    miny = min(extractfield(var2,'data'));
                    maxy = max(extractfield(var2,'data'));
                end
                try
                    minz = min(cat(1,var3.data));
                    maxz = max(cat(1,var3.data));
                catch              
                    minz = min(extractfield(var3,'data'));
                    maxz = max(extractfield(var3,'data'));
                end

                ax = findall(td.Children,'Type','axes');
                for f = 1:numel(app.tfreq_vect)
                    try
                        ax(f).XLim = [floor(minx),ceil(maxx)];
                        if f == numel(app.tfreq_vect)
                            targ.xmin.Value = num2str(floor(minx));
                            targ.xmax.Value = num2str(ceil(maxx));
                        end
                    catch
                        ax(f).XLim = [minx,maxx];
                        if f == numel(app.tfreq_vect)
                            targ.xmin.Value = string(minx);
                            targ.xmax.Value = string(maxx);
                        end
                    end
                    try
                        ax(f).YLim = [floor(miny),ceil(maxy)];
                        if f == numel(app.tfreq_vect)
                            targ.ymin.Value = num2str(floor(miny));
                            targ.ymax.Value = num2str(ceil(maxy));
                        end
                    catch
                        ax(f).YLim = [miny,maxy];
                        if f == numel(app.tfreq_vect)
                            targ.ymin.Value = string(miny);
                            targ.ymax.Value = string(maxy);
                        end
                    end
                    try
                        caxis(ax(f),[floor(minz),ceil(maxz)])
                        if f == numel(app.tfreq_vect)
                            targ.zmin.Value = num2str(floor(minz));
                            targ.zmax.Value = num2str(ceil(maxz));
                        end
                    catch
                        if isa(minz,'datetime') || isa(maxz,'datetime')
                            caxis(ax(f),[datenum(minz),datenum(maxz)])
                            targ.zmin.Value = string(minz);
                            targ.zmax.Value = string(maxz);
                        else
                            caxis(ax(f),[minz,maxz])
                            if f == numel(app.tfreq_vect)
                                targ.zmin.Value = num2str(floor(minz));
                                targ.zmax.Value = num2str(ceil(maxz));
                            end
                        end
                    end                    
                end  
                linkaxes(ax,'X');
                linkaxes(ax,'Y');
                targ.xlabel.Value = txt1;
                targ.ylabel.Value = txt2;
                targ.zlabel.Value = txt3;

                title(td,['\bf','X: ',txt1,' | Y: ',txt2,' | Z: ',txt3],'FontName','Verdana','FontSize',14)

            close(dlg)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FFproc - Statistics (Single Site)';
            app.UIFigure.Icon = 'FFproc_Logo.png';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = 'FFproc_plus.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'FFproc_update.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'FFproc_plot.png';
            app.PushTool3.Separator = 'on';
            app.cloud = uitoggletool(app.Toolbar);
            app.cloud.ClickedCallback = createCallbackFcn(app, @cloudClicked, true);
            app.cloud.Enable = 'off';
            app.cloud.Icon = 'FFproc_scatter.png';
            app.cloud.Separator = 'on';
            app.cloud.State = 'on';
            app.spectrogram = uitoggletool(app.Toolbar);
            app.spectrogram.ClickedCallback = createCallbackFcn(app, @spectrogramClicked, true);
            app.spectrogram.Enable = 'off';
            app.spectrogram.Icon = 'FFproc_spectrogram.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout29 = uigridlayout(app.GridLayout);
            app.MultivariatePanel = uipanel(app.GridLayout29);
            app.MultivariatePanel.Title = 'Multivariate';
            app.GridLayout30_2 = uigridlayout(app.MultivariatePanel);
            app.bvZXXreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZXXreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZXXreButton.Text = 'bvZXXre';
            app.pcohEXBXButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohEXBXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohEXBXButton.Text = 'pcohEXBX';
            app.bvrhoaXXButton = uibutton(app.GridLayout30_2, 'push');
            app.bvrhoaXXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvrhoaXXButton.Text = 'bvrhoaXX';
            app.bvphiXXButton = uibutton(app.GridLayout30_2, 'push');
            app.bvphiXXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvphiXXButton.Text = 'bvphiXX';
            app.bvZXYreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZXYreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZXYreButton.Text = 'bvZXYre';
            app.pcohEXBYButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohEXBYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohEXBYButton.Text = 'pcohEXBY';
            app.bvrhoaXYButton = uibutton(app.GridLayout30_2, 'push');
            app.bvrhoaXYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvrhoaXYButton.Text = 'bvrhoaXY';
            app.bvphiXYButton = uibutton(app.GridLayout30_2, 'push');
            app.bvphiXYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvphiXYButton.Text = 'bvphiXY';
            app.bvZYXreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZYXreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZYXreButton.Text = 'bvZYXre';
            app.pcohEYBXButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohEYBXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohEYBXButton.Text = 'pcohEYBX';
            app.bvrhoaYXButton = uibutton(app.GridLayout30_2, 'push');
            app.bvrhoaYXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvrhoaYXButton.Text = 'bvrhoaYX';
            app.bvphiYXButton = uibutton(app.GridLayout30_2, 'push');
            app.bvphiYXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvphiYXButton.Text = 'bvphiYX';
            app.bvZYYreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZYYreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZYYreButton.Text = 'bvZYYre';
            app.pcohEYBYButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohEYBYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohEYBYButton.Text = 'pcohEYBY';
            app.bvrhoaYYButton = uibutton(app.GridLayout30_2, 'push');
            app.bvrhoaYYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvrhoaYYButton.Text = 'bvrhoaYY';
            app.bvphiYYButton = uibutton(app.GridLayout30_2, 'push');
            app.bvphiYYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvphiYYButton.Text = 'bvphiYY';
            app.bcohEXButton = uibutton(app.GridLayout30_2, 'push');
            app.bcohEXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bcohEXButton.Text = 'bcohEX';
            app.bcohEYButton = uibutton(app.GridLayout30_2, 'push');
            app.bcohEYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bcohEYButton.Text = 'bcohEY';
            app.bvZXXimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZXXimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZXXimButton.Text = 'bvZXXim';
            app.bvZXYimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZXYimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZXYimButton.Text = 'bvZXYim';
            app.bvZYXimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZYXimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZYXimButton.Text = 'bvZYXim';
            app.bvZYYimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvZYYimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvZYYimButton.Text = 'bvZYYim';
            app.bvTXZreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvTXZreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvTXZreButton.Text = 'bvTXZre';
            app.bvTYZreButton = uibutton(app.GridLayout30_2, 'push');
            app.bvTYZreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvTYZreButton.Text = 'bvTYZre';
            app.bvTXZimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvTXZimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvTXZimButton.Text = 'bvTXZim';
            app.bvTYZimButton = uibutton(app.GridLayout30_2, 'push');
            app.bvTYZimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.bvTYZimButton.Text = 'bvTYZim';
            app.pcohBXBZButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohBXBZButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohBXBZButton.Text = 'pcohBXBZ';
            app.pcohBYBZButton = uibutton(app.GridLayout30_2, 'push');
            app.pcohBYBZButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.pcohBYBZButton.Text = 'pcohBYBZ';
            app.UnivariatePanel = uipanel(app.GridLayout29);
            app.UnivariatePanel.Title = 'Univariate';
            app.GridLayout30 = uigridlayout(app.UnivariatePanel);
            app.powEXButton = uibutton(app.GridLayout30, 'push');
            app.powEXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.powEXButton.Text = 'powEX';
            app.uvZXXreButton = uibutton(app.GridLayout30, 'push');
            app.uvZXXreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZXXreButton.Text = 'uvZXXre';
            app.cohEXBXButton = uibutton(app.GridLayout30, 'push');
            app.cohEXBXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.cohEXBXButton.Text = 'cohEXBX';
            app.uvrhoaXXButton = uibutton(app.GridLayout30, 'push');
            app.uvrhoaXXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvrhoaXXButton.Text = 'uvrhoaXX';
            app.uvphiXXButton = uibutton(app.GridLayout30, 'push');
            app.uvphiXXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvphiXXButton.Text = 'uvphiXX';
            app.powEYButton = uibutton(app.GridLayout30, 'push');
            app.powEYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.powEYButton.Text = 'powEY';
            app.uvZXYreButton = uibutton(app.GridLayout30, 'push');
            app.uvZXYreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZXYreButton.Text = 'uvZXYre';
            app.cohEXBYButton = uibutton(app.GridLayout30, 'push');
            app.cohEXBYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.cohEXBYButton.Text = 'cohEXBY';
            app.uvrhoaXYButton = uibutton(app.GridLayout30, 'push');
            app.uvrhoaXYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvrhoaXYButton.Text = 'uvrhoaXY';
            app.uvphiXYButton = uibutton(app.GridLayout30, 'push');
            app.uvphiXYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvphiXYButton.Text = 'uvphiXY';
            app.powBXButton = uibutton(app.GridLayout30, 'push');
            app.powBXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.powBXButton.Text = 'powBX';
            app.uvZYXreButton = uibutton(app.GridLayout30, 'push');
            app.uvZYXreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZYXreButton.Text = 'uvZYXre';
            app.cohEYBXButton = uibutton(app.GridLayout30, 'push');
            app.cohEYBXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.cohEYBXButton.Text = 'cohEYBX';
            app.uvrhoaYXButton = uibutton(app.GridLayout30, 'push');
            app.uvrhoaYXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvrhoaYXButton.Text = 'uvrhoaYX';
            app.uvphiYXButton = uibutton(app.GridLayout30, 'push');
            app.uvphiYXButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvphiYXButton.Text = 'uvphiYX';
            app.powBYButton = uibutton(app.GridLayout30, 'push');
            app.powBYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.powBYButton.Text = 'powBY';
            app.uvZYYreButton = uibutton(app.GridLayout30, 'push');
            app.uvZYYreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZYYreButton.Text = 'uvZYYre';
            app.cohEYBYButton = uibutton(app.GridLayout30, 'push');
            app.cohEYBYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.cohEYBYButton.Text = 'cohEYBY';
            app.uvrhoaYYButton = uibutton(app.GridLayout30, 'push');
            app.uvrhoaYYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvrhoaYYButton.Text = 'uvrhoaYY';
            app.uvphiYYButton = uibutton(app.GridLayout30, 'push');
            app.uvphiYYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvphiYYButton.Text = 'uvphiYY';
            app.powBZButton = uibutton(app.GridLayout30, 'push');
            app.powBZButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.powBZButton.Text = 'powBZ';
            app.uvTXZreButton = uibutton(app.GridLayout30, 'push');
            app.uvTXZreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvTXZreButton.Text = 'uvTXZre';
            app.uvTYZreButton = uibutton(app.GridLayout30, 'push');
            app.uvTYZreButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvTYZreButton.Text = 'uvTYZre';
            app.polEButton = uibutton(app.GridLayout30, 'push');
            app.polEButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.polEButton.Text = 'polE';
            app.polBButton = uibutton(app.GridLayout30, 'push');
            app.polBButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.polBButton.Text = 'polB';
            app.tButton = uibutton(app.GridLayout30, 'push');
            app.tButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.tButton.Text = 't';
            app.uvTXZimButton = uibutton(app.GridLayout30, 'push');
            app.uvTXZimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvTXZimButton.Text = 'uvTXZim';
            app.uvTYZimButton = uibutton(app.GridLayout30, 'push');
            app.uvTYZimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvTYZimButton.Text = 'uvTYZim';
            app.uvZXXimButton = uibutton(app.GridLayout30, 'push');
            app.uvZXXimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZXXimButton.Text = 'uvZXXim';
            app.uvZXYimButton = uibutton(app.GridLayout30, 'push');
            app.uvZXYimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZXYimButton.Text = 'uvZXYim';
            app.uvZYXimButton = uibutton(app.GridLayout30, 'push');
            app.uvZYXimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZYXimButton.Text = 'uvZYXim';
            app.uvZYYimButton = uibutton(app.GridLayout30, 'push');
            app.uvZYYimButton.ButtonPushedFcn = createCallbackFcn(app, @SelectComponentCallback, true);
            app.uvZYYimButton.Text = 'uvZYYim';
            app.AxisButtonGroup = uibuttongroup(app.GridLayout29);
            app.XButton = uiradiobutton(app.AxisButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.AxisButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.AxisButtonGroup);
            app.ZButton.Text = 'Z';
            app.xaxis = uilabel(app.AxisButtonGroup);
            app.xaxis.Text = '';
            app.yaxis = uilabel(app.AxisButtonGroup);
            app.yaxis.Text = '';
            app.zaxis = uilabel(app.AxisButtonGroup);
            app.zaxis.Text = '';
            app.GridLayout28 = uigridlayout(app.GridLayout);
            app.infotable = uitable(app.GridLayout28);
            app.infotable.ColumnName = {'File'; 'Sampling Rate (Hz)'; 'Start date/time'; 'End date/time'};
            app.infotable.RowName = {};
            app.MaxFreqHzLabel = uilabel(app.GridLayout28);
            app.MaxFreqHzLabel.Text = 'Max Freq (Hz):';
            app.MinFreqHzLabel = uilabel(app.GridLayout28);
            app.MinFreqHzLabel.Text = 'Min Freq (Hz):';
            app.FreqDecadeLabel = uilabel(app.GridLayout28);
            app.FreqDecadeLabel.Text = 'Frequencies per decade:';
            app.OscillationsLabel = uilabel(app.GridLayout28);
            app.OscillationsLabel.Text = 'Oscillations:';
            app.fmax = uieditfield(app.GridLayout28, 'numeric');
            app.fmax.Limits = [0 Inf];
            app.fmax.Tooltip = {'Maximum frequency range'; 'fmax = sr/2.8'};
            app.fmax.Value = 6;
            app.fmin = uieditfield(app.GridLayout28, 'numeric');
            app.fmin.Limits = [0 Inf];
            app.fmin.Tooltip = {'Minimum frequency range'; 'fmin = sr/200'};
            app.fmin.Value = 0.004;
            app.tfreq = uieditfield(app.GridLayout28, 'numeric');
            app.tfreq.Limits = [1 Inf];
            app.tfreq.Value = 6;
            app.osc = uieditfield(app.GridLayout28, 'numeric');
            app.osc.Limits = [0 Inf];
            app.osc.RoundFractionalValues = 'on';
            app.osc.Tooltip = {'Length of time windows'; 'for each target frequency'};
            app.osc.Value = 200;
            app.segmenttable = uitable(app.GridLayout28);
            app.segmenttable.ColumnName = {'Frequency/Period'; 'Oscillations'; 'Segments'; 'Time Segment (s)'; 'Samples'};
            app.segmenttable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFproc_StatisticsSingle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end